classdef RPAS_AQT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASAQTUIFigure              matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        CloseApplicationButton       matlab.ui.control.Button
        QualificationsPanel          matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        Twinscan                     matlab.ui.control.Button
        Qual                         matlab.ui.control.Button
        ContamQual                   matlab.ui.control.Button
        MagQual                      matlab.ui.control.Button
        AutoQual                     matlab.ui.control.Button
        AlignmentsPanel              matlab.ui.container.Panel
        Alignment                    matlab.ui.control.Button
        Tool_Cal                     matlab.ui.control.Button
        EngineeringMaintenancePanel  matlab.ui.container.Panel
        Motion_Val                   matlab.ui.control.Button
        Eng_par3                     matlab.ui.control.Button
        Eng_par2                     matlab.ui.control.Button
        Eng_par1                     matlab.ui.control.Button
        Motion_ValSetup              matlab.ui.control.Button
        Motion_setup                 matlab.ui.control.Button
        Sys_status                   matlab.ui.control.Button
        Sys_init                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        handles; % Description
    end
    
    methods (Access = private)
        
        function init(app)
            %add path to RPAS subroutines
            if ~isdeployed
                arch=computer('arch');
                path = mfilename('fullpath');
                if strcmp(arch, 'win32') || strcmp(arch, 'win64')
                    pattern='\';
                else
                    pattern='/';
                end
                j=strfind(path, pattern);
                if strcmp(path(j(end)+1:end), 'RPAS_AQT')
                    path=path(1:j(end)-1);
                    obj.RPAS_HOME=path;
                else
                    path=path(1:j(end-1)-1);
                    obj.RPAS_HOME = path;
                end
                addpath([path '/sublib']);
            end

            global RPAS_C;
            RPAS_C=RPAS_Constants(pwd);


%             fid=fopen('test.txt', 'wt');
%                 fprintf(fid,'RPAS_HOME=%s\n', RPAS_C.RPAS_HOME);
%                 fprintf(fid,'QUAL_RESULT_DIR=%s\n', RPAS_C.QUAL_RESULT_DIR);
%                 fprintf(fid,'QUAL_IMAGE_DIR=%s\n', RPAS_C.QUAL_IMAGE_DIR);
%                 fprintf(fid,'QUAL_DATA_SHEET_DIR=%s\n', RPAS_C.QUAL_DATA_SHEET_DIR);
%                 fprintf(fid,'SCREEN_IMAGE_DIR=%s\n', RPAS_C.SCREEN_IMAGE_DIR);
%                 fprintf(fid,'A3200Path=%s\n', RPAS_C.A3200Path);
% 
%                 fprintf(fid,'ImageJ_algorithm=%d\n', RPAS_C.ImageJ_algorithm);
%                 fprintf(fid,'CameraAvailable=%d\n', RPAS_C.CameraAvailable);
%                 fprintf(fid,'A3200Available=%d\n', RPAS_C.A3200Available);
% 
%                 fprintf(fid,'Pixel_obj_plane=%g\n', RPAS_C.Pixel_obj_plane);
%                 fprintf(fid,'Pixel_img_plane=%g\n', RPAS_C.Pixel_img_plane);
%                 fprintf(fid,'Outer_Frame_Width=%g\n', RPAS_C.Outer_Frame_Width);
%                 fprintf(fid,'Outer_Frame_Height=%g\n', RPAS_C.Outer_Frame_Height);
%                 fprintf(fid,'Iner_Frame_Width=%g\n', RPAS_C.Iner_Frame_Width);
%                 fprintf(fid,'Iner_Frame_Height=%g\n', RPAS_C.Iner_Frame_Height);
%                 fprintf(fid,'DIR = %s\n', pwd);
%             fclose(fid);

            app.Image.ImageSource='AQT.PNG';

            app.handles=struct('motionSetup', [], ...
                'motionSysValSetup', [], ...
                'motionSysVal', [], ...
                'engParam1', [], ...
                'engParam2', [], ...
                'engParam3', [], ...
                'toolCalib', [], ...
                'alignment', [], ...
                'autoQual', [], ...
                'magQual', [], ...
                'contamQual', [], ...
                'qual', [], ...
                'twinscan', []);

            app.Sys_init.Enable=false;
            app.Sys_status.Enable=false;
            app.Motion_setup.Enable=true;
            app.Motion_ValSetup.Enable=true;
            app.Motion_Val.Enable=true;
            app.Eng_par1.Enable=true;
            app.Eng_par2.Enable=true;
            app.Eng_par3.Enable=true;
            app.Tool_Cal.Enable=true;
            app.Alignment.Enable=true;
            app.AutoQual.Enable=true;
            app.MagQual.Enable=true;
            app.ContamQual.Enable=true;
            app.Qual.Enable=true;
            app.Twinscan.Enable=true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Button pushed function: Eng_par1
        function Eng_par1ButtonPushed(app, event)
            if isAuthorized()
                if isempty(app.handles.engParam1) | ~isvalid(app.handles.engParam1)
                    app.handles.engParam1=ENG_PARAM1();
                else
                    app.handles.engParam1.RPASEngineeringParameter1UIFigure.Visible=true;
                end
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: Eng_par2
        function Eng_par2ButtonPushed(app, event)
            if isAuthorized()
                if isempty(app.handles.engParam2) | ~isvalid(app.handles.engParam2)
                    app.handles.engParam2=ENG_PARAM2();
                else
                    app.handles.engParam2.RPASEngineeringParameter2UIFigure.Visible=true;
                end
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: Eng_par3
        function Eng_par3ButtonPushed(app, event)
            if isAuthorized()
                if isempty(app.handles.engParam3) | ~isvalid(app.handles.engParam3)
                    app.handles.engParam3=ENG_PARAM3();
                else
                    app.handles.engParam3.RPASEngineeringParameter3UIFigure.Visible=true;
                end
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: AutoQual
        function AutoQualButtonPushed(app, event)
            if isempty(app.handles.autoQual) | ~isvalid(app.handles.autoQual)
                app.handles.autoQual=RPAS_AutoQual();
            else
                %app.handles.autoQual.RPASAutoQualificationUIFigure.Visible=true;
            end
        end

        % Button pushed function: MagQual
        function MagQualButtonPushed(app, event)
            if isempty(app.handles.magQual) | ~isvalid(app.handles.magQual)
                app.handles.magQual=RPAS_Magnification();
            else
                app.handles.magQual.RPASMagnificationFocusQualificationUIFigure.Visible=true;
            end
        end

        % Button pushed function: ContamQual
        function ContamQualButtonPushed(app, event)
            if isempty(app.handles.contamQual) | ~isvalid(app.handles.contamQual)
                app.handles.contamQual=RPAS_ContamQual();
            else
                app.handles.contamQual.RPASDetectorContaminationQualificationUIFigure.Visible=true;
            end
        end

        % Button pushed function: Qual
        function QualButtonPushed(app, event)
            if isempty(app.handles.qual) | ~isvalid(app.handles.qual)
                app.handles.qual=RPAS_Qual();
            else
                app.handles.qual.RPASQualificationUIFigure.Visible=true;
            end
        end

        % Button pushed function: Alignment
        function AlignmentButtonPushed(app, event)
            if isempty(app.handles.alignment) | ~isvalid(app.handles.alignment)
                app.handles.alignment=RPAS_Alignment();
            else
                app.handles.alignment.RPASAlignmentUIFigure.Visible=true;
            end
        end

        % Button pushed function: Tool_Cal
        function Tool_CalButtonPushed(app, event)
            if isempty(app.handles.toolCalib) | ~isvalid(app.handles.toolCalib)
                app.handles.toolCalib=RPAS_Tool_Calib();
            else
                app.handles.toolCalib.RPASToolCalibrationUIFigure.Visible=true;
            end
        end

        % Button pushed function: Motion_setup
        function Motion_setupButtonPushed(app, event)
            if isAuthorized()
                if isempty(app.handles.motionSetup) | ~isvalid(app.handles.motionSetup)
                    app.handles.motionSetup=RPAS_MotionSetup();
                else
                    app.handles.motionSetup.RPASMotionSetupUIFigure.Visible=true;
                end
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: Motion_ValSetup
        function Motion_ValSetupButtonPushed(app, event)
            if isAuthorized()
                if isempty(app.handles.motionSysValSetup) | ~isvalid(app.handles.motionSysValSetup)
                    app.handles.motionSysValSetup=RPAS_MotionSysValSetup();
                else
                    app.handles.motionSysValSetup.RPASMotionSystemValidationSetupUIFigure.Visible=true;
                end
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: Twinscan
        function TwinscanButtonPushed(app, event)
            if isempty(app.handles.twinscan) | ~isvalid(app.handles.twinscan)
                app.handles.twinscan=RPAS_TwinScanQual();
            else
                app.handles.twinscan.RPASQualificationwTWINSCANUIFigure.Visible=true;
            end
        end

        % Button pushed function: CloseApplicationButton
        function CloseApplicationButtonPushed(app, event)
            names=fieldnames(app.handles);
            for k=1: numel(names)
                handle=getfield(app.handles, names{k});
                if ~isempty(handle) & isvalid(handle)
                    handle.close();
                end
            end
            delete(app);
        end

        % Close request function: RPASAQTUIFigure
        function RPASAQTUIFigureCloseRequest(app, event)
            app.CloseApplicationButtonPushed();
        end

        % Button pushed function: Motion_Val
        function Motion_ValButtonPushed(app, event)
            if isempty(app.handles.motionSysVal) | ~isvalid(app.handles.motionSysVal)
                app.handles.motionSysVal=RPAS_MotionSys_Val();
            else
                app.handles.motionSysVal.RPASMotionSystemValidationUIFigure.Visible=true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASAQTUIFigure = uifigure('Visible', 'off');
            app.RPASAQTUIFigure.Name = 'RPAS AQT';
            app.RPASAQTUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASAQTUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.RPASAQTUIFigure);
            app.EngineeringMaintenancePanel = uipanel(app.GridLayout);
            app.EngineeringMaintenancePanel.Title = 'Engineering Maintenance';
            app.Sys_init = uibutton(app.EngineeringMaintenancePanel, 'push');
            app.Sys_init.Text = 'System Initialization';
            app.Sys_status = uibutton(app.EngineeringMaintenancePanel, 'push');
            app.Sys_status.Text = {'System Status'; ''};
            app.Motion_setup = uibutton(app.EngineeringMaintenancePanel, 'push');
            app.Motion_setup.ButtonPushedFcn = createCallbackFcn(app, @Motion_setupButtonPushed, true);
            app.Motion_setup.Text = 'Motion Setup';
            app.Motion_ValSetup = uibutton(app.EngineeringMaintenancePanel, 'push');
            app.Motion_ValSetup.ButtonPushedFcn = createCallbackFcn(app, @Motion_ValSetupButtonPushed, true);
            app.Motion_ValSetup.Text = {'Motion System '; 'Validation Setup'};
            app.Eng_par1 = uibutton(app.EngineeringMaintenancePanel, 'push');
            app.Eng_par1.ButtonPushedFcn = createCallbackFcn(app, @Eng_par1ButtonPushed, true);
            app.Eng_par1.Text = 'Engineering Parameter 1';
            app.Eng_par2 = uibutton(app.EngineeringMaintenancePanel, 'push');
            app.Eng_par2.ButtonPushedFcn = createCallbackFcn(app, @Eng_par2ButtonPushed, true);
            app.Eng_par2.Text = {'Engineering Parameter 2'; ''};
            app.Eng_par3 = uibutton(app.EngineeringMaintenancePanel, 'push');
            app.Eng_par3.ButtonPushedFcn = createCallbackFcn(app, @Eng_par3ButtonPushed, true);
            app.Eng_par3.Text = {'Engineering Parameter 3'; ''};
            app.Motion_Val = uibutton(app.EngineeringMaintenancePanel, 'push');
            app.Motion_Val.ButtonPushedFcn = createCallbackFcn(app, @Motion_ValButtonPushed, true);
            app.Motion_Val.Text = 'Motion System Validation';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AlignmentsPanel = uipanel(app.GridLayout2);
            app.AlignmentsPanel.Title = 'Alignments';
            app.Tool_Cal = uibutton(app.AlignmentsPanel, 'push');
            app.Tool_Cal.ButtonPushedFcn = createCallbackFcn(app, @Tool_CalButtonPushed, true);
            app.Tool_Cal.Text = 'Tool Calibration';
            app.Alignment = uibutton(app.AlignmentsPanel, 'push');
            app.Alignment.ButtonPushedFcn = createCallbackFcn(app, @AlignmentButtonPushed, true);
            app.Alignment.Text = 'RPAS Alignments';
            app.QualificationsPanel = uipanel(app.GridLayout2);
            app.QualificationsPanel.Title = 'Qualifications';
            app.AutoQual = uibutton(app.QualificationsPanel, 'push');
            app.AutoQual.ButtonPushedFcn = createCallbackFcn(app, @AutoQualButtonPushed, true);
            app.AutoQual.Text = {'RPAS Automated '; 'Qualifications '; 'w/ Work Station'};
            app.MagQual = uibutton(app.QualificationsPanel, 'push');
            app.MagQual.ButtonPushedFcn = createCallbackFcn(app, @MagQualButtonPushed, true);
            app.MagQual.Text = {'RPAS Magnification/'; 'Focus Qualification'};
            app.ContamQual = uibutton(app.QualificationsPanel, 'push');
            app.ContamQual.ButtonPushedFcn = createCallbackFcn(app, @ContamQualButtonPushed, true);
            app.ContamQual.Text = {'RPAS Contamination'; 'Qualification'};
            app.Qual = uibutton(app.QualificationsPanel, 'push');
            app.Qual.ButtonPushedFcn = createCallbackFcn(app, @QualButtonPushed, true);
            app.Qual.Text = 'RPAS Qualification';
            app.Twinscan = uibutton(app.QualificationsPanel, 'push');
            app.Twinscan.ButtonPushedFcn = createCallbackFcn(app, @TwinscanButtonPushed, true);
            app.Twinscan.Text = {'RPAS Qualification'; 'w/ TWINSCAN'};
            app.Image = uiimage(app.QualificationsPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.CloseApplicationButton = uibutton(app.GridLayout3, 'push');
            app.CloseApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @CloseApplicationButtonPushed, true);
            app.CloseApplicationButton.Text = 'Close Application';
            % Show the figure after all components are created
            app.RPASAQTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_AQT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASAQTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASAQTUIFigure)
        end
    end
end