classdef ENG_PARAM1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASEngineeringParameter1UIFigure  matlab.ui.Figure
        EngineeringParameter1Panel      matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        BackToMainScreenButton          matlab.ui.control.Button
        RPASMagnificationFocusQualPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        FocusPanel                      matlab.ui.container.Panel
        MaxAllowableMTFHWHMforPassFailLabel  matlab.ui.control.Label
        MinAllowableMTFCenterAmpforPassFailLabel  matlab.ui.control.Label
        MinHWHMY                        matlab.ui.control.NumericEditField
        YEditField_3Label               matlab.ui.control.Label
        MinHWHMX                        matlab.ui.control.NumericEditField
        XEditField_3Label               matlab.ui.control.Label
        um1Label_2                      matlab.ui.control.Label
        um1Label                        matlab.ui.control.Label
        MinHWHMRecordY                  matlab.ui.control.Button
        MinHWHMRecordX                  matlab.ui.control.Button
        MinAmpY                         matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        MinAmpX                         matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        MinAmpRecordY                   matlab.ui.control.Button
        MinAmpRecordX                   matlab.ui.control.Button
        MagnificationPanel              matlab.ui.container.Panel
        MagErrLimit                     matlab.ui.control.NumericEditField
        MagErrorLimitforPassFailEditFieldLabel  matlab.ui.control.Label
        NomMag                          matlab.ui.control.NumericEditField
        NominalRPASImageMagEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        umumLabel                       matlab.ui.control.Label
        MagErrorLimitRecord             matlab.ui.control.Button
        MagRecord                       matlab.ui.control.Button
        NominalImageCapturePositionsPanel  matlab.ui.container.Panel
        NomPosY                         matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        NomPosX                         matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        NomPosRecordY                   matlab.ui.control.Button
        NomPosRecordX                   matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        RPASAlignmentPanel              matlab.ui.container.Panel
        RzError                         matlab.ui.control.NumericEditField
        AllowableRzErrorEditFieldLabel  matlab.ui.control.Label
        XYError                         matlab.ui.control.NumericEditField
        AllowableXYErrorEditFieldLabel  matlab.ui.control.Label
        uradLabel                       matlab.ui.control.Label
        umLabel                         matlab.ui.control.Label
        RzErrorRecord                   matlab.ui.control.Button
        XYErrorRecord                   matlab.ui.control.Button
        RPASImageCapturePanel           matlab.ui.container.Panel
        MinTime                         matlab.ui.control.NumericEditField
        MinimumTimeBetweenImageCapturesEditFieldLabel  matlab.ui.control.Label
        ImgConvFactor                   matlab.ui.control.NumericEditField
        ImageConversionFactorEditFieldLabel  matlab.ui.control.Label
        msecLabel                       matlab.ui.control.Label
        pxmmLabel                       matlab.ui.control.Label
        MinTimeRecord                   matlab.ui.control.Button
        ImgConvFactorRecord             matlab.ui.control.Button
        RPASTargetZPositionsPanel       matlab.ui.container.Panel
        ZRange                          matlab.ui.control.NumericEditField
        ZRangeEditFieldLabel            matlab.ui.control.Label
        SWDNomZ                         matlab.ui.control.NumericEditField
        SWDNominalZHeightaboveRSMFEditFieldLabel  matlab.ui.control.Label
        LWDNomZ                         matlab.ui.control.NumericEditField
        LWDNominalZHeightaboveRSMFEditFieldLabel  matlab.ui.control.Label
        ZOffset                         matlab.ui.control.NumericEditField
        ZOffsetHeightAboveRSMFtosetZ0Label  matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        ZrangeRecord                    matlab.ui.control.Button
        LWDRecord                       matlab.ui.control.Button
        mmLabel_2                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        SWDRecord                       matlab.ui.control.Button
        ZoffsetRecord                   matlab.ui.control.Button
        MotionPanel                     matlab.ui.container.Panel
        AutoSpeed                       matlab.ui.control.NumericEditField
        AutomaticQualScanSpeedEditFieldLabel  matlab.ui.control.Label
        ManualSpeed                     matlab.ui.control.NumericEditField
        ManualMotionSpeedEditFieldLabel  matlab.ui.control.Label
        mmsecLabel_2                    matlab.ui.control.Label
        mmsecLabel                      matlab.ui.control.Label
        AutoRecord                      matlab.ui.control.Button
        ManualRecord                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        rcdNames={'ManuSpeed', 'AutoSpeed', 'ZOffset', 'SWDZ', ...
            'LWDZ', 'Zrange', 'ImgConvFac', 'MinTime', ...
            'XYError', 'RzError', 'NormPosX', 'NormPosY', ...
            'NormMag', 'MagErrorLimit', 'MinAMPX', 'MinAMPY', ...
            'MinHWHMX', 'MinHWHMY'};
        systemVar; % Description
    end
    
    methods (Access = private)
        
        function updateRecords(app, rcdName)
            var=app.systemVar.engineeringPar1Var;
            switch rcdName
                case app.rcdNames{1}
                    var.ManualMotionSpeed=app.ManualSpeed.Value;
                case app.rcdNames{2}
                    var.AutoQualScanSpeed=app.AutoSpeed.Value;
                case app.rcdNames{3}
                    var.ZOffset=app.ZOffset.Value;
                case app.rcdNames{4}
                    var.SWDNominalZ=app.SWDNomZ.Value;
                case app.rcdNames{5}
                    var.LWDNominalZ=app.LWDNomZ.Value;
                case app.rcdNames{6}
                    var.ZRange=app.ZRange.Value;
                case app.rcdNames{7}
                    var.ImageConversFactor=app.ImgConvFactor.Value;
                case app.rcdNames{8}
                    var.MinTimeBetweenImageCaptures=app.MinTime.Value;
                case app.rcdNames{9}
                    var.AllowableXYError=app.XYError.Value;
                case app.rcdNames{10}
                    var.AllowableRzError=app.RzError.Value;
                case app.rcdNames{11}
                    var.NominalImageCapturePositions_X=app.NomPosX.Value;
                case app.rcdNames{12}
                    var.NominalImageCapturePositions_Y=app.NomPosY.Value;
                case app.rcdNames{13}
                    var.NominalRPASImageMagnification=app.NomMag.Value;
                case app.rcdNames{14}
                    var.MagErrorLimit=app.MagErrLimit.Value;
                case app.rcdNames{15}
                    var.MinAllowableMTFCenterAmp_X=app.MinAmpX.Value;
                case app.rcdNames{16}
                    var.MinAllowableMTFCenterAmp_Y=app.MinAmpY.Value;
                case app.rcdNames{17}
                    var.MaxAllowableMTFHWHM_X=app.MinHWHMX.Value;
                case app.rcdNames{18}
                    var.MaxAllowableMTFHWHM_Y=app.MinHWHMY.Value;
                otherwise
                    error('Unsupported record Name.');
            end
            app.systemVar.updateRecords(var, app.RPASEngineeringParameter1UIFigure.Tag);
        end
        
        function init(app)
            %read in system setup
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            
            %setup initial values
            var=app.systemVar.engineeringPar1Var;
            
            app.ManualSpeed.Value=var.ManualMotionSpeed;
            app.AutoSpeed.Value=var.AutoQualScanSpeed;
            app.ZOffset.Value=var.ZOffset;
            app.SWDNomZ.Value=var.SWDNominalZ;
            app.LWDNomZ.Value=var.LWDNominalZ;
            app.ZRange.Value=var.ZRange;
            app.ImgConvFactor.Value=var.ImageConversFactor;
            app.MinTime.Value=var.MinTimeBetweenImageCaptures;
            app.XYError.Value=var.AllowableXYError;
            app.RzError.Value=var.AllowableRzError;
            app.NomPosX.Value=var.NominalImageCapturePositions_X;
            app.NomPosY.Value=var.NominalImageCapturePositions_Y;
            app.NomMag.Value=var.NominalRPASImageMagnification;
            app.MagErrLimit.Value=var.MagErrorLimit;
            app.MinAmpX.Value=var.MinAllowableMTFCenterAmp_X;
            app.MinAmpY.Value=var.MinAllowableMTFCenterAmp_Y;
            app.MinHWHMX.Value=var.MaxAllowableMTFHWHM_X;
            app.MinHWHMY.Value=var.MaxAllowableMTFHWHM_Y;
        end
    end

    methods (Access = public)
        
        function close(app)
            app.closeRequestFcn();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Button pushed function: ManualRecord
        function ManualRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{1});
        end

        % Button pushed function: AutoRecord
        function AutoRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{2});
        end

        % Button pushed function: ZoffsetRecord
        function ZoffsetRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{3});
        end

        % Button pushed function: SWDRecord
        function SWDRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{4});
        end

        % Button pushed function: LWDRecord
        function LWDRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{5});
        end

        % Button pushed function: ZrangeRecord
        function ZrangeRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{6});
        end

        % Button pushed function: ImgConvFactorRecord
        function ImgConvFactorRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{7});
        end

        % Button pushed function: MinTimeRecord
        function MinTimeRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{8});
        end

        % Button pushed function: XYErrorRecord
        function XYErrorRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{9});
        end

        % Button pushed function: RzErrorRecord
        function RzErrorRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{10});
        end

        % Button pushed function: NomPosRecordX
        function NomPosRecordXButtonPushed(app, event)
            app.updateRecords(app.rcdNames{11});
        end

        % Button pushed function: NomPosRecordY
        function NomPosRecordYButtonPushed(app, event)
            app.updateRecords(app.rcdNames{12});
        end

        % Button pushed function: MagRecord
        function MagRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{13});
        end

        % Button pushed function: MagErrorLimitRecord
        function MagErrorLimitRecordButtonPushed(app, event)
            app.updateRecords(app.rcdNames{14});
        end

        % Button pushed function: MinAmpRecordX
        function MinAmpRecordXButtonPushed(app, event)
            app.updateRecords(app.rcdNames{15});
        end

        % Button pushed function: MinAmpRecordY
        function MinAmpRecordYButtonPushed(app, event)
            app.updateRecords(app.rcdNames{16});
        end

        % Button pushed function: MinHWHMRecordX
        function MinHWHMRecordXButtonPushed(app, event)
            app.updateRecords(app.rcdNames{17});
        end

        % Button pushed function: MinHWHMRecordY
        function MinHWHMRecordYButtonPushed(app, event)
            app.updateRecords(app.rcdNames{18});
        end

        % Button pushed function: BackToMainScreenButton
        function BackToMainScreenButtonPushed(app, event)
            app.closeRequestFcn();
        end

        % Close request function: RPASEngineeringParameter1UIFigure
        function closeRequestFcn(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASEngineeringParameter1UIFigure = uifigure('Visible', 'off');
            app.RPASEngineeringParameter1UIFigure.Name = 'RPAS Engineering Parameter 1';
            app.RPASEngineeringParameter1UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequestFcn, true);
            app.RPASEngineeringParameter1UIFigure.Tag = 'engPar1';
            app.EngineeringParameter1Panel = uipanel(app.RPASEngineeringParameter1UIFigure);
            app.EngineeringParameter1Panel.Title = 'Engineering Parameter 1';
            app.GridLayout = uigridlayout(app.EngineeringParameter1Panel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.MotionPanel = uipanel(app.GridLayout2);
            app.MotionPanel.Title = 'Motion';
            app.ManualRecord = uibutton(app.MotionPanel, 'push');
            app.ManualRecord.ButtonPushedFcn = createCallbackFcn(app, @ManualRecordButtonPushed, true);
            app.ManualRecord.Text = 'RECORD';
            app.AutoRecord = uibutton(app.MotionPanel, 'push');
            app.AutoRecord.ButtonPushedFcn = createCallbackFcn(app, @AutoRecordButtonPushed, true);
            app.AutoRecord.Text = 'RECORD';
            app.mmsecLabel = uilabel(app.MotionPanel);
            app.mmsecLabel.Text = 'mm/sec';
            app.mmsecLabel_2 = uilabel(app.MotionPanel);
            app.mmsecLabel_2.Text = 'mm/sec';
            app.ManualMotionSpeedEditFieldLabel = uilabel(app.MotionPanel);
            app.ManualMotionSpeedEditFieldLabel.Text = 'Manual Motion Speed';
            app.ManualSpeed = uieditfield(app.MotionPanel, 'numeric');
            app.ManualSpeed.ValueDisplayFormat = '%.2f';
            app.ManualSpeed.Value = 10;
            app.AutomaticQualScanSpeedEditFieldLabel = uilabel(app.MotionPanel);
            app.AutomaticQualScanSpeedEditFieldLabel.Text = 'Automatic Qual Scan Speed';
            app.AutoSpeed = uieditfield(app.MotionPanel, 'numeric');
            app.AutoSpeed.ValueDisplayFormat = '%.2f';
            app.RPASTargetZPositionsPanel = uipanel(app.GridLayout2);
            app.RPASTargetZPositionsPanel.Title = 'RPAS Target Z Positions';
            app.ZoffsetRecord = uibutton(app.RPASTargetZPositionsPanel, 'push');
            app.ZoffsetRecord.ButtonPushedFcn = createCallbackFcn(app, @ZoffsetRecordButtonPushed, true);
            app.ZoffsetRecord.Text = 'RECORD';
            app.SWDRecord = uibutton(app.RPASTargetZPositionsPanel, 'push');
            app.SWDRecord.ButtonPushedFcn = createCallbackFcn(app, @SWDRecordButtonPushed, true);
            app.SWDRecord.Text = 'RECORD';
            app.mmLabel = uilabel(app.RPASTargetZPositionsPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.RPASTargetZPositionsPanel);
            app.mmLabel_2.Text = 'mm';
            app.LWDRecord = uibutton(app.RPASTargetZPositionsPanel, 'push');
            app.LWDRecord.ButtonPushedFcn = createCallbackFcn(app, @LWDRecordButtonPushed, true);
            app.LWDRecord.Text = 'RECORD';
            app.ZrangeRecord = uibutton(app.RPASTargetZPositionsPanel, 'push');
            app.ZrangeRecord.ButtonPushedFcn = createCallbackFcn(app, @ZrangeRecordButtonPushed, true);
            app.ZrangeRecord.Text = 'RECORD';
            app.mmLabel_3 = uilabel(app.RPASTargetZPositionsPanel);
            app.mmLabel_3.Text = 'mm';
            app.mmLabel_4 = uilabel(app.RPASTargetZPositionsPanel);
            app.mmLabel_4.Text = 'mm';
            app.ZOffsetHeightAboveRSMFtosetZ0Label = uilabel(app.RPASTargetZPositionsPanel);
            app.ZOffsetHeightAboveRSMFtosetZ0Label.Text = 'Z Offset (Height Above RSMF to set Z 0)';
            app.ZOffset = uieditfield(app.RPASTargetZPositionsPanel, 'numeric');
            app.ZOffset.ValueDisplayFormat = '%.2f';
            app.LWDNominalZHeightaboveRSMFEditFieldLabel = uilabel(app.RPASTargetZPositionsPanel);
            app.LWDNominalZHeightaboveRSMFEditFieldLabel.Text = 'LWD Nominal Z (Height above RSMF)';
            app.LWDNomZ = uieditfield(app.RPASTargetZPositionsPanel, 'numeric');
            app.LWDNomZ.ValueDisplayFormat = '%.2f';
            app.SWDNominalZHeightaboveRSMFEditFieldLabel = uilabel(app.RPASTargetZPositionsPanel);
            app.SWDNominalZHeightaboveRSMFEditFieldLabel.Text = 'SWD Nominal Z (Height above RSMF)';
            app.SWDNomZ = uieditfield(app.RPASTargetZPositionsPanel, 'numeric');
            app.SWDNomZ.ValueDisplayFormat = '%.2f';
            app.ZRangeEditFieldLabel = uilabel(app.RPASTargetZPositionsPanel);
            app.ZRangeEditFieldLabel.Text = 'Z Range';
            app.ZRange = uieditfield(app.RPASTargetZPositionsPanel, 'numeric');
            app.ZRange.ValueDisplayFormat = '%.2f';
            app.ZRange.Tooltip = {'This value will be used in the Alignment Screen'};
            app.RPASImageCapturePanel = uipanel(app.GridLayout2);
            app.RPASImageCapturePanel.Title = 'RPAS Image Capture';
            app.ImgConvFactorRecord = uibutton(app.RPASImageCapturePanel, 'push');
            app.ImgConvFactorRecord.ButtonPushedFcn = createCallbackFcn(app, @ImgConvFactorRecordButtonPushed, true);
            app.ImgConvFactorRecord.Text = 'RECORD';
            app.MinTimeRecord = uibutton(app.RPASImageCapturePanel, 'push');
            app.MinTimeRecord.ButtonPushedFcn = createCallbackFcn(app, @MinTimeRecordButtonPushed, true);
            app.MinTimeRecord.Text = 'RECORD';
            app.pxmmLabel = uilabel(app.RPASImageCapturePanel);
            app.pxmmLabel.Text = 'px/mm';
            app.msecLabel = uilabel(app.RPASImageCapturePanel);
            app.msecLabel.Text = 'msec';
            app.ImageConversionFactorEditFieldLabel = uilabel(app.RPASImageCapturePanel);
            app.ImageConversionFactorEditFieldLabel.Text = 'Image Conversion Factor';
            app.ImgConvFactor = uieditfield(app.RPASImageCapturePanel, 'numeric');
            app.ImgConvFactor.ValueDisplayFormat = '%.4f';
            app.MinimumTimeBetweenImageCapturesEditFieldLabel = uilabel(app.RPASImageCapturePanel);
            app.MinimumTimeBetweenImageCapturesEditFieldLabel.Text = 'Minimum Time Between Image Captures';
            app.MinTime = uieditfield(app.RPASImageCapturePanel, 'numeric');
            app.MinTime.ValueDisplayFormat = '%.2f';
            app.RPASAlignmentPanel = uipanel(app.GridLayout2);
            app.RPASAlignmentPanel.Title = 'RPAS Alignment';
            app.XYErrorRecord = uibutton(app.RPASAlignmentPanel, 'push');
            app.XYErrorRecord.ButtonPushedFcn = createCallbackFcn(app, @XYErrorRecordButtonPushed, true);
            app.XYErrorRecord.Text = 'RECORD';
            app.RzErrorRecord = uibutton(app.RPASAlignmentPanel, 'push');
            app.RzErrorRecord.ButtonPushedFcn = createCallbackFcn(app, @RzErrorRecordButtonPushed, true);
            app.RzErrorRecord.Text = 'RECORD';
            app.umLabel = uilabel(app.RPASAlignmentPanel);
            app.umLabel.Text = 'um';
            app.uradLabel = uilabel(app.RPASAlignmentPanel);
            app.uradLabel.Text = 'urad';
            app.AllowableXYErrorEditFieldLabel = uilabel(app.RPASAlignmentPanel);
            app.AllowableXYErrorEditFieldLabel.Text = 'Allowable X/Y Error';
            app.XYError = uieditfield(app.RPASAlignmentPanel, 'numeric');
            app.XYError.ValueDisplayFormat = '%.2f';
            app.AllowableRzErrorEditFieldLabel = uilabel(app.RPASAlignmentPanel);
            app.AllowableRzErrorEditFieldLabel.Text = 'Allowable Rz Error';
            app.RzError = uieditfield(app.RPASAlignmentPanel, 'numeric');
            app.RzError.ValueDisplayFormat = '%.2f';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RPASMagnificationFocusQualPanel = uipanel(app.GridLayout3);
            app.RPASMagnificationFocusQualPanel.Title = 'RPAS Magnification/Focus Qual';
            app.GridLayout4 = uigridlayout(app.RPASMagnificationFocusQualPanel);
            app.NominalImageCapturePositionsPanel = uipanel(app.GridLayout4);
            app.NominalImageCapturePositionsPanel.Title = 'Nominal Image Capture Positions';
            app.NomPosRecordX = uibutton(app.NominalImageCapturePositionsPanel, 'push');
            app.NomPosRecordX.ButtonPushedFcn = createCallbackFcn(app, @NomPosRecordXButtonPushed, true);
            app.NomPosRecordX.Text = 'RECORD';
            app.NomPosRecordY = uibutton(app.NominalImageCapturePositionsPanel, 'push');
            app.NomPosRecordY.ButtonPushedFcn = createCallbackFcn(app, @NomPosRecordYButtonPushed, true);
            app.NomPosRecordY.Text = 'RECORD';
            app.mmLabel_5 = uilabel(app.NominalImageCapturePositionsPanel);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.NominalImageCapturePositionsPanel);
            app.mmLabel_6.Text = 'mm';
            app.XEditFieldLabel = uilabel(app.NominalImageCapturePositionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.NomPosX = uieditfield(app.NominalImageCapturePositionsPanel, 'numeric');
            app.NomPosX.ValueDisplayFormat = '%.2f';
            app.NomPosX.Value = 10;
            app.YEditFieldLabel = uilabel(app.NominalImageCapturePositionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.NomPosY = uieditfield(app.NominalImageCapturePositionsPanel, 'numeric');
            app.NomPosY.ValueDisplayFormat = '%.2f';
            app.MagnificationPanel = uipanel(app.GridLayout4);
            app.MagnificationPanel.Title = 'Magnification';
            app.MagRecord = uibutton(app.MagnificationPanel, 'push');
            app.MagRecord.ButtonPushedFcn = createCallbackFcn(app, @MagRecordButtonPushed, true);
            app.MagRecord.Text = 'RECORD';
            app.MagErrorLimitRecord = uibutton(app.MagnificationPanel, 'push');
            app.MagErrorLimitRecord.ButtonPushedFcn = createCallbackFcn(app, @MagErrorLimitRecordButtonPushed, true);
            app.MagErrorLimitRecord.Text = 'RECORD';
            app.umumLabel = uilabel(app.MagnificationPanel);
            app.umumLabel.Text = 'um/um';
            app.Label = uilabel(app.MagnificationPanel);
            app.Label.Text = '%';
            app.NominalRPASImageMagEditFieldLabel = uilabel(app.MagnificationPanel);
            app.NominalRPASImageMagEditFieldLabel.Text = 'Nominal RPAS Image Mag';
            app.NomMag = uieditfield(app.MagnificationPanel, 'numeric');
            app.NomMag.ValueDisplayFormat = '%.2f';
            app.NomMag.Value = 10;
            app.MagErrorLimitforPassFailEditFieldLabel = uilabel(app.MagnificationPanel);
            app.MagErrorLimitforPassFailEditFieldLabel.Text = 'Mag Error Limit (+/- for Pass/Fail)';
            app.MagErrLimit = uieditfield(app.MagnificationPanel, 'numeric');
            app.MagErrLimit.ValueDisplayFormat = '%.2f';
            app.FocusPanel = uipanel(app.GridLayout4);
            app.FocusPanel.Title = 'Focus';
            app.MinAmpRecordX = uibutton(app.FocusPanel, 'push');
            app.MinAmpRecordX.ButtonPushedFcn = createCallbackFcn(app, @MinAmpRecordXButtonPushed, true);
            app.MinAmpRecordX.Text = 'RECORD';
            app.MinAmpRecordY = uibutton(app.FocusPanel, 'push');
            app.MinAmpRecordY.ButtonPushedFcn = createCallbackFcn(app, @MinAmpRecordYButtonPushed, true);
            app.MinAmpRecordY.Text = 'RECORD';
            app.Label_2 = uilabel(app.FocusPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.FocusPanel);
            app.Label_3.Text = '%';
            app.XEditField_2Label = uilabel(app.FocusPanel);
            app.XEditField_2Label.Text = 'X';
            app.MinAmpX = uieditfield(app.FocusPanel, 'numeric');
            app.MinAmpX.ValueDisplayFormat = '%.2f';
            app.YEditField_2Label = uilabel(app.FocusPanel);
            app.YEditField_2Label.Text = 'Y';
            app.MinAmpY = uieditfield(app.FocusPanel, 'numeric');
            app.MinAmpY.ValueDisplayFormat = '%.2f';
            app.MinHWHMRecordX = uibutton(app.FocusPanel, 'push');
            app.MinHWHMRecordX.ButtonPushedFcn = createCallbackFcn(app, @MinHWHMRecordXButtonPushed, true);
            app.MinHWHMRecordX.Text = 'RECORD';
            app.MinHWHMRecordY = uibutton(app.FocusPanel, 'push');
            app.MinHWHMRecordY.ButtonPushedFcn = createCallbackFcn(app, @MinHWHMRecordYButtonPushed, true);
            app.MinHWHMRecordY.Text = 'RECORD';
            app.um1Label = uilabel(app.FocusPanel);
            app.um1Label.Interpreter = 'latex';
            app.um1Label.Text = 'um$^{-1}$';
            app.um1Label_2 = uilabel(app.FocusPanel);
            app.um1Label_2.Interpreter = 'latex';
            app.um1Label_2.Text = 'um$^{-1}$';
            app.XEditField_3Label = uilabel(app.FocusPanel);
            app.XEditField_3Label.Text = 'X';
            app.MinHWHMX = uieditfield(app.FocusPanel, 'numeric');
            app.MinHWHMX.ValueDisplayFormat = '%.2f';
            app.YEditField_3Label = uilabel(app.FocusPanel);
            app.YEditField_3Label.Text = 'Y';
            app.MinHWHMY = uieditfield(app.FocusPanel, 'numeric');
            app.MinHWHMY.ValueDisplayFormat = '%.2f';
            app.MinAllowableMTFCenterAmpforPassFailLabel = uilabel(app.FocusPanel);
            app.MinAllowableMTFCenterAmpforPassFailLabel.Text = 'Min Allowable MTF Center Amp (for Pass/Fail)';
            app.MaxAllowableMTFHWHMforPassFailLabel = uilabel(app.FocusPanel);
            app.MaxAllowableMTFHWHMforPassFailLabel.Text = 'Max Allowable MTF HWHM (for Pass/Fail)';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.BackToMainScreenButton = uibutton(app.GridLayout5, 'push');
            app.BackToMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainScreenButtonPushed, true);
            app.BackToMainScreenButton.Text = 'Back To Main Screen';
            % Show the figure after all components are created
            app.RPASEngineeringParameter1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENG_PARAM1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASEngineeringParameter1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASEngineeringParameter1UIFigure)
        end
    end
end