classdef ENG_PARAM2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASEngineeringParameter2UIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        EngineeringParameters2Panel   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        Panel_2                       matlab.ui.container.Panel
        GotoLWDButton                 matlab.ui.control.Button
        GotoSWDButton                 matlab.ui.control.Button
        GotoParkButton                matlab.ui.control.Button
        ImageCapturePanel             matlab.ui.container.Panel
        StartStopCaptureImageButton   matlab.ui.control.Button
        ShotSwitch                    matlab.ui.control.Switch
        WDSwitch                      matlab.ui.control.Switch
        ReticalPositionPanel          matlab.ui.container.Panel
        AbsoluteYMovePanel            matlab.ui.container.Panel
        Abs_Y                         matlab.ui.control.NumericEditField
        mmEditField_12Label           matlab.ui.control.Label
        YStart                        matlab.ui.control.Button
        RelativeZMovePanel            matlab.ui.container.Panel
        mmEditField_13Label           matlab.ui.control.Label
        Rel_Z                         matlab.ui.control.NumericEditField
        Zminus                        matlab.ui.control.Button
        Zplus                         matlab.ui.control.Button
        AbsoluteZMovePanel            matlab.ui.container.Panel
        Abs_Z                         matlab.ui.control.NumericEditField
        mmEditField_14Label           matlab.ui.control.Label
        ZStart                        matlab.ui.control.Button
        RelativeYMovePanel            matlab.ui.container.Panel
        Rel_Y                         matlab.ui.control.NumericEditField
        Yminus                        matlab.ui.control.Button
        Yplus                         matlab.ui.control.Button
        mmEditField_11Label           matlab.ui.control.Label
        AbsoluteXMovePanel            matlab.ui.container.Panel
        XStart                        matlab.ui.control.Button
        Abs_X                         matlab.ui.control.NumericEditField
        mmEditField_5Label            matlab.ui.control.Label
        RelativeXMovePanel            matlab.ui.container.Panel
        Rel_X                         matlab.ui.control.NumericEditField
        mmEditField_2Label            matlab.ui.control.Label
        Xminus                        matlab.ui.control.Button
        Xplus                         matlab.ui.control.Button
        GridLayout7                   matlab.ui.container.GridLayout
        BackToMainScreenButton        matlab.ui.control.Button
        PresentZPosPanel              matlab.ui.container.Panel
        Present_Z                     matlab.ui.control.NumericEditField
        mmEditField_6Label            matlab.ui.control.Label
        PresentYPosPanel              matlab.ui.container.Panel
        Present_Y                     matlab.ui.control.NumericEditField
        mmEditField_4Label            matlab.ui.control.Label
        PresentXPosPanel              matlab.ui.container.Panel
        Present_X                     matlab.ui.control.NumericEditField
        mmEditField_3Label            matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
        GridLayout4                   matlab.ui.container.GridLayout
        DefectsPanel                  matlab.ui.container.Panel
        MaxSizeofreporteddefectsareaLabel  matlab.ui.control.Label
        Reported_Area                 matlab.ui.control.NumericEditField
        RecordArea_2                  matlab.ui.control.Button
        mm2Label                      matlab.ui.control.Label
        MaxAllowableNoofDefectsLabel  matlab.ui.control.Label
        MaxAllowableSingleDefectSizeareaLabel  matlab.ui.control.Label
        Defects_No                    matlab.ui.control.NumericEditField
        Defects_Area                  matlab.ui.control.NumericEditField
        RecordNo                      matlab.ui.control.Button
        RecordArea                    matlab.ui.control.Button
        um2Label                      matlab.ui.control.Label
        RPASContaminationQualPanel    matlab.ui.container.Panel
        Y_pos_4                       matlab.ui.control.NumericEditField
        YEditField_4Label             matlab.ui.control.Label
        X_pos_4                       matlab.ui.control.NumericEditField
        XEditField_4Label             matlab.ui.control.Label
        Position4Label                matlab.ui.control.Label
        mmLabel_8                     matlab.ui.control.Label
        RecordX_pos_4                 matlab.ui.control.Button
        mmLabel_7                     matlab.ui.control.Label
        Y_pos_3                       matlab.ui.control.NumericEditField
        YEditField_3Label             matlab.ui.control.Label
        X_pos_3                       matlab.ui.control.NumericEditField
        XEditField_3Label             matlab.ui.control.Label
        Position3Label                matlab.ui.control.Label
        mmLabel_6                     matlab.ui.control.Label
        RecordX_pos_3                 matlab.ui.control.Button
        mmLabel_5                     matlab.ui.control.Label
        Y_pos_2                       matlab.ui.control.NumericEditField
        YEditField_2Label             matlab.ui.control.Label
        X_pos_2                       matlab.ui.control.NumericEditField
        XEditField_2Label             matlab.ui.control.Label
        Position2Label                matlab.ui.control.Label
        mmLabel_4                     matlab.ui.control.Label
        RecordX_pos_2                 matlab.ui.control.Button
        mmLabel_3                     matlab.ui.control.Label
        Position1Label                matlab.ui.control.Label
        Y_pos_1                       matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        mmLabel_2                     matlab.ui.control.Label
        RecordX_pos_1                 matlab.ui.control.Button
        mmLabel                       matlab.ui.control.Label
        X_pos_1                       matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        NominalImageCapturePositionsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        imgWidth=640;
        imgHeight=480;
        systemVar; % Description
        rcdNames={'pos1', 'pos2', 'pos3', 'pos4', 'Defect_area', 'Defect_no', 'Reported_Area'};
        Park_X; % Description
        Park_Y; % Description
        Park_Z; % Description
        SWD_Xc;
        SWD_Yc;
        SWD_Znom;
        LWD_Xc;
        LWD_Yc;
        LWD_Znom;
        xupdater;
        yupdater;
        zupdater; % Description

        speed;
    end
    
    methods (Access = private)
        
        function init(app)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables; %read in system setup
            
            var=app.systemVar.motionSetupVar;
            app.Park_X=var.XPark;
            app.Park_Y=var.YPark;
            app.Park_Z=var.ZPark;
            
            var=app.systemVar.toolCalibrationVar;
            app.SWD_Xc=var.SWD_Xc;
            app.SWD_Yc=var.SWD_Yc;
            app.LWD_Xc=var.LWD_Xc;
            app.LWD_Yc=var.LWD_Yc;
            
            var=app.systemVar.engineeringPar1Var;
            app.SWD_Znom = var.SWDNominalZ;
            app.LWD_Znom = var.LWDNominalZ;

            app.speed=var.ManualMotionSpeed;
            
            %setup values from recorded sheet
            var=app.systemVar.engineeringPar2Var;
            app.X_pos_1.Value=var.X_pos_1;
            app.X_pos_2.Value=var.X_pos_2;
            app.X_pos_3.Value=var.X_pos_3;
            app.X_pos_4.Value=var.X_pos_4;
            
            app.Y_pos_1.Value=var.Y_pos_1;
            app.Y_pos_2.Value=var.Y_pos_2;
            app.Y_pos_3.Value=var.Y_pos_3;
            app.Y_pos_4.Value=var.Y_pos_4;
            
            app.Defects_Area.Value = var.MaxAllowableDefectArea;
            app.Defects_No.Value = var.MaxAllowableDefectNo;
            app.Reported_Area.Value = var.MaxReportedDefectArea;

            src=[RPAS_C.SCREEN_IMAGE_DIR '/imgPar2.png'];
            app.Image.ImageSource=src;
            
            %start timers
            app.xupdater=updatePosition(app.Present_X, 0, .05);
            app.yupdater=updatePosition(app.Present_Y, 1, .05);
            app.zupdater=updatePosition(app.Present_Z, 2, .05);

            %update position
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1);
            app.stopTimers();

             if app.Present_X.Value==app.LWD_Xc && ...
                     app.Present_Y.Value==app.LWD_Yc && ...
                     app.Present_Z.Value==app.LWD_Znom
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
             else
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
             end
        end
        
        function updateRecords(app, rcdName)
            var=app.systemVar.engineeringPar2Var;
            switch rcdName
                case app.rcdNames{1}
                    var.X_pos_1 = app.X_pos_1.Value;
                    var.Y_pos_1 = app.Y_pos_1.Value;
                case app.rcdNames{2}
                    var.X_pos_2 = app.X_pos_2.Value;
                    var.Y_pos_2 = app.Y_pos_2.Value;
                case app.rcdNames{3}
                    var.X_pos_3 = app.X_pos_3.Value;
                    var.Y_pos_3 = app.Y_pos_3.Value;
                case app.rcdNames{4}
                    var.X_pos_4 = app.X_pos_4.Value;
                    var.Y_pos_4 = app.Y_pos_4.Value;
                case app.rcdNames{5}
                    var.MaxAllowableDefectArea = app.Defects_Area.Value;
                case app.rcdNames{6}
                    var.MaxAllowableDefectNo = app.Defects_No.Value;
                case app.rcdNames{7}
                    var.MaxReportedDefectArea = app.Reported_Area.Value;
            end
            app.systemVar.updateRecords(var, app.RPASEngineeringParameter2UIFigure.Tag);
        end
        
        function stopTimers(app)
            app.xupdater.stop();
            app.yupdater.stop();
            app.zupdater.stop();
        end
        
        function captureImage(app, cameraSource, shotMode)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            global stopICBSignal; %signal flag
                % 0 indicate continuous video is on
                % 1 indicate continuous video is off
            if stopICBSignal==0
                app.stopContCaptureImage();
                return;
            end

            if RPAS_C.CameraAvailable
                var=app.systemVar.engineeringPar1Var;
                pauseTime=max(var.MinTimeBetweenImageCaptures,1)*1.0e-3;
                pause(2*max(var.MinTimeBetweenImageCaptures,1)*1.0e-3);
                if shotMode==app.ShotSwitch.ItemsData(1) % snapshot
                    img = RPAS_ImageAcquisition_7dot1(cameraSource, shotMode);
                    if cameraSource==1 % SWD
                        name='SWD';
                    else
                        name='LWD';
                    end
                    imshow(img, [], 'Parent', app.UIAxes);
                else %live video
                    RPAS_ImageAcquisition_7dot1(cameraSource, shotMode, app.UIAxes, pauseTime);
                end
            else
                path=[RPAS_C.QUAL_IMAGE_DIR '/contaminations'];
                files={'BP1', 'BP2', 'BP3', 'BP4', ...
                    'img_pos1', 'img_pos2', 'img_pos3', 'img_pos4'};
                global kindex;
                if isempty(kindex)
                    kindex=1;
                else
                    kindex=mod(kindex,4)+1;
                end

                if shotMode == app.ShotSwitch.ItemsData(1) % snapshot
                    if cameraSource==app.WDSwitch.ItemsData(1) % SWD
                        fnm=[path '\' files{kindex} '.pgm'];
                    else
                        fnm=[path '\' files{kindex+4} '.pgm'];
                    end
                    img=imread(fnm);
                    imshow(img, [], 'Parent', app.UIAxes);
                else
                    
                    stopICBSignal=0;
                    
                    while stopICBSignal==0
                        if isvalid(app)
                            var=app.systemVar.engineeringPar1Var;
                            pauseTime=max(var.MinTimeBetweenImageCaptures,1)*1.0e-3;
                        
                            if cameraSource==app.WDSwitch.ItemsData(1) % SWD
                                fnm=[path '\' files{kindex} '.pgm'];
                            else
                                fnm=[path '\' files{kindex+4} '.pgm'];
                            end
                            img=imread(fnm);
                            imshow(img, [], 'Parent', app.UIAxes);
                            pause(2*pauseTime);
                            kindex=mod(kindex,4)+1;
                        end

                        if stopICBSignal == 0
                            pause(2*pauseTime);
                        end
                    end
                end
            end
        end
        
        function stopContCaptureImage(app)
            global stopICBSignal;
            stopICBSignal=1;
            var=app.systemVar.engineeringPar1Var;
            pauseTime=max(var.MinTimeBetweenImageCaptures,1)*1.0e-3;
        end
    end

    methods (Access = public)
        
        function close(app)
            app.RPASEngineeringParameter2UIFigureCloseRequest();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Button pushed function: RecordX_pos_1
        function RecordX_pos_1ButtonPushed(app, event)
            app.stopContCaptureImage();
            app.X_pos_1.Value=app.Present_X.Value;
            app.Y_pos_1.Value=app.Present_Y.Value;
            app.updateRecords(app.rcdNames{1});
        end

        % Button pushed function: RecordX_pos_2
        function RecordX_pos_2ButtonPushed(app, event)
            app.stopContCaptureImage();
            app.X_pos_2.Value=app.Present_X.Value;
            app.Y_pos_2.Value=app.Present_Y.Value;
            app.updateRecords(app.rcdNames{2});
        end

        % Button pushed function: RecordX_pos_3
        function RecordX_pos_3ButtonPushed(app, event)
            app.stopContCaptureImage();
            app.X_pos_3.Value=app.Present_X.Value;
            app.Y_pos_3.Value=app.Present_Y.Value;
            app.updateRecords(app.rcdNames{3});
        end

        % Button pushed function: RecordX_pos_4
        function RecordX_pos_4ButtonPushed(app, event)
            app.stopContCaptureImage();
            app.X_pos_4.Value=app.Present_X.Value;
            app.Y_pos_4.Value=app.Present_Y.Value;
            app.updateRecords(app.rcdNames{4});
        end

        % Button pushed function: RecordArea
        function RecordAreaButtonPushed(app, event)
            app.stopContCaptureImage();
            app.updateRecords(app.rcdNames{5});
        end

        % Button pushed function: RecordNo
        function RecordNoButtonPushed(app, event)
            app.stopContCaptureImage();
            app.updateRecords(app.rcdNames{6});
        end

        % Button pushed function: Xplus
        function XplusButtonPushed(app, event)
            app.stopContCaptureImage();
            RelativeMoving(0, app.Rel_X.Value, app.speed, app.xupdater);
        end

        % Button pushed function: Xminus
        function XminusButtonPushed(app, event)
            app.stopContCaptureImage();
            RelativeMoving(0, -app.Rel_X.Value, app.speed, app.xupdater);
        end

        % Button pushed function: Yplus
        function YplusButtonPushed(app, event)
            app.stopContCaptureImage();
            RelativeMoving(1, app.Rel_Y.Value, app.speed, app.yupdater);
        end

        % Button pushed function: Yminus
        function YminusButtonPushed(app, event)
            app.stopContCaptureImage();
            RelativeMoving(1, -app.Rel_Y.Value, app.speed, app.yupdater);
        end

        % Button pushed function: Zplus
        function ZplusButtonPushed(app, event)
            app.stopContCaptureImage();
            RelativeMoving(2, app.Rel_Z.Value, app.speed, app.zupdater);
        end

        % Button pushed function: Zminus
        function ZminusButtonPushed(app, event)
            app.stopContCaptureImage();
            RelativeMoving(2, -app.Rel_Z.Value, app.speed, app.zupdater);
        end

        % Button pushed function: XStart
        function XStartButtonPushed(app, event)
            app.stopContCaptureImage();
            ABSMoving(0, app.Abs_X.Value, app.speed, app.xupdater);
        end

        % Button pushed function: YStart
        function YStartButtonPushed(app, event)
           app.stopContCaptureImage();
            ABSMoving(1, app.Abs_Y.Value, app.speed, app.yupdater);
        end

        % Button pushed function: ZStart
        function ZStartButtonPushed(app, event)
            app.stopContCaptureImage();
            ABSMoving(2, app.Abs_Z.Value, app.speed, app.zupdater);
        end

        % Button pushed function: GotoParkButton
        function GotoParkButtonPushed(app, event)
            app.stopContCaptureImage();
            axes=[0, 1, 2];
            dist=[app.Park_X, app.Park_Y, app.Park_Z];
            
            ABSMoving(axes, dist, app.speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: GotoSWDButton
        function GotoSWDButtonPushed(app, event)
            app.stopContCaptureImage();
            axes=[0, 1, 2];
            dist=[app.SWD_Xc, app.SWD_Yc, app.SWD_Znom];
            ABSMoving(axes, dist, app.speed, [app.xupdater, app.yupdater, app.zupdater]);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(1)
        end

        % Button pushed function: GotoLWDButton
        function GotoLWDButtonPushed(app, event)
            app.stopContCaptureImage();
            axes=[0, 1, 2];
            dist=[app.LWD_Xc, app.LWD_Yc, app.LWD_Znom];
            ABSMoving(axes, dist, app.speed, [app.xupdater, app.yupdater, app.zupdater]);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
        end

        % Button pushed function: StartStopCaptureImageButton
        function StartStopCaptureImageButtonPushed(app, event)
            app.captureImage(app.WDSwitch.Value, app.ShotSwitch.Value);
        end

        % Close request function: RPASEngineeringParameter2UIFigure
        function RPASEngineeringParameter2UIFigureCloseRequest(app, event)
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            global  stopICBSignal;
            if stopICBSignal==0
                app.stopContCaptureImage();
                var=app.systemVar.engineeringPar1Var;
                pauseTime=max(var.MinTimeBetweenImageCaptures,1)*1.0e-3;
                while stopICBSignal==0
                    pause(pauseTime);
                end
            end
            delete(app)
            
        end

        % Button pushed function: RecordArea_2
        function RecordArea_2ButtonPushed(app, event)
            app.stopContCaptureImage();
            app.updateRecords(app.rcdNames{7});
        end

        % Button pushed function: BackToMainScreenButton
        function BackToMainScreenButtonPushed(app, event)
            app.RPASEngineeringParameter2UIFigureCloseRequest();
        end

        % Value changed function: WDSwitch
        function WDSwitchValueChanged(app, event)
            value = app.WDSwitch.Value;
            app.stopContCaptureImage();
        end

        % Value changed function: ShotSwitch
        function ShotSwitchValueChanged(app, event)
            value = app.ShotSwitch.Value;
            app.stopContCaptureImage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASEngineeringParameter2UIFigure = uifigure('Visible', 'off');
            app.RPASEngineeringParameter2UIFigure.Name = 'RPAS Engineering Parameter 2';
            app.RPASEngineeringParameter2UIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASEngineeringParameter2UIFigureCloseRequest, true);
            app.RPASEngineeringParameter2UIFigure.Tag = 'engPar2';
            app.GridLayout = uigridlayout(app.RPASEngineeringParameter2UIFigure);
            app.EngineeringParameters2Panel = uipanel(app.GridLayout);
            app.EngineeringParameters2Panel.Title = 'Engineering Parameters 2';
            app.GridLayout2 = uigridlayout(app.EngineeringParameters2Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.RPASContaminationQualPanel = uipanel(app.GridLayout4);
            app.RPASContaminationQualPanel.Title = 'RPAS Contamination Qual';
            app.NominalImageCapturePositionsLabel = uilabel(app.RPASContaminationQualPanel);
            app.NominalImageCapturePositionsLabel.Text = {'Nominal Image'; 'Capture Positions'};
            app.Label = uilabel(app.RPASContaminationQualPanel);
            app.Label.Text = 'X';
            app.X_pos_1 = uieditfield(app.RPASContaminationQualPanel, 'numeric');
            app.X_pos_1.ValueDisplayFormat = '%.3f';
            app.X_pos_1.Editable = 'off';
            app.mmLabel = uilabel(app.RPASContaminationQualPanel);
            app.mmLabel.Text = 'mm';
            app.RecordX_pos_1 = uibutton(app.RPASContaminationQualPanel, 'push');
            app.RecordX_pos_1.ButtonPushedFcn = createCallbackFcn(app, @RecordX_pos_1ButtonPushed, true);
            app.RecordX_pos_1.Text = 'Record';
            app.mmLabel_2 = uilabel(app.RPASContaminationQualPanel);
            app.mmLabel_2.Text = 'mm';
            app.YEditFieldLabel = uilabel(app.RPASContaminationQualPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.Y_pos_1 = uieditfield(app.RPASContaminationQualPanel, 'numeric');
            app.Y_pos_1.ValueDisplayFormat = '%.3f';
            app.Y_pos_1.Editable = 'off';
            app.Position1Label = uilabel(app.RPASContaminationQualPanel);
            app.Position1Label.Text = 'Position 1';
            app.mmLabel_3 = uilabel(app.RPASContaminationQualPanel);
            app.mmLabel_3.Text = 'mm';
            app.RecordX_pos_2 = uibutton(app.RPASContaminationQualPanel, 'push');
            app.RecordX_pos_2.ButtonPushedFcn = createCallbackFcn(app, @RecordX_pos_2ButtonPushed, true);
            app.RecordX_pos_2.Text = 'Record';
            app.mmLabel_4 = uilabel(app.RPASContaminationQualPanel);
            app.mmLabel_4.Text = 'mm';
            app.Position2Label = uilabel(app.RPASContaminationQualPanel);
            app.Position2Label.Text = 'Position 2';
            app.XEditField_2Label = uilabel(app.RPASContaminationQualPanel);
            app.XEditField_2Label.Text = 'X';
            app.X_pos_2 = uieditfield(app.RPASContaminationQualPanel, 'numeric');
            app.X_pos_2.ValueDisplayFormat = '%.3f';
            app.X_pos_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.RPASContaminationQualPanel);
            app.YEditField_2Label.Text = 'Y';
            app.Y_pos_2 = uieditfield(app.RPASContaminationQualPanel, 'numeric');
            app.Y_pos_2.ValueDisplayFormat = '%.3f';
            app.Y_pos_2.Editable = 'off';
            app.mmLabel_5 = uilabel(app.RPASContaminationQualPanel);
            app.mmLabel_5.Text = 'mm';
            app.RecordX_pos_3 = uibutton(app.RPASContaminationQualPanel, 'push');
            app.RecordX_pos_3.ButtonPushedFcn = createCallbackFcn(app, @RecordX_pos_3ButtonPushed, true);
            app.RecordX_pos_3.Text = 'Record';
            app.mmLabel_6 = uilabel(app.RPASContaminationQualPanel);
            app.mmLabel_6.Text = 'mm';
            app.Position3Label = uilabel(app.RPASContaminationQualPanel);
            app.Position3Label.Text = 'Position 3';
            app.XEditField_3Label = uilabel(app.RPASContaminationQualPanel);
            app.XEditField_3Label.Text = 'X';
            app.X_pos_3 = uieditfield(app.RPASContaminationQualPanel, 'numeric');
            app.X_pos_3.ValueDisplayFormat = '%.3f';
            app.X_pos_3.Editable = 'off';
            app.YEditField_3Label = uilabel(app.RPASContaminationQualPanel);
            app.YEditField_3Label.Text = 'Y';
            app.Y_pos_3 = uieditfield(app.RPASContaminationQualPanel, 'numeric');
            app.Y_pos_3.ValueDisplayFormat = '%.3f';
            app.Y_pos_3.Editable = 'off';
            app.mmLabel_7 = uilabel(app.RPASContaminationQualPanel);
            app.mmLabel_7.Text = 'mm';
            app.RecordX_pos_4 = uibutton(app.RPASContaminationQualPanel, 'push');
            app.RecordX_pos_4.ButtonPushedFcn = createCallbackFcn(app, @RecordX_pos_4ButtonPushed, true);
            app.RecordX_pos_4.Text = 'Record';
            app.mmLabel_8 = uilabel(app.RPASContaminationQualPanel);
            app.mmLabel_8.Text = 'mm';
            app.Position4Label = uilabel(app.RPASContaminationQualPanel);
            app.Position4Label.Text = 'Position 4';
            app.XEditField_4Label = uilabel(app.RPASContaminationQualPanel);
            app.XEditField_4Label.Text = 'X';
            app.X_pos_4 = uieditfield(app.RPASContaminationQualPanel, 'numeric');
            app.X_pos_4.ValueDisplayFormat = '%.3f';
            app.X_pos_4.Editable = 'off';
            app.YEditField_4Label = uilabel(app.RPASContaminationQualPanel);
            app.YEditField_4Label.Text = 'Y';
            app.Y_pos_4 = uieditfield(app.RPASContaminationQualPanel, 'numeric');
            app.Y_pos_4.ValueDisplayFormat = '%.3f';
            app.Y_pos_4.Editable = 'off';
            app.DefectsPanel = uipanel(app.GridLayout4);
            app.DefectsPanel.Title = 'Defects';
            app.um2Label = uilabel(app.DefectsPanel);
            app.um2Label.Interpreter = 'latex';
            app.um2Label.Text = 'um$^{2}$';
            app.RecordArea = uibutton(app.DefectsPanel, 'push');
            app.RecordArea.ButtonPushedFcn = createCallbackFcn(app, @RecordAreaButtonPushed, true);
            app.RecordArea.Text = 'Record';
            app.RecordNo = uibutton(app.DefectsPanel, 'push');
            app.RecordNo.ButtonPushedFcn = createCallbackFcn(app, @RecordNoButtonPushed, true);
            app.RecordNo.Text = 'Record';
            app.Defects_Area = uieditfield(app.DefectsPanel, 'numeric');
            app.Defects_Area.ValueDisplayFormat = '%.3f';
            app.Defects_No = uieditfield(app.DefectsPanel, 'numeric');
            app.Defects_No.ValueDisplayFormat = '%.0f';
            app.MaxAllowableSingleDefectSizeareaLabel = uilabel(app.DefectsPanel);
            app.MaxAllowableSingleDefectSizeareaLabel.Text = {'Max Allowable Single'; 'Defect Size (area)'};
            app.MaxAllowableNoofDefectsLabel = uilabel(app.DefectsPanel);
            app.MaxAllowableNoofDefectsLabel.Text = {'Max Allowable'; 'No. of Defects'};
            app.mm2Label = uilabel(app.DefectsPanel);
            app.mm2Label.Interpreter = 'latex';
            app.mm2Label.Text = 'mm$^{2}$';
            app.RecordArea_2 = uibutton(app.DefectsPanel, 'push');
            app.RecordArea_2.ButtonPushedFcn = createCallbackFcn(app, @RecordArea_2ButtonPushed, true);
            app.RecordArea_2.Text = 'Record';
            app.Reported_Area = uieditfield(app.DefectsPanel, 'numeric');
            app.Reported_Area.ValueDisplayFormat = '%.3f';
            app.MaxSizeofreporteddefectsareaLabel = uilabel(app.DefectsPanel);
            app.MaxSizeofreporteddefectsareaLabel.Text = 'Max Size of reported defects (area)';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.UIAxes = uiaxes(app.GridLayout5);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Y')
            ylabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.Image = uiimage(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.PresentXPosPanel = uipanel(app.GridLayout7);
            app.PresentXPosPanel.Title = 'Present X Pos';
            app.mmEditField_3Label = uilabel(app.PresentXPosPanel);
            app.mmEditField_3Label.Text = 'mm';
            app.Present_X = uieditfield(app.PresentXPosPanel, 'numeric');
            app.Present_X.ValueDisplayFormat = '%.3f';
            app.Present_X.Editable = 'off';
            app.PresentYPosPanel = uipanel(app.GridLayout7);
            app.PresentYPosPanel.Title = 'Present Y Pos';
            app.mmEditField_4Label = uilabel(app.PresentYPosPanel);
            app.mmEditField_4Label.Text = 'mm';
            app.Present_Y = uieditfield(app.PresentYPosPanel, 'numeric');
            app.Present_Y.ValueDisplayFormat = '%.3f';
            app.Present_Y.Editable = 'off';
            app.PresentZPosPanel = uipanel(app.GridLayout7);
            app.PresentZPosPanel.Title = 'Present Z Pos';
            app.mmEditField_6Label = uilabel(app.PresentZPosPanel);
            app.mmEditField_6Label.Text = 'mm';
            app.Present_Z = uieditfield(app.PresentZPosPanel, 'numeric');
            app.Present_Z.ValueDisplayFormat = '%.3f';
            app.Present_Z.Editable = 'off';
            app.BackToMainScreenButton = uibutton(app.GridLayout7, 'push');
            app.BackToMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainScreenButtonPushed, true);
            app.BackToMainScreenButton.Text = {'Back To Main '; 'Screen'};
            app.ReticalPositionPanel = uipanel(app.GridLayout6);
            app.ReticalPositionPanel.Title = 'Retical Position';
            app.RelativeXMovePanel = uipanel(app.ReticalPositionPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.Xplus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xplus.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.Xplus.Text = '+';
            app.Xminus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xminus.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.Xminus.Text = '-';
            app.mmEditField_2Label = uilabel(app.RelativeXMovePanel);
            app.mmEditField_2Label.Text = 'mm';
            app.Rel_X = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.Rel_X.ValueDisplayFormat = '%.3f';
            app.AbsoluteXMovePanel = uipanel(app.ReticalPositionPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.mmEditField_5Label = uilabel(app.AbsoluteXMovePanel);
            app.mmEditField_5Label.Text = 'mm';
            app.Abs_X = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.Abs_X.ValueDisplayFormat = '%.3f';
            app.XStart = uibutton(app.AbsoluteXMovePanel, 'push');
            app.XStart.ButtonPushedFcn = createCallbackFcn(app, @XStartButtonPushed, true);
            app.XStart.Text = 'Start';
            app.RelativeYMovePanel = uipanel(app.ReticalPositionPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.mmEditField_11Label = uilabel(app.RelativeYMovePanel);
            app.mmEditField_11Label.Text = 'mm';
            app.Yplus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yplus.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.Yplus.Text = '+';
            app.Yminus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yminus.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.Yminus.Text = '-';
            app.Rel_Y = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.Rel_Y.ValueDisplayFormat = '%.3f';
            app.AbsoluteZMovePanel = uipanel(app.ReticalPositionPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.ZStart = uibutton(app.AbsoluteZMovePanel, 'push');
            app.ZStart.ButtonPushedFcn = createCallbackFcn(app, @ZStartButtonPushed, true);
            app.ZStart.Text = 'Start';
            app.mmEditField_14Label = uilabel(app.AbsoluteZMovePanel);
            app.mmEditField_14Label.Text = 'mm';
            app.Abs_Z = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.Abs_Z.ValueDisplayFormat = '%.3f';
            app.RelativeZMovePanel = uipanel(app.ReticalPositionPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.Zplus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zplus.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.Zplus.Text = '+';
            app.Zminus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zminus.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.Zminus.Text = '-';
            app.Rel_Z = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.Rel_Z.ValueDisplayFormat = '%.3f';
            app.mmEditField_13Label = uilabel(app.RelativeZMovePanel);
            app.mmEditField_13Label.Text = 'mm';
            app.AbsoluteYMovePanel = uipanel(app.ReticalPositionPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.YStart = uibutton(app.AbsoluteYMovePanel, 'push');
            app.YStart.ButtonPushedFcn = createCallbackFcn(app, @YStartButtonPushed, true);
            app.YStart.Text = 'Start';
            app.mmEditField_12Label = uilabel(app.AbsoluteYMovePanel);
            app.mmEditField_12Label.Text = 'mm';
            app.Abs_Y = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.Abs_Y.ValueDisplayFormat = '%.3f';
            app.ImageCapturePanel = uipanel(app.GridLayout6);
            app.ImageCapturePanel.Title = 'Image Capture';
            app.WDSwitch = uiswitch(app.ImageCapturePanel, 'slider');
            app.WDSwitch.Items = {'SWD', 'LWD'};
            app.WDSwitch.ItemsData = [1 0];
            app.WDSwitch.ValueChangedFcn = createCallbackFcn(app, @WDSwitchValueChanged, true);
            app.WDSwitch.Value = 1;
            app.ShotSwitch = uiswitch(app.ImageCapturePanel, 'slider');
            app.ShotSwitch.Items = {'Single Shot', 'Cont'};
            app.ShotSwitch.ItemsData = [1 0];
            app.ShotSwitch.ValueChangedFcn = createCallbackFcn(app, @ShotSwitchValueChanged, true);
            app.ShotSwitch.Value = 1;
            app.StartStopCaptureImageButton = uibutton(app.ImageCapturePanel, 'push');
            app.StartStopCaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopCaptureImageButtonPushed, true);
            app.StartStopCaptureImageButton.Text = {'Start/Stop'; 'Capture Image'};
            app.Panel_2 = uipanel(app.GridLayout6);
            app.GotoParkButton = uibutton(app.Panel_2, 'push');
            app.GotoParkButton.ButtonPushedFcn = createCallbackFcn(app, @GotoParkButtonPushed, true);
            app.GotoParkButton.Text = {'Go to'; 'Park'};
            app.GotoSWDButton = uibutton(app.Panel_2, 'push');
            app.GotoSWDButton.ButtonPushedFcn = createCallbackFcn(app, @GotoSWDButtonPushed, true);
            app.GotoSWDButton.Text = {'Goto SWD'; 'Xc Yc Znom'};
            app.GotoLWDButton = uibutton(app.Panel_2, 'push');
            app.GotoLWDButton.ButtonPushedFcn = createCallbackFcn(app, @GotoLWDButtonPushed, true);
            app.GotoLWDButton.Text = {'Goto LWD'; 'Xc Yc Znom'};
            % Show the figure after all components are created
            app.RPASEngineeringParameter2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENG_PARAM2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASEngineeringParameter2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASEngineeringParameter2UIFigure)
        end
    end
end