classdef ENG_PARAM3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASEngineeringParameter3UIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        EngineeringParameter3Panel  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        BacktoMainScreenButton      matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        RPASQualPanel               matlab.ui.container.Panel
        Label_8                     matlab.ui.control.Label
        TwinscanValidationLabel     matlab.ui.control.Label
        ImportButton_7              matlab.ui.control.Button
        Pos7                        matlab.ui.control.EditField
        Label_7                     matlab.ui.control.Label
        at3circLabel_2              matlab.ui.control.Label
        ImportButton_6              matlab.ui.control.Button
        Pos6                        matlab.ui.control.EditField
        woEIPWindowLabel            matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        at850umLabel_2              matlab.ui.control.Label
        ImportButton_5              matlab.ui.control.Button
        Pos5                        matlab.ui.control.EditField
        Label_5                     matlab.ui.control.Label
        atZnomLabel_2               matlab.ui.control.Label
        ImportButton_4              matlab.ui.control.Button
        Pos4                        matlab.ui.control.EditField
        Label_4                     matlab.ui.control.Label
        at3circLabel                matlab.ui.control.Label
        ImportButton_3              matlab.ui.control.Button
        Pos3                        matlab.ui.control.EditField
        withEIPWindowLabel          matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        at850umLabel                matlab.ui.control.Label
        ImportButton_2              matlab.ui.control.Button
        Pos2                        matlab.ui.control.EditField
        NoLabel                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        atZnomLabel                 matlab.ui.control.Label
        ImportButton                matlab.ui.control.Button
        DataCollectionSheetLabel    matlab.ui.control.Label
        Pos1                        matlab.ui.control.EditField
    end

    
    properties (Access = private)
         systemVar; 
         rcdNames={'pos1', 'pos2', 'pos3', 'pos4', 'pos5', 'pos6', 'pos7'};
    end
    
    methods (Access = private)
        function updateRecords(app, rcdName, name)
            var=app.systemVar.engineeringPar3Var;
            switch rcdName
                case app.rcdNames{1}
                    var.pos_1 = name;
                    [filepath, name, ext]=fileparts(name);
                    app.Pos1.Value=[name ext];
                case app.rcdNames{2}
                    var.pos_2 = name;
                    [filepath, name, ext]=fileparts(name);
                    app.Pos2.Value=[name ext];
                case app.rcdNames{3}
                    var.pos_3 = name;
                    [filepath, name, ext]=fileparts(name);
                    app.Pos3.Value=[name ext];
                case app.rcdNames{4}
                    var.pos_4 = name;
                    [filepath, name, ext]=fileparts(name);
                    app.Pos4.Value=[name ext];
                case app.rcdNames{5}
                    var.pos_5 = name;
                    [filepath, name, ext]=fileparts(name);
                    app.Pos5.Value=[name ext];
                case app.rcdNames{6}
                    var.pos_6 = name;
                    [filepath, name, ext]=fileparts(name);
                    app.Pos6.Value=[name ext];
                case app.rcdNames{7}
                    var.twinscanFnm = name;
                    [filepath, name, ext]=fileparts(name);
                    app.Pos7.Value=[name ext];
            end
            app.systemVar.updateRecords(var, app.RPASEngineeringParameter3UIFigure.Tag);
        end
        
        function init(app)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables(); %read in system setup

            var=app.systemVar.engineeringPar3Var;

            [filepath, name, ext]=fileparts(var.pos_1);
            app.Pos1.Value=[name ext];
            [filepath, name, ext]=fileparts(var.pos_2);
            app.Pos2.Value=[name ext];
            [filepath, name, ext]=fileparts(var.pos_3);
            app.Pos3.Value=[name ext];
            [filepath, name, ext]=fileparts(var.pos_4);
            app.Pos4.Value=[name ext];
            [filepath, name, ext]=fileparts(var.pos_5);
            app.Pos5.Value=[name ext];
            [filepath, name, ext]=fileparts(var.pos_6);
            app.Pos6.Value=[name ext];
            [filepath, name, ext]=fileparts(var.twinscanFnm);
            app.Pos7.Value=[name ext];

            app.Image.ImageSource=[RPAS_C.SCREEN_IMAGE_DIR '/imgPar3.png'];
        end
        
        function results=selectFile(app, pos)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            results=[];
            spliter='/';
            arch=computer("arch");
            if strcmp(arch, 'win32') | strcmp(arch,'win64')
                spliter='\';
            end

            var=app.systemVar.engineeringPar3Var;
            switch pos
                case 1
                    [filepath, name, ext]=fileparts(var.pos_1);
                    defaultName=var.pos_1;
                case 2
                    [filepath, name, ext]=fileparts(var.pos_2);
                    defaultName=var.pos_2;
                case 3
                    [filepath, name, ext]=fileparts(var.pos_3);
                    defaultName=var.pos_3;
                case 4
                    [filepath, name, ext]=fileparts(var.pos_4);
                    defaultName=var.pos_4;
                case 5
                    [filepath, name, ext]=fileparts(var.pos_5);
                    defaultName=var.pos_5;
                case 6
                    [filepath, name, ext]=fileparts(var.pos_6);
                    defaultName=var.pos_6;
                case 7
                    [filepath, name, ext]=fileparts(var.twinscanFnm);
                    defaultName=var.twinscanFnm;
            end

            defaultPath=[filepath spliter '*.xlsx'];
            [fname, path]=uigetfile(defaultPath, ...
                'Select the Excel File', defaultName);

            if fname==0
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                selection = uiconfirm(fig, 'No file is selected', 'Warning...',...
                                    'Options',{'OK'},...
                                    'icon', 'warning');
                delete(fig);
                return;
            end

            newname=fullfile(path, fname);
            flag=false;
            if strcmp(arch, 'win32') | strcmp(arch,'win64')
                if ~strcmp(lower(defaultName), lower(newname))
                    flag=true;
                end
            else
                if ~strcmp(defaultName, newname)
                    flag=true;
                end
            end

            if flag
                %check if the file is correct format
                T1=readmatrix(newname);
                T2=[];
                for k=1:size(T1,2)
                    if ~all(isnan(T1(:,k)))
                        T2=[T2 T1(:,k)];
                    end
                end
                T=[];
                for k=1:size(T2,1)
                    if ~all(isnan(T2(k,:)))
                        T=[T;T2(k,:)];
                    end
                end

                [m,n]=size(T);

                if n~=10 & n~=9
                    sampleFile=[RPAS_C.QUAL_DATA_SHEET_DIR spliter ...
                        'sample_data_collection_sheet.xlsx'];
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=500;h=200;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                    title = 'Unsupported File Format';
                    msg={'Unsupported File Format.', ...
                        ['See ' sampleFile]};
                    selection = uiconfirm(fig, msg, title,...
                                    'Options',{'OK'},...
                                    'icon', 'error');
                    delete(fig);
                    return;
                end
                results=newname;
            end
        end
    end

    methods (Access = public)
        
        function close(app)
            app.RPASEngineeringParameter3UIFigureCloseRequest();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            newFile=app.selectFile(1);
            if ~isempty(newFile)
                app.updateRecords(app.rcdNames{1}, newFile);
            end
        end

        % Button pushed function: ImportButton_2
        function ImportButton_2Pushed(app, event)
            newFile=app.selectFile(2);
            if ~isempty(newFile)
                app.updateRecords(app.rcdNames{2}, newFile);
            end
        end

        % Button pushed function: ImportButton_3
        function ImportButton_3Pushed(app, event)
            newFile=app.selectFile(3);
            if ~isempty(newFile)
                app.updateRecords(app.rcdNames{3}, newFile);
            end
        end

        % Button pushed function: ImportButton_4
        function ImportButton_4Pushed(app, event)
            newFile=app.selectFile(4);
            if ~isempty(newFile)
                app.updateRecords(app.rcdNames{4}, newFile);
            end
        end

        % Button pushed function: ImportButton_5
        function ImportButton_5Pushed(app, event)
            newFile=app.selectFile(5);
            if ~isempty(newFile)
                app.updateRecords(app.rcdNames{5}, newFile);
            end
        end

        % Button pushed function: ImportButton_6
        function ImportButton_6Pushed(app, event)
            newFile=app.selectFile(6);
            if ~isempty(newFile)
                app.updateRecords(app.rcdNames{6}, newFile);
            end
        end

        % Close request function: RPASEngineeringParameter3UIFigure
        function RPASEngineeringParameter3UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: BacktoMainScreenButton
        function BacktoMainScreenButtonPushed(app, event)
            app.RPASEngineeringParameter3UIFigureCloseRequest();
        end

        % Button pushed function: ImportButton_7
        function ImportButton_7Pushed(app, event)
            newFile=app.selectFile(7);
            if ~isempty(newFile)
                app.updateRecords(app.rcdNames{7}, newFile);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASEngineeringParameter3UIFigure = uifigure('Visible', 'off');
            app.RPASEngineeringParameter3UIFigure.Name = 'RPAS Engineering Parameter 3';
            app.RPASEngineeringParameter3UIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASEngineeringParameter3UIFigureCloseRequest, true);
            app.RPASEngineeringParameter3UIFigure.Tag = 'engPar3';
            app.GridLayout = uigridlayout(app.RPASEngineeringParameter3UIFigure);
            app.EngineeringParameter3Panel = uipanel(app.GridLayout);
            app.EngineeringParameter3Panel.Title = 'Engineering Parameter 3';
            app.GridLayout2 = uigridlayout(app.EngineeringParameter3Panel);
            app.RPASQualPanel = uipanel(app.GridLayout2);
            app.RPASQualPanel.Title = 'RPAS Qual';
            app.Pos1 = uieditfield(app.RPASQualPanel, 'text');
            app.Pos1.Value = 'Test_1';
            app.DataCollectionSheetLabel = uilabel(app.RPASQualPanel);
            app.DataCollectionSheetLabel.Text = 'Data Collection Sheet';
            app.ImportButton = uibutton(app.RPASQualPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.atZnomLabel = uilabel(app.RPASQualPanel);
            app.atZnomLabel.Text = 'at Znom';
            app.Label_2 = uilabel(app.RPASQualPanel);
            app.Label_2.Text = '1';
            app.NoLabel = uilabel(app.RPASQualPanel);
            app.NoLabel.Text = 'No';
            app.Pos2 = uieditfield(app.RPASQualPanel, 'text');
            app.Pos2.Value = 'Test_2';
            app.ImportButton_2 = uibutton(app.RPASQualPanel, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_2Pushed, true);
            app.ImportButton_2.Text = 'Import';
            app.at850umLabel = uilabel(app.RPASQualPanel);
            app.at850umLabel.Text = 'at +/-850 um ';
            app.Label_3 = uilabel(app.RPASQualPanel);
            app.Label_3.Text = '2';
            app.withEIPWindowLabel = uilabel(app.RPASQualPanel);
            app.withEIPWindowLabel.Text = '(with EIP Window)';
            app.Pos3 = uieditfield(app.RPASQualPanel, 'text');
            app.Pos3.Value = 'Test_3';
            app.ImportButton_3 = uibutton(app.RPASQualPanel, 'push');
            app.ImportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_3Pushed, true);
            app.ImportButton_3.Text = 'Import';
            app.at3circLabel = uilabel(app.RPASQualPanel);
            app.at3circLabel.Interpreter = 'latex';
            app.at3circLabel.Text = 'at +/- $3^{\circ}$ ';
            app.Label_4 = uilabel(app.RPASQualPanel);
            app.Label_4.Text = '3';
            app.Pos4 = uieditfield(app.RPASQualPanel, 'text');
            app.Pos4.Value = 'Test_4';
            app.ImportButton_4 = uibutton(app.RPASQualPanel, 'push');
            app.ImportButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_4Pushed, true);
            app.ImportButton_4.Text = 'Import';
            app.atZnomLabel_2 = uilabel(app.RPASQualPanel);
            app.atZnomLabel_2.Text = 'at Znom';
            app.Label_5 = uilabel(app.RPASQualPanel);
            app.Label_5.Text = '4';
            app.Pos5 = uieditfield(app.RPASQualPanel, 'text');
            app.Pos5.Value = 'Test_5';
            app.ImportButton_5 = uibutton(app.RPASQualPanel, 'push');
            app.ImportButton_5.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_5Pushed, true);
            app.ImportButton_5.Text = 'Import';
            app.at850umLabel_2 = uilabel(app.RPASQualPanel);
            app.at850umLabel_2.Text = 'at +/-850 um ';
            app.Label_6 = uilabel(app.RPASQualPanel);
            app.Label_6.Text = '5';
            app.woEIPWindowLabel = uilabel(app.RPASQualPanel);
            app.woEIPWindowLabel.Text = '(w/o EIP Window)';
            app.Pos6 = uieditfield(app.RPASQualPanel, 'text');
            app.Pos6.Value = 'Test_6';
            app.ImportButton_6 = uibutton(app.RPASQualPanel, 'push');
            app.ImportButton_6.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_6Pushed, true);
            app.ImportButton_6.Text = 'Import';
            app.at3circLabel_2 = uilabel(app.RPASQualPanel);
            app.at3circLabel_2.Interpreter = 'latex';
            app.at3circLabel_2.Text = 'at +/- $3^{\circ}$ ';
            app.Label_7 = uilabel(app.RPASQualPanel);
            app.Label_7.Text = '6';
            app.Pos7 = uieditfield(app.RPASQualPanel, 'text');
            app.Pos7.Value = 'Test_7';
            app.ImportButton_7 = uibutton(app.RPASQualPanel, 'push');
            app.ImportButton_7.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_7Pushed, true);
            app.ImportButton_7.Text = 'Import';
            app.TwinscanValidationLabel = uilabel(app.RPASQualPanel);
            app.TwinscanValidationLabel.Interpreter = 'latex';
            app.TwinscanValidationLabel.Text = 'Twinscan Validation';
            app.Label_8 = uilabel(app.RPASQualPanel);
            app.Label_8.Text = {'7'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.BacktoMainScreenButton = uibutton(app.GridLayout4, 'push');
            app.BacktoMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainScreenButtonPushed, true);
            app.BacktoMainScreenButton.Text = {'Back to Main'; 'Screen'};
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            % Show the figure after all components are created
            app.RPASEngineeringParameter3UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENG_PARAM3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASEngineeringParameter3UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASEngineeringParameter3UIFigure)
        end
    end
end