classdef RPAS_Alignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASAlignmentUIFigure          matlab.ui.Figure
        RPASAlignmentPanel             matlab.ui.container.Panel
        ResultsPanel                   matlab.ui.container.Panel
        Target_Rz                      matlab.ui.control.NumericEditField
        RzEditFieldLabel               matlab.ui.control.Label
        Target_Y                       matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        Target_X                       matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        PassFailLabel                  matlab.ui.control.Label
        Pass_X                         matlab.ui.control.Lamp
        Pass_Y                         matlab.ui.control.Lamp
        Pass_Rz                        matlab.ui.control.Lamp
        umLabel_3                      matlab.ui.control.Label
        uradLabel                      matlab.ui.control.Label
        umLabel                        matlab.ui.control.Label
        umLabel_4                      matlab.ui.control.Label
        umLabel_5                      matlab.ui.control.Label
        uradLabel_2                    matlab.ui.control.Label
        TargetSpecsLabel               matlab.ui.control.Label
        ErrorLabel                     matlab.ui.control.Label
        Error_Rz                       matlab.ui.control.NumericEditField
        Error_Y                        matlab.ui.control.NumericEditField
        Error_X                        matlab.ui.control.NumericEditField
        GotoZrangebottomoflimitButton  matlab.ui.control.Button
        GotoZrangetoplimitButton       matlab.ui.control.Button
        GotoRefScreenButton            matlab.ui.control.Button
        ZLamp                          matlab.ui.control.Lamp
        ZLampLabel                     matlab.ui.control.Label
        YLamp                          matlab.ui.control.Lamp
        YLampLabel                     matlab.ui.control.Label
        XLamp                          matlab.ui.control.Lamp
        XLampLabel                     matlab.ui.control.Label
        TravelLimitLabel               matlab.ui.control.Label
        ImageCapturePanel              matlab.ui.container.Panel
        StartStopCaptureImageCalcButton  matlab.ui.control.Button
        ShotMethodSwitch               matlab.ui.control.Switch
        WDSwitch                       matlab.ui.control.Switch
        PresentYPosPanel               matlab.ui.container.Panel
        mmLabel_9                      matlab.ui.control.Label
        PresentYPos                    matlab.ui.control.NumericEditField
        PresentZPosPanel               matlab.ui.container.Panel
        mmLabel_8                      matlab.ui.control.Label
        PresentZPos                    matlab.ui.control.NumericEditField
        ReticlePositionPanel           matlab.ui.container.Panel
        RelativeXMovePanel             matlab.ui.container.Panel
        Rel_X                          matlab.ui.control.NumericEditField
        Xminus                         matlab.ui.control.Button
        mmLabel_18                     matlab.ui.control.Label
        Xplus                          matlab.ui.control.Button
        AbsoluteXMovePanel             matlab.ui.container.Panel
        Abs_X                          matlab.ui.control.NumericEditField
        mmLabel_17                     matlab.ui.control.Label
        XStart                         matlab.ui.control.Button
        RelativeYMovePanel             matlab.ui.container.Panel
        Rel_Y                          matlab.ui.control.NumericEditField
        Yminus                         matlab.ui.control.Button
        Yplus                          matlab.ui.control.Button
        mmLabel_15                     matlab.ui.control.Label
        AbsoluteYMovePanel             matlab.ui.container.Panel
        Abs_Y                          matlab.ui.control.NumericEditField
        mmLabel_16                     matlab.ui.control.Label
        YStart                         matlab.ui.control.Button
        RelativeZMovePanel             matlab.ui.container.Panel
        Rel_Z                          matlab.ui.control.NumericEditField
        Zminus                         matlab.ui.control.Button
        Zplus                          matlab.ui.control.Button
        mmLabel_13                     matlab.ui.control.Label
        AbsoluteZMovePanel             matlab.ui.container.Panel
        Abs_Z                          matlab.ui.control.NumericEditField
        ZStart                         matlab.ui.control.Button
        mmLabel_14                     matlab.ui.control.Label
        GoToSWDXcYcZnomButton          matlab.ui.control.Button
        GoToLWDXcYcZnomButton          matlab.ui.control.Button
        GoToPARKButton                 matlab.ui.control.Button
        PresentXPosPanel               matlab.ui.container.Panel
        mmLabel_7                      matlab.ui.control.Label
        PresentXPos                    matlab.ui.control.NumericEditField
        STOPMOTIONButton               matlab.ui.control.Button
        BackToMainScreenButton         matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        FailColor=[0.6, 0, 0];
        PassColor=[0, 1, 0.5];
        xupdater  % timer to query present x position
        yupdater % timer to query present y position
        zupdater % timer to query present z position
        
        systemVar; % variable hold system configurations
        Park_X;
        Park_Y;
        Park_Z;
        SWD_Xc;
        SWD_Yc;
        SWD_Znorm;
        LWD_Xc;
        LWD_Yc;
        LWD_Znorm;
        Zrange; 
        Xlimit_plus;
        Xlimit_minus;
        Ylimit_plus;
        Ylimit_minus;
        Zlimit_plus;
        Zlimit_minus;
        
        capturedImg; % Description
        refScreenHandle; % Description
        %contImageOn; % Description
        imageCaptureTime % Description
        figureHandle; % Description
    end
    
    methods (Access = private)
        
        function init(app)
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables; %read in system setup
            
            var=app.systemVar.motionSetupVar;
            app.Park_X=var.XPark;
            app.Park_Y=var.YPark;
            app.Park_Z=var.ZPark;
            app.Xlimit_plus = var.Xlimit_Plus;
            app.Xlimit_minus = var.Xlimit_Minus;
            app.Ylimit_plus = var.Ylimit_Plus;
            app.Ylimit_minus = var.Ylimit_Minus;
            app.Zlimit_plus = var.Zlimit_Plus;
            app.Zlimit_minus = var.Zlimit_Minus;
            
            var=app.systemVar.toolCalibrationVar;
            app.SWD_Xc = var.SWD_Xc;
            app.SWD_Yc = var.SWD_Yc;
            app.LWD_Xc = var.LWD_Xc;
            app.LWD_Yc = var.LWD_Yc;
            
            var=app.systemVar.engineeringPar1Var;

            app.imageCaptureTime=max(var.MinTimeBetweenImageCaptures,50)*1.0e-3;

            app.SWD_Znorm = var.SWDNominalZ;
            app.LWD_Znorm = var.LWDNominalZ;
            app.Zrange=var.ZRange;
            
            %input the target specs
            app.Target_X.Value=var.AllowableXYError;
            app.Target_Y.Value=var.AllowableXYError;
            app.Target_Rz.Value=var.AllowableRzError;

            %initialize lamp color
            app.XLamp.Color=app.UnderLimitColor;
            app.YLamp.Color=app.UnderLimitColor;
            app.ZLamp.Color=app.UnderLimitColor;
            app.Pass_X.Color=app.PassColor;
            app.Pass_Y.Color=app.PassColor;
            app.Pass_Rz.Color=app.PassColor;
            
            app.GotoRefScreenButton.Enable=false;

            %app.contImageOn=false;
            
            str='Fail if \abs{Error} > Target Specs';
            app.Pass_X.Tooltip=str;
            app.Pass_Y.Tooltip=str;
            app.Pass_Rz.Tooltip=str;

            str=[];
            str{1}='Green only if Present X Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Xlimit_minus, app.Xlimit_plus);
            app.XLamp.Tooltip=str;

            str{1}='Green only if Present Y Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Ylimit_minus, app.Ylimit_plus);
            app.YLamp.Tooltip=str;

            str{1}='Green only if Present Z Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Zlimit_minus, app.Zlimit_plus);
            app.ZLamp.Tooltip=str;

            app.xupdater=updatePosition(app.PresentXPos, 0, .05, ...
                {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater=updatePosition(app.PresentYPos, 1, .05, ...
                {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater=updatePosition(app.PresentZPos, 2, .05, ...
                {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
             app.xupdater.start();
             app.yupdater.start();
             app.zupdater.start();

             pause(0.1);
             app.stopTimer();

             if app.PresentXPos.Value==app.LWD_Xc && ...
                     app.PresentYPos.Value==app.LWD_Yc && ...
                     app.PresentZPos.Value==app.LWD_Znorm
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
             else
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
             end
        end
        
        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, Lamp)
            try
                val=round(val*1.0e3)*1.0e-3;
                upperLimit=round(upperLimit*1.0e6)*1.0e-6;
                lowerLimit=round(lowerLimit*1.0e6)*1.0e-6;
                c=Lamp.Color;
                warning=false;
                Lamp.Color=app.UnderLimitColor;

                if val>upperLimit || val<lowerLimit
                    Lamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                        sprintf('Lower Limit: %g, upperLimit: %g', lowerLimit, upperLimit), ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
            Lamp.Color=c;
        end
        
        function stopTimer(app)
            app.xupdater.stop();
            app.yupdater.stop();
            app.zupdater.stop();
        end
        
        function [img, name] = captureImage(app, cameraSource)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            if RPAS_C.CameraAvailable
                img = RPAS_ImageAcquisition_7dot1(cameraSource, 1);
                if cameraSource==1 % SWD
                    name='SWD';
                else
                    name='LWD';
                end
            else
                global kindex;

                path=[RPAS_C.QUAL_IMAGE_DIR '/AlignMagImages/'];
                if 0
                    files={'450µm-2.5b-off-Focus-adj-noise-640.bmp', ...
                        '450µm-2.5b-off-Focus-adj-noise-640-1.05.bmp', ...
                        '850µm-7b-off-Focus-adj-noise-640.bmp', ...
                        'InFocus-2° rotated.bmp', ...
                        'InFocus-adj-noise-640-0.4°.bmp', ...
                        'InFocusdj+noise-1.02.bmp', ...
                        'InFocus-FFT-of-final02-640-2adjnois-ot-0.95.bmp'};
                else
                    files={'Inverse FFT of final021024-2adj+noise90°-1.tif', ...
                        'Inverse FFT of final021024-2adj+noise87°-1.bmp', ...
                        'Inverse FFT of final021024-2adj+noise93°-1.bmp'};
                end

                if isempty(kindex)
                    kindex=1;
                else
                    kindex=mod(kindex,numel(files))+1;
                end

                fnm=[path  files{kindex} ];
                img=imread(fnm);
                name=files{kindex};
            end
        end
        
        function displayImage(app, img, fnm)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            app.UIAxes.XLim=[0, size(img,2)];
            app.UIAxes.YLim=[0, size(img,1)];
            tik=0:100:600;
            tikLbl={};
            for k=1:numel(tik)
                tikLbl{k}=num2str(tik(k)*RPAS_C.Pixel_img_plane*1.0e-3);
            end
            app.UIAxes.XTick=tik;
            app.UIAxes.XTickLabel=tikLbl;
            app.UIAxes.YTick=tik(1:5);
            app.UIAxes.YTickLabel=tikLbl(1:5);
            
            
            R=imref2d(size(img));
            imshow(img, R,'Parent', app.UIAxes);
            text(.45,-0.05,fnm,'horiz','center', 'vert','bottom', ...
                'unit',' normalized', 'Parent', app.UIAxes);
            
            drawnow;
        end
        
        function popupWindow(app, img, src, showCrossHair)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            if isempty(app.figureHandle) | ~isvalid(app.figureHandle)
                app.figureHandle=figure(1);
            end
            app.figureHandle.DockControls='on';
            imshow(img, imref2d(size(img)));

            gca.Title.String=src;
            ax=gca;
            gca.Xlim=[0,size(img,2)];
            gca.Ylim= [0,size(img,1)];
            %set(gca,'xlabel', 'Y (mm)');
            %set(gca,'ylabel', 'Y (mm)');
            xlabel('Y (mm)');
            ylabel('X (mm)');
            ax.XAxisLocation='top';
            ax.YAxisLocation='left';
            ax.YDir='reverse';

%             mX=round(size(img,2)/100);
%             dx=round(mX/6)*100;
%             xtik=[0, dx, 2*dx, 3*dx, 4*dx, 5*dx, min(6*dx, mX*100)];
%             ax.XTick=xtik;
%             label={};
%             for j=1:numel(xtik)
%                 label{end+1}=num2str(xtik(j));
%             end
%             yticks(xtik(1:5));
%             yticklabels(label(1:5));

            tik=0:100:600;
            tikLbl={};
            for k=1:numel(tik)
                tikLbl{k}=num2str(tik(k)*RPAS_C.Pixel_img_plane*1.0e-3);
            end

            xticks(tik);
            xticklabels(tikLbl);
            yticks(tik(1:5));
            yticklabels(tikLbl(1:5));

            drawnow;
        end
        
        function  captureContAlignmentImage(app, cameraSource)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            global stopICBSignal;
            if stopICBSignal==0
                app.stopContCaptureImage();
                return;
            end
            %app.contImageOn=true;

            if RPAS_C.CameraAvailable
                var=app.systemVar.engineeringPar1Var;
                pauseTime=max(var.MinTimeBetweenImageCaptures,1)*1.0e-3;
                pause(pauseTime);
                RPAS_ImageAcquisition_with_Calc(cameraSource, app.UIAxes, ...
                    [app.Target_X.Value, app.Target_Y.Value, app.Target_Rz.Value], ...
                    [app.Error_X, app.Error_Y, app.Error_Rz], ...
                    [app.Pass_X, app.Pass_Y, app.Pass_Rz], ...
                    struct('Pass', app.PassColor, 'Fail', app.FailColor), pauseTime);
%                 img = RPAS_ImageAcquisition_7dot1(cameraSource, 1);
%                 if cameraSource==1 % SWD
%                     name='SWD';
%                 else
%                     name='LWD';
%                 end
            else
                stopICBSignal=0;
                while stopICBSignal==0
                    if isvalid(app)
                        img=app.captureImage(cameraSource);
                        factor=1/app.systemVar.engineeringPar1Var.ImageConversFactor;
                        a=RPAS_Alignment_7dot2(imrotate(img, 90));
                     
                        ct=floor((size(img)+1)/2);
                        app.Error_X.Value=(a.X_in_pixels-ct(1))*factor*1.0e3; % to um
                        app.Error_Y.Value=(a.Y_in_pixels-ct(2))*factor*1.0e3; % to um
                        app.Error_Rz.Value=a.Rz*1.0e3; % to urad
                     
                        % lamp color
                        var=app.systemVar.engineeringPar1Var;
                        if abs(app.Error_X.Value)>app.Target_X.Value
                            app.Pass_X.Color=app.FailColor;
                        else
                            app.Pass_X.Color=app.PassColor;
                        end
                        if abs(app.Error_Y.Value)>app.Target_Y.Value
                            app.Pass_Y.Color=app.FailColor;
                        else
                            app.Pass_Y.Color=app.PassColor;
                        end
                        if abs(app.Error_Rz.Value)>app.Target_Rz.Value
                            app.Pass_Rz.Color=app.FailColor;
                        else
                            app.Pass_Rz.Color=app.PassColor;
                        end
    
                        %display image
                        if cameraSource==1 % SWD
                            name='SWD';
                        else
                            name='LWD';
                        end
                        if isvalid(app)
                            app.displayImage(img, name);
                            %app.popupWindow(img, name);
                        end
    
                        if isvalid(app)
                            pause(app.imageCaptureTime);
                        end
                    end
                end
            end
        end
        
        function stopContCaptureImage(app)
            global stopICBSignal;
            stopICBSignal=1;

            %app.contImageOn=false;
            pause(2*app.imageCaptureTime); %wait for a while for ICB power down
        end
    end

    methods (Access = public)
        
        function close(app)
            app.RPASAlignmentUIFigureCloseRequest();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Button pushed function: StartStopCaptureImageCalcButton
        function StartStopCaptureImageCalcButtonPushed(app, event)
            if app.ShotMethodSwitch.Value==1 % Single Shot
                global RPAS_C 
                if isempty(RPAS_C)
                    RPAS_C=RPAS_Constants(parentDir(pwd));
                end
                [app.capturedImg, fnm]= ...
                    app.captureImage(app.WDSwitch.Value);

                %save captured image
                path=[RPAS_C.QUAL_RESULT_DIR '/Alignment'];
                RPAS_Make_folder(path);
        
                dt=datetime();
                datetimestring=datestr(dt, 'mmm_dd_yyyy_HH_MM_SS');
                if app.WDSwitch.Value==1 % SWD
                    fname=[path '/SWD_snapshot_DateTime=' datetimestring '.png'];
                else
                    fname=[path '/LWD_snapshot_DateTime=' datetimestring '.png'];
                end
                
                imwrite(app.capturedImg,fname);

                app.GotoRefScreenButton.Enable=true;
            
                %compute and display alignment X,Y,Rz
                factor=1/app.systemVar.engineeringPar1Var.ImageConversFactor;
                a=RPAS_Alignment_7dot2(imrotate(app.capturedImg,90));
            
                ct=floor((size(app.capturedImg)+1)/2);
                app.Error_X.Value=(a.X_in_pixels-ct(1))*factor*1.0e3; % to um
                app.Error_Y.Value=(a.Y_in_pixels-ct(2))*factor*1.0e3; % to um
                app.Error_Rz.Value=a.Rz*1.0e3; % to urad

                % lamp color
                var=app.systemVar.engineeringPar1Var;
                if abs(app.Error_X.Value)>app.Target_X.Value
                    app.Pass_X.Color=app.FailColor;
                else
                    app.Pass_X.Color=app.PassColor;
                end
                if abs(app.Error_Y.Value)>app.Target_Y.Value
                    app.Pass_Y.Color=app.FailColor;
                else
                    app.Pass_Y.Color=app.PassColor;
                end
                if abs(app.Error_Rz.Value)>app.Target_Rz.Value
                    app.Pass_Rz.Color=app.FailColor;
                else
                    app.Pass_Rz.Color=app.PassColor;
                end
            
            
                app.displayImage(app.capturedImg, fnm);
                app.popupWindow(app.capturedImg, fnm);
            else
                app.captureContAlignmentImage(app.WDSwitch.Value);
            end
        end

        % Button pushed function: Xplus
        function XplusButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            val=app.PresentXPos.Value + app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                RelativeMoving(0, app.Rel_X.Value, egrvar.ManualMotionSpeed, app.xupdater);
            end
        end

        % Button pushed function: Xminus
        function XminusButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;val=app.PresentXPos.Value - app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
               RelativeMoving(0, -app.Rel_X.Value, egrvar.ManualMotionSpeed, app.xupdater);
            end
        end

        % Button pushed function: Yplus
        function YplusButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            val=app.PresentYPos.Value + app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, app.Rel_Y.Value, egrvar.ManualMotionSpeed, app.yupdater);
            end
        end

        % Button pushed function: Yminus
        function YminusButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            val=app.PresentYPos.Value - app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, -app.Rel_Y.Value, egrvar.ManualMotionSpeed, app.yupdater);
            end
        end

        % Button pushed function: Zplus
        function ZplusButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            val=app.PresentZPos.Value + app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
               RelativeMoving(2, app.Rel_Z.Value, egrvar.ManualMotionSpeed, app.zupdater);
            end
        end

        % Button pushed function: Zminus
        function ZminusButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            val=app.PresentZPos.Value - app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                RelativeMoving(2, -app.Rel_Z.Value, egrvar.ManualMotionSpeed, app.zupdater);
            end
        end

        % Button pushed function: XStart
        function XStartButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            val=app.Abs_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                ABSMoving(0, app.Abs_X.Value, egrvar.ManualMotionSpeed, app.xupdater);
            end
        end

        % Button pushed function: YStart
        function YStartButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            val=app.Abs_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                ABSMoving(1, app.Abs_Y.Value, egrvar.ManualMotionSpeed, app.yupdater);
            end
        end

        % Button pushed function: ZStart
        function ZStartButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            val=app.Abs_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                ABSMoving(2, app.Abs_Z.Value, egrvar.ManualMotionSpeed, app.zupdater);
            end
        end

        % Button pushed function: GoToLWDXcYcZnomButton
        function GoToLWDXcYcZnomButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            axes=[0, 1, 2];
            dist=[app.LWD_Xc, app.LWD_Yc, app.LWD_Znorm];
            
            ABSMoving(axes, dist, egrvar.ManualMotionSpeed, [app.xupdater, app.yupdater, app.zupdater]);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
        end

        % Button pushed function: GoToSWDXcYcZnomButton
        function GoToSWDXcYcZnomButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            axes=[0, 1, 2];
            dist=[app.SWD_Xc, app.SWD_Yc, app.SWD_Znorm];
            
            ABSMoving(axes, dist, egrvar.ManualMotionSpeed, [app.xupdater, app.yupdater, app.zupdater]);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
        end

        % Close request function: RPASAlignmentUIFigure
        function RPASAlignmentUIFigureCloseRequest(app, event)
            global stopICBSignal;
            if stopICBSignal==0
                app.stopContCaptureImage();
                var=app.systemVar.engineeringPar1Var;
                pauseTime=max(var.MinTimeBetweenImageCaptures,1)*1.0e-3;
                while stopICBSignal==0
                    pause(pauseTime);
                end
            end
            if ~isempty(app.refScreenHandle) & isvalid(app.refScreenHandle)
                delete(app.refScreenHandle);
            end
            if ~isempty(app.figureHandle) & isvalid(app.figureHandle)
                delete(app.figureHandle)
            end
            app.STOPMOTIONButtonPushed();
            delete(app);             
        end

        % Button pushed function: GotoRefScreenButton
        function GotoRefScreenButtonPushed(app, event)
            app.refScreenHandle=RPAS_Align_Qual_Ref_info();
        end

        % Button pushed function: GotoZrangetoplimitButton
        function GotoZrangetoplimitButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            if app.WDSwitch.Value==1 %SWD
                val=app.SWD_Znorm+app.Zrange;
            else
                val=app.LWD_Znorm+app.Zrange;
            end
            ABSMoving(2, val, egrvar.ManualMotionSpeed, app.zupdater);
        end

        % Button pushed function: GotoZrangebottomoflimitButton
        function GotoZrangebottomoflimitButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            if app.WDSwitch.Value==1 %SWD
                val=app.SWD_Znorm-app.Zrange;
            else
                val=app.LWD_Znorm-app.Zrange;
            end
            ABSMoving(2, val, egrvar.ManualMotionSpeed, app.zupdater);
        end

        % Button pushed function: GoToPARKButton
        function GoToPARKButtonPushed(app, event)
            egrvar = app.systemVar.engineeringPar1Var;
            axes=[0, 1, 2];
            dist=[app.Park_X, app.Park_Y, app.Park_Z];
            
            ABSMoving(axes, dist, egrvar.ManualMotionSpeed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Value changed function: ShotMethodSwitch
        function ShotMethodSwitchValueChanged(app, event)
            global stopICBSignal;
            if stopICBSignal==0
                app.stopContCaptureImage();
            end
%             value = app.ShotMethodSwitch.Value;
%             if value==1
%                 app.StartStopCaptureImageCalcButton.Enable=true;
%             else
%                 app.StartStopCaptureImageCalcButton.Enable=false;
%             end
        end

        % Button pushed function: BackToMainScreenButton
        function BackToMainScreenButtonPushed(app, event)
            app.RPASAlignmentUIFigureCloseRequest();
        end

        % Button pushed function: STOPMOTIONButton
        function STOPMOTIONButtonPushed(app, event)
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
        end

        % Value changed function: WDSwitch
        function WDSwitchValueChanged(app, event)
            global stopICBSignal;
            if stopICBSignal==0
                app.stopContCaptureImage();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASAlignmentUIFigure = uifigure('Visible', 'off');
            app.RPASAlignmentUIFigure.Name = 'RPAS Alignment';
            app.RPASAlignmentUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASAlignmentUIFigureCloseRequest, true);
            app.RPASAlignmentPanel = uipanel(app.RPASAlignmentUIFigure);
            app.RPASAlignmentPanel.Title = 'RPAS Alignment';
            app.UIAxes = uiaxes(app.RPASAlignmentPanel);
            xlabel(app.UIAxes, 'Y (mm)')
            ylabel(app.UIAxes, 'X (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.XAxisLocation = 'top';
            app.BackToMainScreenButton = uibutton(app.RPASAlignmentPanel, 'push');
            app.BackToMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainScreenButtonPushed, true);
            app.BackToMainScreenButton.Text = 'Back To Main Screen';
            app.STOPMOTIONButton = uibutton(app.RPASAlignmentPanel, 'push');
            app.STOPMOTIONButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMOTIONButtonPushed, true);
            app.STOPMOTIONButton.Text = {'STOP'; 'MOTION'};
            app.PresentXPosPanel = uipanel(app.RPASAlignmentPanel);
            app.PresentXPosPanel.Title = 'Present X Pos';
            app.PresentXPos = uieditfield(app.PresentXPosPanel, 'numeric');
            app.PresentXPos.ValueDisplayFormat = '%.3f';
            app.PresentXPos.Editable = 'off';
            app.mmLabel_7 = uilabel(app.PresentXPosPanel);
            app.mmLabel_7.Text = 'mm';
            app.ReticlePositionPanel = uipanel(app.RPASAlignmentPanel);
            app.ReticlePositionPanel.Title = 'Reticle Position';
            app.GoToPARKButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToPARKButton.ButtonPushedFcn = createCallbackFcn(app, @GoToPARKButtonPushed, true);
            app.GoToPARKButton.Text = 'Go To PARK';
            app.GoToLWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToLWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToLWDXcYcZnomButtonPushed, true);
            app.GoToLWDXcYcZnomButton.Text = 'Go To LWD Xc Yc Znom';
            app.GoToSWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToSWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToSWDXcYcZnomButtonPushed, true);
            app.GoToSWDXcYcZnomButton.Text = 'Go To SWD Xc Yc Znom';
            app.AbsoluteZMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.mmLabel_14 = uilabel(app.AbsoluteZMovePanel);
            app.mmLabel_14.Text = 'mm';
            app.ZStart = uibutton(app.AbsoluteZMovePanel, 'push');
            app.ZStart.ButtonPushedFcn = createCallbackFcn(app, @ZStartButtonPushed, true);
            app.ZStart.Text = 'Start';
            app.Abs_Z = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.Abs_Z.ValueDisplayFormat = '%.3f';
            app.RelativeZMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.mmLabel_13 = uilabel(app.RelativeZMovePanel);
            app.mmLabel_13.Text = 'mm';
            app.Zplus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zplus.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.Zplus.Text = '+';
            app.Zminus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zminus.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.Zminus.Text = '-';
            app.Rel_Z = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.Rel_Z.ValueDisplayFormat = '%.3f';
            app.AbsoluteYMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.YStart = uibutton(app.AbsoluteYMovePanel, 'push');
            app.YStart.ButtonPushedFcn = createCallbackFcn(app, @YStartButtonPushed, true);
            app.YStart.Text = 'Start';
            app.mmLabel_16 = uilabel(app.AbsoluteYMovePanel);
            app.mmLabel_16.Text = 'mm';
            app.Abs_Y = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.Abs_Y.ValueDisplayFormat = '%.3f';
            app.RelativeYMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.mmLabel_15 = uilabel(app.RelativeYMovePanel);
            app.mmLabel_15.Text = 'mm';
            app.Yplus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yplus.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.Yplus.Text = '+';
            app.Yminus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yminus.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.Yminus.Text = '-';
            app.Rel_Y = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.Rel_Y.ValueDisplayFormat = '%.3f';
            app.AbsoluteXMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.XStart = uibutton(app.AbsoluteXMovePanel, 'push');
            app.XStart.ButtonPushedFcn = createCallbackFcn(app, @XStartButtonPushed, true);
            app.XStart.Text = 'Start';
            app.mmLabel_17 = uilabel(app.AbsoluteXMovePanel);
            app.mmLabel_17.Text = 'mm';
            app.Abs_X = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.Abs_X.ValueDisplayFormat = '%.3f';
            app.RelativeXMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.Xplus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xplus.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.Xplus.Text = '+';
            app.mmLabel_18 = uilabel(app.RelativeXMovePanel);
            app.mmLabel_18.Text = 'mm';
            app.Xminus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xminus.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.Xminus.Text = '-';
            app.Rel_X = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.Rel_X.ValueDisplayFormat = '%.3f';
            app.PresentZPosPanel = uipanel(app.RPASAlignmentPanel);
            app.PresentZPosPanel.Title = 'Present Z Pos';
            app.PresentZPos = uieditfield(app.PresentZPosPanel, 'numeric');
            app.PresentZPos.ValueDisplayFormat = '%.3f';
            app.PresentZPos.Editable = 'off';
            app.mmLabel_8 = uilabel(app.PresentZPosPanel);
            app.mmLabel_8.Text = 'mm';
            app.PresentYPosPanel = uipanel(app.RPASAlignmentPanel);
            app.PresentYPosPanel.Title = 'Present Y Pos';
            app.PresentYPos = uieditfield(app.PresentYPosPanel, 'numeric');
            app.PresentYPos.ValueDisplayFormat = '%.3f';
            app.PresentYPos.Editable = 'off';
            app.mmLabel_9 = uilabel(app.PresentYPosPanel);
            app.mmLabel_9.Text = 'mm';
            app.ImageCapturePanel = uipanel(app.RPASAlignmentPanel);
            app.ImageCapturePanel.Title = 'Image Capture';
            app.WDSwitch = uiswitch(app.ImageCapturePanel, 'slider');
            app.WDSwitch.Items = {'SWD', 'LWD'};
            app.WDSwitch.ItemsData = [1 0];
            app.WDSwitch.ValueChangedFcn = createCallbackFcn(app, @WDSwitchValueChanged, true);
            app.WDSwitch.Value = 1;
            app.ShotMethodSwitch = uiswitch(app.ImageCapturePanel, 'slider');
            app.ShotMethodSwitch.Items = {'Single Shot', 'Cont.'};
            app.ShotMethodSwitch.ItemsData = [1 0];
            app.ShotMethodSwitch.ValueChangedFcn = createCallbackFcn(app, @ShotMethodSwitchValueChanged, true);
            app.ShotMethodSwitch.Value = 1;
            app.StartStopCaptureImageCalcButton = uibutton(app.ImageCapturePanel, 'push');
            app.StartStopCaptureImageCalcButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopCaptureImageCalcButtonPushed, true);
            app.StartStopCaptureImageCalcButton.Text = {'Start/Stop '; 'Capture Image/Calc'};
            app.TravelLimitLabel = uilabel(app.RPASAlignmentPanel);
            app.TravelLimitLabel.Text = 'Travel Limit';
            app.XLampLabel = uilabel(app.RPASAlignmentPanel);
            app.XLampLabel.Text = 'X';
            app.XLamp = uilamp(app.RPASAlignmentPanel);
            app.YLampLabel = uilabel(app.RPASAlignmentPanel);
            app.YLampLabel.Text = 'Y';
            app.YLamp = uilamp(app.RPASAlignmentPanel);
            app.ZLampLabel = uilabel(app.RPASAlignmentPanel);
            app.ZLampLabel.Text = 'Z';
            app.ZLamp = uilamp(app.RPASAlignmentPanel);
            app.GotoRefScreenButton = uibutton(app.RPASAlignmentPanel, 'push');
            app.GotoRefScreenButton.ButtonPushedFcn = createCallbackFcn(app, @GotoRefScreenButtonPushed, true);
            app.GotoRefScreenButton.Text = 'Go to Ref Screen';
            app.GotoZrangetoplimitButton = uibutton(app.RPASAlignmentPanel, 'push');
            app.GotoZrangetoplimitButton.ButtonPushedFcn = createCallbackFcn(app, @GotoZrangetoplimitButtonPushed, true);
            app.GotoZrangetoplimitButton.Text = 'Go to Z range top limit';
            app.GotoZrangebottomoflimitButton = uibutton(app.RPASAlignmentPanel, 'push');
            app.GotoZrangebottomoflimitButton.ButtonPushedFcn = createCallbackFcn(app, @GotoZrangebottomoflimitButtonPushed, true);
            app.GotoZrangebottomoflimitButton.Text = 'Go to Z range bottom of limit';
            app.ResultsPanel = uipanel(app.RPASAlignmentPanel);
            app.ResultsPanel.Title = 'Results';
            app.Error_X = uieditfield(app.ResultsPanel, 'numeric');
            app.Error_X.ValueDisplayFormat = '%.4f';
            app.Error_X.Editable = 'off';
            app.Error_Y = uieditfield(app.ResultsPanel, 'numeric');
            app.Error_Y.ValueDisplayFormat = '%.4f';
            app.Error_Y.Editable = 'off';
            app.Error_Rz = uieditfield(app.ResultsPanel, 'numeric');
            app.Error_Rz.ValueDisplayFormat = '%.2f';
            app.Error_Rz.Editable = 'off';
            app.ErrorLabel = uilabel(app.ResultsPanel);
            app.ErrorLabel.Text = 'Error';
            app.TargetSpecsLabel = uilabel(app.ResultsPanel);
            app.TargetSpecsLabel.Text = 'Target  Specs';
            app.uradLabel_2 = uilabel(app.ResultsPanel);
            app.uradLabel_2.Text = 'urad';
            app.umLabel_5 = uilabel(app.ResultsPanel);
            app.umLabel_5.Text = 'um';
            app.umLabel_4 = uilabel(app.ResultsPanel);
            app.umLabel_4.Text = 'um';
            app.umLabel = uilabel(app.ResultsPanel);
            app.umLabel.Text = 'um';
            app.uradLabel = uilabel(app.ResultsPanel);
            app.uradLabel.Text = 'urad';
            app.umLabel_3 = uilabel(app.ResultsPanel);
            app.umLabel_3.Text = 'um';
            app.Pass_Rz = uilamp(app.ResultsPanel);
            app.Pass_Y = uilamp(app.ResultsPanel);
            app.Pass_X = uilamp(app.ResultsPanel);
            app.PassFailLabel = uilabel(app.ResultsPanel);
            app.PassFailLabel.Text = 'Pass/Fail';
            app.XEditFieldLabel = uilabel(app.ResultsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.Target_X = uieditfield(app.ResultsPanel, 'numeric');
            app.Target_X.ValueDisplayFormat = '%.2f';
            app.Target_X.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.ResultsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.Target_Y = uieditfield(app.ResultsPanel, 'numeric');
            app.Target_Y.ValueDisplayFormat = '%.2f';
            app.Target_Y.Editable = 'off';
            app.RzEditFieldLabel = uilabel(app.ResultsPanel);
            app.RzEditFieldLabel.Text = 'Rz';
            app.Target_Rz = uieditfield(app.ResultsPanel, 'numeric');
            app.Target_Rz.ValueDisplayFormat = '%.2f';
            app.Target_Rz.Editable = 'off';
            % Show the figure after all components are created
            app.RPASAlignmentUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_Alignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASAlignmentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASAlignmentUIFigure)
        end
    end
end