classdef RPAS_AutoQual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASAutoQualificationUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        RPASAutomatedQualwWorkStationPanel  matlab.ui.container.Panel
        CheckallLabel                  matlab.ui.control.Label
        CheckBox_LWD                   matlab.ui.control.CheckBox
        CheckBox_SWD                   matlab.ui.control.CheckBox
        PassLabel_2                    matlab.ui.control.Label
        PassLabel                      matlab.ui.control.Label
        LWDLabel                       matlab.ui.control.Label
        SWDLabel                       matlab.ui.control.Label
        Pos6_SWD                       matlab.ui.control.Button
        Pos5_SWD                       matlab.ui.control.Button
        Pos4_SWD                       matlab.ui.control.Button
        Pos3_SWD                       matlab.ui.control.Button
        Pos2_SWD                       matlab.ui.control.Button
        Pos1_SWD                       matlab.ui.control.Button
        Contam_SWD                     matlab.ui.control.Button
        Mag_SWD                        matlab.ui.control.Button
        Pos6_LWD                       matlab.ui.control.Button
        Pos5_LWD                       matlab.ui.control.Button
        Pos4_LWD                       matlab.ui.control.Button
        Pos3_LWD                       matlab.ui.control.Button
        Pos2_LWD                       matlab.ui.control.Button
        Pos1_LWD                       matlab.ui.control.Button
        Contam_LWD                     matlab.ui.control.Button
        Mag_LWD                        matlab.ui.control.Button
        StartQualButton                matlab.ui.control.Button
        PauseResumeButton              matlab.ui.control.Button
        AbortTestButton                matlab.ui.control.Button
        BackToMainScreenButton         matlab.ui.control.Button
        atZnompm3circLabel_2           matlab.ui.control.Label
        atpm850umLabel_2               matlab.ui.control.Label
        atZnomLabel_2                  matlab.ui.control.Label
        RPASPerformancewithEIPwindowLabel  matlab.ui.control.Label
        LWD_Pos_6_Lamp                 matlab.ui.control.Lamp
        LWD_Pos_5_Lamp                 matlab.ui.control.Lamp
        LWD_Pos_4_Lamp                 matlab.ui.control.Lamp
        SWD_Pos_6_Lamp                 matlab.ui.control.Lamp
        SWD_Pos_5_Lamp                 matlab.ui.control.Lamp
        SWD_Pos_4_Lamp                 matlab.ui.control.Lamp
        atZnompm3circLabel             matlab.ui.control.Label
        atpm850umLabel                 matlab.ui.control.Label
        atZnomLabel                    matlab.ui.control.Label
        RPASPerformancewoEIPwindowLabel  matlab.ui.control.Label
        DetectorContaminationLabel     matlab.ui.control.Label
        MagnificationFocusLabel        matlab.ui.control.Label
        QualificationTestLabel         matlab.ui.control.Label
        LWD_Pos_3_Lamp                 matlab.ui.control.Lamp
        LWD_Pos_2_Lamp                 matlab.ui.control.Lamp
        LWD_Pos_1_Lamp                 matlab.ui.control.Lamp
        LWDContLamp                    matlab.ui.control.Lamp
        SWD_Pos_3_Lamp                 matlab.ui.control.Lamp
        SWD_Pos_2_Lamp                 matlab.ui.control.Lamp
        SWD_Pos_1_Lamp                 matlab.ui.control.Lamp
        SWDContLamp                    matlab.ui.control.Lamp
        LWDMagLamp                     matlab.ui.control.Lamp
        SWDMagLamp                     matlab.ui.control.Lamp
        ToQualResultButton             matlab.ui.control.Button
        ToQualButton                   matlab.ui.control.Button
        ToContButton                   matlab.ui.control.Button
        ToFocusButton                  matlab.ui.control.Button
        ToMagButton                    matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        TestStatus                     matlab.ui.control.TextArea
        TestStatusLabel                matlab.ui.control.Label
        ConfigureButton                matlab.ui.control.Button
        SaveasButton                   matlab.ui.control.Button
        FileInfoPanel                  matlab.ui.container.Panel
        RPASSN                         matlab.ui.control.EditField
        RPASSNEditFieldLabel           matlab.ui.control.Label
        Operator                       matlab.ui.control.EditField
        OperatorEditFieldLabel         matlab.ui.control.Label
        RPAS12NC                       matlab.ui.control.EditField
        RPAS12NCEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        FailColor=[0.6, 0, 0];
        PassColor=[0, 1, 0.5];
        OnColor=[0.85,0.33,0.10];
        OffColor=[1, 1, 1];
        MAG;
        CONT;
        QUAL; % Description
        systemVar; 
        magHandle; 
        contamHandle;
        qualHandle;
        currentHandle;
        pauseFlag;
        previousStatus;
        previousToggleStatus;
        magSheet;
        contamSheet;
        qualSheets;

        magResults;
        contamResults;
        qualResults;
        showDetail; % Description
        
        
        register;
        abortFlag;
    end
    
    methods (Access = public)
        function close(app)
            app.RPASAutoQualificationUIFigureCloseRequest();
        end
    end

    methods (Access = private)
        
        function warningMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Warning';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'icon', 'warning');
            switch selection
                case {'OK'}
                otherwise
            end
            delete(fig);
        end


        function showInfoMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Info';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'icon', 'info');
            switch selection
                case {'OK'}
                otherwise
            end
            delete(fig);
        end

        
        function [result, settings, image]=qualMag(app,dataSheet)

            result=[];
            settings=[];
            image=[];

            app.register.addProcess();

            if app.register.isAborting
                return;
            end
            app.checkPause();

            if isempty(app.magHandle) | ~isvalid(app.magHandle)
                app.magHandle=RPAS_Magnification();
            else
                app.magHandle.hide(false); %show the app
            end
            app.currentHandle=app.magHandle;

            app.currentHandle.freezeAll(true);
            app.currentHandle.reinit(dataSheet);
                
            if app.register.isAborting
                app.currentHandle.close();
                return;
            end
            app.checkPause();

            
            % set automatic qualification method
            app.currentHandle.setAutoQualMethod();

            app.currentHandle.autoQual(dataSheet.position);
                
            if app.register.isAborting
                app.currentHandle.close();
                return;
            end
            app.checkPause();

            %hide the app
            if app.showDetail
                app.showInfoMsg("Paused. Press OK to continue.");
            end
            app.currentHandle.hide(true);
                
            if app.register.isAborting
                app.currentHandle.close();
                return;
            end
            app.checkPause();

            %output result
            [result, settings, image]=app.currentHandle.getResults();
            
            if abs(result.MagError)>dataSheet.MagErr | ...
                    result.centerAmp_x_Error<0 | ...
                    result.HWHM_x_Error>0 | ...
                    result.centerAmp_y_Error<0 | ...
                    result.HWHM_y_Error>0
                if strcmp(dataSheet.camera, 'SWD')
                    app.SWDMagLamp.Color=app.FailColor;
                else
                    app.LWDMagLamp.Color=app.FailColor;
                end
            else
                if strcmp(dataSheet.camera, 'SWD')
                    app.SWDMagLamp.Color=app.PassColor;
                else
                    app.LWDMagLamp.Color=app.PassColor;
                end
            end

            % set back to manual qualification method
            app.currentHandle.setManualQualMethod();

            app.register.removeProcess();
        end
        
        function [result, settings, images]=qualContam(app,dataSheet)
            result=[];
            settings=[];
            images=[];

            app.register.addProcess();

            if app.register.isAborting
                return;
            end
            app.checkPause();

            if isempty(app.contamHandle) | ~isvalid(app.contamHandle)
                app.contamHandle=RPAS_ContamQual();
            else
                app.contamHandle.hide(false); %show the app
            end
            app.currentHandle=app.contamHandle;

            app.currentHandle.freezeAll(true);

            app.currentHandle.reinit(dataSheet);

            % set automatic qualification method
            app.currentHandle.setAutoQualMethod();
                
            if app.register.isAborting
                return;
            end
            app.checkPause();

            app.currentHandle.autoQual(dataSheet.positions);
            if app.showDetail
                app.showInfoMsg("Paused. Press OK to continue.");
            end
            
            app.currentHandle.hide(true); %hide the app
                
            if app.register.isAborting
                return;
            end
            app.checkPause();
            
            %output result
            [result, settings, images]=app.currentHandle.getResults();
                
            if app.register.isAborting
                return;
            end
            app.checkPause();

            
            if result>dataSheet.MaxDefectNum
                if strcmp(dataSheet.camera, 'SWD')
                    app.SWDContLamp.Color=app.FailColor;
                else
                    app.LWDContLamp.Color=app.FailColor;
                end
            else
                if strcmp(dataSheet.camera, 'SWD')
                    app.SWDContLamp.Color=app.PassColor;
                else
                    app.LWDContLamp.Color=app.PassColor;
                end
            end

            % set back to manual qualification method
            app.currentHandle.setManualQualMethod();

            app.register.removeProcess();
        end
        
        function [result, settings, images]=qual(app, data)
            result=[];
            settings=[]; 
            images=[];

            app.register.addProcess();

            if app.register.isAborting
                return;
            end
            app.checkPause();

            if isempty(app.qualHandle) | ~isvalid(app.qualHandle)
                app.qualHandle=RPAS_Qual();
            else
                app.qualHandle.hide(false); %show the app
            end
%             if isempty(app.qualHandle)
%                 app.qualHandle=RPAS_Qual();
%             elseif isvalid(app.qualHandle)
%                 app.qualHandle.hide(false); %show the app
%             else
%                 app.qualHandle=RPAS_Qual();
%             end

            app.currentHandle=app.qualHandle;

            % set automatic qualification method
            app.currentHandle.setAutoQualMethod();
                
            if app.register.isAborting
                return;
            end
            app.checkPause();

            app.currentHandle.freezeAll(true);
            app.currentHandle.autoQual(app.RPAS12NC.Value, ...
                app.Operator.Value, data, app.showDetail);
                
            if app.register.isAborting
                return;
            end
            app.checkPause();

            app.currentHandle.hide(true); %hide the app
                
            if app.register.isAborting
                return;
            end
            app.checkPause();

            
            %output result
            [result, settings, images]=app.currentHandle.getResults();
            
            flag=false;
            for k=1:numel(data.numTestPoints)
                flag=flag| any(abs(result(k,4:6))>data.Error(k,:));
                if flag
                    break;
                end
            end
                
            if app.register.isAborting
                return;
            end
            app.checkPause();

            
            if strcmp(data.camera, 'SWD')
                switch data.Pos
                    case 1
                        lamp=app.SWD_Pos_1_Lamp;
                    case 2
                        lamp=app.SWD_Pos_2_Lamp;
                    case 3
                        lamp=app.SWD_Pos_3_Lamp;
                    case 4
                        lamp=app.SWD_Pos_4_Lamp;
                    case 5
                        lamp=app.SWD_Pos_5_Lamp;
                    case 6
                        lamp=app.SWD_Pos_6_Lamp;
                end
            else
                switch data.Pos
                    case 1
                        lamp=app.LWD_Pos_1_Lamp;
                    case 2
                        lamp=app.LWD_Pos_2_Lamp;
                    case 3
                        lamp=app.LWD_Pos_3_Lamp;
                    case 4
                        lamp=app.LWD_Pos_4_Lamp;
                    case 5
                        lamp=app.LWD_Pos_5_Lamp;
                    case 6
                        lamp=app.LWD_Pos_6_Lamp;
                end
            end

            if flag
                lamp.Color=app.FailColor;
            else
                lamp.Color=app.PassColor;
            end

            % set back to manual qualification method
            app.currentHandle.setManualQualMethod();

            app.register.removeProcess();

        end

        function value=readConfiguration(app)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            fnm=[RPAS_C.QUAL_DATA_SHEET_DIR '/RPAS_Configuration.dat'];
            magic_number=35217;
            if ~exist(fnm)
                value=ones(16,1);
            else
                fid=fopen(fnm,'rb');
                    value=fread(fid,1, 'int');
                    if value~=magic_number
                        str{1}=sprintf('Wrong tile (magic-cookie incorrect : %d', ...
                            value)
                        str{2}='Use default configuration.';
                        app.warningMsg(str);
                        value=ones(16,1);
                    else
                        value=fread(fid,16,'int');
                    end
                fclose(fid);
            end
        end
        
        function setConfiguration(app, value)
            app.setOn(app.Mag_SWD, value(1));
            app.setOn(app.Mag_LWD, value(2));
            app.setOn(app.Contam_SWD, value(3));
            app.setOn(app.Contam_LWD, value(4));
            app.setOn(app.Pos1_SWD, value(5));
            app.setOn(app.Pos1_LWD, value(6));
            app.setOn(app.Pos2_SWD, value(7));
            app.setOn(app.Pos2_LWD, value(8));
            app.setOn(app.Pos3_SWD, value(9));
            app.setOn(app.Pos3_LWD, value(10));
            app.setOn(app.Pos4_SWD, value(11));
            app.setOn(app.Pos4_LWD, value(12));
            app.setOn(app.Pos5_SWD, value(13));
            app.setOn(app.Pos5_LWD, value(14));
            app.setOn(app.Pos6_SWD, value(15));
            app.setOn(app.Pos6_LWD, value(16));
        end
        
        function setOn(app, button, val)
            if val==1
                button.Text='On';
                button.BackgroundColor=app.OnColor;
            else
                button.Text='Off';
                button.BackgroundColor=app.OffColor;
            end
        end
    end
       
    methods (Access = public)

        function init(app)
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables; %read in system setup
            
            app.pauseFlag=false;

            app.resetLampColor();

            app.qualResults = cell(6,1);

            if isempty(app.previousToggleStatus)
                app.setConfiguration(app.readConfiguration());
            else
                app.freeze(false);
            end

            app.SaveasButton.Enable=true;
            app.ConfigureButton.Enable=true;
            app.ToMagButton.Enable=false;
            app.ToFocusButton.Enable=false;
            app.ToContButton.Enable=false;
            app.ToQualButton.Enable=false;
            app.ToQualResultButton.Enable=false;

            app.TestStatus.Value='';


            if isempty(app.register) 
                app.register=processRegister(app, 0.5);
            elseif isvalid(app.register)
                app.register.reset();
            else
                app.register=processRegister(app, 0.5);
            end

        end
    end

    methods (Access = private)
        
        function freeze(app, val)
            val=~val;
            app.RPAS12NC.Enable=val;
            app.RPASSN.Enable=val;
            app.Operator.Enable=val;

            app.SaveasButton.Enable=val;
            app.ConfigureButton.Enable=val;

            app.BackToMainScreenButton.Enable=val;

            if val % restore previous status
                app.Mag_SWD.Enable = ...
                    app.previousToggleStatus.Mag_SWD.Enable;
                app.Mag_LWD.Enable = ...
                    app.previousToggleStatus.Mag_LWD.Enable;

                app.Contam_SWD.Enable = ...
                    app.previousToggleStatus.Contam_SWD.Enable;
                app.Contam_LWD.Enable = ...
                    app.previousToggleStatus.Contam_LWD.Enable;

                app.Pos1_SWD.Enable = ...
                    app.previousToggleStatus.Pos1_SWD.Enable;
                app.Pos1_LWD.Enable = ...
                    app.previousToggleStatus.Pos1_LWD.Enable;

                app.Pos2_SWD.Enable = ...
                    app.previousToggleStatus.Pos2_SWD.Enable;
                app.Pos2_LWD.Enable = ...
                    app.previousToggleStatus.Pos2_LWD.Enable;

                app.Pos3_SWD.Enable = ...
                    app.previousToggleStatus.Pos3_SWD.Enable;
                app.Pos3_LWD.Enable = ...
                    app.previousToggleStatus.Pos3_LWD.Enable;

                app.Pos4_SWD.Enable = ...
                    app.previousToggleStatus.Pos4_SWD.Enable;
                app.Pos4_LWD.Enable = ...
                    app.previousToggleStatus.Pos4_LWD.Enable;

                app.Pos5_SWD.Enable = ...
                    app.previousToggleStatus.Pos5_SWD.Enable;
                app.Pos5_LWD.Enable = ...
                    app.previousToggleStatus.Pos5_LWD.Enable;

                app.Pos6_SWD.Enable = ...
                    app.previousToggleStatus.Pos6_SWD.Enable;
                app.Pos6_LWD.Enable = ...
                    app.previousToggleStatus.Pos6_LWD.Enable;
            else
                app.previousToggleStatus.Mag_SWD.Enable = ...
                    app.Mag_SWD.Enable;
                app.Mag_SWD.Enable = val;
            
                app.previousToggleStatus.Mag_LWD.Enable = ...
                    app.Mag_LWD.Enable;
                app.Mag_LWD.Enable=val;

                app.previousToggleStatus.Contam_SWD.Enable = ...
                    app.Contam_SWD.Enable;
                app.Contam_SWD.Enable=val;
            
                app.previousToggleStatus.Contam_LWD.Enable = ...
                    app.Contam_LWD.Enable;
                app.Contam_LWD.Enable=val;

                app.previousToggleStatus.Pos1_SWD.Enable = ...
                    app.Pos1_SWD.Enable;
                app.Pos1_SWD.Enable=val;

                app.previousToggleStatus.Pos1_LWD.Enable = ...
                    app.Pos1_LWD.Enable;
                app.Pos1_LWD.Enable=val;
            
                app.previousToggleStatus.Pos2_SWD.Enable = ...
                    app.Pos2_SWD.Enable;
                app.Pos2_SWD.Enable=val;
            
                app.previousToggleStatus.Pos2_LWD.Enable= ...
                    app.Pos2_LWD.Enable;
                app.Pos2_LWD.Enable=val;   
            
                app.previousToggleStatus.Pos3_SWD.Enable = ...
                    app.Pos3_SWD.Enable;
                app.Pos3_SWD.Enable=val;
            
                app.previousToggleStatus.Pos3_LWD.Enable = ...
                    app.Pos3_LWD.Enable;
                app.Pos3_LWD.Enable=val;
            
                app.previousToggleStatus.Pos4_SWD.Enable = ...
                    app.Pos4_SWD.Enable;
                app.Pos4_SWD.Enable=val;
            
                app.previousToggleStatus.Pos4_LWD.Enable= ...
                    app.Pos4_LWD.Enable;
                app.Pos4_LWD.Enable=val;    
            
                app.previousToggleStatus.Pos5_SWD.Enable = ...
                    app.Pos5_SWD.Enable;
                app.Pos5_SWD.Enable=val;
            
                app.previousToggleStatus.Pos5_LWD.Enable = ...
                    app.Pos5_LWD.Enable;
                app.Pos5_LWD.Enable=val; 
            
                app.previousToggleStatus.Pos6_SWD.Enable = ...
                    app.Pos6_SWD.Enable;
                app.Pos6_SWD.Enable=val;
            
                app.previousToggleStatus.Pos6_LWD.Enable = ...
                    app.Pos6_LWD.Enable;
                app.Pos6_LWD.Enable=val;
            end
        end
        
        function checkPause(app)
            while app.pauseFlag
                pause(0.1);
            end
        end
        
        
        function flag=chooseQualType(app)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
            title = 'Qualification Method';
            selection = uiconfirm(fig, 'Show qualification step by step?', title,...
                                   'Options',{'Yes','No'},...
                                   'Icon', "question", ...
                                   'DefaultOption', 1);

            switch selection
                case {'Yes'}
                    flag=true;
                otherwise
                    flag=false;
            end
            delete(fig);
            return;
        end
        
        function resetLampColor(app)
            
            app.SWDMagLamp.Color=app.PassColor;
            app.LWDMagLamp.Color=app.PassColor;
            app.SWDContLamp.Color=app.PassColor;
            app.LWDContLamp.Color=app.PassColor;
            app.SWD_Pos_1_Lamp.Color=app.PassColor;
            app.LWD_Pos_1_Lamp.Color=app.PassColor;
            app.SWD_Pos_2_Lamp.Color=app.PassColor;
            app.LWD_Pos_2_Lamp.Color=app.PassColor;
            app.SWD_Pos_3_Lamp.Color=app.PassColor;
            app.LWD_Pos_3_Lamp.Color=app.PassColor;
            app.SWD_Pos_4_Lamp.Color=app.PassColor;
            app.LWD_Pos_4_Lamp.Color=app.PassColor;
            app.SWD_Pos_5_Lamp.Color=app.PassColor;
            app.LWD_Pos_5_Lamp.Color=app.PassColor;
            app.SWD_Pos_6_Lamp.Color=app.PassColor;
            app.LWD_Pos_6_Lamp.Color=app.PassColor;
        end

        function pt = chooseTestPointDialog(app, items)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=240; h=160;
            d = uifigure('Position',[ct(1)-w/2, ct(2)-h/2, w, h], ...
                'Name', 'Select Test Point');
            %d.Visible='off';
            
            txt = uilabel('Parent', d, ...
                      'Text', 'Select Test Point', ...
                      'Position', [60, 100, 240, 40],...
                      'FontWeight', 'bold', ...
                      'FontSize', 14);
            itemStr=cell(numel(items),1);
            for k=1:numel(items)
              itemStr{k}=num2str(items(k));
            end
            popup = uidropdown('Parent',d,...
                   'Position',[40 60 70 25],...
                   'Items',itemStr,...
                   'ItemsData', items, ...
                   'ValueChangedFcn',@popup_callback);
                   
               
            btn = uibutton('Parent',d,...
                   'Position',[140 60 70 25],...
                   'Text','Select',...
                   'ButtonPushedFcn',@posSelected);
                   
            
            pt=popup.Value;
            
            uiwait(d);
           
               function popup_callback(popup,event)
                  pt = popup.Value;
               end
               
               function posSelected(btn, event)
                    delete(d);
               end
        end

        function [choice, cam] = choosePosDialog(app, ...
                title, msg, items, camera)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=280; h=200;
            d = uifigure('Position',[ct(1)-w/2, ct(2)-h/2, w, h], ...
                'Name', title);
            %d.Visible='off';
            
            txt = uilabel('Parent', d, ...
                      'Text', msg, ...
                      'Position', [45, 150, 240, 40],...
                      'FontWeight', 'bold', ...
                      'FontSize', 14);
            
            
            bg = uibuttongroup('Parent', d, ...
                    'Visible', 'off', ...
                    'Position', [40 80, 100, 60], ...
                    'SelectionChangedFcn',@bselection);
                          
            % Create three radio buttons in the button group.
            r1 = uiradiobutton(bg,'Position',[10 25 60 25], 'Text', 'SWD');
            r2 = uiradiobutton(bg,'Position',[10 5 60 25], 'Text', 'LWD');
            
            if (iscell(camera) & (numel(camera)==1)) | ischar(camera)
              if strcmp(camera, 'SWD')
                bg.SelectedObject=r1;
              else
                bg.SelectedObject=r2;
              end
              bg.Enable='off';
            else
              bg.SelectedObject=r1;
              bg.Enable='on';
            end
            
            % Make the uibuttongroup visible after creating child objects. 
            bg.Visible = 'on';
            
            txt = uilabel('Parent',d,...
                   'Text','Test No',...
                   'Position',[180 125 80 25]);
                   
            [selection, selectionData]=selectItems('SWD');
            popup = uidropdown('Parent',d,...
                   'Position',[180 100 60 25],...
                   'Items',selection,...
                   'ItemsData', selectionData, ...
                   'ValueChangedFcn',@popup_callback);
                   
               
            btn = uibutton('Parent',d,...
                   'Position',[180 20 70 25],...
                   'Text','Select',...
                   'ButtonPushedFcn',@posSelected);
                   
            cam=bg.SelectedObject.Text;
            choice=popup.Value;
            
            uiwait(d);

               function bselection(source,event)
                   cam = bg.SelectedObject.Text;
                   [selection,selectionData]=selectItems(cam);
                   v=popup.Value;
                   if v>selectionData(end)
                     popup.Value=selectionData(end);
                     choice=popup.Value;
                   end
                   popup.Items=selection;
                   popup.ItemsData=selectionData;
               end
           
               function popup_callback(popup,event)
                  choice = popup.Value;
               end
    
            function [selection, selectionData] = selectItems(val)
                   selection={};
                   if strcmp(val, 'SWD')
                      for k=1:numel(items.SWD)
                          selection{end+1}=num2str(items.SWD(k));
                      end
                      selectionData=items.SWD;
                    else
                      for k=1:numel(items.LWD)
                          selection{end+1}=num2str(items.LWD(k));
                      end
                      selectionData=items.LWD;
                    end
               end
               
               function posSelected(btn, event)
                    delete(d);
               end
        end
        
        function val=savingDialog(app, qualPos)
          global output;
          val=[];
          
          dialog=RPAS_AutoQual_savingDialog();
          dialog.UIFigure.Visible=false;
          
          for k=1:numel(dialog.handles)
            dialog.handles(k).Enable=qualPos(k);
          end
          dialog.UIFigure.Visible=true;
          
          uiwait(dialog.UIFigure);
          
          val=output;
  
        end
        
        function cam = selectCamera(app)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=240; h=160;
            d = uifigure('Position',[ct(1)-w/2, ct(2)-h/2, w, h], ...
                'Name', 'Select Test Point');
            %d.Visible='off';
            
            txt = uilabel('Parent', d, ...
                      'Text', 'Select SWD/LWD Test', ...
                      'Position', [60, 100, 240, 40],...
                      'FontWeight', 'bold', ...
                      'FontSize', 14);
            itemStr={'SWD', 'LWD'};
            items=1:2;
            popup = uidropdown('Parent',d,...
                   'Position',[40 60 70 25],...
                   'Items',itemStr,...
                   'ItemsData', items, ...
                   'ValueChangedFcn',@popup_callback);
                   
               
            btn = uibutton('Parent',d,...
                   'Position',[140 60 70 25],...
                   'Text','Select',...
                   'ButtonPushedFcn',@posSelected);
                   
            
            cam=popup.Items{popup.Value};
            
            uiwait(d);
           
               function popup_callback(popup,event)
                  cam=popup.Items{popup.Value};
               end
               
               function posSelected(btn, event)
                    delete(d);
               end
        end
        
        function [WD, METHOD] = selectWD(app)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=280; h=200;
            d = uifigure('Position',[ct(1)-w/2, ct(2)-h/2, w, h], ...
                'Name', 'Select');
            %d.Visible='off';
            
            txt = uilabel('Parent', d, ...
                      'Text', 'Select WD & Qual Method', ...
                      'Position', [45, 150, 240, 40],...
                      'FontWeight', 'bold', ...
                      'FontSize', 14);
            
            
            bg1 = uibuttongroup('Parent', d, ...
                    'Visible', 'off', ...
                    'Position', [40 70, 80, 70], ...
                    'Title', 'WD', ...
                    'TitlePosition', 'centertop', ...
                    'FontWeight', 'bold');
                          
            % Create three radio buttons in the button group.
            r1 = uiradiobutton(bg1,'Position',[10 25 60 25], 'Text', 'SWD');
            r2 = uiradiobutton(bg1,'Position',[10 5 60 25], 'Text', 'LWD');
            bg1.SelectedObject=r1;
            
            
            bg2 = uibuttongroup('Parent', d, ...
                    'Visible', 'off', ...
                    'Position', [140 70, 110, 70], ...
                    'Title', 'Qual Method', ...
                    'TitlePosition', 'lefttop', ...
                    'FontWeight', 'bold');
                          
            % Create three radio buttons in the button group.
            r3 = uiradiobutton(bg2,'Position',[10 25 100 25], 'Text', 'Continuous');
            r4 = uiradiobutton(bg2,'Position',[10 5 100 25], 'Text', 'One by One');
            bg2.SelectedObject=r3;
            
            % Make the uibuttongroup visible after creating child objects. 
            bg1.Visible = 'on';
            bg2.Visible = 'on';
                   
               
            btnSelect = uibutton('Parent',d,...
                   'Position',[90 20 70 25],...
                   'Text','Select',...
                   'ButtonPushedFcn',@posSelected);
                   
               
            btnCancel = uibutton('Parent',d,...
                   'Position',[180 20 70 25],...
                   'Text','Cancel',...
                   'ButtonPushedFcn',@posCanceled);
                   
            if strcmp(bg1.SelectedObject.Text, 'SWD')
              WD=1;
            else
              WD=0;
            end
            if strcmp(bg2.SelectedObject.Text, 'Continuous')
              METHOD=0;
            else
              METHOD=1;
            end
            
            uiwait(d);
               
               function posSelected(btn, event)
                    if strcmp(bg1.SelectedObject.Text, 'SWD')
                      WD=1;
                    else
                      WD=0;
                    end
                    if strcmp(bg2.SelectedObject.Text, 'Continuous')
                      METHOD=0;
                    else
                      METHOD=1;
                    end
                    delete(d);
               end
               
               function posCanceled(btn, event)
                   WD=-1;
                   METHOD=-1;
                    delete(d);
               end
        end

        
        function flag = selectResultsToSave(app)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=280; h=180;
            d = uifigure('Position',[ct(1)-w/2, ct(2)-h/2, w, h], ...
                'Name', 'Select');
            %d.Visible='off';
            
            txt = uilabel('Parent', d, ...
                      'Text', 'Select Qual Results to Save', ...
                      'Position', [45, 130, 240, 40],...
                      'FontWeight', 'bold', ...
                      'FontSize', 14);
            
            
            bg1 = uibuttongroup('Parent', d, ...
                    'Visible', 'off', ...
                    'Position', [40 30, 80, 95]);
                          
            % Create three radio buttons in the button group.
            r1 = uitogglebutton(bg1,'Position',[10 65 60 25], 'Text', 'SWD');
            r2 = uitogglebutton(bg1,'Position',[10 35 60 25], 'Text', 'LWD');
            r3 = uitogglebutton(bg1,'Position',[10 5 60 25], 'Text', 'BOTH');
            bg1.SelectedObject=r3;
            
            
            bg1.Visible = 'on';
                   
               
            btnSelect = uibutton('Parent',d,...
                   'Position',[180 40 70 25],...
                   'Text','Select',...
                   'ButtonPushedFcn',@posSelected);
                   
                   
            if strcmp(bg1.SelectedObject.Text, 'SWD')
              flag=0;
            elseif strcmp(bg1.SelectedObject.Text, 'LWD')
              flag=1;
            else
              flag=2;
            end
            
            uiwait(d);
               
               function posSelected(btn, event)
                    if strcmp(bg1.SelectedObject.Text, 'SWD')
                      flag=0;
                    elseif strcmp(bg1.SelectedObject.Text, 'LWD')
                      flag=1;
                    else
                      flag=2;
                    end
                    delete(d);
               end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Button pushed function: StartQualButton
        function StartQualButtonPushed(app, event)
            app.register.addProcess();

            app.resetLampColor;

            app.TestStatus.Value='Running';
            drawnow

            if app.register.isAborting
                return;
            end
            app.checkPause();
            
            if isempty(app.RPAS12NC.Value)
                app.warningMsg('Please key in RPAS 12NC');
            elseif isempty(app.RPASSN.Value)
                app.warningMsg('Please key in RPAS S/N');
            elseif isempty(app.Operator.Value)
                app.warningMsg('Please key in Operator');
            else
                
                if app.register.isAborting
                app.TestStatus.Value='is Aborting';
                    return;
                end
                app.checkPause();

                %first disable all buttons and entries that might causing problems
                app.freeze(true);

                %freeze goto buttons
                app.ToMagButton.Enable=false;
                app.ToFocusButton.Enable=false;
                app.ToContButton.Enable=false;
                app.ToQualButton.Enable=false;
                app.ToQualResultButton.Enable=false;
                
                WDS={1,0};
                app.showDetail=0;
                
                for jwd=1:2
                    SWD=WDS{jwd};
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Magnification parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    sheets=app.systemVar.autoQualTableVar.magSheet;
    
                    if SWD==1 % QUAL for SWD
                        if strcmp(app.Mag_SWD.Text, 'On')
                            app.magSheet=sheets.SWD;
                        else
                            app.magSheet=[];
                        end
                    elseif SWD==0 %QUAL for LWD
                        if strcmp(app.Mag_LWD.Text, 'On')
                            app.magSheet=sheets.LWD;
                        else
                            app.magSheet=[];
                        end
                    end

                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();
                

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Contamination parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %sheet for Contamination
                    sheets=app.systemVar.autoQualTableVar.contamSheet;

                    if SWD==1 % QUAL SWD
                        if strcmp(app.Contam_SWD.Text, 'On')
                            app.contamSheet=sheets.SWD;
                        else
                            app.contamSheet=[];
                        end
                    elseif SWD==0 %QUAL LWD
                        if strcmp(app.Contam_LWD.Text, 'On')
                            app.contamSheet=sheets.LWD;
                        else
                            app.contamSheet=[];
                        end
                    end
                
                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();
                

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Qualification parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %sheets for Qualification
                    app.qualSheets={};
                    sheetNames=app.systemVar.autoQualTableVar.qualSheets;
                    for k=1:6
                        try
                            switch k
                                case 1
                                fnm=sheetNames.pos1;
                                case 2
                                    fnm=sheetNames.pos2;
                                case 3
                                    fnm=sheetNames.pos3;
                                case 4
                                    fnm=sheetNames.pos4;
                                case 5
                                    fnm=sheetNames.pos5;
                                case 6
                                    fnm=sheetNames.pos6;
                            end

                            if app.register.isAborting
                                return;
                            end
                            app.checkPause();
    
                            sheet=RPAS_ReadDataSheet(fnm);
                            sheet.Pos=k;
                            app.qualSheets{k}=sheet;
                        catch ME
                            sz=get(0,'ScreenSize');
                            ct=[sz(3)/2, sz(4)/2];
                            w=300;h=160;
                            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                            title = 'File not found';
                            selection = uiconfirm(fig, ME.message, ME.identifier,...
                                        'Options',{'OK'},...
                                        'icon', 'error');
                            delete(fig);
                            app.RPASAutoQualificationUIFigureCloseRequest();
    
                    
                            if app.register.isAborting
                                return;
                            end
                            app.checkPause();
                            return;
                        end
                    end
                
                    if app.register.isAborting
                            return;
                    end
                    app.checkPause();

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Position 1 parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Switch No 1/11
                    if SWD==1 %QUAL SWD
                        if strcmp(app.Pos1_SWD.Text, 'On')
                            app.qualSheets{1}.camera='SWD';
                        else
                            app.qualSheets{1}=[];
                        end
                    elseif SWD==0 %QUAL LWD
                        if strcmp(app.Pos1_LWD.Text, 'On')
                            app.qualSheets{1}.camera='LWD';
                        else
                            app.qualSheets{1}=[];
                        end
                    end

                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();
    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Position 2 parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    %Switch No 2/12
                    if SWD==1 %QUAL SWD
                        if strcmp(app.Pos2_SWD.Text, 'On')
                            app.qualSheets{2}.camera='SWD';
                        else
                            app.qualSheets{2}=[];
                        end
                    elseif SWD==0 %QUAL LWD
                        if strcmp(app.Pos2_LWD.Text, 'On')
                            app.qualSheets{2}.camera='LWD';
                        else
                            app.qualSheets{2}=[];
                        end
                    end
              
                
                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Position 3 parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                     
                    %Switch No 3/13
                    if SWD==1 %QUAL SWD
                        if strcmp(app.Pos3_SWD.Text, 'On')
                            app.qualSheets{3}.camera='SWD';
                        else
                            app.qualSheets{3}=[];
                        end
                    elseif SWD==0 %QUAL LWD
                        if strcmp(app.Pos3_LWD.Text, 'On')
                            app.qualSheets{3}.camera='LWD';
                        else
                            app.qualSheets{3}=[];
                        end
                    end
                

                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();
    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Position 4 parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               
                    %Switch No 4/14
                    if SWD==1 %QUAL SWD
                        if strcmp(app.Pos4_SWD.Text, 'On')
                            app.qualSheets{4}.camera='SWD';
                        else
                            app.qualSheets{4}=[];
                        end
                    elseif SWD==0 %QUAL LWD
                        if strcmp(app.Pos4_LWD.Text, 'On')
                            app.qualSheets{4}.camera='LWD';
                        else
                            app.qualSheets{4}=[];
                        end
                    end
       
                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();
    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Position 5 parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                  
                    %Switch No 5/15
                    if SWD==1 %QUAL SWD
                        if strcmp(app.Pos5_SWD.Text, 'On')
                            app.qualSheets{5}.camera='SWD';
                        else
                            app.qualSheets{5}=[];
                        end
                    elseif SWD==0 %QUAL LWD
                        if strcmp(app.Pos5_LWD.Text, 'On')
                            app.qualSheets{5}.camera='LWD';
                        else
                            app.qualSheets{5}=[];
                        end
                    end
                   
                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Position 6 parameters
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                     
                    %Switch No 6/16
                    if SWD==1 %QUAL SWD
                        if strcmp(app.Pos6_SWD.Text, 'On')
                            app.qualSheets{6}.camera='SWD';
                        else
                            app.qualSheets{6}=[];
                        end
                    elseif SWD==0 %QUAL LWD
                        if strcmp(app.Pos6_LWD.Text, 'On')
                            app.qualSheets{6}.camera='LWD';
                        else
                            app.qualSheets{6}=[];
                        end
                    end     
                    
                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Magnification Qualification
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Mag qualification
                    if ~isempty(app.magSheet)
                        if strcmp(app.magSheet.camera, 'SWD')
                            app.TestStatus.Value = {'Running SWD', 'Mag/Foc'};
                        else
                            app.TestStatus.Value = {'Running LWD', 'Mag/Foc'};
                        end
                    
                    
                        if app.register.isAborting
                            return;
                        end
                        app.checkPause();
    
                        [result, setting, images]=app.qualMag(app.magSheet);
                        if ~isempty(result)
                            if strcmp(app.magSheet.camera, 'SWD')
                                if isempty (app.magResults)
                                    out=struct('SWD_data', app.magSheet, ...
                                        'SWD_result', result, ...
                                        'SWD_screenSetting', setting, ...
                                        'SWD_images', images, ...
                                        'LWD_data', [], ...
                                        'LWD_result', [], ...
                                        'LWD_screenSetting', [], ...
                                        'LWD_images', []);
                                else
                                    out=app.magResults;
                                    out.SWD_data= app.magSheet;
                                    out.SWD_result=result;
                                    out.SWD_screenSetting=setting;
                                    out.SWD_images=images;
                                end
                            else
                                if isempty (app.magResults)
                                    out=struct('LWD_data', app.magSheet, ...
                                        'LWD_result', result, ...
                                        'LWD_screenSetting', setting, ...
                                        'LWD_images', images, ...
                                        'SWD_data', [], ...
                                        'SWD_result', [], ...
                                        'SWD_screenSetting', [], ...
                                        'SWD_images', []);
                                else
                                    out=app.magResults;
                                    out.LWD_data= app.magSheet;
                                    out.LWD_result=result;
                                    out.LWD_screenSetting=setting;
                                    out.LWD_images=images;
                                end
                            end
                            app.magResults=out;
                        end
                    end
                    
                    if app.register.isAborting
                        return;
                    end
                    app.checkPause();
    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Contamination Qualification
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %contamination Qual
                    if ~isempty(app.contamSheet)
                        if strcmp(app.contamSheet.camera, 'SWD')
                            app.TestStatus.Value = {'Running SWD', 'Det Contam'};
                        else
                            app.TestStatus.Value = {'Running LWD', 'Det Contam'};
                        end
                    
                        if app.register.isAborting
                            return;
                        end
                        app.checkPause();

                        [result, setting, images]=app.qualContam(app.contamSheet);
                        if ~isempty(result)
                            if strcmp(app.contamSheet.camera, 'SWD')
                                if isempty (app.contamResults)
                                    out=struct('SWD_data', app.contamSheet, ...
                                        'SWD_result', result, ...
                                        'SWD_screenSetting', setting, ...
                                        'SWD_images', images, ...
                                        'LWD_data', [], ...
                                        'LWD_result', [], ...
                                        'LWD_screenSetting', [], ...
                                        'LWD_images', []);
                                else
                                    out=app.contamResults;
                                    out.SWD_data= app.contamSheet;
                                    out.SWD_result=result;
                                    out.SWD_screenSetting=setting;
                                    out.SWD_images=images;
                                end
                            else
                                if isempty (app.contamResults)
                                    out=struct('LWD_data', app.contamSheet, ...
                                        'LWD_result', result, ...
                                        'LWD_screenSetting', setting, ...
                                        'LWD_images', images, ...
                                        'SWD_data', [], ...
                                        'SWD_result', [], ...
                                        'SWD_screenSetting', [], ...
                                        'SWD_images', []);
                                else
                                    out=app.contamResults;
                                    out.LWD_data= app.contamSheet;
                                    out.LWD_result=result;
                                    out.LWD_screenSetting=setting;
                                    out.LWD_images=images;
                                end
                            end
                            app.contamResults=out;
                        end
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Qualification
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                    for k=1:numel(app.qualSheets)
                        sheet=app.qualSheets{k};
                        
                        if ~isempty(sheet)
                            if app.register.isAborting
                                return;
                            end
                            app.checkPause();

                            app.ToQualButton.Enable=false;
                            app.ToQualResultButton.Enable=false;
        
                            if strcmp(sheet.camera, 'SWD')
                                posNum=k;
                                str=['RPAS Qual (Test ' num2str(posNum) ')'];
                                app.TestStatus.Value = {'Running SWD', str};
                            else
                                posNum=k;
                                str=['RPAS Qual (Test ' num2str(posNum) ')'];
                                app.TestStatus.Value = {'Running LWD', str};
                            end
                            [result, setting, images]=app.qual(sheet);
                
                            if app.register.isAborting
                                return;
                            end
                            app.checkPause();
                    
                            if ~isempty(result)
                                if isempty(app.qualResults{sheet.Pos})
                                    out = sheet;
                                    out.SWD_result=[];
                                    out.SWD_setting=[];
                                    out.SWD_images=[];
                                    out.LWD_result=[];
                                    out.LWD_setting=[];
                                    out.LWD_images=[];
                                else
                                    out=app.qualResults{sheet.Pos};
                                end
                                if strcmp(sheet.camera, 'SWD')
                                    out.SWD_result=result;
                                    out.SWD_setting=setting;
                                    out.SWD_images=images;
                                    fprintf(1,'')
                                else
                                    out.LWD_result=result;
                                    out.LWD_setting=setting;
                                    out.LWD_images=images;
                                end
                                app.qualResults{sheet.Pos}=out;
                            end
                        end
                    end
                end
                
                %release buttons and fields
                app.freeze(false);

                if strcmp(app.Mag_SWD.Text, 'On') || strcmp(app.Mag_LWD.Text, 'On')
                    app.ToMagButton.Enable=true;
                    app.ToFocusButton.Enable=true;
                end
                if strcmp(app.Contam_SWD.Text, 'On') || strcmp(app.Contam_LWD.Text, 'On')
                    app.ToContButton.Enable=true;
                end
                if strcmp(app.Pos1_SWD.Text, 'On') || strcmp(app.Pos1_LWD.Text, 'On') || ...
                        strcmp(app.Pos2_SWD.Text, 'On') || strcmp(app.Pos2_LWD.Text, 'On') || ...
                        strcmp(app.Pos3_SWD.Text, 'On') || strcmp(app.Pos3_LWD.Text, 'On') || ...
                        strcmp(app.Pos4_SWD.Text, 'On') || strcmp(app.Pos4_LWD.Text, 'On') || ...
                        strcmp(app.Pos5_SWD.Text, 'On') || strcmp(app.Pos5_LWD.Text, 'On') || ...
                        strcmp(app.Pos6_SWD.Text, 'On') || strcmp(app.Pos6_LWD.Text, 'On')
                    app.ToQualButton.Enable=true;
                    app.ToQualResultButton.Enable=true;
                end
            end

            app.SaveasButton.Enable=true;
            app.ConfigureButton.Enable=true;

            %saveResults
            info=struct('NC', app.RPAS12NC.Value, ...
                            'SN', app.RPASSN.Value, ...
                            'operator', app.Operator.Value);
            if ~isempty(app.magResults)
                RPAS_SaveMagResults(info, app.magResults);
            end
            if ~isempty(app.contamResults)
                RPAS_SaveContamResults(info, app.contamResults);
            end
            reports={};
            for s=1:numel(app.qualResults)
                tmp=app.qualResults{s};
                if ~isempty(tmp)
                    RPAS_SaveQualResult(info,tmp);
                    reports{end+1}=tmp;
                end
            end
            if numel(reports)>0
                RPAS_QualReport(info, reports);
            end
            
            app.register.removeProcess();

        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            if ~isempty(app.currentHandle)& isvalid(app.currentHandle)
                app.pauseFlag=~app.pauseFlag;
            
                app.currentHandle.pausing(app.pauseFlag);
            else
                %do nothing
            end
            if app.pauseFlag
                app.previousStatus=app.TestStatus.Value;
                app.TestStatus.Value="pausing ...";
            elseif ~isempty(app.previousStatus)
                app.TestStatus.Value=app.previousStatus;
            end
        end

        % Button pushed function: ToMagButton
        function ToMagButtonPushed(app, event)
            result=[];
            setting=[];
            if isempty(app.magResults)
                app.warningMsg({'No result to display. Make qualification first.'});
                return;
            end

            if isempty(app.magResults.SWD_result)
                if ~isempty(app.magResults.LWD_result)
                    result=app.magResults.LWD_result;
                    setting=app.magResults.LWD_screenSetting;
                end
            else
                if isempty(app.magResults.LWD_result)
                    result=app.magResults.SWD_result;
                    setting=app.magResults.SWD_screenSetting;
                else
                    cam=app.selectCamera();
                    if strcmp(cam, 'SWD')
                        result=app.magResults.SWD_result;
                        setting=app.magResults.SWD_screenSetting;
                    else
                        result=app.magResults.LWD_result;
                        setting=app.magResults.LWD_screenSetting;
                    end
                end
            end
            if isempty(setting)
                app.warningMsg({'No result to display. Make qualification first.'});
            else
                RPAS_Magnification(setting);
            end
        end

        % Button pushed function: ToContButton
        function ToContButtonPushed(app, event)
            result=[];
            setting=[];
            if isempty(app.contamResults)
                app.warningMsg({'No result to display. Make qualification first.'});
                return;
            end

            if isempty(app.contamResults.SWD_result)
                if ~isempty(app.contamResults.LWD_result)
                    result=app.contamResults.LWD_result;
                    setting=app.contamResults.LWD_screenSetting;
                end
            else
                if isempty(app.contamResults.LWD_result)
                    result=app.contamResults.SWD_result;
                    setting=app.contamResults.SWD_screenSetting;
                else
                    cam=app.selectCamera();
                    if strcmp(cam, 'SWD')
                        result=app.contamResults.SWD_result;
                        setting=app.contamResults.SWD_screenSetting;
                    else
                        result=app.contamResults.LWD_result;
                        setting=app.contamResults.LWD_screenSetting;
                    end
                end
            end

            if isempty(setting)
                app.warningMsg({'No result to display. Make qualification first.'})
            else
                RPAS_ContamQual(setting);
            end
        end

        % Button pushed function: ToQualButton
        function ToQualButtonPushed(app, event)
            SWD=[];
            LWD=[];
            for k=1:numel(app.qualResults)
                out=app.qualResults{k};
                if ~isempty(out) & ~isempty(out.SWD_result)
                    SWD(end+1)=out.Pos;
                end
                if ~isempty(out) & ~isempty(out.LWD_result)
                    LWD(end+1)=out.Pos;
                end
            end
            items=struct('SWD', SWD, 'LWD',LWD);
            camera={};
            if ~isempty(SWD)
                camera{end+1}='SWD';
            end
            if ~isempty(LWD)
                camera{end+1}='LWD';
            end

            if numel(camera)==1
                msg='Select Test No';
            elseif numel(camera)==2
                msg='Select SWD/LWD & Test No';
            end

            if numel(camera)>0
                [pos, cam]=app.choosePosDialog(msg,msg,items, camera);

                data=app.qualResults{pos};
                if strcmp(cam, 'SWD')
                    settings=data.SWD_setting;
                else
                    settings=data.LWD_setting;
                end
                items=[];
                for k=1:data.numTestPoints
                    if data.BI(k)==1& ~isempty(settings{k})
                        items(end+1)=data.TestPoint(k);
                    end
                end
                pt=app.chooseTestPointDialog(items);
                RPAS_Qual(settings{pt});
            else
                app.warningMsg({'No result to display. Make qualification first.'})
            end
        end

        % Button pushed function: ToFocusButton
        function ToFocusButtonPushed(app, event)
            result=[];
            data=[];
            if isempty(app.magResults)
                app.warningMsg({'No result to display. Make qualification first.'});
                return;
            end

            if isempty(app.magResults.SWD_result)
                if ~isempty(app.magResults.LWD_result)
                    result=app.magResults.LWD_result;
                    data=app.magResults.LWD_data;
                end
            else
                if isempty(app.magResults.LWD_result)
                    result=app.magResults.SWD_result;
                    data=app.magResults.SWD_data;
                else
                    cam=app.selectCamera();
                    if strcmp(cam, 'SWD')
                        result=app.magResults.SWD_result;
                        data=app.magResults.SWD_data;
                    else
                        result=app.magResults.LWD_result;
                        data=app.magResults.LWD_data;
                    end
                end
            end
            if isempty(data)
                app.warningMsg({'No result to display. Make qualification first.'})
            else
                err=struct('Amp_X', data.CenterAmpErr_X, ...
                'HWHM_X', data.HWHMErr_X, ...
                'Amp_Y', data.CenterAmpErr_Y, ...
                'HWHM_Y', data.HWHMErr_Y);
                RPAS_FocusPlot(result, err);
            end
        end

        % Button pushed function: ToQualResultButton
        function ToQualResultButtonPushed(app, event)
            SWD=[];
            LWD=[];
            for k=1:numel(app.qualResults)
                out=app.qualResults{k};
                if ~isempty(out) & ~isempty(out.SWD_result)
                    SWD(end+1)=out.Pos;
                end
                if ~isempty(out) & ~isempty(out.LWD_result)
                    LWD(end+1)=out.Pos;
                end
            end
            items=struct('SWD', SWD, 'LWD',LWD);

            camera={};
            if ~isempty(SWD)
                camera{end+1}='SWD';
            end
            if ~isempty(LWD)
                camera{end+1}='LWD';
            end

            if numel(camera)==1
                msg='Select Test No';
            elseif numel(camera)==2
                msg='Select SWD/LWD & Test No';
            end
            if numel(camera)>0
                [pos, cam]=app.choosePosDialog(msg,msg,items, camera);

                data=app.qualResults{pos};
                if strcmp(cam, 'SWD')
                    dist=data.SWD_result(:,4:6);
                else
                    dist=data.LWD_result(:,4:6);
                end
                mat=~isnan(dist);
                dist=dist(mat);
                dist=reshape(dist,[numel(dist)/3,3]);
                err=data.Error;
                RPAS_QualResults(dist, err);
            else
                app.warningMsg({'No result to display. Make qualification first.'})
            end

            
        end

        % Close request function: RPASAutoQualificationUIFigure
        function RPASAutoQualificationUIFigureCloseRequest(app, event)
            if ~app.abortFlag
                app.AbortTestButtonPushed();
            end
            if ~isempty(app.magHandle)
                if isvalid(app.magHandle)
                    app.magHandle.close();
                end
            end
            if ~isempty(app.contamHandle)
                if isvalid(app.contamHandle)
                    app.contamHandle.close();
                end
            end
            if ~isempty(app.qualHandle)
                if isvalid(app.qualHandle)
                    app.qualHandle.close();
                end
            end

            if ~isempty(app.register) & isvalid(app.register)
                app.register.delete();
            end
            delete(app);
            
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            if isAuthorized
                global RPAS_C
                if isempty(RPAS_C)
                    RPAS_C=RPAS_Constants(parentDir(pwd));
                end
                fnm=[RPAS_C.QUAL_DATA_SHEET_DIR '/RPAS_Configuration.dat'];
                value=[];
                %Magnification
                if strcmp(app.Mag_SWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end
                if strcmp(app.Mag_LWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end

                %Contamination
                if strcmp(app.Contam_SWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end
                if strcmp(app.Contam_LWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end

                %Position 1
                if strcmp(app.Pos1_SWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end
                if strcmp(app.Pos1_LWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end

                %Position 2
                if strcmp(app.Pos2_SWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end
                if strcmp(app.Pos2_LWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end

                %Position 3
                if strcmp(app.Pos3_SWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end
                if strcmp(app.Pos3_LWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end

                %Position 4
                if strcmp(app.Pos4_SWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end
                if strcmp(app.Pos4_LWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end

                %Position 5
                if strcmp(app.Pos5_SWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end
                if strcmp(app.Pos5_LWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end

                %Position 6
                if strcmp(app.Pos6_SWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end
                if strcmp(app.Pos6_LWD.Text, 'On')
                    value=[value 1];
                else
                    value=[value 0];
                end

                fid=fopen(fnm,'wb');
                    magic_number=35217;
                    fwrite(fid, magic_number,'int');
                    fwrite(fid,value, 'int');
                fclose(fid);
            else
                app.warningMsg({'You are not authorized to save.'})
            end
        end

        % Button pushed function: Mag_SWD
        function Mag_SWDButtonPushed(app, event)
            if strcmp(app.Mag_SWD.Text, 'On')
                app.setOn(app.Mag_SWD, 0);
            else
                app.setOn(app.Mag_SWD, 1);
            end
        end

        % Button pushed function: Mag_LWD
        function Mag_LWDButtonPushed(app, event)
            if strcmp(app.Mag_LWD.Text, 'On')
                app.setOn(app.Mag_LWD, 0);
            else
                app.setOn(app.Mag_LWD, 1);
            end
        end

        % Button pushed function: Contam_SWD
        function Contam_SWDButtonPushed(app, event)
            if strcmp(app.Contam_SWD.Text, 'Off')
                app.setOn(app.Contam_SWD, 1);
            else
                app.setOn(app.Contam_SWD, 0);
            end
        end

        % Button pushed function: Contam_LWD
        function Contam_LWDButtonPushed(app, event)
            if strcmp(app.Contam_LWD.Text, 'Off')
                app.setOn(app.Contam_LWD, 1);
            else
                app.setOn(app.Contam_LWD, 0);
            end
        end

        % Button pushed function: Pos1_LWD
        function Pos1_LWDButtonPushed(app, event)
            if strcmp(app.Pos1_LWD.Text, 'Off')
                app.setOn(app.Pos1_LWD, 1);
            else
                app.setOn(app.Pos1_LWD, 0);
            end
        end

        % Button pushed function: Pos1_SWD
        function Pos1_SWDButtonPushed(app, event)
            if strcmp(app.Pos1_SWD.Text, 'Off')
                app.setOn(app.Pos1_SWD, 1);
            else
                app.setOn(app.Pos1_SWD, 0);
            end
        end

        % Button pushed function: Pos2_SWD
        function Pos2_SWDButtonPushed(app, event)
            if strcmp(app.Pos2_SWD.Text, 'Off')
                app.setOn(app.Pos2_SWD, 1);
            else
                app.setOn(app.Pos2_SWD, 0);
            end
        end

        % Button pushed function: Pos2_LWD
        function Pos2_LWDButtonPushed(app, event)
            if strcmp(app.Pos2_LWD.Text, 'Off')
                app.setOn(app.Pos2_LWD, 1);
            else
                app.setOn(app.Pos2_LWD, 0);
            end
        end

        % Button pushed function: Pos3_SWD
        function Pos3_SWDButtonPushed(app, event)
            if strcmp(app.Pos3_SWD.Text, 'Off')
                app.setOn(app.Pos3_SWD, 1);
            else
                app.setOn(app.Pos3_SWD, 0);
            end
        end

        % Button pushed function: Pos3_LWD
        function Pos3_LWDButtonPushed(app, event)
            if strcmp(app.Pos3_LWD.Text, 'Off')
                app.setOn(app.Pos3_LWD, 1);
            else
                app.setOn(app.Pos3_LWD, 0);
            end
        end

        % Button pushed function: Pos4_SWD
        function Pos4_SWDButtonPushed(app, event)
            if strcmp(app.Pos4_SWD.Text, 'Off')
                app.setOn(app.Pos4_SWD, 1);
            else
                app.setOn(app.Pos4_SWD, 0);
            end
        end

        % Button pushed function: Pos4_LWD
        function Pos4_LWDButtonPushed(app, event)
            if strcmp(app.Pos4_LWD.Text, 'Off')
                app.setOn(app.Pos4_LWD, 1);
            else
                app.setOn(app.Pos4_LWD, 0);
            end
        end

        % Button pushed function: Pos5_LWD
        function Pos5_LWDButtonPushed(app, event)
            if strcmp(app.Pos5_LWD.Text, 'Off')
                app.setOn(app.Pos5_LWD, 1);
            else
                app.setOn(app.Pos5_LWD, 0);
            end
        end

        % Button pushed function: Pos5_SWD
        function Pos5_SWDButtonPushed(app, event)
            if strcmp(app.Pos5_SWD.Text, 'Off')
                app.setOn(app.Pos5_SWD, 1);
            else
                app.setOn(app.Pos5_SWD, 0);
            end
        end

        % Button pushed function: Pos6_SWD
        function Pos6_SWDButtonPushed(app, event)
            if strcmp(app.Pos6_SWD.Text, 'Off')
                app.setOn(app.Pos6_SWD, 1);
            else
                app.setOn(app.Pos6_SWD, 0);
            end
        end

        % Button pushed function: Pos6_LWD
        function Pos6_LWDButtonPushed(app, event)
            if strcmp(app.Pos6_LWD.Text, 'Off')
                app.setOn(app.Pos6_LWD, 1);
            else
                app.setOn(app.Pos6_LWD, 0);
            end
        end

        % Button pushed function: AbortTestButton
        function AbortTestButtonPushed(app, event)
            if (app.pauseFlag)
                app.PauseResumeButtonPushed();
            end
            app.abortFlag=true;
            app.TestStatus.Value='Aborting ...';

            app.register.Aborting(true);
            %wait running app to abort
            if ~isempty(app.currentHandle) & isvalid(app.currentHandle)
                app.currentHandle.abortProgram();
            end
            
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            app.setConfiguration(ones(16,1));
        end

        % Button pushed function: BackToMainScreenButton
        function BackToMainScreenButtonPushed(app, event)
            app.RPASAutoQualificationUIFigureCloseRequest();
        end

        % Value changed function: CheckBox_SWD
        function CheckBox_SWDValueChanged(app, event)
            value = app.CheckBox_SWD.Value;
            app.setOn(app.Mag_SWD,value);
            app.setOn(app.Contam_SWD,value);
            app.setOn(app.Pos1_SWD,value);
            app.setOn(app.Pos2_SWD,value);
            app.setOn(app.Pos3_SWD,value);
            app.setOn(app.Pos4_SWD,value);
            app.setOn(app.Pos5_SWD,value);
            app.setOn(app.Pos6_SWD,value);

        end

        % Value changed function: CheckBox_LWD
        function CheckBox_LWDValueChanged(app, event)
            value = app.CheckBox_LWD.Value;
            app.setOn(app.Mag_LWD,value);
            app.setOn(app.Contam_LWD,value);
            app.setOn(app.Pos1_LWD,value);
            app.setOn(app.Pos2_LWD,value);
            app.setOn(app.Pos3_LWD,value);
            app.setOn(app.Pos4_LWD,value);
            app.setOn(app.Pos5_LWD,value);
            app.setOn(app.Pos6_LWD,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASAutoQualificationUIFigure = uifigure('Visible', 'off');
            app.RPASAutoQualificationUIFigure.Name = 'RPAS Auto Qualification';
            app.RPASAutoQualificationUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASAutoQualificationUIFigureCloseRequest, true);
            app.RPASAutoQualificationUIFigure.Tag = 'RPAS_AutoQual';
            app.GridLayout = uigridlayout(app.RPASAutoQualificationUIFigure);
            app.RPASAutomatedQualwWorkStationPanel = uipanel(app.GridLayout);
            app.RPASAutomatedQualwWorkStationPanel.Title = 'RPAS Automated Qual w/ Work Station';
            app.FileInfoPanel = uipanel(app.RPASAutomatedQualwWorkStationPanel);
            app.FileInfoPanel.Title = 'File Info';
            app.RPAS12NCEditFieldLabel = uilabel(app.FileInfoPanel);
            app.RPAS12NCEditFieldLabel.Text = 'RPAS 12NC';
            app.RPAS12NC = uieditfield(app.FileInfoPanel, 'text');
            app.OperatorEditFieldLabel = uilabel(app.FileInfoPanel);
            app.OperatorEditFieldLabel.Text = 'Operator';
            app.Operator = uieditfield(app.FileInfoPanel, 'text');
            app.Operator.Value = 'DSQU';
            app.RPASSNEditFieldLabel = uilabel(app.FileInfoPanel);
            app.RPASSNEditFieldLabel.Text = 'RPAS S/N';
            app.RPASSN = uieditfield(app.FileInfoPanel, 'text');
            app.Panel = uipanel(app.RPASAutomatedQualwWorkStationPanel);
            app.SaveasButton = uibutton(app.Panel, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = {'Save as'; 'Default Qual'};
            app.ConfigureButton = uibutton(app.Panel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = {'Configure for'; 'Default Qual'};
            app.TestStatusLabel = uilabel(app.Panel);
            app.TestStatusLabel.Text = 'Test Status';
            app.TestStatus = uitextarea(app.Panel);
            app.TestStatus.Editable = 'off';
            app.ToMagButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.ToMagButton.ButtonPushedFcn = createCallbackFcn(app, @ToMagButtonPushed, true);
            app.ToMagButton.Text = {'To RPAS '; 'Mag/Focus '; 'Qual Screen'};
            app.ToFocusButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.ToFocusButton.ButtonPushedFcn = createCallbackFcn(app, @ToFocusButtonPushed, true);
            app.ToFocusButton.Text = {'To RPAS '; 'Focus  '; 'Results Screen'};
            app.ToContButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.ToContButton.ButtonPushedFcn = createCallbackFcn(app, @ToContButtonPushed, true);
            app.ToContButton.Text = {'To RPAS '; 'Contamination'; 'Qual Screen'};
            app.ToQualButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.ToQualButton.ButtonPushedFcn = createCallbackFcn(app, @ToQualButtonPushed, true);
            app.ToQualButton.Text = {'To RPAS '; 'Qual Screen'};
            app.ToQualResultButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.ToQualResultButton.ButtonPushedFcn = createCallbackFcn(app, @ToQualResultButtonPushed, true);
            app.ToQualResultButton.Text = {'To RPAS Qual'; 'Results Screen'};
            app.SWDMagLamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.LWDMagLamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.SWDContLamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.SWD_Pos_1_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.SWD_Pos_2_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.SWD_Pos_3_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.LWDContLamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.LWD_Pos_1_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.LWD_Pos_2_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.LWD_Pos_3_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.QualificationTestLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.QualificationTestLabel.Text = {'Qualification '; 'Test'};
            app.MagnificationFocusLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.MagnificationFocusLabel.Text = 'Magnification/Focus';
            app.DetectorContaminationLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.DetectorContaminationLabel.Text = 'Detector Contamination';
            app.RPASPerformancewoEIPwindowLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.RPASPerformancewoEIPwindowLabel.Text = {'RPAS Performance'; '(w/o EIP window)'};
            app.atZnomLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.atZnomLabel.Text = 'at Znom';
            app.atpm850umLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.atpm850umLabel.Interpreter = 'latex';
            app.atpm850umLabel.Text = 'at$\pm$850um';
            app.atZnompm3circLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.atZnompm3circLabel.Interpreter = 'latex';
            app.atZnompm3circLabel.Text = 'at Znom$\pm3^{\circ}$';
            app.SWD_Pos_4_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.SWD_Pos_5_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.SWD_Pos_6_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.LWD_Pos_4_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.LWD_Pos_5_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.LWD_Pos_6_Lamp = uilamp(app.RPASAutomatedQualwWorkStationPanel);
            app.RPASPerformancewithEIPwindowLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.RPASPerformancewithEIPwindowLabel.Text = {'RPAS Performance'; '(with EIP window)'};
            app.atZnomLabel_2 = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.atZnomLabel_2.Text = 'at Znom';
            app.atpm850umLabel_2 = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.atpm850umLabel_2.Interpreter = 'latex';
            app.atpm850umLabel_2.Text = 'at$\pm$850um';
            app.atZnompm3circLabel_2 = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.atZnompm3circLabel_2.Interpreter = 'latex';
            app.atZnompm3circLabel_2.Text = 'at Znom$\pm3^{\circ}$';
            app.BackToMainScreenButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.BackToMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainScreenButtonPushed, true);
            app.BackToMainScreenButton.Text = {'Back To Main'; 'Screen'};
            app.AbortTestButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.AbortTestButton.ButtonPushedFcn = createCallbackFcn(app, @AbortTestButtonPushed, true);
            app.AbortTestButton.Text = 'Abort Test';
            app.PauseResumeButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause/Resume';
            app.StartQualButton = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.StartQualButton.ButtonPushedFcn = createCallbackFcn(app, @StartQualButtonPushed, true);
            app.StartQualButton.Text = {'Start'; 'Automated '; 'Qual'};
            app.Mag_LWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Mag_LWD.ButtonPushedFcn = createCallbackFcn(app, @Mag_LWDButtonPushed, true);
            app.Mag_LWD.Text = 'Off';
            app.Contam_LWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Contam_LWD.ButtonPushedFcn = createCallbackFcn(app, @Contam_LWDButtonPushed, true);
            app.Contam_LWD.Text = 'Off';
            app.Pos1_LWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos1_LWD.ButtonPushedFcn = createCallbackFcn(app, @Pos1_LWDButtonPushed, true);
            app.Pos1_LWD.Text = 'Off';
            app.Pos2_LWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos2_LWD.ButtonPushedFcn = createCallbackFcn(app, @Pos2_LWDButtonPushed, true);
            app.Pos2_LWD.Text = 'Off';
            app.Pos3_LWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos3_LWD.ButtonPushedFcn = createCallbackFcn(app, @Pos3_LWDButtonPushed, true);
            app.Pos3_LWD.Text = 'Off';
            app.Pos4_LWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos4_LWD.ButtonPushedFcn = createCallbackFcn(app, @Pos4_LWDButtonPushed, true);
            app.Pos4_LWD.Text = 'Off';
            app.Pos5_LWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos5_LWD.ButtonPushedFcn = createCallbackFcn(app, @Pos5_LWDButtonPushed, true);
            app.Pos5_LWD.Text = 'Off';
            app.Pos6_LWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos6_LWD.ButtonPushedFcn = createCallbackFcn(app, @Pos6_LWDButtonPushed, true);
            app.Pos6_LWD.Text = 'Off';
            app.Mag_SWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Mag_SWD.ButtonPushedFcn = createCallbackFcn(app, @Mag_SWDButtonPushed, true);
            app.Mag_SWD.Text = 'On';
            app.Contam_SWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Contam_SWD.ButtonPushedFcn = createCallbackFcn(app, @Contam_SWDButtonPushed, true);
            app.Contam_SWD.Text = 'On';
            app.Pos1_SWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos1_SWD.ButtonPushedFcn = createCallbackFcn(app, @Pos1_SWDButtonPushed, true);
            app.Pos1_SWD.Text = 'On';
            app.Pos2_SWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos2_SWD.ButtonPushedFcn = createCallbackFcn(app, @Pos2_SWDButtonPushed, true);
            app.Pos2_SWD.Text = {'On'; ''};
            app.Pos3_SWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos3_SWD.ButtonPushedFcn = createCallbackFcn(app, @Pos3_SWDButtonPushed, true);
            app.Pos3_SWD.Text = {'On'; ''};
            app.Pos4_SWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos4_SWD.ButtonPushedFcn = createCallbackFcn(app, @Pos4_SWDButtonPushed, true);
            app.Pos4_SWD.Text = {'On'; ''};
            app.Pos5_SWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos5_SWD.ButtonPushedFcn = createCallbackFcn(app, @Pos5_SWDButtonPushed, true);
            app.Pos5_SWD.Text = {'On'; ''};
            app.Pos6_SWD = uibutton(app.RPASAutomatedQualwWorkStationPanel, 'push');
            app.Pos6_SWD.ButtonPushedFcn = createCallbackFcn(app, @Pos6_SWDButtonPushed, true);
            app.Pos6_SWD.Text = {'On'; ''};
            app.SWDLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.SWDLabel.Text = 'SWD';
            app.LWDLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.LWDLabel.Text = 'LWD';
            app.PassLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.PassLabel.Text = 'Pass?';
            app.PassLabel_2 = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.PassLabel_2.Text = 'Pass?';
            app.CheckBox_SWD = uicheckbox(app.RPASAutomatedQualwWorkStationPanel);
            app.CheckBox_SWD.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SWDValueChanged, true);
            app.CheckBox_SWD.Text = '';
            app.CheckBox_LWD = uicheckbox(app.RPASAutomatedQualwWorkStationPanel);
            app.CheckBox_LWD.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LWDValueChanged, true);
            app.CheckBox_LWD.Text = '';
            app.CheckallLabel = uilabel(app.RPASAutomatedQualwWorkStationPanel);
            app.CheckallLabel.Text = 'Check all';
            % Show the figure after all components are created
            app.RPASAutoQualificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_AutoQual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASAutoQualificationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASAutoQualificationUIFigure)
        end
    end
end