classdef RPAS_AutoQual_savingDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Contam             matlab.ui.control.CheckBox
        Mag                matlab.ui.control.CheckBox
        SaveButton         matlab.ui.control.Button
        SelectAllCheckBox  matlab.ui.control.CheckBox
        Pos3               matlab.ui.control.CheckBox
        Pos4               matlab.ui.control.CheckBox
        Pos1               matlab.ui.control.CheckBox
        Pos5               matlab.ui.control.CheckBox
        Pos2               matlab.ui.control.CheckBox
        Pos6               matlab.ui.control.CheckBox
        CancelButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        handles; % Description
    end
    
    methods (Access = private)
        
        function init(app)
            app.handles=[app.Mag, app.Contam, app.Pos1, app.Pos2, ...
                app.Pos3, app.Pos4, app.Pos5, app.Pos6];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            for k=1:numel(app.handles)
                if app.handles(k).Enable
                    app.handles(k).Value=value;
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global output;
            output=[];
            for k=1:numel(app.handles)
                output(end+1)=app.handles(k).Value;
            end
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            global output;
            output=[];
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CancelButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Select Qual to Save';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Pos6 = uicheckbox(app.UIFigure);
            app.Pos6.Text = 'Qual Test 6';
            app.Pos2 = uicheckbox(app.UIFigure);
            app.Pos2.Text = 'Qual Test 2 ';
            app.Pos5 = uicheckbox(app.UIFigure);
            app.Pos5.Text = 'Qual Test 5 ';
            app.Pos1 = uicheckbox(app.UIFigure);
            app.Pos1.Text = 'Qual Test 1';
            app.Pos4 = uicheckbox(app.UIFigure);
            app.Pos4.Text = 'Qual Test 4';
            app.Pos3 = uicheckbox(app.UIFigure);
            app.Pos3.Text = 'Qual Test 3';
            app.SelectAllCheckBox = uicheckbox(app.UIFigure);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Mag = uicheckbox(app.UIFigure);
            app.Mag.Text = 'Mag/Focus';
            app.Contam = uicheckbox(app.UIFigure);
            app.Contam.Text = 'Contam Qual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_AutoQual_savingDialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end