classdef RPAS_ContamQual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASDetectorContaminationQualificationUIFigure  matlab.ui.Figure
        RPASContaminationQualPanel    matlab.ui.container.Panel
        statusArea                    matlab.ui.control.TextArea
        RunningStatusTextAreaLabel    matlab.ui.control.Label
        DisplayResultButton           matlab.ui.control.Button
        PerformTestButton             matlab.ui.control.Button
        ZLamp                         matlab.ui.control.Lamp
        ZLamp_2Label                  matlab.ui.control.Label
        YLamp                         matlab.ui.control.Lamp
        YLamp_2Label                  matlab.ui.control.Label
        XLamp                         matlab.ui.control.Lamp
        XLamp_2Label                  matlab.ui.control.Label
        TravelLimitLabel              matlab.ui.control.Label
        BacktoMasterQualScreenButton  matlab.ui.control.Button
        AbortTestButton               matlab.ui.control.Button
        PauseResumeButton             matlab.ui.control.Button
        ResultsPanel                  matlab.ui.container.Panel
        MaxAllowableSingleDefectSizeareamathrmum2Label  matlab.ui.control.Label
        AllowableSize                 matlab.ui.control.NumericEditField
        AllowableNumber               matlab.ui.control.NumericEditField
        NoofDefectsLabel              matlab.ui.control.Label
        QualPass                      matlab.ui.control.Lamp
        FoundBlob                     matlab.ui.control.NumericEditField
        PassFailLabel                 matlab.ui.control.Label
        MaxAllowableLabel             matlab.ui.control.Label
        FoundLabel                    matlab.ui.control.Label
        ImageCapturePanel             matlab.ui.container.Panel
        ShotModeSwitch                matlab.ui.control.Switch
        WDSwitch                      matlab.ui.control.Switch
        PresentYPosPanel              matlab.ui.container.Panel
        mmLabel_9                     matlab.ui.control.Label
        PresentYPos                   matlab.ui.control.NumericEditField
        PresentZPosPanel              matlab.ui.container.Panel
        mmLabel_8                     matlab.ui.control.Label
        PresentZPos                   matlab.ui.control.NumericEditField
        ReticlePositionPanel          matlab.ui.container.Panel
        RelativeXMovePanel            matlab.ui.container.Panel
        Rel_X                         matlab.ui.control.NumericEditField
        Xminus                        matlab.ui.control.Button
        mmLabel_15                    matlab.ui.control.Label
        Xplus                         matlab.ui.control.Button
        AbsoluteXMovePanel            matlab.ui.container.Panel
        Abs_X                         matlab.ui.control.NumericEditField
        mmLabel_14                    matlab.ui.control.Label
        XStart                        matlab.ui.control.Button
        RelativeYMovePanel            matlab.ui.container.Panel
        Rel_Y                         matlab.ui.control.NumericEditField
        Yminus                        matlab.ui.control.Button
        Yplus                         matlab.ui.control.Button
        mmLabel_13                    matlab.ui.control.Label
        AbsoluteYMovePanel            matlab.ui.container.Panel
        Abs_Y                         matlab.ui.control.NumericEditField
        mmLabel_12                    matlab.ui.control.Label
        YStart                        matlab.ui.control.Button
        RelativeZMovePanel            matlab.ui.container.Panel
        Rel_Z                         matlab.ui.control.NumericEditField
        Zminus                        matlab.ui.control.Button
        Zplus                         matlab.ui.control.Button
        mmLabel_11                    matlab.ui.control.Label
        AbsoluteZMovePanel            matlab.ui.container.Panel
        Abs_Z                         matlab.ui.control.NumericEditField
        ZStart                        matlab.ui.control.Button
        mmLabel_10                    matlab.ui.control.Label
        GoToSWDXcYcZnomButton         matlab.ui.control.Button
        GoToLWDXcYcZnomButton         matlab.ui.control.Button
        GoToPARKButton                matlab.ui.control.Button
        PresentXPosPanel              matlab.ui.container.Panel
        mmLabel_7                     matlab.ui.control.Label
        PresentXPos                   matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        FailColor=[0.6, 0, 0];
        PassColor=[0, 1, 0.5];
        
        manualQualMethod=1;
        autoQualMethod = 0;

        manualSpeed;
        autoSpeed;
        movingSpeed;

        qualMethod;

        xupdater  % timer to query present x position
        yupdater % timer to query present y position
        zupdater % timer to query present z position
        
        systemVar; % variable hold system configurations

        Park_X;
        Park_Y;
        Park_Z;
        SWD_Xc;
        SWD_Yc;
        SWD_Znorm;
        LWD_Xc;
        LWD_Yc;
        LWD_Znorm;
        
        Xlimit_plus;
        Xlimit_minus;
        Ylimit_plus;
        Ylimit_minus;
        Zlimit_plus;
        Zlimit_minus;
        
        Pos_1;
        Pos_2;
        Pos_3;
        Pos_4;
        
        img1; % Description
        img2;
        img3;
        img4;
        
        BGimg1;
        BGimg2;
        BGimg3;
        BGimg4;
        
        Spots1;
        Spots2;
        Spots3;
        Spots4;
        
        defects12;
        defects34;
        
        MaxAllowableSize;
        MaxReportedSize;
        MaxNumSpots;

        % 
        currentPos; % variable indicating the present moving position
        
        % variables used to bookmark the pausing status;
        pauseFlag; 
        previousStatus; 
        isFrozen;
        
        % variables used to bookmark the aborting status;
        register;
        outerAbortFlag;

        displayResultsHandle; % Description
    end

    properties (Access=public)
        motionStatus; % Description
    end

    methods (Access = private)

        function settings=recordSettings(app)
            settings=[];
            
            %properties
            %settings.xupdater =app.xupdater; % timer to query present x position
            %settings.yupdater =app.yupdater;% timer to query present y position
            %settings.zupdater =app.zupdater;% timer to query present z position
            
            %settings.systemVar=app.systemVar; % variable hold system configurations
            settings.manualQualMethod=app.manualQualMethod;
            settings.autoQualMethod =app.autoQualMethod;
    
            settings.manualSpeed=app.manualSpeed;
            settings.autoSpeed=app.autoSpeed;
            settings.movingSpeed=app.movingSpeed;

            settings.qualMethod=app.qualMethod;
    
            settings.Park_X=app.Park_X;
            settings.Park_Y=app.Park_Y;
            settings.Park_Z=app.Park_Z;
            settings.SWD_Xc=app.SWD_Xc;
            settings.SWD_Yc=app.SWD_Yc;
            settings.SWD_Znorm=app.SWD_Znorm;
            settings.LWD_Xc=app.LWD_Xc;
            settings.LWD_Yc=app.LWD_Yc;
            settings.LWD_Znorm=app.LWD_Znorm;
            
            settings.Xlimit_plus=app.Xlimit_plus;
            settings.Xlimit_minus=app.Xlimit_minus;
            settings.Ylimit_plus=app.Ylimit_plus;
            settings.Ylimit_minus=app.Ylimit_minus;
            settings.Zlimit_plus=app.Zlimit_plus;
            settings.Zlimit_minus=app.Zlimit_minus;
            
            
            settings.Pos_1 = app.Pos_1; 
            settings.Pos_2 = app.Pos_2;
            settings.Pos_3 = app.Pos_3;
            settings.Pos_4 = app.Pos_4;
        
            settings.img1 = app.img1; 
            settings.img2 = app.img2;
            settings.img3 = app.img3;
            settings.img4 = app.img4;
            
            settings.BGimg1 = app.BGimg1;
            settings.BGimg2 = app.BGimg2;
            settings.BGimg3 = app.BGimg3;
            settings.BGimg4 = app.BGimg4;
            
            settings.Spots1 = app.Spots1;
            settings.Spots2 = app.Spots2;
            settings.Spots3 = app.Spots3;
            settings.Spots4 = app.Spots4;
            
            settings.defects12 = app.defects12;
            settings.defects34 = app.defects34;
            
            settings.MaxAllowableSize = app.MaxAllowableSize;
            settings.MaxNumSpots = app.MaxNumSpots;
            
            settings.currentPos = app.currentPos; 
            
            % variable flag for pausing
            settings.pauseFlag=app.pauseFlag; % Description
            settings.previousStatus=app.previousStatus;
            settings.isFrozen=app.isFrozen;
            settings.displayResultsHandle=app.displayResultsHandle;
            
            settings.register = app.register;
            settings.outerAbortFlag = app.outerAbortFlag;
            

            %components
            settings.WDSwitch.Value = app.WDSwitch.Value;
            settings.WDSwitch.Enable = app.WDSwitch.Enable;
            
            settings.ShotModeSwitch.Value = app.ShotModeSwitch.Value;
            settings.ShotModeSwitch.Enable = app.ShotModeSwitch.Enable;
            

            settings.Rel_X.Value=app.Rel_X.Value;
            settings.Rel_X.Enable=app.Rel_X.Enable;
            
            settings.Abs_X.Value=app.Abs_X.Value;
            settings.Abs_X.Enable=app.Abs_X.Enable;
        
            settings.Rel_Y.Value=app.Rel_Y.Value;
            settings.Rel_Y.Enable=app.Rel_Y.Enable;
            
            settings.Abs_Y.Value=app.Abs_Y.Value;
            settings.Abs_Y.Enable=app.Abs_Y.Enable;
            
            settings.Rel_Z.Value=app.Rel_Z.Value;
            settings.Rel_Z.Enable=app.Rel_Z.Enable;
            
            settings.Abs_Z.Value=app.Abs_Z.Value;
            settings.Abs_Z.Enable=app.Abs_Z.Enable;
            
            settings.Xplus.Enable=app.Xplus.Enable;
            
            settings.Xminus.Enable=app.Xminus.Enable;
            
            settings.XStart.Enable=app.XStart.Enable;
        
            settings.Yplus.Enable=app.Yplus.Enable;
            
            settings.Yminus.Enable=app.Yminus.Enable;
            
            settings.YStart.Enable=app.YStart.Enable;
            
            settings.Zplus.Enable=app.Zplus.Enable;
            
            settings.Zminus.Enable=app.Zminus.Enable;
            
            settings.ZStart.Enable=app.ZStart.Enable;
            
            settings.GoToLWDXcYcZnomButton.Enable=app.GoToLWDXcYcZnomButton.Enable;
            settings.GoToPARKButton.Enable=app.GoToPARKButton.Enable;
            settings.GoToSWDXcYcZnomButton.Enable=app.GoToSWDXcYcZnomButton.Enable;
        
            settings.PauseResumeButton.Enable=app.PauseResumeButton.Enable;
            settings.AbortTestButton.Enable=app.AbortTestButton.Enable;
            settings.BacktoMasterQualScreenButton.Enable=app.BacktoMasterQualScreenButton.Enable;
            
            settings.XLamp.Color=app.XLamp.Color;
            settings.YLamp.Color=app.YLamp.Color;
            settings.ZLamp.Color=app.ZLamp.Color;
            
            settings.PerformTestButton.Enable = app.PerformTestButton.Enable;
            settings.DisplayResultButton.Enable = app.DisplayResultButton.Enable;
            
            settings.statusArea.Value=app.statusArea.Value;
            settings.statusArea.Enable=app.statusArea.Enable;
            
            settings.AllowableNumber.Value=app.AllowableNumber.Value;
            settings.AllowableNumber.Enable=app.AllowableNumber.Enable;
            
            settings.AllowableSize.Value=app.AllowableSize.Value;
            settings.AllowableSize.Enable=app.AllowableSize.Enable;
            
            settings.FoundBlob.Value=app.FoundBlob.Value;
            settings.FoundBlob.Enable=app.FoundBlob.Enable;
            
            settings.QualPass.Color=app.QualPass.Color;
            
            settings.image=getimage(app.UIAxes);
        end
        
        
        function showInfoMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Info';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'icon', 'info');
            switch selection
                case {'OK'}
                otherwise
            end
            delete(fig);
        end
    end

    methods (Access = public)

        function init(app)
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables; %read in system setup
            
            var=app.systemVar.motionSetupVar;
            app.Park_X=var.XPark;
            app.Park_Y=var.YPark;
            app.Park_Z=var.ZPark;
            app.Xlimit_plus = var.Xlimit_Plus;
            app.Xlimit_minus = var.Xlimit_Minus;
            app.Ylimit_plus = var.Ylimit_Plus;
            app.Ylimit_minus = var.Ylimit_Minus;
            app.Zlimit_plus = var.Zlimit_Plus;
            app.Zlimit_minus = var.Zlimit_Minus;
            
            var=app.systemVar.toolCalibrationVar;
            app.SWD_Xc = var.SWD_Xc;
            app.SWD_Yc = var.SWD_Yc;
            app.LWD_Xc = var.LWD_Xc;
            app.LWD_Yc = var.LWD_Yc;
            
            var=app.systemVar.engineeringPar1Var;
            app.SWD_Znorm = var.SWDNominalZ;
            app.LWD_Znorm = var.LWDNominalZ;


            app.manualSpeed=var.ManualMotionSpeed;
            app.autoSpeed = var.AutoQualScanSpeed;
            app.movingSpeed = app.manualSpeed;
            app.qualMethod = app.manualQualMethod;
            
            %fill in Target values
            var=app.systemVar.engineeringPar2Var;
            app.AllowableNumber.Value = var.MaxAllowableDefectNo;
            app.AllowableSize.Value = var.MaxAllowableDefectArea;
            app.MaxReportedSize=var.MaxReportedDefectArea*1.0e6; %mm^2 to um^2

            
            app.Pos_1=[var.X_pos_1, var.Y_pos_1];
            app.Pos_2=[var.X_pos_2, var.Y_pos_2];
            app.Pos_3=[var.X_pos_3, var.Y_pos_3];
            app.Pos_4=[var.X_pos_4, var.Y_pos_4];
            
            app.img1=[]; 
            app.img2=[]; 
            app.img3=[]; 
            app.img4=[]; 
        
            app.BGimg1=[]; 
            app.BGimg2=[]; 
            app.BGimg3=[]; 
            app.BGimg4=[]; 
            
            app.Spots1=[];
            app.Spots2=[];
            app.Spots3=[];
            app.Spots4=[];
            
            app.defects12=[];
            app.defects34=[];
            
            app.currentPos=0;
            
            app.pauseFlag=false;

            if isempty(app.register) 
                app.register=processRegister(app, 0.5);
            elseif isvalid(app.register)
                app.register.reset();
            else
                app.register=processRegister(app, 0.5);
            end

            app.outerAbortFlag=false;

            %initialize lamp color
            app.XLamp.Color=app.UnderLimitColor;
            app.YLamp.Color=app.UnderLimitColor;
            app.ZLamp.Color=app.UnderLimitColor;
            app.QualPass.Color=app.PassColor;
            
            app.Rel_X.Value=0;
            app.Abs_X.Value=0;
            app.Rel_Y.Value=0;
            app.Abs_Y.Value=0;
            app.Rel_Z.Value=0;
            app.Abs_Z.Value=0;
            app.FoundBlob.Value=0;
            if ~isempty(app.displayResultsHandle) & ...
                    isvalid(app.displayResultsHandle)
                app.displayResultsHandle.close();
            end

            app.statusArea.Value='';
            if ~isempty(app.displayResultsHandle) & ...
                    isvalid(app.displayResultsHandle)
                app.displayResultsHandle.close();
            end
            
            
            str=[];
            str{1}='Green only if Present X Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Xlimit_minus, app.Xlimit_plus);
            app.XLamp.Tooltip=str;

            str{1}='Green only if Present Y Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Ylimit_minus, app.Ylimit_plus);
            app.YLamp.Tooltip=str;

            str{1}='Green only if Present Z Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Zlimit_minus, app.Zlimit_plus);
            app.ZLamp.Tooltip=str;
            
            str=sprintf('Fail if found number > %d', ...
                app.AllowableNumber.Value);
            app.QualPass.Tooltip=str;
            
            %disable buttons
            app.DisplayResultButton.Enable=false;
            if app.ShotModeSwitch.Value==0 % LWD
                app.PerformTestButton.Enable=false;
            end
            
            % initialize pausing parameters
            app.pauseFlag=false; 
            app.isFrozen=false;
            app.recordPreviousValue();
        
            app.xupdater=updatePosition(app.PresentXPos, 0, .05, ...
                {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater=updatePosition(app.PresentYPos, 1, .05, ...
                {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater=updatePosition(app.PresentZPos, 2, .05, ...
                {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1);
            app.stopTimer();

             if app.PresentXPos.Value==app.LWD_Xc && ...
                     app.PresentYPos.Value==app.LWD_Yc && ...
                     app.PresentZPos.Value==app.LWD_Znorm
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
             else
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
             end
        end
        
        function stopTimer(app)
            app.xupdater.stop();
            app.yupdater.stop();
            app.zupdater.stop();
        end
    end

    methods (Access = private)

        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, Lamp)
            try
                val=round(val*1.0e3)*1.0e-3;
                upperLimit=round(upperLimit*1.0e6)*1.0e-6;
                lowerLimit=round(lowerLimit*1.0e6)*1.0e-6;
                c=Lamp.Color;
                warning=false;
                Lamp.Color=app.UnderLimitColor;

                if val>upperLimit | val<lowerLimit
                    Lamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
            Lamp.Color=c;
        end
        
        function [img, name] = captureImage(app, cameraSource, pos)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            if RPAS_C.CameraAvailable
                var=app.systemVar.engineeringPar1Var;
                pause(max(var.MinTimeBetweenImageCaptures,1)*1.0e-3);
                img = RPAS_ImageAcquisition_7dot1(cameraSource, 1);
                if cameraSource==1 % SWD
                    name='SWD';
                else
                    name='LWD';
                end
            else
                path=[RPAS_C.QUAL_IMAGE_DIR '/contaminations/'];
                files={'BP1', 'BP2', 'BP3', 'BP4', ...
                    'img_pos1', 'img_pos2', 'img_pos3', 'img_pos4'};
                if app.WDSwitch.Value==1 % SWD
                    fnm=[path '\' files{app.currentPos} '.pgm'];
                    name=files{app.currentPos};
                else
                    fnm=[path '\' files{app.currentPos+4} '.pgm'];
                    name=files{app.currentPos+4};
                end
                img=imread(fnm);
            end

            if app.qualMethod==app.manualQualMethod

                path=[RPAS_C.QUAL_RESULT_DIR '/Manual_Contamination'];
                RPAS_Make_folder(path);
    
                dt=datetime();
                datetimestring=datestr(dt, 'mmm_dd_yyyy_HH_MM_SS');
                if cameraSource==1 % SWD
                    fnm=[path '/SWD_Position=' num2str(pos) 'DateTime=' datetimestring '.png'];
                else
                    fnm=[path '/LWD_Position=' num2str(pos) 'DateTime=' datetimestring '.png'];
                end
            
                imwrite(img,fnm);
            end
        end
        
        function displayImage(app, img)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            app.UIAxes.XLim=[0, size(img,2)];
            app.UIAxes.YLim=[0, size(img,1)];
            tik=0:100:600;
%             tikLbl={};
%             for k=1:numel(tik)
%                 tikLbl{k}=num2str(tik(k)*RPAS_C.Pixel_img_plane*1.0e-3);
%             end
            app.UIAxes.XTick=tik;
%             app.UIAxes.XTickLabel=tikLbl;
            app.UIAxes.YTick=tik(1:5);
%             app.UIAxes.YTickLabel=tikLbl(1:5);
            
            
            R=imref2d(size(img));
            imshow(img, [],'Parent', app.UIAxes);
            
            drawnow;
        end
        
        function displayResults(app)
            app.displayResultsHandle=contResultFig;
            ax=app.displayResultsHandle.UIAxes1;
            imshow(app.img1,[],'parent',ax);
            ax.Title.String='Pos 1';
            if ~isempty(app.Spots1)
                ax.NextPlot='add';
                m=size(app.Spots1.position,1);
                spots=[app.Spots1.position(:,1:2) ...
                    reshape(app.Spots1.area, [m,1])];
                plot3(spots(:,1), spots(:,2), spots(:,3), 'o', ...
                    'MarkerSize',20, 'MarkerEdgeColor', 'b', 'Parent', ax);
                if ~isempty(app.defects12)
                    spots=app.defects12;
                    plot3(spots(:,1), spots(:,2), spots(:,3), 'o', ...
                    'MarkerSize',25, 'MarkerEdgeColor', 'y', 'Parent', ax, ...
                    'LineWidth',2);
                end
                ax.NextPlot='replace';
            end
            
            ax=app.displayResultsHandle.UIAxes2;
            imshow(app.img2,[],'parent',ax);
            ax.Title.String='Pos 2';
            if ~isempty(app.Spots2)
                ax.NextPlot='add';
                m=size(app.Spots2.position,1);
                spots=[app.Spots2.position(:,1:2) ...
                    reshape(app.Spots2.area, [m,1])];
                plot3(spots(:,1), spots(:,2), spots(:,3), 'o', ...
                    'MarkerSize',20, 'MarkerEdgeColor', 'b', 'Parent', ax);
                if ~isempty(app.defects12)
                    spots=app.defects12;
                    plot3(spots(:,1), spots(:,2), spots(:,3), 'o', ...
                    'MarkerSize',25, 'MarkerEdgeColor', 'y', 'Parent', ax, ...
                    'LineWidth',2);
                end
                ax.NextPlot='replace';
            end
       
            ax=app.displayResultsHandle.UIAxes3;
            imshow(app.img3,[],'parent',ax);
            ax.Title.String='Pos 3';
            if ~isempty(app.Spots3)
                ax.NextPlot='add';
                m=size(app.Spots3.position,1);
                spots=[app.Spots3.position(:,1:2) ...
                    reshape(app.Spots3.area, [m,1])];
                plot3(spots(:,1), spots(:,2), spots(:,3), 'o', ...
                    'MarkerSize',20, 'MarkerEdgeColor', 'b', 'Parent', ax);
                if ~isempty(app.defects34)
                    spots=app.defects34;
                    plot3(spots(:,1), spots(:,2), spots(:,3), 'o', ...
                    'MarkerSize',25, 'MarkerEdgeColor', 'y', 'Parent', ax, ...
                    'LineWidth',2);
                end
                ax.NextPlot='replace';
            end
            
            ax=app.displayResultsHandle.UIAxes4;
            imshow(app.img4,[],'parent',ax);
            ax.Title.String='Pos 4';
            if ~isempty(app.Spots4)
                ax.NextPlot='add';
                m=size(app.Spots4.position,1);
                spots=[app.Spots4.position(:,1:2) ...
                    reshape(app.Spots4.area, [m,1])];
                plot3(spots(:,1), spots(:,2), spots(:,3), 'o', ...
                    'MarkerSize',20, 'MarkerEdgeColor', 'b', 'Parent', ax);
                if ~isempty(app.defects34)
                    spots=app.defects34;
                    plot3(spots(:,1), spots(:,2), spots(:,3), 'o', ...
                    'MarkerSize',25, 'MarkerEdgeColor', 'y', 'Parent', ax, ...
                    'LineWidth',2);
                end
                ax.NextPlot='replace';
            end
        end
        
        function checkPause(app)
            while app.pauseFlag
                pause(0.1);
            end
        end
        
        function contamQual(app)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            app.register.addProcess();

            pix=RPAS_C.Pixel_img_plane;
            minArea=app.AllowableSize.Value;
            maxArea=app.MaxReportedSize;
            
            %check if is pausing
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            %search for particles
            [app.Spots1, app.BGimg1] = ...
                RPAS_findSpots_7dot4(app.img1, pix, minArea, maxArea);
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            [app.Spots2, app.BGimg2] = ...
                RPAS_findSpots_7dot4(app.img2, pix, minArea, maxArea);
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            [app.Spots3, app.BGimg3] = ...
                RPAS_findSpots_7dot4(app.img3, pix, minArea, maxArea);
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            [app.Spots4, app.BGimg4] = ...
                RPAS_findSpots_7dot4(app.img4, pix, minArea, maxArea);
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            %find defects
            app.defects12 = RPAS_findDefects_7dot4(app.BGimg1, app.BGimg2);
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();

            app.defects34 = RPAS_findDefects_7dot4(app.BGimg3, app.BGimg4);
            
            if isempty(app.defects12)
                n1=0;
            else
                n1=size(app.defects12,1);
            end
            if isempty(app.defects34)
                n2=0;
            else
                n2=size(app.defects34,1);
            end
            
            app.fillResult(n1+n2);

            app.register.removeProcess();
        end


        function freeze(app, val)
            val=~val;

            if val % restore previous status
                %moving fields
                app.Rel_X.Enable = app.previousStatus.Rel_XValue;
                app.Abs_X.Enable = app.previousStatus.Abs_XValue;
                app.Rel_Y.Enable = app.previousStatus.Rel_YValue;
                app.Abs_Y.Enable = app.previousStatus.Abs_YValue;
                app.Rel_Z.Enable = app.previousStatus.Rel_ZValue;
                app.Abs_Z.Enable = app.previousStatus.Abs_ZValue;

                %moving buttons
                app.Xplus.Enable = app.previousStatus.XplusValue;
                app.Xminus.Enable = app.previousStatus.XminusValue;
                app.XStart.Enable = app.previousStatus.XStartValue;
                app.Yplus.Enable = app.previousStatus.YplusValue;
                app.Yminus.Enable = app.previousStatus.YminusValue;
                app.YStart.Enable = app.previousStatus.YStartValue;
                app.Zplus.Enable = app.previousStatus.ZplusValue;
                app.Zminus.Enable = app.previousStatus.ZminusValue;
                app.ZStart.Enable = app.previousStatus.ZStartValue;

                %switch buttons
                app.WDSwitch.Enable = app.previousStatus.WDSwitchValue;
                app.ShotModeSwitch.Enable = app.previousStatus.ShotModeSwitchValue;  

                %Go to buttons
                app.GoToLWDXcYcZnomButton.Enable = ...
                    app.previousStatus.GoToLWDXcYcZnomButtonValue; 
                app.GoToSWDXcYcZnomButton.Enable = ...
                    app.previousStatus.GoToSWDXcYcZnomButtonValue; 
                app.GoToPARKButton.Enable = ...
                    app.previousStatus.GoToPARKButtonValue; 

                %BackToMaster Button
                app.BacktoMasterQualScreenButton.Enable = ...
                    app.previousStatus.BacktoMasterQualScreenButtonValue; 

                %display and performing test buttons
                app.DisplayResultButton.Enable = ...
                    app.previousStatus.DisplayResultButtonValue; 
                app.PerformTestButton.Enable = ...
                    app.previousStatus.PerformTestButtonValue; 
            else
                app.recordPreviousValue();

                app.WDSwitch.Enable=val;
                app.ShotModeSwitch.Enable=val;
            
                app.Xplus.Enable=val;
                app.Xminus.Enable=val;
                app.XStart.Enable=val;
            
                app.Yplus.Enable=val;
                app.Yminus.Enable=val;
                app.YStart.Enable=val;
            
                app.Zplus.Enable=val;
                app.Zminus.Enable=val;
                app.ZStart.Enable=val;
            
                app.GoToLWDXcYcZnomButton.Enable=val;
                app.GoToSWDXcYcZnomButton.Enable=val;
                app.GoToPARKButton.Enable=val;
            
                app.BacktoMasterQualScreenButton.Enable=val;

                app.DisplayResultButton.Enable = val;
                app.PerformTestButton.Enable = val;
            
                app.Rel_X.Enable=val;
                app.Abs_X.Enable=val;
                
                app.Rel_Y.Enable=val;
                app.Abs_Y.Enable=val;
                
                app.Rel_Z.Enable=val;
                app.Abs_Z.Enable=val;
            end
        end
        
        function reset(app)
            app.currentPos=1;
            
            %reset values to avoid messup
            app.img1=[];
            app.img2=[];
            app.img3=[];
            app.img4=[];
            
            app.BGimg1=[];
            app.BGimg2=[];
            app.BGimg3=[];
            app.BGimg4=[];
            
            app.Spots1=[];
            app.Spots2=[];
            app.Spots3=[];
            app.Spots4=[];
            
            app.defects12=[];
            app.defects34=[];
            
            app.FoundBlob.Value=0;
            app.QualPass.Color=app.PassColor;
        end
        
        function showStatus(app, status)
            msg=app.statusArea.Value;
            msg{end+1}=status;
            app.statusArea.Value=msg;
            drawnow;
        end
        
        function clearStatus(app)
            str='';
            app.statusArea.Value=str;
        end
        
        function recordPreviousValue(app)
            %moving fields
            app.previousStatus.Rel_XValue=...
                app.Rel_X.Enable;
            app.previousStatus.Abs_XValue=...
                app.Abs_X.Enable;
            app.previousStatus.Rel_YValue=...
                app.Rel_Y.Enable;
            app.previousStatus.Abs_YValue=...
                app.Abs_Y.Enable;
            app.previousStatus.Rel_ZValue=...
                app.Rel_Z.Enable;
            app.previousStatus.Abs_ZValue=...
                app.Abs_Z.Enable;

            %moving buttons
            app.previousStatus.XplusValue=...
                app.Xplus.Enable;
            app.previousStatus.XminusValue=...
                app.Xminus.Enable;
            app.previousStatus.XStartValue=...
                app.XStart.Enable;
            app.previousStatus.YplusValue=...
                app.Yplus.Enable;
            app.previousStatus.YminusValue=...
                app.Yminus.Enable;
            app.previousStatus.YStartValue=...
                app.YStart.Enable;
            app.previousStatus.ZplusValue=...
                app.Zplus.Enable;
            app.previousStatus.ZminusValue=...
                app.Zminus.Enable;
            app.previousStatus.ZStartValue=...
                app.ZStart.Enable;

            %switch buttons
            app.previousStatus.WDSwitchValue=...
                app.WDSwitch.Enable;
            app.previousStatus.ShotModeSwitchValue=...
                app.ShotModeSwitch.Enable;  

            %Go to buttons
            app.previousStatus.GoToLWDXcYcZnomButtonValue=...
                app.GoToLWDXcYcZnomButton.Enable; 
            app.previousStatus.GoToSWDXcYcZnomButtonValue=...
                app.GoToSWDXcYcZnomButton.Enable; 
            app.previousStatus.GoToPARKButtonValue=...
                app.GoToPARKButton.Enable; 

            %BackToMaster Button
            app.previousStatus.BacktoMasterQualScreenButtonValue=...
                app.BacktoMasterQualScreenButton.Enable; 

            %display and performing test buttons
            app.previousStatus.DisplayResultButtonValue=...
                app.DisplayResultButton.Enable; 
            app.previousStatus.PerformTestButtonValue=...
                app.PerformTestButton.Enable; 
        end
        
        
    end
    
    methods (Access=public)  
        function restoreSettings(app, settings)
            %properties
            %app.xupdater =settings.xupdater; % timer to query present x position
            %app.yupdater =settings.yupdater;% timer to query present y position
            %app.zupdater =settings.zupdater;% timer to query present z position
            
            %app.systemVar=settings.systemVar; % variable hold system configurations
            app.manualQualMethod=settings.manualQualMethod;
            app.autoQualMethod =settings.autoQualMethod;
    
            app.manualSpeed=settings.manualSpeed;
            app.autoSpeed=settings.autoSpeed;
            app.movingSpeed=settings.movingSpeed;

            app.qualMethod=settings.qualMethod;
    
            app.Park_X=settings.Park_X;
            app.Park_Y=settings.Park_Y;
            app.Park_Z=settings.Park_Z;
            app.SWD_Xc=settings.SWD_Xc;
            app.SWD_Yc=settings.SWD_Yc;
            app.SWD_Znorm=settings.SWD_Znorm;
            app.LWD_Xc=settings.LWD_Xc;
            app.LWD_Yc=settings.LWD_Yc;
            app.LWD_Znorm=settings.LWD_Znorm;
            
            app.Xlimit_plus=settings.Xlimit_plus;
            app.Xlimit_minus=settings.Xlimit_minus;
            app.Ylimit_plus=settings.Ylimit_plus;
            app.Ylimit_minus=settings.Ylimit_minus;
            app.Zlimit_plus=settings.Zlimit_plus;
            app.Zlimit_minus=settings.Zlimit_minus;
            
            
            app.Pos_1 = settings.Pos_1; 
            app.Pos_2 = settings.Pos_2;
            app.Pos_3 = settings.Pos_3;
            app.Pos_4 = settings.Pos_4;
        
            app.img1 = settings.img1; 
            app.img2 = settings.img2;
            app.img3 = settings.img3;
            app.img4 = settings.img4;
            
            app.BGimg1 = settings.BGimg1;
            app.BGimg2 = settings.BGimg2;
            app.BGimg3 = settings.BGimg3;
            app.BGimg4 = settings.BGimg4;
            
            app.Spots1 = settings.Spots1;
            app.Spots2 = settings.Spots2;
            app.Spots3 = settings.Spots3;
            app.Spots4 = settings.Spots4;
            
            app.defects12 = settings.defects12;
            app.defects34 = settings.defects34;
            
            app.MaxAllowableSize = settings.MaxAllowableSize;
            app.MaxNumSpots = settings.MaxNumSpots;
            
            app.currentPos = settings.currentPos; 
            
            % variable flag for pausing
            app.pauseFlag=settings.pauseFlag; % Description
            app.previousStatus=settings.previousStatus;
            app.isFrozen=settings.isFrozen;
            app.displayResultsHandle=settings.displayResultsHandle;
            
            app.register = settings.register;
            app.outerAbortFlag = settings.outerAbortFlag;
            
            %components
            app.WDSwitch.Value = settings.WDSwitch.Value;
            app.WDSwitch.Enable = settings.WDSwitch.Enable;
            
            app.ShotModeSwitch.Value = settings.ShotModeSwitch.Value;
            app.ShotModeSwitch.Enable = settings.ShotModeSwitch.Enable;
            
            
            app.Rel_X.Value=settings.Rel_X.Value;
            app.Rel_X.Enable=settings.Rel_X.Enable;
            
            app.Abs_X.Value=settings.Abs_X.Value;
            app.Abs_X.Enable=settings.Abs_X.Enable;
        
            app.Rel_Y.Value=settings.Rel_Y.Value;
            app.Rel_Y.Enable=settings.Rel_Y.Enable;
            
            app.Abs_Y.Value=settings.Abs_Y.Value;
            app.Abs_Y.Enable=settings.Abs_Y.Enable;
            
            app.Rel_Z.Value=settings.Rel_Z.Value;
            app.Rel_Z.Enable=settings.Rel_Z.Enable;
            
            app.Abs_Z.Value=settings.Abs_Z.Value;
            app.Abs_Z.Enable=settings.Abs_Z.Enable;
            
            app.Xplus.Enable=settings.Xplus.Enable;
            
            app.Xminus.Enable=settings.Xminus.Enable;
            
            app.XStart.Enable=settings.XStart.Enable;
        
            app.Yplus.Enable=settings.Yplus.Enable;
            
            app.Yminus.Enable=settings.Yminus.Enable;
            
            app.YStart.Enable=settings.YStart.Enable;
            
            app.Zplus.Enable=settings.Zplus.Enable;
            
            app.Zminus.Enable=settings.Zminus.Enable;
            
            app.ZStart.Enable=settings.ZStart.Enable;
            
            app.GoToLWDXcYcZnomButton.Enable=settings.GoToLWDXcYcZnomButton.Enable;
            app.GoToPARKButton.Enable=settings.GoToPARKButton.Enable;
            app.GoToSWDXcYcZnomButton.Enable=settings.GoToSWDXcYcZnomButton.Enable;
        
            app.PauseResumeButton.Enable=settings.PauseResumeButton.Enable;
            app.AbortTestButton.Enable=settings.AbortTestButton.Enable;
            app.BacktoMasterQualScreenButton.Enable=settings.BacktoMasterQualScreenButton.Enable;
            
            app.XLamp.Color=settings.XLamp.Color;
            app.YLamp.Color=settings.YLamp.Color;
            app.ZLamp.Color=settings.ZLamp.Color;
            
            app.PerformTestButton.Enable = settings.PerformTestButton.Enable;
            app.DisplayResultButton.Enable = settings.DisplayResultButton.Enable;
            
            app.statusArea.Value=settings.statusArea.Value;
            app.statusArea.Enable=settings.statusArea.Enable;
            
            app.AllowableNumber.Value=settings.AllowableNumber.Value;
            app.AllowableNumber.Enable=settings.AllowableNumber.Enable;
            
            app.AllowableSize.Value=settings.AllowableSize.Value;
            app.AllowableSize.Enable=settings.AllowableSize.Enable;
            
            app.FoundBlob.Value=settings.FoundBlob.Value;
            app.FoundBlob.Enable=settings.FoundBlob.Enable;
            
            app.QualPass.Color=settings.QualPass.Color;
            
            if ~isempty(settings.image)
                app.displayImage(settings.image);
            end
        end

        
        function pausing(app, val)
            app.pauseFlag=val;
            if app.pauseFlag
                app.showStatus('paused ... ');
            else
                app.showStatus('pause resumed ... ');
            end

            if ~isempty(app.motionStatus)
                if app.motionStatus.isPausing
                    lamps=[];
                    for k=1:numel(app.motionStatus.axes)
                        switch app.motionStatus.axes(k)
                            case {0}
                                lamps=[lamps app.xupdater];
                            case {1}
                                lamps=[lamps app.yupdater];
                            case {2}
                                lamps=[lamps app.zupdater];
                        end
                    end
                    app.motionStatus.isPausing=false;
                    if ~isempty(app.motionStatus.axes)
                        ABSMoving(app.motionStatus.axes, app.motionStatus.movingDistances, ...
                            app.motionStatus.movingSpeeds, lamps, app);
                    end
                else
                    app.motionStatus.isPausing=true;
                    if ~isempty(app.motionStatus.axes)
                        stopMotion([app.xupdater, app.yupdater, app.zupdater], app);
                    end
                end
            end
        end
        
        function autoQual(app,poses)
            app.register.addProcess();

            %check if is pausing enabled
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            %reset to initial status
            cla(app.UIAxes);
            app.reset;
            

            if app.WDSwitch.Value==1 %SWD
                app.showStatus('SWD:');
            else
                app.showStatus('LWD:');
            end
            %reset
            app.reset();
            
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % position 1
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end

            msg=sprintf('Moving to Position 1: [%g, %g, %g] ...', poses(1,:));
            app.showStatus(msg);
            app.currentPos=1;
            % moving to position 1
            axes=[0, 1, 2];
            ABSMoving(axes, poses(1,:), app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            app.showStatus('Capture image ... ');
            %capture image
            app.img1 = app.captureImage(app.WDSwitch.Value, 1);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            app.showStatus('Display image ... ');
            %display image
            app.displayImage(app.img1);

            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % position 2
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            msg=sprintf('Moving to Position 2: [%g, %g, %g] ...', poses(2,:));
            app.showStatus(msg);
            app.currentPos=2;
            ABSMoving(axes, poses(2,:), app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            app.showStatus('Capture image ... ');
            %capture image
            app.img2 = app.captureImage(app.WDSwitch.Value, 2);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            app.showStatus('Display image ... ');
            %display image
            app.displayImage(app.img2);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % position 3
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            msg=sprintf('Moving to Position 3: [%g, %g, %g] ...', poses(3,:));
            app.showStatus(msg);
            app.currentPos=3;
            ABSMoving(axes, poses(3,:), app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            app.showStatus('Capture image ... ');
            %capture image
            app.img3 = app.captureImage(app.WDSwitch.Value, 3);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            app.showStatus('Display image ... ');
            %display image
            app.displayImage(app.img3);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % position 4
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            msg=sprintf('Moving to Position 4: [%g, %g, %g] ...', poses(4,:));
            app.showStatus(msg);
            app.currentPos=4;
            ABSMoving(axes, poses(4,:), app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            app.showStatus('Capture image ... ');
            %capture image
            app.img4 = app.captureImage(app.WDSwitch.Value, 4);
            
            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            app.showStatus('Display image ... ');
            %display image
            app.displayImage(app.img4);

            %check again
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % begin evaluation
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.showStatus('Qualification ... ');
            app.contamQual();
            

            app.register.removeProcess();
        end

        function hide(app, val)
%             if val
%                 app.stopTimer();
%             else
%                 app.xupdater.start();
%                 app.yupdater.start();
%                 app.zupdater.start();
%             end
            app.RPASDetectorContaminationQualificationUIFigure.Visible=~val;
        end
        
        function [numFound, settings, images]=getResults(app)
            numFound=app.FoundBlob.Value;
            settings=app.recordSettings();
            images=struct('Pos1', app.img1, ...
                            'Pos2', app.img2, ...
                            'Pos3', app.img3, ...
                            'Pos4', app.img4);
        end
        
        function freezeAll(app, val)
            app.freeze(val);
            app.PauseResumeButton.Enable=~val;
            app.AbortTestButton.Enable=~val;
            app.isFrozen=val;
        end
        
        function reinit(app, dataSheet)
            app.statusArea.Value='';
            if strcmp(dataSheet.camera, 'SWD')
                app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
            else
                app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
            end
            
            app.AllowableNumber.Value = dataSheet.MaxDefectNum;
            app.AllowableSize.Value = dataSheet.MaxSingleSize;
            
            app.Rel_X.Value=0;
            app.Abs_X.Value=0;
            app.Rel_Y.Value=0;
            app.Abs_Y.Value=0;
            app.Rel_Z.Value=0;
            app.Abs_Z.Value=0;
            app.FoundBlob.Value=0;
            if ~isempty(app.displayResultsHandle) & ...
                    isvalid(app.displayResultsHandle)
                app.displayResultsHandle.close();
            end
            
            str=sprintf('Fail if found number > %d', ...
                app.AllowableNumber.Value);
            app.QualPass.Tooltip=str;

            if isempty(app.register) 
                app.register=processRegister(app, 0.5);
            elseif isvalid(app.register)
                app.register.reset();
            else
                app.register=processRegister(app, 0.5);
            end
        end
        
        function fillResult(app, val)
            
            app.FoundBlob.Value=val;
            if app.FoundBlob.Value>app.AllowableNumber.Value
                app.QualPass.Color=app.FailColor;
            else
                app.QualPass.Color=app.PassColor;
            end
        end

        function setAutoQualMethod(app)
            app.qualMethod=app.autoQualMethod;
        end

        function setManuQualMethod(app)
            app.qualMethod=app.manualQualMethod;
        end

        function close(app)
            app.RPASDetectorContaminationQualificationUIFigureCloseRequest;
        end
        
        function setManualQualMethod(app)
            app.qualMethod=app.manualQualMethod;
        end

        function abortProgram(app)
            app.AbortTestButtonPushed();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, settings)
            
            if nargin==2
                app.restoreSettings(settings);
                global systemVar;
                if isempty(systemVar)
                    systemVar=savedVariables; %read in system setup
                end
                app.systemVar=systemVar;
                %app.systemVar=savedVariables; %read in system setup
                
                app.xupdater=updatePosition(app.PresentXPos, 0, .3, ...
                    {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
                app.yupdater=updatePosition(app.PresentYPos, 1, .3, ...
                    {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
                app.zupdater=updatePosition(app.PresentZPos, 2, .3, ...
                    {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
            
                app.xupdater.start();
                app.yupdater.start();
                app.zupdater.start();
            else
                app.init();
            end
        end

        % Button pushed function: Xplus
        function XplusButtonPushed(app, event)
           if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end

            val=app.PresentXPos.Value + app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                RelativeMoving(0, app.Rel_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: Xminus
        function XminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentXPos.Value - app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
               RelativeMoving(0, -app.Rel_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: Yplus
        function YplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentYPos.Value + app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, app.Rel_Y.Value, app.movingSpeed, app.yupdater, app);
            end
        end

        % Button pushed function: Yminus
        function YminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentYPos.Value - app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, -app.Rel_Y.Value, app.movingSpeed, app.yupdater, app);
            end
        end

        % Button pushed function: Zplus
        function ZplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentZPos.Value + app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
               RelativeMoving(2, app.Rel_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: Zminus
        function ZminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentZPos.Value - app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                RelativeMoving(2, -app.Rel_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: XStart
        function XStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                ABSMoving(0, app.Abs_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: YStart
        function YStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                ABSMoving(1, app.Abs_Y.Value, app.movingSpeed, app.yupdater, app);
            end
        end

        % Button pushed function: ZStart
        function ZStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                ABSMoving(2, app.Abs_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: GoToLWDXcYcZnomButton
        function GoToLWDXcYcZnomButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.LWD_Xc, app.LWD_Yc, app.LWD_Znorm];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
        end

        % Button pushed function: GoToPARKButton
        function GoToPARKButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.Park_X, app.Park_Y, app.Park_Z];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
        end

        % Button pushed function: GoToSWDXcYcZnomButton
        function GoToSWDXcYcZnomButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.SWD_Xc, app.SWD_Yc, app.SWD_Znorm];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
        end

        % Value changed function: ShotModeSwitch
        function ShotModeSwitchValueChanged(app, event)
            value = app.ShotModeSwitch.Value;
            if value==1 %Single shot
                app.PerformTestButton.Enable=true;
            else
                app.PerformTestButton.Enable=false;
            end
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            
            app.pauseFlag=~app.pauseFlag;
            if app.pauseFlag
                app.showStatus('paused ... ');
            else
                app.showStatus('pause resumed ... ');
            end
            if ~app.isFrozen
                app.freeze(app.pauseFlag);
            end

            if ~isempty(app.motionStatus)
                if app.motionStatus.isPausing
                    lamps=[];
                    for k=1:numel(app.motionStatus.axes)
                        switch app.motionStatus.axes(k)
                            case {0}
                                lamps=[lamps app.xupdater];
                            case {1}
                                lamps=[lamps app.yupdater];
                            case {2}
                                lamps=[lamps app.zupdater];
                        end
                    end
                    app.motionStatus.isPausing=false;
                    if ~isempty(app.motionStatus.axes)
                        ABSMoving(app.motionStatus.axes, app.motionStatus.movingDistances, ...
                            app.motionStatus.movingSpeeds, lamps, app);
                    end
                else
                    app.motionStatus.isPausing=true;
                    if ~isempty(app.motionStatus.axes)
                        stopMotion([app.xupdater, app.yupdater, app.zupdater], app);
                    end
                end
            end
        end

        % Button pushed function: AbortTestButton
        function AbortTestButtonPushed(app, event)
            app.pauseFlag=false;
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            app.motionStatus=struct('axes', [], 'movingSpeeds', [], ...
                'movingDistances', [], 'movingType', '', ...
                'status', '', 'isPausing', false);
            app.showStatus('Aborting ...');
            app.register.Aborting(true);
        end

        % Close request function: 
        % RPASDetectorContaminationQualificationUIFigure
        function RPASDetectorContaminationQualificationUIFigureCloseRequest(app, event)
            if ~isempty(app.register) && isvalid(app.register) ...
                    && app.register.numberOfActiveProcess()>0
                app.showInfoMsg("Please abort test or waiting the test finish before closing.");
                return;
            end
            
            if ~isempty(app.displayResultsHandle) & ...
                    isvalid(app.displayResultsHandle)
                app.displayResultsHandle.close();
            end
            if ~isempty(app.register) & isvalid(app.register)
                app.register.delete();
            end
            
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            delete(app)
            
        end

        % Button pushed function: PerformTestButton
        function PerformTestButtonPushed(app, event)
            
            app.register.addProcess();

            app.clearStatus();
            app.freeze(true);
            app.isFrozen=true;
            poses=[app.Pos_1; app.Pos_2; app.Pos_3; app.Pos_4];
            if app.WDSwitch.Value==1 % SWD
                poses=[poses ones(4,1)*app.SWD_Znorm];
            else
                poses=[poses ones(4,1)*app.LWD_Znorm];
            end

            app.autoQual(poses);
            app.freeze(false);
            app.isFrozen=false;
            app.DisplayResultButton.Enable=true;
             
            app.register.removeProcess();

        end

        % Button pushed function: DisplayResultButton
        function DisplayResultButtonPushed(app, event)
            
            %check for pausing
            app.displayResults();
        end

        % Value changed function: WDSwitch
        function WDSwitchValueChanged(app, event)
            value = app.WDSwitch.Value;
            app.PerformTestButton.Enable=true;
            app.DisplayResultButton.Enable=false;
        end

        % Button pushed function: BacktoMasterQualScreenButton
        function BacktoMasterQualScreenButtonPushed(app, event)
            app.RPASDetectorContaminationQualificationUIFigureCloseRequest();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASDetectorContaminationQualificationUIFigure = uifigure('Visible', 'off');
            app.RPASDetectorContaminationQualificationUIFigure.Name = 'RPAS Detector Contamination Qualification';
            app.RPASDetectorContaminationQualificationUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASDetectorContaminationQualificationUIFigureCloseRequest, true);
            app.RPASDetectorContaminationQualificationUIFigure.Tag = 'RPAS_ContamQual';
            app.RPASContaminationQualPanel = uipanel(app.RPASDetectorContaminationQualificationUIFigure);
            app.RPASContaminationQualPanel.Title = 'RPAS Contamination Qual';
            app.UIAxes = uiaxes(app.RPASContaminationQualPanel);
            xlabel(app.UIAxes, 'Y (Pixels)')
            ylabel(app.UIAxes, 'X (Pixels)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.YTick = [0 100 200 300 400];
            app.PresentXPosPanel = uipanel(app.RPASContaminationQualPanel);
            app.PresentXPosPanel.Title = 'Present X Pos';
            app.PresentXPos = uieditfield(app.PresentXPosPanel, 'numeric');
            app.PresentXPos.ValueDisplayFormat = '%.2f';
            app.PresentXPos.Editable = 'off';
            app.mmLabel_7 = uilabel(app.PresentXPosPanel);
            app.mmLabel_7.Text = 'mm';
            app.ReticlePositionPanel = uipanel(app.RPASContaminationQualPanel);
            app.ReticlePositionPanel.Title = 'Reticle Position';
            app.GoToPARKButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToPARKButton.ButtonPushedFcn = createCallbackFcn(app, @GoToPARKButtonPushed, true);
            app.GoToPARKButton.Text = 'Go To PARK';
            app.GoToLWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToLWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToLWDXcYcZnomButtonPushed, true);
            app.GoToLWDXcYcZnomButton.Text = 'Go To LWD Xc Yc Znom';
            app.GoToSWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToSWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToSWDXcYcZnomButtonPushed, true);
            app.GoToSWDXcYcZnomButton.Text = 'Go To SWD Xc Yc Znom';
            app.AbsoluteZMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.mmLabel_10 = uilabel(app.AbsoluteZMovePanel);
            app.mmLabel_10.Text = 'mm';
            app.ZStart = uibutton(app.AbsoluteZMovePanel, 'push');
            app.ZStart.ButtonPushedFcn = createCallbackFcn(app, @ZStartButtonPushed, true);
            app.ZStart.Text = 'Start';
            app.Abs_Z = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.Abs_Z.ValueDisplayFormat = '%.3f';
            app.RelativeZMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.mmLabel_11 = uilabel(app.RelativeZMovePanel);
            app.mmLabel_11.Text = 'mm';
            app.Zplus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zplus.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.Zplus.Text = '+';
            app.Zminus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zminus.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.Zminus.Text = '-';
            app.Rel_Z = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.Rel_Z.ValueDisplayFormat = '%.3f';
            app.AbsoluteYMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.YStart = uibutton(app.AbsoluteYMovePanel, 'push');
            app.YStart.ButtonPushedFcn = createCallbackFcn(app, @YStartButtonPushed, true);
            app.YStart.Text = 'Start';
            app.mmLabel_12 = uilabel(app.AbsoluteYMovePanel);
            app.mmLabel_12.Text = 'mm';
            app.Abs_Y = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.Abs_Y.ValueDisplayFormat = '%.3f';
            app.RelativeYMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.mmLabel_13 = uilabel(app.RelativeYMovePanel);
            app.mmLabel_13.Text = 'mm';
            app.Yplus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yplus.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.Yplus.Text = '+';
            app.Yminus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yminus.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.Yminus.Text = '-';
            app.Rel_Y = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.Rel_Y.ValueDisplayFormat = '%.3f';
            app.AbsoluteXMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.XStart = uibutton(app.AbsoluteXMovePanel, 'push');
            app.XStart.ButtonPushedFcn = createCallbackFcn(app, @XStartButtonPushed, true);
            app.XStart.Text = 'Start';
            app.mmLabel_14 = uilabel(app.AbsoluteXMovePanel);
            app.mmLabel_14.Text = 'mm';
            app.Abs_X = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.Abs_X.ValueDisplayFormat = '%.3f';
            app.RelativeXMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.Xplus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xplus.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.Xplus.Text = '+';
            app.mmLabel_15 = uilabel(app.RelativeXMovePanel);
            app.mmLabel_15.Text = 'mm';
            app.Xminus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xminus.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.Xminus.Text = '-';
            app.Rel_X = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.Rel_X.ValueDisplayFormat = '%.3f';
            app.PresentZPosPanel = uipanel(app.RPASContaminationQualPanel);
            app.PresentZPosPanel.Title = 'Present Z Pos';
            app.PresentZPos = uieditfield(app.PresentZPosPanel, 'numeric');
            app.PresentZPos.ValueDisplayFormat = '%.2f';
            app.PresentZPos.Editable = 'off';
            app.mmLabel_8 = uilabel(app.PresentZPosPanel);
            app.mmLabel_8.Text = 'mm';
            app.PresentYPosPanel = uipanel(app.RPASContaminationQualPanel);
            app.PresentYPosPanel.Title = 'Present Y Pos';
            app.PresentYPos = uieditfield(app.PresentYPosPanel, 'numeric');
            app.PresentYPos.ValueDisplayFormat = '%.2f';
            app.PresentYPos.Editable = 'off';
            app.mmLabel_9 = uilabel(app.PresentYPosPanel);
            app.mmLabel_9.Text = 'mm';
            app.ImageCapturePanel = uipanel(app.RPASContaminationQualPanel);
            app.ImageCapturePanel.Title = 'Image Capture';
            app.WDSwitch = uiswitch(app.ImageCapturePanel, 'slider');
            app.WDSwitch.Items = {'SWD', 'LWD'};
            app.WDSwitch.ItemsData = [1 0];
            app.WDSwitch.ValueChangedFcn = createCallbackFcn(app, @WDSwitchValueChanged, true);
            app.WDSwitch.Value = 1;
            app.ShotModeSwitch = uiswitch(app.ImageCapturePanel, 'slider');
            app.ShotModeSwitch.Items = {'Single Shot', 'Cont.'};
            app.ShotModeSwitch.ItemsData = [1 0];
            app.ShotModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ShotModeSwitchValueChanged, true);
            app.ShotModeSwitch.Value = 1;
            app.ResultsPanel = uipanel(app.RPASContaminationQualPanel);
            app.ResultsPanel.Title = ' Results';
            app.FoundLabel = uilabel(app.ResultsPanel);
            app.FoundLabel.Text = 'Found';
            app.MaxAllowableLabel = uilabel(app.ResultsPanel);
            app.MaxAllowableLabel.Text = 'Max Allowable';
            app.PassFailLabel = uilabel(app.ResultsPanel);
            app.PassFailLabel.Text = 'Pass/Fail';
            app.FoundBlob = uieditfield(app.ResultsPanel, 'numeric');
            app.FoundBlob.ValueDisplayFormat = '%d';
            app.FoundBlob.Editable = 'off';
            app.QualPass = uilamp(app.ResultsPanel);
            app.NoofDefectsLabel = uilabel(app.ResultsPanel);
            app.NoofDefectsLabel.Text = 'No of Defects';
            app.AllowableNumber = uieditfield(app.ResultsPanel, 'numeric');
            app.AllowableNumber.ValueDisplayFormat = '%d';
            app.AllowableNumber.Editable = 'off';
            app.AllowableSize = uieditfield(app.ResultsPanel, 'numeric');
            app.AllowableSize.Editable = 'off';
            app.MaxAllowableSingleDefectSizeareamathrmum2Label = uilabel(app.ResultsPanel);
            app.MaxAllowableSingleDefectSizeareamathrmum2Label.Interpreter = 'latex';
            app.MaxAllowableSingleDefectSizeareamathrmum2Label.Text = {'Max Allowable Single'; 'Defect Size (area $\mathrm{um}^2$)'; ''};
            app.PauseResumeButton = uibutton(app.RPASContaminationQualPanel, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause/Resume';
            app.AbortTestButton = uibutton(app.RPASContaminationQualPanel, 'push');
            app.AbortTestButton.ButtonPushedFcn = createCallbackFcn(app, @AbortTestButtonPushed, true);
            app.AbortTestButton.Text = 'Abort Test';
            app.BacktoMasterQualScreenButton = uibutton(app.RPASContaminationQualPanel, 'push');
            app.BacktoMasterQualScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMasterQualScreenButtonPushed, true);
            app.BacktoMasterQualScreenButton.Text = {'Back to Master'; 'Qual Screen'; ''};
            app.TravelLimitLabel = uilabel(app.RPASContaminationQualPanel);
            app.TravelLimitLabel.Text = 'Travel Limit';
            app.XLamp_2Label = uilabel(app.RPASContaminationQualPanel);
            app.XLamp_2Label.Text = 'X';
            app.XLamp = uilamp(app.RPASContaminationQualPanel);
            app.YLamp_2Label = uilabel(app.RPASContaminationQualPanel);
            app.YLamp_2Label.Text = 'Y';
            app.YLamp = uilamp(app.RPASContaminationQualPanel);
            app.ZLamp_2Label = uilabel(app.RPASContaminationQualPanel);
            app.ZLamp_2Label.Text = 'Z';
            app.ZLamp = uilamp(app.RPASContaminationQualPanel);
            app.PerformTestButton = uibutton(app.RPASContaminationQualPanel, 'push');
            app.PerformTestButton.ButtonPushedFcn = createCallbackFcn(app, @PerformTestButtonPushed, true);
            app.PerformTestButton.Text = 'Perform Test';
            app.DisplayResultButton = uibutton(app.RPASContaminationQualPanel, 'push');
            app.DisplayResultButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayResultButtonPushed, true);
            app.DisplayResultButton.Text = 'Display Result';
            app.RunningStatusTextAreaLabel = uilabel(app.RPASContaminationQualPanel);
            app.RunningStatusTextAreaLabel.Text = {'Running'; 'Status'};
            app.statusArea = uitextarea(app.RPASContaminationQualPanel);
            % Show the figure after all components are created
            app.RPASDetectorContaminationQualificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_ContamQual(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASDetectorContaminationQualificationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASDetectorContaminationQualificationUIFigure)
        end
    end
end