classdef RPAS_FocusPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FocusResultsUIFigure       matlab.ui.Figure
        CloseWindowButton          matlab.ui.control.Button
        YPanel                     matlab.ui.container.Panel
        PassLamp_HWHM_Y            matlab.ui.control.Lamp
        PassLamp_Amp_Y             matlab.ui.control.Lamp
        PassFailLabel_2            matlab.ui.control.Label
        Yum1Label_2                matlab.ui.control.Label
        YCenterAmpEditField        matlab.ui.control.NumericEditField
        CenterAmpEditFieldLabel_2  matlab.ui.control.Label
        YHWHMEditField             matlab.ui.control.NumericEditField
        HWHMEditFieldLabel_2       matlab.ui.control.Label
        Yum1Label                  matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        YAxes                      matlab.ui.control.UIAxes
        XPanel                     matlab.ui.container.Panel
        PassLamp_HWHM_X            matlab.ui.control.Lamp
        PassLamp_Amp_X             matlab.ui.control.Lamp
        PassFailLabel              matlab.ui.control.Label
        Yum1Label_3                matlab.ui.control.Label
        XCenterAmpEditField        matlab.ui.control.NumericEditField
        CenterAmpEditFieldLabel    matlab.ui.control.Label
        XHWHMEditField             matlab.ui.control.NumericEditField
        HWHMEditFieldLabel         matlab.ui.control.Label
        Xum1Label                  matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        XAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FailColor=[0.6, 0, 0];
        PassColor=[0, 1, 0.5];
    end
    
    methods (Access = public)
        
        function close(app)
            app.CloseWindowPushed();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, results, err)
            if nargin>1
                % XAxes plot
                plot(app.XAxes,...
                    results.frequency_x, results.mtf_x, '-b', ...
                    [0, results.HWHM_x], [50, 50], '--r', ...
                    'LineWidth', 2);
                
                xlim=round(1.1*max(results.frequency_x),3);
                ylim=max(results.mtf_x)*1.1;
                
                app.XAxes.XLim=[0, xlim];
                app.XAxes.YLim=[0, ylim];
                xaxis='Spatial Freq ($\mathrm{um}^{-1}$)';
    
                %move y-label
                ylh=app.XAxes.YLabel;
                ylh.Position(2) = ylh.Position(2)+35;
                
                text(.45,-0.22,xaxis,'horiz','center', 'vert','bottom', ...
                    'unit',' normalized', 'Interpreter', 'latex', 'Parent', app.XAxes);
                
                %ticks
                xtik=[0, round(max(results.frequency_x)/3,3), ...
                        round(max(results.frequency_x)*2/3,3), ...
                        max(results.frequency_x)];
                ytik=[0, 25, 50, 75, 100];
                ytik_label={'0', '25', 'HWHM', '75', '100'};
                app.XAxes.XTick=xtik;
                app.XAxes.YTick=ytik;
                app.XAxes.YTickLabel=ytik_label;
                
                % Label HWHM field value
                app.XHWHMEditField.Value=results.HWHM_x;
                app.XCenterAmpEditField.Value=results.centerAmp_x;
                
                % YAxes plot
                plot(app.YAxes,...
                    results.frequency_y, results.mtf_y, '-b', ...
                    [0, results.HWHM_y], [50, 50], '--r', ...
                    'LineWidth', 2);
                
                xlim=round(1.1*max(results.frequency_y),3);
                ylim=max(results.mtf_y)*1.1;
                app.YAxes.XLim=[0, xlim];
                app.YAxes.YLim=[0, ylim];
                
                text(.45,-0.22,xaxis,'horiz','center', 'vert','bottom', ...
                    'unit',' normalized', 'Interpreter', 'latex', 'Parent', app.YAxes);
                
                %move y-label
                ylh=app.YAxes.YLabel;
                ylh.Position(2) = ylh.Position(2)+35;
                
                %ticks
                xtik=[0, round(max(results.frequency_y)/3, 3), ...
                        round(max(results.frequency_y)*2/3,3), ...
                        max(results.frequency_y)];
                ytik=[0, 25, 50, 75, 100];
                ytik_label={'0', '25', 'HWHM', '75', '100'};
                
                app.YAxes.XTick=xtik;
                app.YAxes.YTick=ytik;
                app.YAxes.YTickLabel=ytik_label;
                
                % Label HWHM field value
                app.YHWHMEditField.Value=results.HWHM_y;
                app.YCenterAmpEditField.Value=results.centerAmp_y;
            
                % Tooltips
                str=sprintf('Fail if Center Amp < %5.2f%%', err.Amp_X);
                app.PassLamp_Amp_X.Tooltip=str;
                if app.XCenterAmpEditField.Value< err.Amp_X
                    app.PassLamp_Amp_X.Color=app.FailColor;
                else
                    app.PassLamp_Amp_X.Color=app.PassColor;
                end
                
                str=sprintf('Fail if Center Amp < %5.2f%%', err.Amp_Y);
                app.PassLamp_Amp_Y.Tooltip=str;
                if app.YCenterAmpEditField.Value< err.Amp_Y
                    app.PassLamp_Amp_Y.Color=app.FailColor;
                else
                    app.PassLamp_Amp_Y.Color=app.PassColor;
                end
                
                str=sprintf('Fail if HWHM > %5.2fum^{-1}', err.HWHM_X);
                app.PassLamp_HWHM_X.Tooltip=str;
                if app.XHWHMEditField.Value> err.HWHM_X
                    app.PassLamp_HWHM_X.Color=app.FailColor;
                else
                    app.PassLamp_HWHM_X.Color=app.PassColor;
                end
                
                str=sprintf('Fail if HWHM > %5.2f%%', err.HWHM_Y);
                app.PassLamp_HWHM_Y.Tooltip=str;
                if app.YHWHMEditField.Value> err.HWHM_Y
                    app.PassLamp_HWHM_Y.Color=app.FailColor;
                else
                    app.PassLamp_HWHM_Y.Color=app.PassColor;
                end
            end
        end

        % Button pushed function: CloseWindowButton
        function CloseWindowPushed(app, event)
            delete(app.FocusResultsUIFigure);
        end

        % Close request function: FocusResultsUIFigure
        function CloseWindowRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FocusResultsUIFigure = uifigure('Visible', 'off');
            app.FocusResultsUIFigure.Name = 'RPAS Focus (MTF) Results';
            app.FocusResultsUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseWindowRequest, true);
            app.XPanel = uipanel(app.FocusResultsUIFigure);
            app.XAxes = uiaxes(app.XPanel);
            ylabel(app.XAxes, 'Amp (%)')
            zlabel(app.XAxes, 'Z')
            app.XLabel = uilabel(app.XPanel);
            app.XLabel.Text = 'X';
            app.Xum1Label = uilabel(app.XPanel);
            app.Xum1Label.Interpreter = 'latex';
            app.Xum1Label.Text = '$\mathrm{um}^{-1}$';
            app.HWHMEditFieldLabel = uilabel(app.XPanel);
            app.HWHMEditFieldLabel.Text = 'HWHM';
            app.XHWHMEditField = uieditfield(app.XPanel, 'numeric');
            app.XHWHMEditField.ValueDisplayFormat = '%5.2f';
            app.XHWHMEditField.Editable = 'off';
            app.CenterAmpEditFieldLabel = uilabel(app.XPanel);
            app.CenterAmpEditFieldLabel.Text = 'Center Amp';
            app.XCenterAmpEditField = uieditfield(app.XPanel, 'numeric');
            app.XCenterAmpEditField.ValueDisplayFormat = '%5.2f';
            app.Yum1Label_3 = uilabel(app.XPanel);
            app.Yum1Label_3.Interpreter = 'latex';
            app.Yum1Label_3.Text = '\%';
            app.PassFailLabel = uilabel(app.XPanel);
            app.PassFailLabel.Text = 'Pass/Fail';
            app.PassLamp_Amp_X = uilamp(app.XPanel);
            app.PassLamp_HWHM_X = uilamp(app.XPanel);
            app.YPanel = uipanel(app.FocusResultsUIFigure);
            app.YAxes = uiaxes(app.YPanel);
            ylabel(app.YAxes, 'Amp')
            zlabel(app.YAxes, 'Z')
            app.YLabel = uilabel(app.YPanel);
            app.YLabel.Text = 'Y';
            app.Yum1Label = uilabel(app.YPanel);
            app.Yum1Label.Interpreter = 'latex';
            app.Yum1Label.Text = '$\mathrm{um}^{-1}$';
            app.HWHMEditFieldLabel_2 = uilabel(app.YPanel);
            app.HWHMEditFieldLabel_2.Text = 'HWHM';
            app.YHWHMEditField = uieditfield(app.YPanel, 'numeric');
            app.YHWHMEditField.ValueDisplayFormat = '%5.2f';
            app.YHWHMEditField.Editable = 'off';
            app.CenterAmpEditFieldLabel_2 = uilabel(app.YPanel);
            app.CenterAmpEditFieldLabel_2.Text = 'Center Amp';
            app.YCenterAmpEditField = uieditfield(app.YPanel, 'numeric');
            app.YCenterAmpEditField.ValueDisplayFormat = '%5.2f';
            app.Yum1Label_2 = uilabel(app.YPanel);
            app.Yum1Label_2.Interpreter = 'latex';
            app.Yum1Label_2.Text = '\%';
            app.PassFailLabel_2 = uilabel(app.YPanel);
            app.PassFailLabel_2.Text = 'Pass/Fail';
            app.PassLamp_Amp_Y = uilamp(app.YPanel);
            app.PassLamp_HWHM_Y = uilamp(app.YPanel);
            app.CloseWindowButton = uibutton(app.FocusResultsUIFigure, 'push');
            app.CloseWindowButton.ButtonPushedFcn = createCallbackFcn(app, @CloseWindowPushed, true);
            app.CloseWindowButton.Text = 'Close Window';
            % Show the figure after all components are created
            app.FocusResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_FocusPlot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FocusResultsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FocusResultsUIFigure)
        end
    end
end