classdef RPAS_Magnification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASMagnificationFocusQualificationUIFigure  matlab.ui.Figure
        RPASMagnificationFocusQualPanel  matlab.ui.container.Panel
        ZLamp                         matlab.ui.control.Lamp
        ZLamp_2Label                  matlab.ui.control.Label
        YLamp                         matlab.ui.control.Lamp
        YLamp_2Label                  matlab.ui.control.Label
        XLamp                         matlab.ui.control.Lamp
        XLamp_2Label                  matlab.ui.control.Label
        TravelLimitLabel_2            matlab.ui.control.Label
        BacktoMasterQualScreenButton  matlab.ui.control.Button
        AbortTestButton               matlab.ui.control.Button
        PauseResumeButton             matlab.ui.control.Button
        ResultsPanel                  matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        MTFYLabel                     matlab.ui.control.Label
        MTFXLabel                     matlab.ui.control.Label
        GotoMTFResultsButton          matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        CenterAmpTarget_Y             matlab.ui.control.NumericEditField
        CenterAmpLabel_3              matlab.ui.control.Label
        HWHMTarget_Y                  matlab.ui.control.NumericEditField
        HWHMum1Label_2                matlab.ui.control.Label
        HWHMError_Y                   matlab.ui.control.NumericEditField
        HWHMCalculated_Y              matlab.ui.control.NumericEditField
        CenterAmpError_Y              matlab.ui.control.NumericEditField
        CenterAmpCalculated_Y         matlab.ui.control.NumericEditField
        CenterAmpPass_Y               matlab.ui.control.Lamp
        HWHMPass_Y                    matlab.ui.control.Lamp
        MagTarget                     matlab.ui.control.NumericEditField
        MagnificationLabel            matlab.ui.control.Label
        MagError                      matlab.ui.control.NumericEditField
        MagPass                       matlab.ui.control.Lamp
        MagCalculated                 matlab.ui.control.NumericEditField
        Panel                         matlab.ui.container.Panel
        HWHMTarget_X                  matlab.ui.control.NumericEditField
        HWHMum1Label                  matlab.ui.control.Label
        HWHMError_X                   matlab.ui.control.NumericEditField
        HWHMCalculated_X              matlab.ui.control.NumericEditField
        CenterAmpError_X              matlab.ui.control.NumericEditField
        CenterAmpCalculated_X         matlab.ui.control.NumericEditField
        CenterAmpPass_X               matlab.ui.control.Lamp
        HWHMPass_X                    matlab.ui.control.Lamp
        CenterAmpTarget_X             matlab.ui.control.NumericEditField
        CenterAmpLabel_2              matlab.ui.control.Label
        ErrorLabel                    matlab.ui.control.Label
        PassFailLabel                 matlab.ui.control.Label
        TargetValLabel                matlab.ui.control.Label
        CalculatedLabel               matlab.ui.control.Label
        StartMagFocusQualButton       matlab.ui.control.Button
        ImageCapturePanel             matlab.ui.container.Panel
        CaptureImageButton            matlab.ui.control.Button
        WDSwitch                      matlab.ui.control.Switch
        PresentYPosPanel              matlab.ui.container.Panel
        mmLabel_9                     matlab.ui.control.Label
        PresentYPos                   matlab.ui.control.NumericEditField
        PresentZPosPanel              matlab.ui.container.Panel
        mmLabel_8                     matlab.ui.control.Label
        PresentZPos                   matlab.ui.control.NumericEditField
        ReticlePositionPanel          matlab.ui.container.Panel
        GoToZRangeBottomLimitButton   matlab.ui.control.Button
        GoToZRangeTopLimitButton      matlab.ui.control.Button
        RelativeXMovePanel            matlab.ui.container.Panel
        Rel_X                         matlab.ui.control.NumericEditField
        Xminus                        matlab.ui.control.Button
        mmLabel_15                    matlab.ui.control.Label
        Xplus                         matlab.ui.control.Button
        AbsoluteXMovePanel            matlab.ui.container.Panel
        Abs_X                         matlab.ui.control.NumericEditField
        mmLabel_14                    matlab.ui.control.Label
        XStart                        matlab.ui.control.Button
        RelativeYMovePanel            matlab.ui.container.Panel
        Rel_Y                         matlab.ui.control.NumericEditField
        Yminus                        matlab.ui.control.Button
        Yplus                         matlab.ui.control.Button
        mmLabel_13                    matlab.ui.control.Label
        AbsoluteYMovePanel            matlab.ui.container.Panel
        Abs_Y                         matlab.ui.control.NumericEditField
        mmLabel_12                    matlab.ui.control.Label
        YStart                        matlab.ui.control.Button
        RelativeZMovePanel            matlab.ui.container.Panel
        Rel_Z                         matlab.ui.control.NumericEditField
        Zminus                        matlab.ui.control.Button
        Zplus                         matlab.ui.control.Button
        mmLabel_11                    matlab.ui.control.Label
        AbsoluteZMovePanel            matlab.ui.container.Panel
        Abs_Z                         matlab.ui.control.NumericEditField
        ZStart                        matlab.ui.control.Button
        mmLabel_10                    matlab.ui.control.Label
        GoToSWDXcYcZnomButton         matlab.ui.control.Button
        GoToLWDXcYcZnomButton         matlab.ui.control.Button
        GoToPARKButton                matlab.ui.control.Button
        PresentXPosPanel              matlab.ui.container.Panel
        mmLabel_7                     matlab.ui.control.Label
        PresentXPos                   matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        FailColor=[0.6, 0, 0];
        PassColor=[0, 1, 0.5];
        xupdater  % timer to query present x position
        yupdater % timer to query present y position
        zupdater % timer to query present z position
        
        systemVar; % variable hold system configurations
        manualQualMethod=1;
        autoQualMethod = 0;

        manualSpeed;
        autoSpeed;
        movingSpeed;

        qualMethod;

        Park_X;
        Park_Y;
        Park_Z;
        SWD_Xc;
        SWD_Yc;
        SWD_Znorm;
        LWD_Xc;
        LWD_Yc;
        LWD_Znorm;
        
        Xlimit_plus;
        Xlimit_minus;
        Ylimit_plus;
        Ylimit_minus;
        Zlimit_plus;
        Zlimit_minus;
        
        capturedImg; % Description
        magResult;
        MagAllowablError; % Description
        
        % variable flag for pausing
        pauseFlag; % Description
        previousStatus;
        isFrozen;
        focusPlotHandle; % Description

         % variables used to bookmark the aborting status;
        register;
        outerAbortFlag;
    end
    properties (Access=public)
        motionStatus;
    end
    
    methods (Access = public)
        
        function init(app)
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables; %read in system setup
            
            var=app.systemVar.motionSetupVar;
            app.Park_X=var.XPark;
            app.Park_Y=var.YPark;
            app.Park_Z=var.ZPark;
            app.Xlimit_plus = var.Xlimit_Plus;
            app.Xlimit_minus = var.Xlimit_Minus;
            app.Ylimit_plus = var.Ylimit_Plus;
            app.Ylimit_minus = var.Ylimit_Minus;
            app.Zlimit_plus = var.Zlimit_Plus;
            app.Zlimit_minus = var.Zlimit_Minus;
            
            var=app.systemVar.toolCalibrationVar;
            app.SWD_Xc = var.SWD_Xc;
            app.SWD_Yc = var.SWD_Yc;
            app.LWD_Xc = var.LWD_Xc;
            app.LWD_Yc = var.LWD_Yc;
            
            var=app.systemVar.engineeringPar1Var;
            app.SWD_Znorm = var.SWDNominalZ;
            app.LWD_Znorm = var.LWDNominalZ;


            app.manualSpeed=var.ManualMotionSpeed;
            app.autoSpeed = var.AutoQualScanSpeed;
            app.movingSpeed = app.manualSpeed;
            app.qualMethod = app.manualQualMethod;
            
            %fill in Target values
            app.MagAllowablError=var.MagErrorLimit;
            app.MagTarget.Value=var.NominalRPASImageMagnification;
            app.CenterAmpTarget_X.Value=var.MinAllowableMTFCenterAmp_X;
            app.HWHMTarget_X.Value = var.MaxAllowableMTFHWHM_X;
            app.CenterAmpTarget_Y.Value=var.MinAllowableMTFCenterAmp_Y;
            app.HWHMTarget_Y.Value = var.MaxAllowableMTFHWHM_Y;

            app.MagCalculated.Value=0;
            app.MagError.Value=0;
            app.CenterAmpCalculated_X.Value=0;
            app.CenterAmpError_X.Value=0;
            app.HWHMCalculated_X.Value=0;
            app.HWHMError_X.Value=0;
            app.CenterAmpCalculated_Y.Value=0;
            app.CenterAmpError_Y.Value=0;
            app.HWHMCalculated_Y.Value=0;
            app.HWHMError_Y.Value=0;

            app.Rel_X.Value=0;
            app.Abs_X.Value=0;
            app.Rel_Y.Value=0;
            app.Abs_Y.Value=0;
            app.Rel_Z.Value=0;
            app.Abs_Z.Value=0;
            if ~isempty(app.focusPlotHandle) & isvalid(app.focusPlotHandle)
                app.focusPlotHandle.close();
            end
            
            %initialize lamp color
            app.XLamp.Color=app.UnderLimitColor;
            app.YLamp.Color=app.UnderLimitColor;
            app.ZLamp.Color=app.UnderLimitColor;
            app.MagPass.Color=app.PassColor;
            app.CenterAmpPass_X.Color=app.PassColor;
            app.HWHMPass_X.Color=app.PassColor;
            app.CenterAmpPass_Y.Color=app.PassColor;
            app.HWHMPass_Y.Color=app.PassColor;


            str=[];
            str{1}='Green only if Present X Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Xlimit_minus, app.Xlimit_plus);
            app.XLamp.Tooltip=str;

            str{1}='Green only if Present Y Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Ylimit_minus, app.Ylimit_plus);
            app.YLamp.Tooltip=str;

            str{1}='Green only if Present Z Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Zlimit_minus, app.Zlimit_plus);
            app.ZLamp.Tooltip=str;
            
            str=sprintf('Fail if abs(Error) > %5.2f%%', app.MagAllowablError);
            app.MagPass.Tooltip=str;
            
            %disable buttons
            app.StartMagFocusQualButton.Enable=false;
            app.GotoMTFResultsButton.Enable = false;
            
            
            %initialize pausing related variables
            app.pauseFlag=false;
            app.isFrozen=false;

            if isempty(app.register) 
                app.register=processRegister(app, 0.5);
            elseif isvalid(app.register)
                app.register.reset();
            else
                app.register=processRegister(app, 0.5);
            end

            app.outerAbortFlag=false;

            app.previousStatus.StartMagFocusQualButtonValue= ...
                    app.StartMagFocusQualButton.Enable;
            
            app.previousStatus.GotoMTFResultsButtonValue = ...
                    app.GotoMTFResultsButton.Enable;

                
            app.xupdater=updatePosition(app.PresentXPos, 0, .05, ...
                {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater=updatePosition(app.PresentYPos, 1, .05, ...
                {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater=updatePosition(app.PresentZPos, 2, .05, ...
                {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1)
            app.stopTimer();


             if app.PresentXPos.Value==app.LWD_Xc && ...
                     app.PresentYPos.Value==app.LWD_Yc && ...
                     app.PresentZPos.Value==app.LWD_Znorm
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
             else
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
             end
        end
        
        function stopTimer(app)
            app.xupdater.stop();
            app.yupdater.stop();
            app.zupdater.stop();
        end
    end

    methods (Access = private)
        
        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, Lamp)
            try
                val=round(val*1.0e3)*1.0e-3;
                upperLimit=round(upperLimit*1.0e6)*1.0e-6;
                lowerLimit=round(lowerLimit*1.0e6)*1.0e-6;
                c=Lamp.Color;
                warning=false;
                Lamp.Color=app.UnderLimitColor;

                if val>upperLimit || val<lowerLimit
                    Lamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
            Lamp.Color=c;
        end
        
        function [img, name] = captureImage(app, cameraSource)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            if RPAS_C.CameraAvailable
                img = RPAS_ImageAcquisition_7dot1(cameraSource, 1);
                if cameraSource==1 % SWD
                    name='SWD';
                else
                    name='LWD';
                end
            else
                global kindex;

                path=[RPAS_C.QUAL_IMAGE_DIR '/AlignMagImages/'];
                files={'450µm-2.5b-off-Focus-adj-noise-640.bmp', ...
                    '450µm-2.5b-off-Focus-adj-noise-640-1.05.bmp', ...
                    '850µm-7b-off-Focus-adj-noise-640.bmp', ...
                    'InFocus-2° rotated.bmp', ...
                    'InFocus-adj-noise-640-0.4°.bmp', ...
                    'InFocusdj+noise-1.02.bmp', ...
                    'InFocus-FFT-of-final02-640-2adjnois-ot-0.95.bmp'};
%                 files={'Inverse FFT of final021024-2adj+noise90°-1.tif', ...
%                     'Inverse FFT of final021024-2adj+noise87°-1.bmp', ...
%                     'Inverse FFT of final021024-2adj+noise93°-1.bmp'};


                if isempty(kindex)
                    kindex=1;
                else
                    kindex=mod(kindex,numel(files))+1;
                end

                fnm=[path  files{kindex} ];
                img=imread(fnm);
                name=files{kindex};
            end
            if app.qualMethod==app.manualQualMethod

                path=[RPAS_C.QUAL_RESULT_DIR '/Manual_Magnification'];
                RPAS_Make_folder(path);
    
                dt=datetime();
                datetimestring=datestr(dt, 'mmm_dd_yyyy_HH_MM_SS');
                if cameraSource==1 % SWD
                    fnm=[path '/SWD_DateTime=' datetimestring '.png'];
                else
                    fnm=[path '/LWD_DateTime=' datetimestring '.png'];
                end
            
                imwrite(img,fnm);
            end
                
        end
        
        function displayImage(app, img)
            app.UIAxes.XLim=[0, size(img,2)];
            app.UIAxes.YLim=[0, size(img,1)];
            tik=0:100:600;
            
            
            R=imref2d(size(img));
            imshow(img, R,'Parent', app.UIAxes);
            
            drawnow;
        end
        
%         function app.checkPause(app)
%             while app.pauseFlag
%                 pause(0.1);
%             end
%         end
        
        function freeze(app, val)
            val=~val;

            app.WDSwitch.Enable=val;
            app.CaptureImageButton.Enable=val;
            
            app.Xplus.Enable=val;
            app.Xminus.Enable=val;
            app.XStart.Enable=val;
            
            app.Yplus.Enable=val;
            app.Yminus.Enable=val;
            app.YStart.Enable=val;
            
            app.Zplus.Enable=val;
            app.Zminus.Enable=val;
            app.ZStart.Enable=val;
            
            app.GoToLWDXcYcZnomButton.Enable=val;
            app.GoToSWDXcYcZnomButton.Enable=val;
            app.GoToPARKButton.Enable=val;
            app.GoToZRangeTopLimitButton.Enable=val;
            app.GoToZRangeBottomLimitButton.Enable=val;
            
            app.StartMagFocusQualButton.Enable=val;
            app.BacktoMasterQualScreenButton.Enable=val;
            
            if val
                app.StartMagFocusQualButton.Enable = ...
                    app.previousStatus.StartMagFocusQualButtonValue;
                app.GotoMTFResultsButton.Enable= ...
                    app.previousStatus.GotoMTFResultsButtonValue;

            else
                app.previousStatus.StartMagFocusQualButtonValue= ...
                    app.StartMagFocusQualButton.Enable;
                app.StartMagFocusQualButton.Enable=val;
            
                app.previousStatus.GotoMTFResultsButtonValue = ...
                    app.GotoMTFResultsButton.Enable;
                app.GotoMTFResultsButton.Enable=val;

            end
            
            app.Rel_X.Enable=val;
            app.Abs_X.Enable=val;
            
            app.Rel_Y.Enable=val;
            app.Abs_Y.Enable=val;
            
            app.Rel_Z.Enable=val;
            app.Abs_Z.Enable=val;
        end
        
        function checkPause(app)
            while app.pauseFlag
                pause(0.1);
            end
        end
        
        function resetPFLamps(app)
            app.MagPass.Color=app.PassColor;
            app.CenterAmpPass_X.Color=app.PassColor;
            app.HWHMPass_X.Color=app.PassColor;
            app.CenterAmpPass_Y.Color=app.PassColor;
            app.HWHMPass_Y.Color=app.PassColor;
        end
        
        function settings=recordSettings(app)
            settings=[];
            
            %properties
            %settings.xupdater =app.xupdater; % timer to query present x position
            %settings.yupdater =app.yupdater;% timer to query present y position
            %settings.zupdater =app.zupdater;% timer to query present z position
            
            %settings.systemVar=app.systemVar; % variable hold system configurations
            settings.manualQualMethod=app.manualQualMethod;
            settings.autoQualMethod =app.autoQualMethod;
    
            settings.manualSpeed=app.manualSpeed;
            settings.autoSpeed=app.autoSpeed;
            settings.movingSpeed=app.movingSpeed;

            settings.qualMethod=app.qualMethod;
    
            settings.Park_X=app.Park_X;
            settings.Park_Y=app.Park_Y;
            settings.Park_Z=app.Park_Z;
            settings.SWD_Xc=app.SWD_Xc;
            settings.SWD_Yc=app.SWD_Yc;
            settings.SWD_Znorm=app.SWD_Znorm;
            settings.LWD_Xc=app.LWD_Xc;
            settings.LWD_Yc=app.LWD_Yc;
            settings.LWD_Znorm=app.LWD_Znorm;
            
            settings.Xlimit_plus=app.Xlimit_plus;
            settings.Xlimit_minus=app.Xlimit_minus;
            settings.Ylimit_plus=app.Ylimit_plus;
            settings.Ylimit_minus=app.Ylimit_minus;
            settings.Zlimit_plus=app.Zlimit_plus;
            settings.Zlimit_minus=app.Zlimit_minus;
        
            settings.capturedImg=app.capturedImg; % Description
            settings.magResult=app.magResult;
            settings.MagAllowablError=app.MagAllowablError; % Description
            
            % variable flag for pausing
            settings.pauseFlag=app.pauseFlag; % Description
            settings.previousStatus=app.previousStatus;
            settings.isFrozen=app.isFrozen;
            settings.focusPlotHandle=app.focusPlotHandle;

            % variaable flag for aborting
            settings.register = app.register;
            settings.outerAbortFlag = app.outerAbortFlag;

            %components
            settings.WDSwitch.Value = app.WDSwitch.Value;
            settings.WDSwitch.Enable = app.WDSwitch.Enable;
            
            settings.CaptureImageButton.Enable=app.CaptureImageButton.Enable;
            
            settings.Rel_X.Value=app.Rel_X.Value;
            settings.Rel_X.Enable=app.Rel_X.Enable;
            
            settings.Abs_X.Value=app.Abs_X.Value;
            settings.Abs_X.Enable=app.Abs_X.Enable;
        
            settings.Rel_Y.Value=app.Rel_Y.Value;
            settings.Rel_Y.Enable=app.Rel_Y.Enable;
            
            settings.Abs_Y.Value=app.Abs_Y.Value;
            settings.Abs_Y.Enable=app.Abs_Y.Enable;
            
            settings.Rel_Z.Value=app.Rel_Z.Value;
            settings.Rel_Z.Enable=app.Rel_Z.Enable;
            
            settings.Abs_Z.Value=app.Abs_Z.Value;
            settings.Abs_Z.Enable=app.Abs_Z.Enable;
            
            settings.Xplus.Enable=app.Xplus.Enable;
            
            settings.Xminus.Enable=app.Xminus.Enable;
            
            settings.XStart.Enable=app.XStart.Enable;
        
            settings.Yplus.Enable=app.Yplus.Enable;
            
            settings.Yminus.Enable=app.Yminus.Enable;
            
            settings.YStart.Enable=app.YStart.Enable;
            
            settings.Zplus.Enable=app.Zplus.Enable;
            
            settings.Zminus.Enable=app.Zminus.Enable;
            
            settings.ZStart.Enable=app.ZStart.Enable;
            
            settings.GoToLWDXcYcZnomButton.Enable=app.GoToLWDXcYcZnomButton.Enable;
            settings.GoToPARKButton.Enable=app.GoToPARKButton.Enable;
            settings.GoToSWDXcYcZnomButton.Enable=app.GoToSWDXcYcZnomButton.Enable;
        
            settings.PauseResumeButton.Enable=app.PauseResumeButton.Enable;
            settings.AbortTestButton.Enable=app.AbortTestButton.Enable;
            settings.BacktoMasterQualScreenButton.Enable=app.BacktoMasterQualScreenButton.Enable;
            
            settings.StartMagFocusQualButton.Enable=app.StartMagFocusQualButton.Enable;
            settings.XLamp.Color=app.XLamp.Color;
            settings.YLamp.Color=app.YLamp.Color;
            settings.ZLamp.Color=app.ZLamp.Color;
            
            settings.GotoMTFResultsButton.Enable = app.GotoMTFResultsButton.Enable;
            
            settings.MagTarget.Value=app.MagTarget.Value;
            settings.MagTarget.Enable=app.MagTarget.Enable;
            
            settings.CenterAmpTarget_X.Value=app.CenterAmpTarget_X.Value;
            settings.CenterAmpTarget_X.Enable=app.CenterAmpTarget_X.Enable;
            
            settings.HWHMTarget_X.Value=app.HWHMTarget_X.Value;
            settings.HWHMTarget_X.Enable=app.HWHMTarget_X.Enable;
        
            settings.CenterAmpTarget_Y.Value=app.CenterAmpTarget_Y.Value;
            settings.CenterAmpTarget_Y.Enable=app.CenterAmpTarget_Y.Enable;
            
            settings.HWHMTarget_Y.Value=app.HWHMTarget_Y.Value;
            settings.HWHMTarget_Y.Enable=app.HWHMTarget_Y.Enable;
            
            settings.MagCalculated.Value=app.MagCalculated.Value;
            settings.MagCalculated.Enable=app.MagCalculated.Enable;
            
            settings.CenterAmpCalculated_X.Value=app.CenterAmpCalculated_X.Value;
            settings.CenterAmpCalculated_X.Enable=app.CenterAmpCalculated_X.Enable;
            
            settings.HWHMCalculated_X.Value=app.HWHMCalculated_X.Value;
            settings.HWHMCalculated_X.Enable=app.HWHMCalculated_X.Enable;
            
            settings.CenterAmpCalculated_Y.Value=app.CenterAmpCalculated_Y.Value;
            settings.CenterAmpCalculated_Y.Enable=app.CenterAmpCalculated_Y.Enable;
            
            settings.HWHMCalculated_Y.Value=app.HWHMCalculated_Y.Value;
            settings.HWHMCalculated_Y.Enable=app.HWHMCalculated_Y.Enable;
            
            settings.MagError.Value=app.MagError.Value;
            settings.MagError.Enable=app.MagError.Enable;
            
            settings.CenterAmpError_X.Value=app.CenterAmpError_X.Value;
            settings.CenterAmpError_X.Enable=app.CenterAmpError_X.Enable;
        
            settings.HWHMError_X.Value=app.HWHMError_X.Value;
            settings.HWHMError_X.Enable=app.HWHMError_X.Enable;
            
            settings.CenterAmpError_Y.Value=app.CenterAmpError_Y.Value;
            settings.CenterAmpError_Y.Enable=app.CenterAmpError_Y.Enable;
            
            settings.HWHMError_Y.Value=app.HWHMError_Y.Value;
            settings.HWHMError_Y.Enable=app.HWHMError_Y.Enable;
            
            settings.MagPass.Color=app.MagPass.Color;
            settings.CenterAmpPass_X.Color=app.CenterAmpPass_X.Color;
            settings.HWHMPass_X.Color=app.HWHMPass_X.Color;
            settings.CenterAmpPass_Y.Color=app.CenterAmpPass_Y.Color;
            settings.HWHMPass_Y.Color=app.HWHMPass_Y.Color;
            
            settings.image=getimage(app.UIAxes);
        end
        
        function showInfoMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Info';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'icon', 'info');
            switch selection
                case {'OK'}
                otherwise
            end
            delete(fig);
        end
    end
    
    methods (Access = public)
        
        function performingTest(app, pos)
            % moving to nominal position
            egrvar = app.systemVar.engineeringPar1Var;
            axes=[0, 1, 2];
            ABSMoving(axes, pos, egrvar.ManualMotionSpeed, [app.xupdater, app.yupdater, app.zupdater],app);
            
            % capture image
            app.CaptureImageButtonPushed();
            
            %Magnification/Focus evaluation
            app.StartMagFocusQualButtonPushed();
        end
        
        function freezeAll(app, val)
            app.freeze(val);
            app.PauseResumeButton.Enable=~val;
            app.AbortTestButton.Enable=~val;
            app.isFrozen=val;
        end

        function hide(app, val)
%             if val
%                 app.stopTimer;
%             else
%                 app.xupdater.start();
%                 app.yupdater.start();
%                 app.zupdater.start();
%             end
            app.RPASMagnificationFocusQualificationUIFigure.Visible=~val;
        end
        
        function [results, settings, image]=getResults(app)
            results=app.magResult;
            settings=app.recordSettings();
            image=app.capturedImg;
        end
        
        function reinit(app, dataSheet)

            if strcmp(dataSheet.camera, 'SWD')
                app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
            else
                app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
            end
     
            app.MagTarget.Value = dataSheet.MagTarget;
            app.MagAllowablError=dataSheet.MagErr;
            app.CenterAmpTarget_X.Value=dataSheet.CenterAmpErr_X;
            app.HWHMTarget_X.Value = dataSheet.HWHMErr_X;
            app.CenterAmpTarget_Y.Value=dataSheet.CenterAmpErr_Y;
            app.HWHMTarget_Y.Value = dataSheet.HWHMErr_Y;
            
            app.MagCalculated.Value=0;
            app.MagError.Value=0;
            app.CenterAmpCalculated_X.Value=0;
            app.CenterAmpError_X.Value=0;
            app.HWHMCalculated_X.Value=0;
            app.HWHMError_X.Value=0;
            app.CenterAmpCalculated_Y.Value=0;
            app.CenterAmpError_Y.Value=0;
            app.HWHMCalculated_Y.Value=0;
            app.HWHMError_Y.Value=0;

            app.Rel_X.Value=0;
            app.Abs_X.Value=0;
            app.Rel_Y.Value=0;
            app.Abs_Y.Value=0;
            app.Rel_Z.Value=0;
            app.Abs_Z.Value=0;
            if ~isempty(app.focusPlotHandle) & isvalid(app.focusPlotHandle)
                app.focusPlotHandle.close();
            end
            
            %initialize lamp color
            app.XLamp.Color=app.UnderLimitColor;
            app.YLamp.Color=app.UnderLimitColor;
            app.ZLamp.Color=app.UnderLimitColor;
            app.MagPass.Color=app.PassColor;
            app.CenterAmpPass_X.Color=app.PassColor;
            app.HWHMPass_X.Color=app.PassColor;
            app.CenterAmpPass_Y.Color=app.PassColor;
            app.HWHMPass_Y.Color=app.PassColor;
            
            
            str=sprintf('Fail if abs(Error) > %5.2f%%', app.MagAllowablError);
            app.MagPass.Tooltip=str;

            if isempty(app.register) 
                app.register=processRegister(app, 0.5);
            elseif isvalid(app.register)
                app.register.reset();
            else
                app.register=processRegister(app, 0.5);
            end
        end
        
        function autoQual(app,position)
            %reset previous result
            cla(app.UIAxes);
            app.MagCalculated.Value=0;
            app.CenterAmpCalculated_X.Value=0;
            app.HWHMCalculated_X.Value=0;
            app.CenterAmpCalculated_Y.Value=0;
            app.HWHMCalculated_Y.Value=0;
            app.MagError.Value=0;
            app.CenterAmpError_X.Value=0;
            app.HWHMError_X.Value=0;
            app.CenterAmpError_Y.Value=0;
            app.HWHMError_Y.Value=0;
            
            % moving to required position
            egrvar = app.systemVar.engineeringPar1Var;
            axes=[0, 1, 2];
            ABSMoving(axes, position, egrvar.ManualMotionSpeed, [app.xupdater, app.yupdater, app.zupdater],app);
            
            % capture image
            app.CaptureImageButtonPushed();
            
            %qualification
            app.StartMagFocusQualButtonPushed();
        end
        
        function pausing(app, val)
            app.pauseFlag=val;

            if ~isempty(app.motionStatus)
                if app.motionStatus.isPausing
                    lamps=[];
                    for k=1:numel(app.motionStatus.axes)
                        switch app.motionStatus.axes(k)
                            case {0}
                                lamps=[lamps app.xupdater];
                            case {1}
                                lamps=[lamps app.yupdater];
                            case {2}
                                lamps=[lamps app.zupdater];
                        end
                    end
                    app.motionStatus.isPausing=false;
                    if ~isempty(app.motionStatus.axes)
                        ABSMoving(app.motionStatus.axes, app.motionStatus.movingDistances, ...
                            app.motionStatus.movingSpeeds, lamps, app);
                    end
                else
                    app.motionStatus.isPausing=true;
                    if ~isempty(app.motionStatus.axes)
                        stopMotion([app.xupdater, app.yupdater, app.zupdater], app);
                    end
                end
            end
        end
        
        function fillResult(app, result)
            
            app.MagCalculated.Value=result.Mag;
            app.MagError.Value=result.MagError; %percentage
            if abs(app.MagError.Value)<=app.MagAllowablError
                app.MagPass.Color=app.PassColor;
            else
                app.MagPass.Color=app.FailColor;
            end
            
            app.checkPause();
            %MTF-X
            app.CenterAmpCalculated_X.Value=result.centerAmp_x;
            app.CenterAmpError_X.Value = result.centerAmp_x_Error;
            if app.CenterAmpError_X.Value<0
                app.CenterAmpPass_X.Color=app.FailColor;
            else
                app.CenterAmpPass_X.Color=app.PassColor;
            end

            app.checkPause();
            app.HWHMCalculated_X.Value = result.HWHM_x;
            app.HWHMError_X.Value = result.HWHM_x_Error;
            if app.HWHMError_X.Value>0
                app.HWHMPass_X.Color=app.FailColor;
            else
                app.HWHMPass_X.Color=app.PassColor;
            end
            
            app.checkPause();
            %MTF-Y
            app.CenterAmpCalculated_Y.Value=result.centerAmp_y;
            app.CenterAmpError_Y.Value = result.centerAmp_y_Error;
            if app.CenterAmpError_Y.Value<0
                app.CenterAmpPass_Y.Color=app.FailColor;
            else
                app.CenterAmpPass_Y.Color=app.PassColor;
            end
            
            app.checkPause();
            app.HWHMCalculated_Y.Value = result.HWHM_y;
            app.HWHMError_Y.Value = result.HWHM_y_Error;
            if app.HWHMError_Y.Value>0
                app.HWHMPass_Y.Color=app.FailColor;
            else
                app.HWHMPass_Y.Color=app.PassColor;
            end
        end

        function setAutoQualMethod(app)
            app.qualMethod=app.autoQualMethod;
        end
        
        function setManualQualMethod(app)
            app.qualMethod=app.manualQualMethod;
        end

        function close(app)
            app.RPASMagnificationFocusQualificationUIFigureCloseRequest();
        end
        
        function restoreSettings(app, settings)
            %properties
            %app.xupdater =settings.xupdater; % timer to query present x position
            %app.yupdater =settings.yupdater;% timer to query present y position
            %app.zupdater =settings.zupdater;% timer to query present z position
            
            %app.systemVar=settings.systemVar; % variable hold system configurations
            app.manualQualMethod=settings.manualQualMethod;
            app.autoQualMethod =settings.autoQualMethod;
    
            app.manualSpeed=settings.manualSpeed;
            app.autoSpeed=settings.autoSpeed;
            app.movingSpeed=settings.movingSpeed;

            app.qualMethod=settings.qualMethod;
    
            app.Park_X=settings.Park_X;
            app.Park_Y=settings.Park_Y;
            app.Park_Z=settings.Park_Z;
            app.SWD_Xc=settings.SWD_Xc;
            app.SWD_Yc=settings.SWD_Yc;
            app.SWD_Znorm=settings.SWD_Znorm;
            app.LWD_Xc=settings.LWD_Xc;
            app.LWD_Yc=settings.LWD_Yc;
            app.LWD_Znorm=settings.LWD_Znorm;
            
            app.Xlimit_plus=settings.Xlimit_plus;
            app.Xlimit_minus=settings.Xlimit_minus;
            app.Ylimit_plus=settings.Ylimit_plus;
            app.Ylimit_minus=settings.Ylimit_minus;
            app.Zlimit_plus=settings.Zlimit_plus;
            app.Zlimit_minus=settings.Zlimit_minus;
        
            app.capturedImg=settings.capturedImg; % Description
            app.magResult=settings.magResult;
            app.MagAllowablError=settings.MagAllowablError; % Description
            
            % variable flag for pausing
            app.pauseFlag=settings.pauseFlag; % Description
            app.previousStatus=settings.previousStatus;
            app.isFrozen=settings.isFrozen;
            app.focusPlotHandle=settings.focusPlotHandle;
            
            app.register = settings.register;
            app.outerAbortFlag = settings.outerAbortFlag;

            %components
            app.WDSwitch.Value = settings.WDSwitch.Value;
            app.WDSwitch.Enable = settings.WDSwitch.Enable;
            
            app.CaptureImageButton.Enable=settings.CaptureImageButton.Enable;
            
            app.Rel_X.Value=settings.Rel_X.Value;
            app.Rel_X.Enable=settings.Rel_X.Enable;
            
            app.Abs_X.Value=settings.Abs_X.Value;
            app.Abs_X.Enable=settings.Abs_X.Enable;
        
            app.Rel_Y.Value=settings.Rel_Y.Value;
            app.Rel_Y.Enable=settings.Rel_Y.Enable;
            
            app.Abs_Y.Value=settings.Abs_Y.Value;
            app.Abs_Y.Enable=settings.Abs_Y.Enable;
            
            app.Rel_Z.Value=settings.Rel_Z.Value;
            app.Rel_Z.Enable=settings.Rel_Z.Enable;
            
            app.Abs_Z.Value=settings.Abs_Z.Value;
            app.Abs_Z.Enable=settings.Abs_Z.Enable;
            
            app.Xplus.Enable=settings.Xplus.Enable;
            
            app.Xminus.Enable=settings.Xminus.Enable;
            
            app.XStart.Enable=settings.XStart.Enable;
        
            app.Yplus.Enable=settings.Yplus.Enable;
            
            app.Yminus.Enable=settings.Yminus.Enable;
            
            app.YStart.Enable=settings.YStart.Enable;
            
            app.Zplus.Enable=settings.Zplus.Enable;
            
            app.Zminus.Enable=settings.Zminus.Enable;
            
            app.ZStart.Enable=settings.ZStart.Enable;
            
            app.GoToLWDXcYcZnomButton.Enable=settings.GoToLWDXcYcZnomButton.Enable;
            app.GoToPARKButton.Enable=settings.GoToPARKButton.Enable;
            app.GoToSWDXcYcZnomButton.Enable=settings.GoToSWDXcYcZnomButton.Enable;
        
            app.PauseResumeButton.Enable=settings.PauseResumeButton.Enable;
            app.AbortTestButton.Enable=settings.AbortTestButton.Enable;
            app.BacktoMasterQualScreenButton.Enable=settings.BacktoMasterQualScreenButton.Enable;
            
            app.StartMagFocusQualButton.Enable=settings.StartMagFocusQualButton.Enable;
            app.XLamp.Color=settings.XLamp.Color;
            app.YLamp.Color=settings.YLamp.Color;
            app.ZLamp.Color=settings.ZLamp.Color;
            
            app.GotoMTFResultsButton.Enable = settings.GotoMTFResultsButton.Enable;
            
            app.MagTarget.Value=settings.MagTarget.Value;
            app.MagTarget.Enable=settings.MagTarget.Enable;
            
            app.CenterAmpTarget_X.Value=settings.CenterAmpTarget_X.Value;
            app.CenterAmpTarget_X.Enable=settings.CenterAmpTarget_X.Enable;
            
            app.HWHMTarget_X.Value=settings.HWHMTarget_X.Value;
            app.HWHMTarget_X.Enable=settings.HWHMTarget_X.Enable;
        
            app.CenterAmpTarget_Y.Value=settings.CenterAmpTarget_Y.Value;
            app.CenterAmpTarget_Y.Enable=settings.CenterAmpTarget_Y.Enable;
            
            app.HWHMTarget_Y.Value=settings.HWHMTarget_Y.Value;
            app.HWHMTarget_Y.Enable=settings.HWHMTarget_Y.Enable;
            
            app.MagCalculated.Value=settings.MagCalculated.Value;
            app.MagCalculated.Enable=settings.MagCalculated.Enable;
            
            app.CenterAmpCalculated_X.Value=settings.CenterAmpCalculated_X.Value;
            app.CenterAmpCalculated_X.Enable=settings.CenterAmpCalculated_X.Enable;
            
            app.HWHMCalculated_X.Value=settings.HWHMCalculated_X.Value;
            app.HWHMCalculated_X.Enable=settings.HWHMCalculated_X.Enable;
            
            app.CenterAmpCalculated_Y.Value=settings.CenterAmpCalculated_Y.Value;
            app.CenterAmpCalculated_Y.Enable=settings.CenterAmpCalculated_Y.Enable;
            
            app.HWHMCalculated_Y.Value=settings.HWHMCalculated_Y.Value;
            app.HWHMCalculated_Y.Enable=settings.HWHMCalculated_Y.Enable;
            
            app.MagError.Value=settings.MagError.Value;
            app.MagError.Enable=settings.MagError.Enable;
            
            app.CenterAmpError_X.Value=settings.CenterAmpError_X.Value;
            app.CenterAmpError_X.Enable=settings.CenterAmpError_X.Enable;
        
            app.HWHMError_X.Value=settings.HWHMError_X.Value;
            app.HWHMError_X.Enable=settings.HWHMError_X.Enable;
            
            app.CenterAmpError_Y.Value=settings.CenterAmpError_Y.Value;
            app.CenterAmpError_Y.Enable=settings.CenterAmpError_Y.Enable;
            
            app.HWHMError_Y.Value=settings.HWHMError_Y.Value;
            app.HWHMError_Y.Enable=settings.HWHMError_Y.Enable;
            
            app.MagPass.Color=settings.MagPass.Color;
            app.CenterAmpPass_X.Color=settings.CenterAmpPass_X.Color;
            app.HWHMPass_X.Color=settings.HWHMPass_X.Color;
            app.CenterAmpPass_Y.Color=settings.CenterAmpPass_Y.Color;
            app.HWHMPass_Y.Color=settings.HWHMPass_Y.Color;
            
            if ~isempty(settings.image)
              app.displayImage(settings.image);
            end
        end

        function abortProgram(app)
            app.AbortTestButtonPushed();
            %app.hide(true);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, settings)
            
            if nargin==2
                app.restoreSettings(settings);
                global systemVar;
                if isempty(systemVar)
                    systemVar=savedVariables; %read in system setup
                end
                app.systemVar=systemVar;
                app.systemVar=savedVariables; %read in system setup
                
                app.xupdater=updatePosition(app.PresentXPos, 0, .3, ...
                    {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
                app.yupdater=updatePosition(app.PresentYPos, 1, .3, ...
                    {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
                app.zupdater=updatePosition(app.PresentZPos, 2, .3, ...
                    {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
            
                app.xupdater.start();
                app.yupdater.start();
                app.zupdater.start();
            else
                app.init();
            end
        end

        % Button pushed function: Xplus
        function XplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end

            val=app.PresentXPos.Value + app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                RelativeMoving(0, app.Rel_X.Value, app.movingSpeed, app.xupdater,app);
            end
        end

        % Button pushed function: Xminus
        function XminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentXPos.Value - app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
               RelativeMoving(0, -app.Rel_X.Value, app.movingSpeed, app.xupdater,app);
            end
        end

        % Button pushed function: Yplus
        function YplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentYPos.Value + app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, app.Rel_Y.Value, app.movingSpeed, app.yupdater,app);
            end
        end

        % Button pushed function: Yminus
        function YminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentYPos.Value - app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, -app.Rel_Y.Value, app.movingSpeed, app.yupdater,app);
            end
        end

        % Button pushed function: Zplus
        function ZplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentZPos.Value + app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
               RelativeMoving(2, app.Rel_Z.Value, app.movingSpeed, app.zupdater,app);
            end
        end

        % Button pushed function: Zminus
        function ZminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentZPos.Value - app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                RelativeMoving(2, -app.Rel_Z.Value, app.movingSpeed, app.zupdater,app);
            end
        end

        % Button pushed function: XStart
        function XStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                ABSMoving(0, app.Abs_X.Value, app.movingSpeed, app.xupdater,app);
            end
        end

        % Button pushed function: YStart
        function YStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                ABSMoving(1, app.Abs_Y.Value, app.movingSpeed, app.yupdater,app);
            end
        end

        % Button pushed function: ZStart
        function ZStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                ABSMoving(2, app.Abs_Z.Value, app.movingSpeed, app.zupdater,app);
            end
        end

        % Button pushed function: GoToLWDXcYcZnomButton
        function GoToLWDXcYcZnomButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.LWD_Xc, app.LWD_Yc, app.LWD_Znorm];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater],app);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
        end

        % Button pushed function: GoToPARKButton
        function GoToPARKButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.Park_X, app.Park_Y, app.Park_Z];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater],app);
        end

        % Button pushed function: GoToSWDXcYcZnomButton
        function GoToSWDXcYcZnomButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.SWD_Xc, app.SWD_Yc, app.SWD_Znorm];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater],app);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            app.resetPFLamps();
            
            % if pause is reuired program will pause util it is resumed
            app.checkPause();
            [app.capturedImg, fnm]= ...
                app.captureImage(app.WDSwitch.Value);
            
            app.checkPause();
            %Enable the button to calculate Mag/Focus
            app.StartMagFocusQualButton.Enable=true;
            app.GotoMTFResultsButton.Enable=false;
            
            app.checkPause();
            %display image
            app.displayImage(app.capturedImg);
        end

        % Button pushed function: StartMagFocusQualButton
        function StartMagFocusQualButtonPushed(app, event)
            % if pause is reuired program will pause util it is resumed
            app.register.addProcess();

            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();

            %compute mag/focus
            app.magResult=RPAS_Mag_Focus_7dot3(app.capturedImg);
            
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();

            %normalize results
            app.magResult.Mag = round(app.magResult.Mag,2);
            app.magResult.MagError= ...
                round(1.0e2*(app.magResult.Mag-app.MagTarget.Value)./ ...
                app.MagTarget.Value,2);
            
            app.magResult.centerAmp_x=round(app.magResult.centerAmp_x,2);
            app.magResult.centerAmp_x_Error = ...
                app.magResult.centerAmp_x-app.CenterAmpTarget_X.Value;
%             app.magResult.HWHM_x = round(app.magResult.HWHM_x,2);
             app.magResult.HWHM_x_Error = ...
                 app.magResult.HWHM_x-app.HWHMTarget_X.Value;
            
            app.magResult.centerAmp_y=round(app.magResult.centerAmp_y,2);
            app.magResult.centerAmp_y_Error = ...
                app.magResult.centerAmp_y-app.CenterAmpTarget_Y.Value;
%             app.magResult.HWHM_y = round(app.magResult.HWHM_y,2);
             app.magResult.HWHM_y_Error = ...
                 app.magResult.HWHM_x-app.HWHMTarget_Y.Value;
            

            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();

            %output to screen
            %Magnification
            app.fillResult(app.magResult);
            if ~app.isFrozen
                app.GotoMTFResultsButton.Enable=true;
            end

            app.register.removeProcess();
        end

        % Button pushed function: GotoMTFResultsButton
        function GotoMTFResultsButtonPushed(app, event)
            err=struct('Amp_X', app.CenterAmpTarget_X.Value, ...
                'HWHM_X', app.HWHMTarget_X.Value, ...
                'Amp_Y', app.CenterAmpTarget_Y.Value, ...
                'HWHM_Y', app.HWHMTarget_Y.Value);
            app.focusPlotHandle=RPAS_FocusPlot(app.magResult, err);
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            app.pauseFlag=~app.pauseFlag;
            app.freeze(app.pauseFlag);

            if ~isempty(app.motionStatus)
                if app.motionStatus.isPausing
                    lamps=[];
                    for k=1:numel(app.motionStatus.axes)
                        switch app.motionStatus.axes(k)
                            case {0}
                                lamps=[lamps app.xupdater];
                            case {1}
                                lamps=[lamps app.yupdater];
                            case {2}
                                lamps=[lamps app.zupdater];
                        end
                    end
                    app.motionStatus.isPausing=false;
                    if ~isempty(app.motionStatus.axes)
                        ABSMoving(app.motionStatus.axes, app.motionStatus.movingDistances, ...
                            app.motionStatus.movingSpeeds, lamps, app);
                    end
                else
                    app.motionStatus.isPausing=true;
                    if ~isempty(app.motionStatus.axes)
                        stopMotion([app.xupdater, app.yupdater, app.zupdater], app);
                    end
                end
            end
        end

        % Close request function: 
        % RPASMagnificationFocusQualificationUIFigure
        function RPASMagnificationFocusQualificationUIFigureCloseRequest(app, event)
            if ~isempty(app.register) && isvalid(app.register) ...
                    && app.register.numberOfActiveProcess()>0
                app.showInfoMsg("Please abort test or waiting the test finish before closing.");
                return;
            end
            
            if ~isempty(app.focusPlotHandle) & isvalid(app.focusPlotHandle)
                app.focusPlotHandle.close();
            end
            if ~isempty(app.register) & isvalid(app.register)
                app.register.delete();
            end
            
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            delete(app);
            
        end

        % Button pushed function: AbortTestButton
        function AbortTestButtonPushed(app, event)
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            app.motionStatus=struct('axes', [], 'movingSpeeds', [], ...
                'movingDistances', [], 'movingType', '', ...
                'status', '', 'isPausing', false);
            app.pauseFlag=false;
            app.register.Aborting(true);
        end

        % Button pushed function: BacktoMasterQualScreenButton
        function BacktoMasterQualScreenButtonPushed(app, event)
            app.RPASMagnificationFocusQualificationUIFigureCloseRequest();
        end

        % Button pushed function: GoToZRangeTopLimitButton
        function GoToZRangeTopLimitButtonPushed(app, event)
            var = app.systemVar.engineeringPar1Var;
            if app.WDSwitch.Value==1 %SWD
                val=app.SWD_Znorm+var.ZRange;
            else
                val=app.LWD_Znorm+var.ZRange;
            end
            ABSMoving(2, val, var.ManualMotionSpeed, app.zupdater,app);
        end

        % Button pushed function: GoToZRangeBottomLimitButton
        function GoToZRangeBottomLimitButtonPushed(app, event)
            var = app.systemVar.engineeringPar1Var;
            if app.WDSwitch.Value==1 %SWD
                val=app.SWD_Znorm-var.ZRange;
            else
                val=app.LWD_Znorm-var.ZRange;
            end
            ABSMoving(2, val, var.ManualMotionSpeed, app.zupdater,app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASMagnificationFocusQualificationUIFigure = uifigure('Visible', 'off');
            app.RPASMagnificationFocusQualificationUIFigure.Name = 'RPAS Magnification/Focus Qualification';
            app.RPASMagnificationFocusQualificationUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASMagnificationFocusQualificationUIFigureCloseRequest, true);
            app.RPASMagnificationFocusQualificationUIFigure.Tag = 'RPAS_Mag';
            app.RPASMagnificationFocusQualPanel = uipanel(app.RPASMagnificationFocusQualificationUIFigure);
            app.RPASMagnificationFocusQualPanel.Title = 'RPAS Magnification/Focus Qual';
            app.UIAxes = uiaxes(app.RPASMagnificationFocusQualPanel);
            xlabel(app.UIAxes, 'Y (Pixels)')
            ylabel(app.UIAxes, 'X (Pixels)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.YTick = [0 100 200 300 400];
            app.PresentXPosPanel = uipanel(app.RPASMagnificationFocusQualPanel);
            app.PresentXPosPanel.Title = 'Present X Pos';
            app.PresentXPos = uieditfield(app.PresentXPosPanel, 'numeric');
            app.PresentXPos.ValueDisplayFormat = '%.2f';
            app.PresentXPos.Editable = 'off';
            app.mmLabel_7 = uilabel(app.PresentXPosPanel);
            app.mmLabel_7.Text = 'mm';
            app.ReticlePositionPanel = uipanel(app.RPASMagnificationFocusQualPanel);
            app.ReticlePositionPanel.Title = 'Reticle Position';
            app.GoToPARKButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToPARKButton.ButtonPushedFcn = createCallbackFcn(app, @GoToPARKButtonPushed, true);
            app.GoToPARKButton.Text = 'Go To PARK';
            app.GoToLWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToLWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToLWDXcYcZnomButtonPushed, true);
            app.GoToLWDXcYcZnomButton.Text = 'Go To LWD Xc Yc Znom';
            app.GoToSWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToSWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToSWDXcYcZnomButtonPushed, true);
            app.GoToSWDXcYcZnomButton.Text = 'Go To SWD Xc Yc Znom';
            app.AbsoluteZMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.mmLabel_10 = uilabel(app.AbsoluteZMovePanel);
            app.mmLabel_10.Text = 'mm';
            app.ZStart = uibutton(app.AbsoluteZMovePanel, 'push');
            app.ZStart.ButtonPushedFcn = createCallbackFcn(app, @ZStartButtonPushed, true);
            app.ZStart.Text = 'Start';
            app.Abs_Z = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.Abs_Z.ValueDisplayFormat = '%.3f';
            app.RelativeZMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.mmLabel_11 = uilabel(app.RelativeZMovePanel);
            app.mmLabel_11.Text = 'mm';
            app.Zplus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zplus.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.Zplus.Text = '+';
            app.Zminus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zminus.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.Zminus.Text = '-';
            app.Rel_Z = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.Rel_Z.ValueDisplayFormat = '%.3f';
            app.AbsoluteYMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.YStart = uibutton(app.AbsoluteYMovePanel, 'push');
            app.YStart.ButtonPushedFcn = createCallbackFcn(app, @YStartButtonPushed, true);
            app.YStart.Text = 'Start';
            app.mmLabel_12 = uilabel(app.AbsoluteYMovePanel);
            app.mmLabel_12.Text = 'mm';
            app.Abs_Y = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.Abs_Y.ValueDisplayFormat = '%.3f';
            app.RelativeYMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.mmLabel_13 = uilabel(app.RelativeYMovePanel);
            app.mmLabel_13.Text = 'mm';
            app.Yplus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yplus.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.Yplus.Text = '+';
            app.Yminus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yminus.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.Yminus.Text = '-';
            app.Rel_Y = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.Rel_Y.ValueDisplayFormat = '%.3f';
            app.AbsoluteXMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.XStart = uibutton(app.AbsoluteXMovePanel, 'push');
            app.XStart.ButtonPushedFcn = createCallbackFcn(app, @XStartButtonPushed, true);
            app.XStart.Text = 'Start';
            app.mmLabel_14 = uilabel(app.AbsoluteXMovePanel);
            app.mmLabel_14.Text = 'mm';
            app.Abs_X = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.Abs_X.ValueDisplayFormat = '%.3f';
            app.RelativeXMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.Xplus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xplus.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.Xplus.Text = '+';
            app.mmLabel_15 = uilabel(app.RelativeXMovePanel);
            app.mmLabel_15.Text = 'mm';
            app.Xminus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xminus.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.Xminus.Text = '-';
            app.Rel_X = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.Rel_X.ValueDisplayFormat = '%.3f';
            app.GoToZRangeTopLimitButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToZRangeTopLimitButton.ButtonPushedFcn = createCallbackFcn(app, @GoToZRangeTopLimitButtonPushed, true);
            app.GoToZRangeTopLimitButton.Text = {'Go To Z Range'; 'Top Limit'};
            app.GoToZRangeBottomLimitButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToZRangeBottomLimitButton.ButtonPushedFcn = createCallbackFcn(app, @GoToZRangeBottomLimitButtonPushed, true);
            app.GoToZRangeBottomLimitButton.Text = {'Go To Z Range'; 'Bottom Limit'};
            app.PresentZPosPanel = uipanel(app.RPASMagnificationFocusQualPanel);
            app.PresentZPosPanel.Title = 'Present Z Pos';
            app.PresentZPos = uieditfield(app.PresentZPosPanel, 'numeric');
            app.PresentZPos.ValueDisplayFormat = '%.2f';
            app.PresentZPos.Editable = 'off';
            app.mmLabel_8 = uilabel(app.PresentZPosPanel);
            app.mmLabel_8.Text = 'mm';
            app.PresentYPosPanel = uipanel(app.RPASMagnificationFocusQualPanel);
            app.PresentYPosPanel.Title = 'Present Y Pos';
            app.PresentYPos = uieditfield(app.PresentYPosPanel, 'numeric');
            app.PresentYPos.ValueDisplayFormat = '%.2f';
            app.PresentYPos.Editable = 'off';
            app.mmLabel_9 = uilabel(app.PresentYPosPanel);
            app.mmLabel_9.Text = 'mm';
            app.ImageCapturePanel = uipanel(app.RPASMagnificationFocusQualPanel);
            app.ImageCapturePanel.Title = 'Image Capture';
            app.WDSwitch = uiswitch(app.ImageCapturePanel, 'slider');
            app.WDSwitch.Items = {'SWD', 'LWD'};
            app.WDSwitch.ItemsData = [1 0];
            app.WDSwitch.Value = 1;
            app.CaptureImageButton = uibutton(app.ImageCapturePanel, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.StartMagFocusQualButton = uibutton(app.RPASMagnificationFocusQualPanel, 'push');
            app.StartMagFocusQualButton.ButtonPushedFcn = createCallbackFcn(app, @StartMagFocusQualButtonPushed, true);
            app.StartMagFocusQualButton.Text = {'Start Mag/Focus'; 'Qual'; ''};
            app.ResultsPanel = uipanel(app.RPASMagnificationFocusQualPanel);
            app.ResultsPanel.Title = ' Results';
            app.CalculatedLabel = uilabel(app.ResultsPanel);
            app.CalculatedLabel.Text = 'Calculated';
            app.TargetValLabel = uilabel(app.ResultsPanel);
            app.TargetValLabel.Text = {'Target Val'; ''};
            app.PassFailLabel = uilabel(app.ResultsPanel);
            app.PassFailLabel.Text = 'Pass/Fail';
            app.ErrorLabel = uilabel(app.ResultsPanel);
            app.ErrorLabel.Text = 'Error';
            app.Panel = uipanel(app.ResultsPanel);
            app.CenterAmpLabel_2 = uilabel(app.Panel);
            app.CenterAmpLabel_2.Text = 'Center Amp (%)';
            app.CenterAmpTarget_X = uieditfield(app.Panel, 'numeric');
            app.CenterAmpTarget_X.ValueDisplayFormat = '%.2f';
            app.CenterAmpTarget_X.Editable = 'off';
            app.HWHMPass_X = uilamp(app.Panel);
            app.HWHMPass_X.Tooltip = {'Fails if Calculated HWHM >  Target HWHM'};
            app.CenterAmpPass_X = uilamp(app.Panel);
            app.CenterAmpPass_X.Tooltip = {'Fails if Calculated Center Amp <  Target Center Amp'};
            app.CenterAmpCalculated_X = uieditfield(app.Panel, 'numeric');
            app.CenterAmpCalculated_X.ValueDisplayFormat = '%.2f';
            app.CenterAmpCalculated_X.Editable = 'off';
            app.CenterAmpError_X = uieditfield(app.Panel, 'numeric');
            app.CenterAmpError_X.ValueDisplayFormat = '%.2f';
            app.CenterAmpError_X.Editable = 'off';
            app.CenterAmpError_X.Tooltip = {'Difference of Calculated Center Amp  and Target Center Amp'};
            app.HWHMCalculated_X = uieditfield(app.Panel, 'numeric');
            app.HWHMCalculated_X.ValueDisplayFormat = '%.4f';
            app.HWHMCalculated_X.Editable = 'off';
            app.HWHMError_X = uieditfield(app.Panel, 'numeric');
            app.HWHMError_X.ValueDisplayFormat = '%.4f';
            app.HWHMError_X.Editable = 'off';
            app.HWHMError_X.Tooltip = {'Difference of Calculated HWHM  and Target HWHM'};
            app.HWHMum1Label = uilabel(app.Panel);
            app.HWHMum1Label.Interpreter = 'latex';
            app.HWHMum1Label.Text = 'HWHM (um$^{-1}$)';
            app.HWHMTarget_X = uieditfield(app.Panel, 'numeric');
            app.HWHMTarget_X.ValueDisplayFormat = '%.2f';
            app.HWHMTarget_X.Editable = 'off';
            app.MagCalculated = uieditfield(app.ResultsPanel, 'numeric');
            app.MagCalculated.ValueDisplayFormat = '%.2f';
            app.MagCalculated.Editable = 'off';
            app.MagPass = uilamp(app.ResultsPanel);
            app.MagError = uieditfield(app.ResultsPanel, 'numeric');
            app.MagError.ValueDisplayFormat = '%.2f';
            app.MagError.Editable = 'off';
            app.MagError.Tooltip = {'Relative Error of Calculate Magnification to Target Magnification'};
            app.MagnificationLabel = uilabel(app.ResultsPanel);
            app.MagnificationLabel.Text = 'Magnification';
            app.MagTarget = uieditfield(app.ResultsPanel, 'numeric');
            app.MagTarget.ValueDisplayFormat = '%.2f';
            app.MagTarget.Editable = 'off';
            app.Panel_2 = uipanel(app.ResultsPanel);
            app.HWHMPass_Y = uilamp(app.Panel_2);
            app.HWHMPass_Y.Tooltip = {'Fails if Calculated HWHM >  Target HWHM'};
            app.CenterAmpPass_Y = uilamp(app.Panel_2);
            app.CenterAmpPass_Y.Tooltip = {'Fails if Calculated Center Amp <  Target Center Amp'};
            app.CenterAmpCalculated_Y = uieditfield(app.Panel_2, 'numeric');
            app.CenterAmpCalculated_Y.ValueDisplayFormat = '%.2f';
            app.CenterAmpCalculated_Y.Editable = 'off';
            app.CenterAmpError_Y = uieditfield(app.Panel_2, 'numeric');
            app.CenterAmpError_Y.ValueDisplayFormat = '%.2f';
            app.CenterAmpError_Y.Editable = 'off';
            app.CenterAmpError_Y.Tooltip = {'Difference of Calculated Center Ampl  and Target Center Amp'};
            app.HWHMCalculated_Y = uieditfield(app.Panel_2, 'numeric');
            app.HWHMCalculated_Y.ValueDisplayFormat = '%.4f';
            app.HWHMCalculated_Y.Editable = 'off';
            app.HWHMError_Y = uieditfield(app.Panel_2, 'numeric');
            app.HWHMError_Y.ValueDisplayFormat = '%.4f';
            app.HWHMError_Y.Editable = 'off';
            app.HWHMError_Y.Tooltip = {'Difference of Calculated HWHM  and Target  HWHM'};
            app.HWHMum1Label_2 = uilabel(app.Panel_2);
            app.HWHMum1Label_2.Interpreter = 'latex';
            app.HWHMum1Label_2.Text = 'HWHM (um$^{-1}$)';
            app.HWHMTarget_Y = uieditfield(app.Panel_2, 'numeric');
            app.HWHMTarget_Y.ValueDisplayFormat = '%.2f';
            app.HWHMTarget_Y.Editable = 'off';
            app.CenterAmpLabel_3 = uilabel(app.Panel_2);
            app.CenterAmpLabel_3.Text = 'Center Amp (%)';
            app.CenterAmpTarget_Y = uieditfield(app.Panel_2, 'numeric');
            app.CenterAmpTarget_Y.ValueDisplayFormat = '%.2f';
            app.CenterAmpTarget_Y.Editable = 'off';
            app.GotoMTFResultsButton = uibutton(app.ResultsPanel, 'push');
            app.GotoMTFResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoMTFResultsButtonPushed, true);
            app.GotoMTFResultsButton.Text = {'Go to MTF'; 'Results'; ''};
            app.MTFXLabel = uilabel(app.ResultsPanel);
            app.MTFXLabel.Text = 'MTF-X';
            app.MTFYLabel = uilabel(app.ResultsPanel);
            app.MTFYLabel.Text = 'MTF-Y';
            app.Label = uilabel(app.ResultsPanel);
            app.Label.Text = '%';
            app.PauseResumeButton = uibutton(app.RPASMagnificationFocusQualPanel, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause/Resume';
            app.AbortTestButton = uibutton(app.RPASMagnificationFocusQualPanel, 'push');
            app.AbortTestButton.ButtonPushedFcn = createCallbackFcn(app, @AbortTestButtonPushed, true);
            app.AbortTestButton.Text = 'Abort Test';
            app.BacktoMasterQualScreenButton = uibutton(app.RPASMagnificationFocusQualPanel, 'push');
            app.BacktoMasterQualScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMasterQualScreenButtonPushed, true);
            app.BacktoMasterQualScreenButton.Text = {'Back to Master'; 'Qual Screen'; ''};
            app.TravelLimitLabel_2 = uilabel(app.RPASMagnificationFocusQualPanel);
            app.TravelLimitLabel_2.Text = 'Travel Limit';
            app.XLamp_2Label = uilabel(app.RPASMagnificationFocusQualPanel);
            app.XLamp_2Label.Text = 'X';
            app.XLamp = uilamp(app.RPASMagnificationFocusQualPanel);
            app.YLamp_2Label = uilabel(app.RPASMagnificationFocusQualPanel);
            app.YLamp_2Label.Text = 'Y';
            app.YLamp = uilamp(app.RPASMagnificationFocusQualPanel);
            app.ZLamp_2Label = uilabel(app.RPASMagnificationFocusQualPanel);
            app.ZLamp_2Label.Text = 'Z';
            app.ZLamp = uilamp(app.RPASMagnificationFocusQualPanel);
            % Show the figure after all components are created
            app.RPASMagnificationFocusQualificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_Magnification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASMagnificationFocusQualificationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASMagnificationFocusQualificationUIFigure)
        end
    end
end