classdef RPAS_MotionSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASMotionSetupUIFigure        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        AerotechPresentPositionsPanel  matlab.ui.container.Panel
        PresentZposEditField_2         matlab.ui.control.NumericEditField
        PresentYposEditField_2         matlab.ui.control.NumericEditField
        PresentXposEditField_2         matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        BackToMainScreenButton         matlab.ui.control.Button
        STOPMOTIONButton               matlab.ui.control.Button
        ZmmLabel                       matlab.ui.control.Label
        YmmLabel                       matlab.ui.control.Label
        XmmLabel                       matlab.ui.control.Label
        PARKPanel                      matlab.ui.container.Panel
        PresentZParkEditField          matlab.ui.control.NumericEditField
        PresentYParkEditField          matlab.ui.control.NumericEditField
        PresentXParkEditField          matlab.ui.control.NumericEditField
        GotoPARKButton                 matlab.ui.control.Button
        RecordasPARKButton             matlab.ui.control.Button
        PresentPARKXmmLabel            matlab.ui.control.Label
        PresentPARKYmmLabel            matlab.ui.control.Label
        PresentPARKZmmLabel            matlab.ui.control.Label
        MotionSetupPanel               matlab.ui.container.Panel
        PresentZHomeEditField          matlab.ui.control.NumericEditField
        PresentYHomeEditField          matlab.ui.control.NumericEditField
        PresentXHomeEditField          matlab.ui.control.NumericEditField
        PresentHOMEZmmLabel            matlab.ui.control.Label
        PresentHOMEYmmLabel            matlab.ui.control.Label
        PresentHOMEXmmLabel            matlab.ui.control.Label
        RecordasHOMEButton             matlab.ui.control.Button
        GotoHOMEButton                 matlab.ui.control.Button
        ZAxisPanel                     matlab.ui.container.Panel
        TravelLimitPanel_3             matlab.ui.container.Panel
        ZLamp                          matlab.ui.control.Lamp
        ZLampLabel                     matlab.ui.control.Label
        ZLamp_2                        matlab.ui.control.Lamp
        ZLamp_2Label                   matlab.ui.control.Label
        AbsoluteZMovePanel             matlab.ui.container.Panel
        mmLabel_10                     matlab.ui.control.Label
        AbsoluteZMoveEditField         matlab.ui.control.NumericEditField
        StartButton_3                  matlab.ui.control.Button
        RelativeZMovePanel             matlab.ui.container.Panel
        minusZButtonPushed             matlab.ui.control.Button
        plusZButtonPushed              matlab.ui.control.Button
        RelativeZMoveEditField         matlab.ui.control.NumericEditField
        mmLabel_9                      matlab.ui.control.Label
        mmLabel_20                     matlab.ui.control.Label
        EditField_17                   matlab.ui.control.NumericEditField
        mmLabel_18                     matlab.ui.control.Label
        EditField_15                   matlab.ui.control.NumericEditField
        mmLabel_13                     matlab.ui.control.Label
        PresentZposEditField           matlab.ui.control.NumericEditField
        PresentZLimitLabel_2           matlab.ui.control.Label
        PresentZLimitLabel             matlab.ui.control.Label
        RecordasZTravelLimitButton_2   matlab.ui.control.Button
        GotoPARKZButton                matlab.ui.control.Button
        PresentZPosLabel               matlab.ui.control.Label
        RecordasZTravelLimitButton     matlab.ui.control.Button
        GotoHOMEZButton                matlab.ui.control.Button
        YAxisPanel                     matlab.ui.container.Panel
        TravelLimitPanel_2             matlab.ui.container.Panel
        YLamp                          matlab.ui.control.Lamp
        YLampLabel                     matlab.ui.control.Label
        YLamp_2                        matlab.ui.control.Lamp
        YLamp_2Label                   matlab.ui.control.Label
        AbsoluteYMovePanel             matlab.ui.container.Panel
        StartButton_2                  matlab.ui.control.Button
        AbsoluteYMoveEditField         matlab.ui.control.NumericEditField
        mmLabel_23                     matlab.ui.control.Label
        RelativeYMovePanel             matlab.ui.container.Panel
        mmLabel_5                      matlab.ui.control.Label
        minusYButtonPushed             matlab.ui.control.Button
        plusYButtonPushed              matlab.ui.control.Button
        RelativeYMoveEditField         matlab.ui.control.NumericEditField
        mmLabel_19                     matlab.ui.control.Label
        EditField_16                   matlab.ui.control.NumericEditField
        mmLabel_17                     matlab.ui.control.Label
        EditField_14                   matlab.ui.control.NumericEditField
        mmLabel_14                     matlab.ui.control.Label
        PresentYposEditField           matlab.ui.control.NumericEditField
        PresentYLimitLabel_2           matlab.ui.control.Label
        PresentYLimitLabel_3           matlab.ui.control.Label
        RecordasYTravelLimitButton_2   matlab.ui.control.Button
        GotoPARKYButton                matlab.ui.control.Button
        PresentYPosLabel               matlab.ui.control.Label
        RecordasYTravelLimitButton     matlab.ui.control.Button
        GotoHOMEYButton                matlab.ui.control.Button
        XAxisPanel                     matlab.ui.container.Panel
        TravelLimitPanel               matlab.ui.container.Panel
        XLamp_2                        matlab.ui.control.Lamp
        XLamp_2Label                   matlab.ui.control.Label
        PlusXLimitLamp                 matlab.ui.control.Lamp
        XLampLabel                     matlab.ui.control.Label
        AbsoluteXMovePanel             matlab.ui.container.Panel
        mmLabel_2                      matlab.ui.control.Label
        StartButtonAbsX                matlab.ui.control.Button
        AbsoluteXMoveEditField         matlab.ui.control.NumericEditField
        mmLabel_21                     matlab.ui.control.Label
        PresentXminusLimitEditField    matlab.ui.control.NumericEditField
        mmLabel_16                     matlab.ui.control.Label
        PresentXplusLimitEditField     matlab.ui.control.NumericEditField
        mmLabel_15                     matlab.ui.control.Label
        PresentXposEditField           matlab.ui.control.NumericEditField
        PresentXLimitLabel_3           matlab.ui.control.Label
        PresentXLimitLabel             matlab.ui.control.Label
        RecordasXTravelLimitButton_2   matlab.ui.control.Button
        GotoPARKXButton                matlab.ui.control.Button
        PresentXPosLabel               matlab.ui.control.Label
        RecordasXTravelLimitButton     matlab.ui.control.Button
        GotoHOMEXButton                matlab.ui.control.Button
        RelativeXMovePanel             matlab.ui.container.Panel
        RelativeXMoveEditField         matlab.ui.control.NumericEditField
        minusXButtonPushed             matlab.ui.control.Button
        plusXButtonPushed              matlab.ui.control.Button
        mmLabel                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        posX % Description
    end
    
    properties (Access = private)
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        xupdater  % timer to query present x position
        yupdater % timer to query present y position
        zupdater % timer to query present z position
        
        systemVar;
        rcdNames={'upperXLimit', 'lowerXLimit', ...
            'upperYLimit', 'lowerYLimit', ...
            'upperZLimit', 'lowerZLimit', ...
            'recordHomePosition', ...
            'recordParkPosition'}; % Description
    end

   methods (Access = private)
        
        function updateRecords(app, rcdName)
            var=app.systemVar.motionSetupVar;
            switch rcdName
                case app.rcdNames{1}
                    var.Xlimit_Plus = app.PresentXplusLimitEditField.Value;
                case app.rcdNames{2}
                    var.Xlimit_Minus = app.PresentXminusLimitEditField.Value
                case app.rcdNames{3}
                    var.Ylimit_Plus = app.EditField_14.Value;
                case app.rcdNames{4}
                    var.Ylimit_Minus = app.EditField_16.Value;
                case app.rcdNames{5}
                    var.Zlimit_Plus = app.EditField_15.Value;
                case app.rcdNames{6}
                    var.Zlimit_Minus = app.EditField_17.Value;
                case app.rcdNames{7}
                    var.Xhome = app.PresentXHomeEditField.Value;
                    var.Yhome = app.PresentYHomeEditField.Value;
                    var.Zhome = app.PresentZHomeEditField.Value;
                case app.rcdNames{8}
                    var.XPark = app.PresentXParkEditField.Value;
                    var.YPark = app.PresentYParkEditField.Value;
                    var.ZPark = app.PresentZParkEditField.Value;
                    
            end
            app.systemVar.updateRecords(var, app.RPASMotionSetupUIFigure.Tag);
        end
        
        function init(app)
            global systemVar
            if isempty(systemVar)
                systemVar=savedVariables();
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables;
            
            vars=app.systemVar.motionSetupVar;
            app.PresentXplusLimitEditField.Value = ...
                vars.Xlimit_Plus;
            app.PresentXminusLimitEditField.Value= ...
                vars.Xlimit_Minus;
            app.EditField_14.Value = ...
                vars.Ylimit_Plus;
            app.EditField_16.Value = ...
                vars.Ylimit_Minus;
            app.EditField_15.Value = ...
                vars.Zlimit_Plus;
            app.EditField_17.Value = ...
                vars.Zlimit_Minus;
            
            app.PresentXHomeEditField.Value = ...
                vars.Xhome;
            app.PresentYHomeEditField.Value = ...
                vars.Yhome;
            app.PresentZHomeEditField.Value = ...
                vars.Zhome;
            
            app.PresentXParkEditField.Value = ...
                vars.XPark;
            app.PresentYParkEditField.Value = ...
                vars.YPark;
            app.PresentZParkEditField.Value = ...
                vars.ZPark;
            
            app.PlusXLimitLamp.Color = app.UnderLimitColor;
            app.XLamp_2.Color = app.UnderLimitColor;
            app.YLamp_2.Color = app.UnderLimitColor;
            app.YLamp.Color = app.UnderLimitColor;
            app.ZLamp_2.Color = app.UnderLimitColor;
            app.ZLamp.Color = app.UnderLimitColor;
        end
        
        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, PlusLamp, MinusLamp)

            try
                val=round(val*1.0e3)*1.0e-3;
                upperLimit=round(upperLimit*1.0e6)*1.0e-6;
                lowerLimit=round(lowerLimit*1.0e6)*1.0e-6;
                c1=PlusLamp.Color;
                c2=MinusLamp.Color;
                warning=false;
                if val>upperLimit
                    PlusLamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if val<lowerLimit
                    MinusLamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
                PlusLamp.Color=c1;
                MinusLamp.Color=c2;
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
        end
        
        function flag=confirmRecord(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
            title = 'Confirm';
            selection = uiconfirm(fig, [msg '?'], title,...
                                   'Options',{'Save','Cancel'},...
                                   'DefaultOption', 1,'CancelOption', 2);

            switch selection
                case {'Save'}
                    flag=true;
                otherwise
                    flag=false;
            end
            delete(fig);
            return;
        end
        
        function stopTimer(app)
            app.xupdater.stop();
            app.yupdater.stop();
            app.zupdater.stop();
        end
        
        function results = warningMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);

            title = 'Warning';
            selection = uiconfirm(fig, msg, title,...
                                       'Options',{'OK'},...
                                       'icon', 'warning');
            switch selection
                case {'OK'}
                    %donothing;
                otherwise
                    %donothing;
            end
            delete(fig);
        end
   end

   methods (Access=public)
       
        function close(app)
            app.closeFcn();
        end
   end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.init();
            
            app.xupdater = updatePosition( ...
                [app.PresentXposEditField, app.PresentXposEditField_2], ...
                0, .05, ...
                {app.PlusXLimitLamp, app.XLamp_2}, ...
                {app.PresentXplusLimitEditField.Value, app.PresentXminusLimitEditField.Value}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater = updatePosition(...
                [app.PresentYposEditField, app.PresentYposEditField_2], ...
                1, .05,...
                {app.YLamp_2, app.YLamp}, ...
                {app.EditField_14.Value, app.EditField_16.Value}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater = updatePosition(...
                [app.PresentZposEditField, app.PresentZposEditField_2], ...
                2, .05, ...
                {app.ZLamp_2, app.ZLamp}, ...
                {app.EditField_15.Value, app.EditField_17.Value}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1);
            app.stopTimer();
        
        end

        % Button pushed function: RecordasXTravelLimitButton
        function RecordasXTravelLimitButtonPushed(app, event)
            if app.confirmRecord('Record X- Limit')
                if app.PresentXposEditField.Value<app.PresentXplusLimitEditField.Value
                    app.PresentXminusLimitEditField.Value=app.PresentXposEditField.Value;
                    app.XLamp_2.Color=app.UnderLimitColor;
                    app.updateRecords(app.rcdNames{2});
                    app.xupdater.updateLimitValue(...
                        app.PresentXplusLimitEditField.Value, ...
                        app.PresentXminusLimitEditField.Value);
                else
                    msg={'X- Limit must be less than X+ Limit!'};
                    app.warningMsg(msg);
                end
            end
        end

        % Button pushed function: RecordasXTravelLimitButton_2
        function RecordasXTravelLimitButton_2Pushed(app, event)
            if app.confirmRecord('Record X+ Limit')
                if app.PresentXposEditField.Value>app.PresentXminusLimitEditField.Value
                    app.PresentXplusLimitEditField.Value=app.PresentXposEditField.Value;
                    app.PlusXLimitLamp.Color=app.UnderLimitColor;
                    app.updateRecords(app.rcdNames{1});
                    app.xupdater.updateLimitValue(...
                        app.PresentXplusLimitEditField.Value, ...
                        app.PresentXminusLimitEditField.Value);
                else
                    msg={'X+ Limit must be greater than X- Limit!'};
                    app.warningMsg(msg);
                end
            end
        end

        % Button pushed function: RecordasYTravelLimitButton
        function RecordasYTravelLimitButtonPushed(app, event)
           if app.confirmRecord('Record Y- Limit')
                if app.PresentYposEditField.Value<app.EditField_14.Value
                    app.EditField_16.Value=app.PresentYposEditField.Value;
                    app.YLamp.Color=app.UnderLimitColor;
                    app.updateRecords(app.rcdNames{4});
                    app.yupdater.updateLimitValue(...
                        app.EditField_14.Value, ...
                        app.EditField_16.Value);
                else
                    msg={'Y- Limit must be less than Y+ Limit!'};
                    app.warningMsg(msg);
                end
           end
        end

        % Button pushed function: RecordasYTravelLimitButton_2
        function RecordasYTravelLimitButton_2Pushed(app, event)
           if app.confirmRecord('Record Y+ Limit')
                if app.PresentYposEditField.Value>app.EditField_16.Value
                    app.EditField_14.Value=app.PresentYposEditField.Value;
                    app.YLamp_2.Color=app.UnderLimitColor;
                    app.updateRecords(app.rcdNames{3});
                    app.yupdater.updateLimitValue(...
                        app.EditField_14.Value, ...
                        app.EditField_16.Value);
                else
                    msg={'Y+ Limit must be greater than Y- Limit!'};
                    app.warningMsg(msg);
                end
           end
        end

        % Button pushed function: RecordasZTravelLimitButton
        function RecordasZTravelLimitButtonPushed(app, event)
            if app.confirmRecord('Record Z- Limit')
                if app.PresentZposEditField.Value<app.EditField_15.Value
                    app.EditField_17.Value=app.PresentZposEditField.Value;
                    app.ZLamp.Color=app.UnderLimitColor;
                    app.updateRecords(app.rcdNames{6});
                    app.zupdater.updateLimitValue(...
                        app.EditField_15.Value, ...
                        app.EditField_17.Value);
                else
                    msg={'Z- Limit must be less than Z+ Limit!'};
                    app.warningMsg(msg);
                end
            end
        end

        % Button pushed function: RecordasZTravelLimitButton_2
        function RecordasZTravelLimitButton_2Pushed(app, event)
            if app.confirmRecord('Record Z+ Limit')
                if app.PresentZposEditField.Value>app.EditField_17.Value
                    app.EditField_15.Value=app.PresentZposEditField.Value;
                    app.ZLamp_2.Color=app.UnderLimitColor;
                    app.updateRecords(app.rcdNames{5});
                    app.zupdater.updateLimitValue(...
                        app.EditField_15.Value, ...
                        app.EditField_17.Value);
                else
                    msg={'Z+ Limit must be greater than Z- Limit!'};
                    app.warningMsg(msg);
                end
            end
        end

        % Button pushed function: GotoHOMEButton
        function GotoHOMEButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1,2];
            dist=[app.PresentXHomeEditField.Value ...
                app.PresentYHomeEditField.Value ...
                app.PresentZHomeEditField.Value];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: GotoHOMEXButton
        function GotoHOMEXButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            ABSMoving(0,app.PresentXHomeEditField.Value,speed, app.xupdater);
        end

        % Button pushed function: GotoHOMEYButton
        function GotoHOMEYButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            ABSMoving(1,app.PresentYHomeEditField.Value,speed, app.yupdater);
        end

        % Button pushed function: GotoHOMEZButton
        function GotoHOMEZButtonPushed(app, event)
           speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
           ABSMoving(2,app.PresentZHomeEditField.Value, speed, app.zupdater);
        end

        % Button pushed function: StartButtonAbsX
        function StartButtonAbsXPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.AbsoluteXMoveEditField.Value;
            if app.isValidPosition(val, app.PresentXplusLimitEditField.Value, ...
                    app.PresentXminusLimitEditField.Value, ...
                    app.PlusXLimitLamp, app.XLamp_2)
                ABSMoving(0, val, speed, app.xupdater);
            end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.AbsoluteYMoveEditField.Value;
            if app.isValidPosition(val, app.EditField_14.Value, ...
                    app.EditField_16.Value, ...
                    app.YLamp_2, app.YLamp)
                ABSMoving(1, val,speed, app.yupdater);
            end
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.AbsoluteZMoveEditField.Value;
            if app.isValidPosition(val, app.EditField_15.Value, ...
                    app.EditField_17.Value, ...
                    app.ZLamp_2, app.ZLamp)
                ABSMoving(2, val, speed, app.zupdater);
            end
        end

        % Button pushed function: plusYButtonPushed
        function plusYButtonPushedPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.PresentYposEditField.Value + ...
                    app.RelativeYMoveEditField.Value;
            if app.isValidPosition(val, app.EditField_14.Value, ...
                    app.EditField_16.Value, ...
                    app.YLamp_2, app.YLamp)
                RelativeMoving(1, app.RelativeYMoveEditField.Value, speed, app.yupdater);
            end 
        end

        % Button pushed function: plusXButtonPushed
        function plusXButtonPushedPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.PresentXposEditField.Value + ...
                    app.RelativeXMoveEditField.Value;
            if app.isValidPosition(val, app.PresentXplusLimitEditField.Value, ...
                    app.PresentXminusLimitEditField.Value, ...
                    app.PlusXLimitLamp, app.XLamp_2)
                RelativeMoving(0, app.RelativeXMoveEditField.Value, speed, app.xupdater);
            end 
        end

        % Button pushed function: minusXButtonPushed
        function minusXButtonPushedPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.PresentXposEditField.Value - ...
                    app.RelativeXMoveEditField.Value;
            if app.isValidPosition(val, app.PresentXplusLimitEditField.Value, ...
                    app.PresentXminusLimitEditField.Value, ...
                    app.PlusXLimitLamp, app.XLamp_2)
                RelativeMoving(0, -app.RelativeXMoveEditField.Value, speed, app.xupdater);
            end 
        end

        % Button pushed function: minusYButtonPushed
        function minusYButtonPushedPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.PresentYposEditField.Value - ...
                    app.RelativeYMoveEditField.Value;
            if app.isValidPosition(val, app.EditField_14.Value, ...
                    app.EditField_16.Value, ...
                    app.YLamp_2, app.YLamp)
                RelativeMoving(1, -app.RelativeYMoveEditField.Value, speed, app.yupdater);
            end 
        end

        % Button pushed function: plusZButtonPushed
        function plusZButtonPushedPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.PresentZposEditField.Value + ...
                    app.RelativeZMoveEditField.Value;
            if app.isValidPosition(val, app.EditField_15.Value, ...
                    app.EditField_17.Value, ...
                    app.ZLamp_2, app.ZLamp)
                RelativeMoving(2, app.RelativeZMoveEditField.Value, speed, app.zupdater);
            end 
        end

        % Button pushed function: minusZButtonPushed
        function minusZButtonPushedPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.PresentZposEditField.Value - ...
                    app.RelativeZMoveEditField.Value;
            if app.isValidPosition(val, app.EditField_15.Value, ...
                    app.EditField_17.Value, ...
                    app.ZLamp_2, app.ZLamp)
                RelativeMoving(2,-app.RelativeZMoveEditField.Value, speed, app.zupdater);
            end 
        end

        % Button pushed function: STOPMOTIONButton
        function STOPMOTIONButtonPushed(app, event)
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: RecordasHOMEButton
        function RecordasHOMEButtonPushed(app, event)
            if isAuthorized()
                app.PresentXHomeEditField.Value=app.PresentXposEditField.Value;
                app.PresentYHomeEditField.Value=app.PresentYposEditField.Value;
                app.PresentZHomeEditField.Value=app.PresentZposEditField.Value;
                app.updateRecords(app.rcdNames{7});
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %fprintf(1,'OK selected\n')
                        %RelativeXmove(app.RelativeXMoveEditField.Value);
                    otherwise
                        %fprintf(1,'Do nothing\n');
                        %do nothing
                end
                delete(fig);
end
        end

        % Value changed function: AbsoluteXMoveEditField
        function AbsoluteXMoveEditFieldValueChanged(app, event)
            app.StartButtonAbsX.Enable=true;
        end

        % Value changed function: AbsoluteZMoveEditField
        function AbsoluteZMoveEditFieldValueChanged(app, event)
            app.StartButton_3.Enable=true;
        end

        % Value changed function: AbsoluteYMoveEditField
        function AbsoluteYMoveEditFieldValueChanged(app, event)
            app.StartButton_2.Enable=true;
        end

        % Button pushed function: BackToMainScreenButton
        function BackToMainScreenButtonPushed(app, event)
            app.closeFcn();
        end

        % Close request function: RPASMotionSetupUIFigure
        function closeFcn(app, event)
            app.STOPMOTIONButtonPushed();
            delete(app);
            
        end

        % Button pushed function: GotoPARKXButton
        function GotoPARKXButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            ABSMoving(0, app.PresentXParkEditField.Value, speed, app.xupdater);
        end

        % Window key press function: RPASMotionSetupUIFigure
        function StopMotionWindowKeyPress(app, event)
            key = event.Key;
            if strcmpi(key,'s')
          % the key that was pressed was the space bar so set focus to 
          % the snap button
          STOPMOTIONButtonPushed(app,event);
            end
        end

        % Button pushed function: GotoPARKYButton
        function GotoPARKYButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            ABSMoving(1, app.PresentYParkEditField.Value, speed, app.yupdater);
        end

        % Button pushed function: RecordasPARKButton
        function RecordasPARKButtonPushed(app, event)
            if isAuthorized()
                app.PresentXParkEditField.Value=app.PresentXposEditField.Value;
                app.PresentYParkEditField.Value=app.PresentYposEditField.Value;
                app.PresentZParkEditField.Value=app.PresentZposEditField.Value;
                app.updateRecords(app.rcdNames{8});
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %fprintf(1,'OK selected\n')
                        %RelativeXmove(app.RelativeXMoveEditField.Value);
                    otherwise
                        %fprintf(1,'Do nothing\n');
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: GotoPARKButton
        function GotoPARKButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1,2];
            dist=[app.PresentXParkEditField.Value ...
                app.PresentYParkEditField.Value ...
                app.PresentZParkEditField.Value];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: GotoPARKZButton
        function GotoPARKZButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            ABSMoving(2,app.PresentZParkEditField.Value, speed, app.zupdater);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASMotionSetupUIFigure = uifigure('Visible', 'off');
            app.RPASMotionSetupUIFigure.Name = 'RPAS Motion Setup';
            app.RPASMotionSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.RPASMotionSetupUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @StopMotionWindowKeyPress, true);
            app.RPASMotionSetupUIFigure.Tag = 'MotionSetup';
            app.GridLayout = uigridlayout(app.RPASMotionSetupUIFigure);
            app.XAxisPanel = uipanel(app.GridLayout);
            app.XAxisPanel.Title = 'X Axis';
            app.RelativeXMovePanel = uipanel(app.XAxisPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.mmLabel = uilabel(app.RelativeXMovePanel);
            app.mmLabel.Text = 'mm';
            app.plusXButtonPushed = uibutton(app.RelativeXMovePanel, 'push');
            app.plusXButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @plusXButtonPushedPushed, true);
            app.plusXButtonPushed.Text = '+';
            app.minusXButtonPushed = uibutton(app.RelativeXMovePanel, 'push');
            app.minusXButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @minusXButtonPushedPushed, true);
            app.minusXButtonPushed.Text = '-';
            app.RelativeXMoveEditField = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.RelativeXMoveEditField.ValueDisplayFormat = '%5.3f';
            app.GotoHOMEXButton = uibutton(app.XAxisPanel, 'push');
            app.GotoHOMEXButton.ButtonPushedFcn = createCallbackFcn(app, @GotoHOMEXButtonPushed, true);
            app.GotoHOMEXButton.Text = 'Go to HOME X';
            app.RecordasXTravelLimitButton = uibutton(app.XAxisPanel, 'push');
            app.RecordasXTravelLimitButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasXTravelLimitButtonPushed, true);
            app.RecordasXTravelLimitButton.Text = {'Record as'; 'X- Travel'; 'Limit'};
            app.PresentXPosLabel = uilabel(app.XAxisPanel);
            app.PresentXPosLabel.Text = 'Present X Pos';
            app.GotoPARKXButton = uibutton(app.XAxisPanel, 'push');
            app.GotoPARKXButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPARKXButtonPushed, true);
            app.GotoPARKXButton.Text = 'Go to PARK X ';
            app.RecordasXTravelLimitButton_2 = uibutton(app.XAxisPanel, 'push');
            app.RecordasXTravelLimitButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordasXTravelLimitButton_2Pushed, true);
            app.RecordasXTravelLimitButton_2.Text = {'Record as'; 'X+ Travel'; 'Limit'};
            app.PresentXLimitLabel = uilabel(app.XAxisPanel);
            app.PresentXLimitLabel.Text = 'Present X- Limit';
            app.PresentXLimitLabel_3 = uilabel(app.XAxisPanel);
            app.PresentXLimitLabel_3.Text = 'Present X+ Limit';
            app.PresentXposEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.PresentXposEditField.ValueDisplayFormat = '%5.3f';
            app.PresentXposEditField.Editable = 'off';
            app.mmLabel_15 = uilabel(app.XAxisPanel);
            app.mmLabel_15.Text = 'mm';
            app.PresentXplusLimitEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.PresentXplusLimitEditField.ValueDisplayFormat = '%5.3f';
            app.PresentXplusLimitEditField.Editable = 'off';
            app.mmLabel_16 = uilabel(app.XAxisPanel);
            app.mmLabel_16.Text = 'mm';
            app.PresentXminusLimitEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.PresentXminusLimitEditField.ValueDisplayFormat = '%5.3f';
            app.PresentXminusLimitEditField.Editable = 'off';
            app.PresentXminusLimitEditField.Value = 10;
            app.mmLabel_21 = uilabel(app.XAxisPanel);
            app.mmLabel_21.Text = 'mm';
            app.AbsoluteXMovePanel = uipanel(app.XAxisPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.AbsoluteXMoveEditField = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.AbsoluteXMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteXMoveEditField.ValueChangedFcn = createCallbackFcn(app, @AbsoluteXMoveEditFieldValueChanged, true);
            app.StartButtonAbsX = uibutton(app.AbsoluteXMovePanel, 'push');
            app.StartButtonAbsX.ButtonPushedFcn = createCallbackFcn(app, @StartButtonAbsXPushed, true);
            app.StartButtonAbsX.Text = 'Start';
            app.mmLabel_2 = uilabel(app.AbsoluteXMovePanel);
            app.mmLabel_2.Text = 'mm';
            app.TravelLimitPanel = uipanel(app.XAxisPanel);
            app.TravelLimitPanel.Title = 'Travel Limit';
            app.XLampLabel = uilabel(app.TravelLimitPanel);
            app.XLampLabel.Text = 'X+';
            app.PlusXLimitLamp = uilamp(app.TravelLimitPanel);
            app.XLamp_2Label = uilabel(app.TravelLimitPanel);
            app.XLamp_2Label.Text = 'X-';
            app.XLamp_2 = uilamp(app.TravelLimitPanel);
            app.YAxisPanel = uipanel(app.GridLayout);
            app.YAxisPanel.Title = 'Y Axis';
            app.GotoHOMEYButton = uibutton(app.YAxisPanel, 'push');
            app.GotoHOMEYButton.ButtonPushedFcn = createCallbackFcn(app, @GotoHOMEYButtonPushed, true);
            app.GotoHOMEYButton.Text = 'Go to HOME Y';
            app.RecordasYTravelLimitButton = uibutton(app.YAxisPanel, 'push');
            app.RecordasYTravelLimitButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasYTravelLimitButtonPushed, true);
            app.RecordasYTravelLimitButton.Text = {'Record as'; 'Y- Travel '; 'Limit'};
            app.PresentYPosLabel = uilabel(app.YAxisPanel);
            app.PresentYPosLabel.Text = 'Present Y Pos';
            app.GotoPARKYButton = uibutton(app.YAxisPanel, 'push');
            app.GotoPARKYButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPARKYButtonPushed, true);
            app.GotoPARKYButton.Text = 'Go to PARK Y';
            app.RecordasYTravelLimitButton_2 = uibutton(app.YAxisPanel, 'push');
            app.RecordasYTravelLimitButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordasYTravelLimitButton_2Pushed, true);
            app.RecordasYTravelLimitButton_2.Text = {'Record as '; 'Y+ Travel '; 'Limit'};
            app.PresentYLimitLabel_3 = uilabel(app.YAxisPanel);
            app.PresentYLimitLabel_3.Text = 'Present Y- Limit';
            app.PresentYLimitLabel_2 = uilabel(app.YAxisPanel);
            app.PresentYLimitLabel_2.Text = 'Present Y+ Limit';
            app.PresentYposEditField = uieditfield(app.YAxisPanel, 'numeric');
            app.PresentYposEditField.ValueDisplayFormat = '%5.3f';
            app.PresentYposEditField.Editable = 'off';
            app.mmLabel_14 = uilabel(app.YAxisPanel);
            app.mmLabel_14.Text = 'mm';
            app.EditField_14 = uieditfield(app.YAxisPanel, 'numeric');
            app.EditField_14.ValueDisplayFormat = '%5.3f';
            app.EditField_14.Editable = 'off';
            app.mmLabel_17 = uilabel(app.YAxisPanel);
            app.mmLabel_17.Text = 'mm';
            app.EditField_16 = uieditfield(app.YAxisPanel, 'numeric');
            app.EditField_16.ValueDisplayFormat = '%5.3f';
            app.EditField_16.Editable = 'off';
            app.mmLabel_19 = uilabel(app.YAxisPanel);
            app.mmLabel_19.Text = 'mm';
            app.RelativeYMovePanel = uipanel(app.YAxisPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.RelativeYMoveEditField = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.RelativeYMoveEditField.ValueDisplayFormat = '%5.3f';
            app.plusYButtonPushed = uibutton(app.RelativeYMovePanel, 'push');
            app.plusYButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @plusYButtonPushedPushed, true);
            app.plusYButtonPushed.Text = '+';
            app.minusYButtonPushed = uibutton(app.RelativeYMovePanel, 'push');
            app.minusYButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @minusYButtonPushedPushed, true);
            app.minusYButtonPushed.Text = '-';
            app.mmLabel_5 = uilabel(app.RelativeYMovePanel);
            app.mmLabel_5.Text = 'mm';
            app.AbsoluteYMovePanel = uipanel(app.YAxisPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.mmLabel_23 = uilabel(app.AbsoluteYMovePanel);
            app.mmLabel_23.Text = 'mm';
            app.AbsoluteYMoveEditField = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.AbsoluteYMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteYMoveEditField.ValueChangedFcn = createCallbackFcn(app, @AbsoluteYMoveEditFieldValueChanged, true);
            app.StartButton_2 = uibutton(app.AbsoluteYMovePanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.TravelLimitPanel_2 = uipanel(app.YAxisPanel);
            app.TravelLimitPanel_2.Title = 'Travel Limit';
            app.YLamp_2Label = uilabel(app.TravelLimitPanel_2);
            app.YLamp_2Label.Text = 'Y+';
            app.YLamp_2 = uilamp(app.TravelLimitPanel_2);
            app.YLampLabel = uilabel(app.TravelLimitPanel_2);
            app.YLampLabel.Text = 'Y-';
            app.YLamp = uilamp(app.TravelLimitPanel_2);
            app.ZAxisPanel = uipanel(app.GridLayout);
            app.ZAxisPanel.Title = 'Z Axis';
            app.GotoHOMEZButton = uibutton(app.ZAxisPanel, 'push');
            app.GotoHOMEZButton.ButtonPushedFcn = createCallbackFcn(app, @GotoHOMEZButtonPushed, true);
            app.GotoHOMEZButton.Text = 'Go to HOME Z';
            app.RecordasZTravelLimitButton = uibutton(app.ZAxisPanel, 'push');
            app.RecordasZTravelLimitButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasZTravelLimitButtonPushed, true);
            app.RecordasZTravelLimitButton.Text = {'Record as'; 'Z- Travel '; 'Limit'};
            app.PresentZPosLabel = uilabel(app.ZAxisPanel);
            app.PresentZPosLabel.Text = 'Present Z Pos';
            app.GotoPARKZButton = uibutton(app.ZAxisPanel, 'push');
            app.GotoPARKZButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPARKZButtonPushed, true);
            app.GotoPARKZButton.Text = 'Go to PARK Z';
            app.RecordasZTravelLimitButton_2 = uibutton(app.ZAxisPanel, 'push');
            app.RecordasZTravelLimitButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordasZTravelLimitButton_2Pushed, true);
            app.RecordasZTravelLimitButton_2.Text = {'Record as '; 'Z+ Travel '; 'Limit'};
            app.PresentZLimitLabel = uilabel(app.ZAxisPanel);
            app.PresentZLimitLabel.Text = 'Present Z- Limit';
            app.PresentZLimitLabel_2 = uilabel(app.ZAxisPanel);
            app.PresentZLimitLabel_2.Text = 'Present Z+ Limit';
            app.PresentZposEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.PresentZposEditField.ValueDisplayFormat = '%5.3f';
            app.PresentZposEditField.Editable = 'off';
            app.mmLabel_13 = uilabel(app.ZAxisPanel);
            app.mmLabel_13.Text = 'mm';
            app.EditField_15 = uieditfield(app.ZAxisPanel, 'numeric');
            app.EditField_15.ValueDisplayFormat = '%5.3f';
            app.EditField_15.Editable = 'off';
            app.mmLabel_18 = uilabel(app.ZAxisPanel);
            app.mmLabel_18.Text = 'mm';
            app.EditField_17 = uieditfield(app.ZAxisPanel, 'numeric');
            app.EditField_17.ValueDisplayFormat = '%5.3f';
            app.EditField_17.Editable = 'off';
            app.mmLabel_20 = uilabel(app.ZAxisPanel);
            app.mmLabel_20.Text = 'mm';
            app.RelativeZMovePanel = uipanel(app.ZAxisPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.mmLabel_9 = uilabel(app.RelativeZMovePanel);
            app.mmLabel_9.Text = 'mm';
            app.RelativeZMoveEditField = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.RelativeZMoveEditField.ValueDisplayFormat = '%5.3f';
            app.plusZButtonPushed = uibutton(app.RelativeZMovePanel, 'push');
            app.plusZButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @plusZButtonPushedPushed, true);
            app.plusZButtonPushed.Text = '+';
            app.minusZButtonPushed = uibutton(app.RelativeZMovePanel, 'push');
            app.minusZButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @minusZButtonPushedPushed, true);
            app.minusZButtonPushed.Text = '-';
            app.AbsoluteZMovePanel = uipanel(app.ZAxisPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.StartButton_3 = uibutton(app.AbsoluteZMovePanel, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start';
            app.AbsoluteZMoveEditField = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.AbsoluteZMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteZMoveEditField.ValueChangedFcn = createCallbackFcn(app, @AbsoluteZMoveEditFieldValueChanged, true);
            app.mmLabel_10 = uilabel(app.AbsoluteZMovePanel);
            app.mmLabel_10.Text = 'mm';
            app.TravelLimitPanel_3 = uipanel(app.ZAxisPanel);
            app.TravelLimitPanel_3.Title = 'Travel Limit';
            app.ZLamp_2Label = uilabel(app.TravelLimitPanel_3);
            app.ZLamp_2Label.Text = 'Z+';
            app.ZLamp_2 = uilamp(app.TravelLimitPanel_3);
            app.ZLampLabel = uilabel(app.TravelLimitPanel_3);
            app.ZLampLabel.Text = 'Z-';
            app.ZLamp = uilamp(app.TravelLimitPanel_3);
            app.MotionSetupPanel = uipanel(app.GridLayout);
            app.MotionSetupPanel.Title = 'Motion Setup';
            app.GotoHOMEButton = uibutton(app.MotionSetupPanel, 'push');
            app.GotoHOMEButton.ButtonPushedFcn = createCallbackFcn(app, @GotoHOMEButtonPushed, true);
            app.GotoHOMEButton.Text = 'Go to HOME';
            app.RecordasHOMEButton = uibutton(app.MotionSetupPanel, 'push');
            app.RecordasHOMEButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasHOMEButtonPushed, true);
            app.RecordasHOMEButton.Text = {'Record as'; 'HOME'};
            app.PresentHOMEXmmLabel = uilabel(app.MotionSetupPanel);
            app.PresentHOMEXmmLabel.Text = {'Present'; 'HOME X'; '(mm)'};
            app.PresentHOMEYmmLabel = uilabel(app.MotionSetupPanel);
            app.PresentHOMEYmmLabel.Text = {'Present'; 'HOME Y'; '(mm)'};
            app.PresentHOMEZmmLabel = uilabel(app.MotionSetupPanel);
            app.PresentHOMEZmmLabel.Text = {'Present'; 'HOME Z'; '(mm)'};
            app.PresentXHomeEditField = uieditfield(app.MotionSetupPanel, 'numeric');
            app.PresentXHomeEditField.ValueDisplayFormat = '%5.3f';
            app.PresentXHomeEditField.Editable = 'off';
            app.PresentYHomeEditField = uieditfield(app.MotionSetupPanel, 'numeric');
            app.PresentYHomeEditField.ValueDisplayFormat = '%5.3f';
            app.PresentYHomeEditField.Editable = 'off';
            app.PresentZHomeEditField = uieditfield(app.MotionSetupPanel, 'numeric');
            app.PresentZHomeEditField.ValueDisplayFormat = '%5.3f';
            app.PresentZHomeEditField.Editable = 'off';
            app.PARKPanel = uipanel(app.GridLayout);
            app.PARKPanel.Title = 'PARK';
            app.PresentPARKZmmLabel = uilabel(app.PARKPanel);
            app.PresentPARKZmmLabel.Text = {'Present'; 'PARK Z'; '(mm)'};
            app.PresentPARKYmmLabel = uilabel(app.PARKPanel);
            app.PresentPARKYmmLabel.Text = {'Present'; 'PARK Y'; '(mm)'};
            app.PresentPARKXmmLabel = uilabel(app.PARKPanel);
            app.PresentPARKXmmLabel.Text = {'Present'; 'PARK X'; '(mm)'};
            app.RecordasPARKButton = uibutton(app.PARKPanel, 'push');
            app.RecordasPARKButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasPARKButtonPushed, true);
            app.RecordasPARKButton.Text = {'Record as'; 'PARK'};
            app.GotoPARKButton = uibutton(app.PARKPanel, 'push');
            app.GotoPARKButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPARKButtonPushed, true);
            app.GotoPARKButton.Text = 'Go to PARK';
            app.PresentXParkEditField = uieditfield(app.PARKPanel, 'numeric');
            app.PresentXParkEditField.ValueDisplayFormat = '%5.3f';
            app.PresentXParkEditField.Editable = 'off';
            app.PresentYParkEditField = uieditfield(app.PARKPanel, 'numeric');
            app.PresentYParkEditField.ValueDisplayFormat = '%5.3f';
            app.PresentYParkEditField.Editable = 'off';
            app.PresentZParkEditField = uieditfield(app.PARKPanel, 'numeric');
            app.PresentZParkEditField.ValueDisplayFormat = '%5.3f';
            app.PresentZParkEditField.Editable = 'off';
            app.AerotechPresentPositionsPanel = uipanel(app.GridLayout);
            app.AerotechPresentPositionsPanel.Title = 'Aerotech Present Positions';
            app.XmmLabel = uilabel(app.AerotechPresentPositionsPanel);
            app.XmmLabel.Text = 'X (mm)';
            app.YmmLabel = uilabel(app.AerotechPresentPositionsPanel);
            app.YmmLabel.Text = 'Y (mm)';
            app.ZmmLabel = uilabel(app.AerotechPresentPositionsPanel);
            app.ZmmLabel.Text = 'Z (mm)';
            app.Panel = uipanel(app.AerotechPresentPositionsPanel);
            app.STOPMOTIONButton = uibutton(app.Panel, 'push');
            app.STOPMOTIONButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMOTIONButtonPushed, true);
            app.STOPMOTIONButton.Text = {'STOP'; 'MOTION'};
            app.BackToMainScreenButton = uibutton(app.Panel, 'push');
            app.BackToMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainScreenButtonPushed, true);
            app.BackToMainScreenButton.Text = 'Back To Main Screen';
            app.PresentXposEditField_2 = uieditfield(app.AerotechPresentPositionsPanel, 'numeric');
            app.PresentXposEditField_2.ValueDisplayFormat = '%5.3f';
            app.PresentXposEditField_2.Editable = 'off';
            app.PresentYposEditField_2 = uieditfield(app.AerotechPresentPositionsPanel, 'numeric');
            app.PresentYposEditField_2.ValueDisplayFormat = '%5.3f';
            app.PresentYposEditField_2.Editable = 'off';
            app.PresentZposEditField_2 = uieditfield(app.AerotechPresentPositionsPanel, 'numeric');
            app.PresentZposEditField_2.ValueDisplayFormat = '%5.3f';
            app.PresentZposEditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.RPASMotionSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_MotionSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASMotionSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASMotionSetupUIFigure)
        end
    end
end