classdef RPAS_MotionSysValSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASMotionSystemValidationSetupUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        Panel_4                        matlab.ui.container.Panel
        PerformMotionValidationCheckButton  matlab.ui.control.Button
        GotoApproachPositionXYZButton  matlab.ui.control.Button
        RecordasCheckingPositionXYZButton  matlab.ui.control.Button
        RecordasApproachPositionXYZButton  matlab.ui.control.Button
        Panel_5                        matlab.ui.container.Panel
        BackToMainScreenButton         matlab.ui.control.Button
        STOPMOTIONButton               matlab.ui.control.Button
        GotoPARKButton_2               matlab.ui.control.Button
        GotoHOMEButton_2               matlab.ui.control.Button
        ZAxisPanel                     matlab.ui.container.Panel
        SYSVALPresentZPosEditField     matlab.ui.control.NumericEditField
        PresentZPosEditFieldLabel      matlab.ui.control.Label
        AbsoluteZMovePanel             matlab.ui.container.Panel
        mmLabel_26                     matlab.ui.control.Label
        SysValStartButtonAbsZ          matlab.ui.control.Button
        SYSVALSETUPAbsoluteZMoveEditField  matlab.ui.control.NumericEditField
        RelativeZMovePanel             matlab.ui.container.Panel
        SYSVALSETUPRelativeZMoveEditField  matlab.ui.control.NumericEditField
        SysValDecrementZButton         matlab.ui.control.Button
        SysValincrementZButton         matlab.ui.control.Button
        mmLabel_25                     matlab.ui.control.Label
        DIALZUpperRecordButton         matlab.ui.control.Button
        DIALZLowerRecordButton         matlab.ui.control.Button
        ZLowerLimit                    matlab.ui.control.NumericEditField
        ZUpperLimit                    matlab.ui.control.NumericEditField
        SYSVALZLampLower               matlab.ui.control.Lamp
        ZLamp_2Label                   matlab.ui.control.Label
        SYSVALZLampUpper               matlab.ui.control.Lamp
        ZLampLabel                     matlab.ui.control.Label
        UpperLimitLabel_3              matlab.ui.control.Label
        TravelLimitLabel_5             matlab.ui.control.Label
        mmLabel_20                     matlab.ui.control.Label
        mmLabel_19                     matlab.ui.control.Label
        LowerLimitLabel_3              matlab.ui.control.Label
        ZAxisValDialIndicatorAllowableReadingLabel  matlab.ui.control.Label
        YAxisPanel                     matlab.ui.container.Panel
        SYSVALPresentYPosEditField     matlab.ui.control.NumericEditField
        PresentYPosEditFieldLabel      matlab.ui.control.Label
        AbsoluteYMovePanel             matlab.ui.container.Panel
        mmLabel_24                     matlab.ui.control.Label
        SysValStartButtonAbsY          matlab.ui.control.Button
        SYSVALSETUPAbsoluteYMoveEditField  matlab.ui.control.NumericEditField
        RelativeYMovePanel             matlab.ui.container.Panel
        SYSVALSETUPRelativeYMoveEditField  matlab.ui.control.NumericEditField
        SysValDecrementYButton         matlab.ui.control.Button
        SysValincrementYButton         matlab.ui.control.Button
        mmLabel_23                     matlab.ui.control.Label
        DIALYUpperRecordButton         matlab.ui.control.Button
        DIALYLowerRecordButton         matlab.ui.control.Button
        YLowerLimit                    matlab.ui.control.NumericEditField
        YUpperLimit                    matlab.ui.control.NumericEditField
        SYSVALYLampLower               matlab.ui.control.Lamp
        YLampLabel                     matlab.ui.control.Label
        SYSVALYLampUpper               matlab.ui.control.Lamp
        YLamp_2Label                   matlab.ui.control.Label
        UpperLimitLabel_4              matlab.ui.control.Label
        TravelLimitLabel_4             matlab.ui.control.Label
        mmLabel_16                     matlab.ui.control.Label
        mmLabel_15                     matlab.ui.control.Label
        LowerLimitLabel_4              matlab.ui.control.Label
        YAxisValDialIndicatorAllowableReadingLabel  matlab.ui.control.Label
        XAxisPanel                     matlab.ui.container.Panel
        SYSVALPresentXPosEditField     matlab.ui.control.NumericEditField
        PresentXPosEditFieldLabel      matlab.ui.control.Label
        AbsoluteXMovePanel             matlab.ui.container.Panel
        mmLabel_22                     matlab.ui.control.Label
        SysValStartButtonAbsX          matlab.ui.control.Button
        SYSVALSETUPAbsoluteXMoveEditField  matlab.ui.control.NumericEditField
        RelativeXMovePanel             matlab.ui.container.Panel
        SYSVALSETUPRelativeXMoveEditField  matlab.ui.control.NumericEditField
        SysValDecrementXButton         matlab.ui.control.Button
        SysValincrementXButton         matlab.ui.control.Button
        mmLabel_21                     matlab.ui.control.Label
        DIALXUpperRecordButton         matlab.ui.control.Button
        DIALXLowerRecordButton         matlab.ui.control.Button
        XLowerLimit                    matlab.ui.control.NumericEditField
        XUpperLimit                    matlab.ui.control.NumericEditField
        SYSVALXLampLower               matlab.ui.control.Lamp
        XLamp_2Label                   matlab.ui.control.Label
        SYSVALXLampUpper               matlab.ui.control.Lamp
        XLampLabel                     matlab.ui.control.Label
        UpperLimitLabel_5              matlab.ui.control.Label
        TravelLimitLabel               matlab.ui.control.Label
        mmLabel_4                      matlab.ui.control.Label
        mmLabel_3                      matlab.ui.control.Label
        LowerLimitLabel_5              matlab.ui.control.Label
        XAxisValDialIndicatorAllowableReadingLabel  matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        Panel_3                        matlab.ui.container.Panel
    end

    
    properties (Access = private)
        systemVar; % variable hold system configurations
        rcdNames={'lowerXLimit', 'upperXLimit', ...
            'lowerYLimit', 'upperYLimit', ...
            'lowerZLimit', 'upperZLimit', ...
            'recordCheckingPosition', ...
            'recordApproachPosition'}; % Description
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        xupdater  % timer to query present x position
        yupdater % timer to query present y position
        zupdater % timer to query present z position
        motionStopped; % Description
    end
    
    methods (Access = private)
        function init(app)
            %read in system setup
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables();
            end
            app.systemVar=systemVar; %read in system setup
            %app.systemVar=savedVariables; % This line need deleted if we want to use global variables
            var=app.systemVar.motionSetupVar;
            var2=app.systemVar.motionSysValSetupVar;
            
            app.XLowerLimit.Value=var2.XlowerLimit;
            app.XUpperLimit.Value=var2.XupperLimit;
            app.YLowerLimit.Value=var2.YlowerLimit;
            app.YUpperLimit.Value=var2.YupperLimit;
            app.ZLowerLimit.Value=var2.ZlowerLimit;
            app.ZUpperLimit.Value=var2.ZupperLimit;

            app.motionStopped=false;
            
            app.xupdater=updatePosition(app.SYSVALPresentXPosEditField, 0, .05, ...
                {app.SYSVALXLampUpper, app.SYSVALXLampLower}, ...
                {var.Xlimit_Plus, var.Xlimit_Minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater=updatePosition(app.SYSVALPresentYPosEditField, 1, .05,...
                {app.SYSVALYLampUpper, app.SYSVALYLampLower}, ...
                {var.Ylimit_Plus, var.Ylimit_Minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater=updatePosition(app.SYSVALPresentZPosEditField, 2, .05, ...
                {app.SYSVALZLampUpper, app.SYSVALZLampLower}, ...
                {var.Zlimit_Plus, var.Zlimit_Minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1);
            app.stopTimer();
        end
        
        function updateRecords(app, rcdName)
            try
                var=app.systemVar.motionSysValSetupVar;
                switch rcdName
                    case app.rcdNames{1}
                        var.XlowerLimit=app.XLowerLimit.Value;
                    case app.rcdNames{2}
                        var.XupperLimit=app.XUpperLimit.Value;
                    case app.rcdNames{3}
                        var.YlowerLimit=app.YLowerLimit.Value;
                    case app.rcdNames{4}
                        var.YupperLimit=app.YUpperLimit.Value;
                    case app.rcdNames{5}
                        var.ZlowerLimit=app.ZLowerLimit.Value;
                    case app.rcdNames{6}
                        var.ZupperLimit=app.ZUpperLimit.Value;
                    case app.rcdNames{7}
                        var.checkingPosition_X=...
                            app.SYSVALPresentXPosEditField.Value;
                        var.checkingPosition_Y=...
                            app.SYSVALPresentYPosEditField.Value;
                        var.checkingPosition_Z=...
                            app.SYSVALPresentZPosEditField.Value;
                    case app.rcdNames{8}
                        var.approachPosition_X=...
                            app.SYSVALPresentXPosEditField.Value;
                        var.approachPosition_Y=...
                            app.SYSVALPresentYPosEditField.Value;
                        var.approachPosition_Z=...
                            app.SYSVALPresentZPosEditField.Value;
                end
                app.systemVar.updateRecords(var, app.RPASMotionSystemValidationSetupUIFigure.Tag);
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
        end
        
        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, PlusLamp, MinusLamp)
            val=round(val*1.0e3)*1.0e-3;

            try
                c1=PlusLamp.Color;
                c2=MinusLamp.Color;
                warning=false;
                if val>upperLimit
                    PlusLamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if val<lowerLimit
                    MinusLamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
                PlusLamp.Color=c1;
                MinusLamp.Color=c2;
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
        end
        
        function flag=confirmRecord(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
            title = 'Confirm';
            selection = uiconfirm(fig, [msg '?'], title,...
                                   'Options',{'Save','Cancel'},...
                                   'DefaultOption', 1,'CancelOption', 2);

            switch selection
                case {'Save'}
                    flag=true;
                otherwise
                    flag=false;
            end
            delete(fig);
            return;
        end
        
        function stopTimer(app)
            app.xupdater.stop();
            app.yupdater.stop();
            app.zupdater.stop();
        end
        
        function results = warningMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);

            title = 'Warning';
            selection = uiconfirm(fig, msg, title,...
                                       'Options',{'OK'},...
                                       'icon', 'warning');
            switch selection
                case {'OK'}
                    %donothing;
                otherwise
                    %donothing;
            end
            delete(fig);
        end
    end

    methods (Access = public)
        
        function close(app)
            app.CloseRequestFcn();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init();
        end

        % Callback function
        function RecordasXAXISCHECKPOSButtonPushed(app, event)
            fig = uifigure;
            msg = 'Record New X-Axis Position?';
            title = 'Confirm Position';
            selection = uiconfirm(fig, msg, title,...
           'Options',{'Save','Cancel'},...
           'DefaultOption', 1,'CancelOption', 2);
        end

        % Callback function
        function RecordasYAXISCHECKPOSButtonPushed(app, event)
             fig = uifigure;
            msg = 'Record New Y-Axis Position?';
            title = 'Confirm Position';
            selection = uiconfirm(fig, msg, title,...
           'Options',{'Save','Cancel'},...
           'DefaultOption', 1,'CancelOption', 2);
        end

        % Callback function
        function RecordasZAXISCHECKPOSButtonPushed(app, event)
             fig = uifigure;
            msg = 'Record New Z-Axis Position?';
            title = 'Confirm Position';
            selection = uiconfirm(fig, msg, title,...
           'Options',{'Save','Cancel'},...
           'DefaultOption', 1,'CancelOption', 2);
        end

        % Callback function
        function SysValincrementXButtonPushed(app, event)
            finalPos=app.SYSVALPresentXPosEditField.Value+app.RelativeXMoveEditField.Value;
            if finalPos>app.PresentXplusLimitEditField.Value
                app.PlusXLimitLamp.Color=app.OverLimitColor; %exceed the Plus Limit
                
                % need confirm with Rich if the following code is needed
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                msg = {'Operation will exceed X+ Travel Limit',
                    'Do you want to move'};
                title = 'Warning';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'Yes','No'},...
                                   'DefaultOption', 1,...
                                   'CancelOption', 2, ...
                                   'icon', 'Warning');

                switch selection
                    case {'Yes'}
                        RelativeXmove(app.RelativeXMoveEditField.Value);
                    otherwise
                        %do nothing
                end
                delete(fig);
            else
                app.PlusXLimitLamp.Color=app.UnderLimitColor; %within the Plus Limit
            end
            if finalPos<app.PresentXminusLimitEditField.Value
                app.SYSVALXLampLower.Color=app.OverLimitColor;
            else
                app.SYSVALXLampLower.Color=app.UnderLimitColor;
            end
            RelativeXmove(app.RelativeXMoveEditField.Value);

        end

        % Button pushed function: GotoHOMEButton_2
        function GotoHOMEButton_2Pushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1,2];
            var=app.systemVar.motionSetupVar;
            dist=[var.Xhome var.Yhome var.Zhome];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: GotoPARKButton_2
        function GotoPARKButton_2Pushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1,2];
            var=app.systemVar.motionSetupVar;
            dist=[var.XPark var.YPark var.ZPark];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: DIALXUpperRecordButton
        function DIALXUpperRecordButtonPushed(app, event)
            if app.confirmRecord('Record Upper X Limit')
                %app.XUpperLimit.Value = app.SYSVALPresentXPosEditField.Value;
                app.updateRecords(app.rcdNames{2});
            end
        end

        % Button pushed function: DIALYUpperRecordButton
        function DIALYUpperRecordButtonPushed(app, event)
            if app.confirmRecord('Record Upper Y Limit')
                %app.YUpperLimit.Value = app.SYSVALPresentYPosEditField.Value;
                app.updateRecords(app.rcdNames{4});
            end
        end

        % Button pushed function: DIALXLowerRecordButton
        function DIALXLowerRecordButtonPushed(app, event)
            if app.confirmRecord('Record Lower X Limit')
                %app.XLowerLimit.Value = app.SYSVALPresentXPosEditField.Value;
                app.updateRecords(app.rcdNames{1});
            end
        end

        % Button pushed function: DIALYLowerRecordButton
        function DIALYLowerRecordButtonPushed(app, event)
            if app.confirmRecord('Record Lower Y Limit')
                %app.YLowerLimit.Value = app.SYSVALPresentYPosEditField.Value;
                app.updateRecords(app.rcdNames{3});
            end
        end

        % Button pushed function: DIALZUpperRecordButton
        function DIALZUpperRecordButtonPushed(app, event)
            if app.confirmRecord('Record Upper Z Limit')
                %app.ZUpperLimit.Value = app.SYSVALPresentZPosEditField.Value;
                app.updateRecords(app.rcdNames{6});
            end
        end

        % Button pushed function: DIALZLowerRecordButton
        function DIALZLowerRecordButtonPushed(app, event)
            if app.confirmRecord('Record Lower Z Limit')
                %app.ZLowerLimit.Value = app.SYSVALPresentZPosEditField.Value;
                app.updateRecords(app.rcdNames{5});
            end
        end

        % Button pushed function: GotoApproachPositionXYZButton
        function GotoApproachPositionXYZButtonPushed(app, event)
            var=app.systemVar.motionSysValSetupVar;
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1,2];
            dist=[var.approachPosition_X ...
                var.approachPosition_Y ...
                var.approachPosition_Z];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: PerformMotionValidationCheckButton
        function PerformMotionValidationCheckButtonPushed(app, event)
            var=app.systemVar.motionSetupVar;
            var2=app.systemVar.motionSysValSetupVar;
            axes = [0,1,2]; % X  (using default names)
            dist=[var.Xhome var.Yhome var.Zhome];
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
            if app.motionStopped
                app.motionStopped=false;
                return;
            else
                pause(1);
            end

            dist=[var2.approachPosition_X ... 
                var2.approachPosition_Y ...
                var2.approachPosition_Z];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
            if app.motionStopped
                app.motionStopped=false;
                return;
            else
                pause(1);
            end

            dist=[var2.checkingPosition_X ...
                var2.checkingPosition_Y ...
                var2.checkingPosition_Z];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: RecordasApproachPositionXYZButton
        function RecordasApproachPositionXYZButtonPushed(app, event)
            if isAuthorized()
                app.updateRecords(app.rcdNames{8});
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: RecordasCheckingPositionXYZButton
        function RecordasCheckingPositionXYZButtonPushed(app, event)
             if isAuthorized()
                app.updateRecords(app.rcdNames{7});
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed;
                    otherwise
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: SysValincrementXButton
        function SysValincrementXButtonPushed2(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALPresentXPosEditField.Value + ...
                    app.SYSVALSETUPRelativeXMoveEditField.Value;
            if app.isValidPosition(val, var.Xlimit_Plus, ...
                    var.Xlimit_Minus, app.SYSVALXLampUpper, app.SYSVALXLampLower)
                RelativeMoving(0, app.SYSVALSETUPRelativeXMoveEditField.Value,speed, app.xupdater);
            end 
        end

        % Button pushed function: SysValDecrementXButton
        function SysValDecrementXButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALPresentXPosEditField.Value - ...
                    app.SYSVALSETUPRelativeXMoveEditField.Value;
            if app.isValidPosition(val, var.Xlimit_Plus, ...
                    var.Xlimit_Minus, app.SYSVALXLampUpper, app.SYSVALXLampLower)
                RelativeMoving(0,-app.SYSVALSETUPRelativeXMoveEditField.Value, speed, app.xupdater);
            end 
        end

        % Button pushed function: SysValStartButtonAbsX
        function SysValStartButtonAbsXPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALSETUPAbsoluteXMoveEditField.Value;
            if app.isValidPosition(val, var.Xlimit_Plus, ...
                    var.Xlimit_Minus, app.SYSVALXLampUpper, app.SYSVALXLampLower)
                ABSMoving(0, val, speed, app.xupdater);
            end
        end

        % Button pushed function: SysValincrementYButton
        function SysValincrementYButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALPresentYPosEditField.Value + ...
                    app.SYSVALSETUPRelativeYMoveEditField.Value;
            if app.isValidPosition(val, var.Ylimit_Plus, ...
                    var.Ylimit_Minus, app.SYSVALYLampUpper, app.SYSVALYLampLower)
                RelativeMoving(1,app.SYSVALSETUPRelativeYMoveEditField.Value, speed, app.yupdater);
            end 
        end

        % Button pushed function: SysValDecrementYButton
        function SysValDecrementYButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALPresentYPosEditField.Value - ...
                    app.SYSVALSETUPRelativeYMoveEditField.Value;
            if app.isValidPosition(val, var.Ylimit_Plus, ...
                    var.Ylimit_Minus, app.SYSVALYLampUpper, app.SYSVALYLampLower)
                RelativeMoving(1,-app.SYSVALSETUPRelativeYMoveEditField.Value, speed, app.yupdater);
            end 
        end

        % Button pushed function: SysValStartButtonAbsY
        function SysValStartButtonAbsYPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALSETUPAbsoluteYMoveEditField.Value;
            if app.isValidPosition(val, var.Ylimit_Plus, ...
                    var.Ylimit_Minus, app.SYSVALYLampUpper, app.SYSVALYLampLower)
                ABSMoving(1, val, speed, app.yupdater);
            end  
        end

        % Button pushed function: SysValincrementZButton
        function SysValincrementZButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALPresentZPosEditField.Value + ...
                    app.SYSVALSETUPRelativeZMoveEditField.Value;
            if app.isValidPosition(val, var.Zlimit_Plus, ...
                    var.Zlimit_Minus, app.SYSVALZLampUpper, app.SYSVALZLampLower)
                RelativeMoving(2,app.SYSVALSETUPRelativeZMoveEditField.Value, speed, app.zupdater);
            end  
        end

        % Button pushed function: SysValDecrementZButton
        function SysValDecrementZButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALPresentZPosEditField.Value - ...
                    app.SYSVALSETUPRelativeZMoveEditField.Value;
            if app.isValidPosition(val, var.Zlimit_Plus, ...
                    var.Zlimit_Minus, app.SYSVALZLampUpper, app.SYSVALZLampLower)
                RelativeMoving(2,-app.SYSVALSETUPRelativeZMoveEditField.Value, speed, app.zupdater);
            end    
        end

        % Button pushed function: SysValStartButtonAbsZ
        function SysValStartButtonAbsZPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.motionSetupVar;
            val=app.SYSVALSETUPAbsoluteZMoveEditField.Value;
            if app.isValidPosition(val, var.Zlimit_Plus, ...
                    var.Zlimit_Minus, app.SYSVALZLampUpper, app.SYSVALZLampLower)
                ABSMoving(2, val, speed, app.zupdater);
            end    
        end

        % Button pushed function: STOPMOTIONButton
        function STOPMOTIONButtonPushed(app, event)
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            app.motionStopped=true;
        end

        % Close request function: RPASMotionSystemValidationSetupUIFigure
        function CloseRequestFcn(app, event)
            app.STOPMOTIONButtonPushed();
            delete(app)
            
        end

        % Button pushed function: BackToMainScreenButton
        function BackToMainScreenButtonPushed(app, event)
            app.CloseRequestFcn();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASMotionSystemValidationSetupUIFigure = uifigure('Visible', 'off');
            app.RPASMotionSystemValidationSetupUIFigure.Name = 'RPAS Motion System Validation Setup';
            app.RPASMotionSystemValidationSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.RPASMotionSystemValidationSetupUIFigure.Tag = 'motionSysValSetup';
            app.GridLayout = uigridlayout(app.RPASMotionSystemValidationSetupUIFigure);
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_2 = uipanel(app.GridLayout);
            app.XAxisPanel = uipanel(app.GridLayout);
            app.XAxisPanel.Title = 'X Axis';
            app.XAxisValDialIndicatorAllowableReadingLabel = uilabel(app.XAxisPanel);
            app.XAxisValDialIndicatorAllowableReadingLabel.Text = 'X Axis Val Dial Indicator Allowable Reading';
            app.LowerLimitLabel_5 = uilabel(app.XAxisPanel);
            app.LowerLimitLabel_5.Text = 'Lower Limit';
            app.mmLabel_3 = uilabel(app.XAxisPanel);
            app.mmLabel_3.Text = 'mm';
            app.mmLabel_4 = uilabel(app.XAxisPanel);
            app.mmLabel_4.Text = 'mm';
            app.TravelLimitLabel = uilabel(app.XAxisPanel);
            app.TravelLimitLabel.Text = {'Travel'; 'Limit'};
            app.UpperLimitLabel_5 = uilabel(app.XAxisPanel);
            app.UpperLimitLabel_5.Text = 'Upper Limit';
            app.XLampLabel = uilabel(app.XAxisPanel);
            app.XLampLabel.Text = 'X+';
            app.SYSVALXLampUpper = uilamp(app.XAxisPanel);
            app.XLamp_2Label = uilabel(app.XAxisPanel);
            app.XLamp_2Label.Text = 'X-';
            app.SYSVALXLampLower = uilamp(app.XAxisPanel);
            app.XUpperLimit = uieditfield(app.XAxisPanel, 'numeric');
            app.XUpperLimit.ValueDisplayFormat = '%5.3f';
            app.XLowerLimit = uieditfield(app.XAxisPanel, 'numeric');
            app.XLowerLimit.ValueDisplayFormat = '%5.3f';
            app.DIALXLowerRecordButton = uibutton(app.XAxisPanel, 'push');
            app.DIALXLowerRecordButton.ButtonPushedFcn = createCallbackFcn(app, @DIALXLowerRecordButtonPushed, true);
            app.DIALXLowerRecordButton.Text = 'RECORD';
            app.DIALXUpperRecordButton = uibutton(app.XAxisPanel, 'push');
            app.DIALXUpperRecordButton.ButtonPushedFcn = createCallbackFcn(app, @DIALXUpperRecordButtonPushed, true);
            app.DIALXUpperRecordButton.Text = 'RECORD';
            app.RelativeXMovePanel = uipanel(app.XAxisPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.mmLabel_21 = uilabel(app.RelativeXMovePanel);
            app.mmLabel_21.Text = 'mm';
            app.SysValincrementXButton = uibutton(app.RelativeXMovePanel, 'push');
            app.SysValincrementXButton.ButtonPushedFcn = createCallbackFcn(app, @SysValincrementXButtonPushed2, true);
            app.SysValincrementXButton.Text = '+';
            app.SysValDecrementXButton = uibutton(app.RelativeXMovePanel, 'push');
            app.SysValDecrementXButton.ButtonPushedFcn = createCallbackFcn(app, @SysValDecrementXButtonPushed, true);
            app.SysValDecrementXButton.Text = '-';
            app.SYSVALSETUPRelativeXMoveEditField = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.SYSVALSETUPRelativeXMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteXMovePanel = uipanel(app.XAxisPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.SYSVALSETUPAbsoluteXMoveEditField = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.SYSVALSETUPAbsoluteXMoveEditField.ValueDisplayFormat = '%5.3f';
            app.SysValStartButtonAbsX = uibutton(app.AbsoluteXMovePanel, 'push');
            app.SysValStartButtonAbsX.ButtonPushedFcn = createCallbackFcn(app, @SysValStartButtonAbsXPushed, true);
            app.SysValStartButtonAbsX.Text = 'Start';
            app.mmLabel_22 = uilabel(app.AbsoluteXMovePanel);
            app.mmLabel_22.Text = 'mm';
            app.PresentXPosEditFieldLabel = uilabel(app.XAxisPanel);
            app.PresentXPosEditFieldLabel.Text = 'Present X Pos';
            app.SYSVALPresentXPosEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.SYSVALPresentXPosEditField.ValueDisplayFormat = '%5.3f';
            app.SYSVALPresentXPosEditField.Editable = 'off';
            app.YAxisPanel = uipanel(app.GridLayout);
            app.YAxisPanel.Title = 'Y Axis';
            app.YAxisValDialIndicatorAllowableReadingLabel = uilabel(app.YAxisPanel);
            app.YAxisValDialIndicatorAllowableReadingLabel.Text = 'Y Axis Val Dial Indicator Allowable Reading';
            app.LowerLimitLabel_4 = uilabel(app.YAxisPanel);
            app.LowerLimitLabel_4.Text = 'Lower Limit';
            app.mmLabel_15 = uilabel(app.YAxisPanel);
            app.mmLabel_15.Text = 'mm';
            app.mmLabel_16 = uilabel(app.YAxisPanel);
            app.mmLabel_16.Text = 'mm';
            app.TravelLimitLabel_4 = uilabel(app.YAxisPanel);
            app.TravelLimitLabel_4.Text = {'Travel'; 'Limit'};
            app.UpperLimitLabel_4 = uilabel(app.YAxisPanel);
            app.UpperLimitLabel_4.Text = 'Upper Limit';
            app.YLamp_2Label = uilabel(app.YAxisPanel);
            app.YLamp_2Label.Text = 'Y+';
            app.SYSVALYLampUpper = uilamp(app.YAxisPanel);
            app.YLampLabel = uilabel(app.YAxisPanel);
            app.YLampLabel.Text = 'Y-';
            app.SYSVALYLampLower = uilamp(app.YAxisPanel);
            app.YUpperLimit = uieditfield(app.YAxisPanel, 'numeric');
            app.YUpperLimit.ValueDisplayFormat = '%5.3f';
            app.YLowerLimit = uieditfield(app.YAxisPanel, 'numeric');
            app.YLowerLimit.ValueDisplayFormat = '%5.3f';
            app.DIALYLowerRecordButton = uibutton(app.YAxisPanel, 'push');
            app.DIALYLowerRecordButton.ButtonPushedFcn = createCallbackFcn(app, @DIALYLowerRecordButtonPushed, true);
            app.DIALYLowerRecordButton.Text = 'RECORD';
            app.DIALYUpperRecordButton = uibutton(app.YAxisPanel, 'push');
            app.DIALYUpperRecordButton.ButtonPushedFcn = createCallbackFcn(app, @DIALYUpperRecordButtonPushed, true);
            app.DIALYUpperRecordButton.Text = 'RECORD';
            app.RelativeYMovePanel = uipanel(app.YAxisPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.mmLabel_23 = uilabel(app.RelativeYMovePanel);
            app.mmLabel_23.Text = 'mm';
            app.SysValincrementYButton = uibutton(app.RelativeYMovePanel, 'push');
            app.SysValincrementYButton.ButtonPushedFcn = createCallbackFcn(app, @SysValincrementYButtonPushed, true);
            app.SysValincrementYButton.Text = '+';
            app.SysValDecrementYButton = uibutton(app.RelativeYMovePanel, 'push');
            app.SysValDecrementYButton.ButtonPushedFcn = createCallbackFcn(app, @SysValDecrementYButtonPushed, true);
            app.SysValDecrementYButton.Text = '-';
            app.SYSVALSETUPRelativeYMoveEditField = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.SYSVALSETUPRelativeYMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteYMovePanel = uipanel(app.YAxisPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.SYSVALSETUPAbsoluteYMoveEditField = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.SYSVALSETUPAbsoluteYMoveEditField.ValueDisplayFormat = '%5.3f';
            app.SysValStartButtonAbsY = uibutton(app.AbsoluteYMovePanel, 'push');
            app.SysValStartButtonAbsY.ButtonPushedFcn = createCallbackFcn(app, @SysValStartButtonAbsYPushed, true);
            app.SysValStartButtonAbsY.Text = 'Start';
            app.mmLabel_24 = uilabel(app.AbsoluteYMovePanel);
            app.mmLabel_24.Text = 'mm';
            app.PresentYPosEditFieldLabel = uilabel(app.YAxisPanel);
            app.PresentYPosEditFieldLabel.Text = 'Present Y Pos';
            app.SYSVALPresentYPosEditField = uieditfield(app.YAxisPanel, 'numeric');
            app.SYSVALPresentYPosEditField.ValueDisplayFormat = '%5.3f';
            app.SYSVALPresentYPosEditField.Editable = 'off';
            app.ZAxisPanel = uipanel(app.GridLayout);
            app.ZAxisPanel.Title = 'Z Axis';
            app.ZAxisValDialIndicatorAllowableReadingLabel = uilabel(app.ZAxisPanel);
            app.ZAxisValDialIndicatorAllowableReadingLabel.Text = 'Z Axis Val Dial Indicator Allowable Reading';
            app.LowerLimitLabel_3 = uilabel(app.ZAxisPanel);
            app.LowerLimitLabel_3.Text = 'Lower Limit';
            app.mmLabel_19 = uilabel(app.ZAxisPanel);
            app.mmLabel_19.Text = 'mm';
            app.mmLabel_20 = uilabel(app.ZAxisPanel);
            app.mmLabel_20.Text = 'mm';
            app.TravelLimitLabel_5 = uilabel(app.ZAxisPanel);
            app.TravelLimitLabel_5.Text = {'Travel'; 'Limit'};
            app.UpperLimitLabel_3 = uilabel(app.ZAxisPanel);
            app.UpperLimitLabel_3.Text = 'Upper Limit';
            app.ZLampLabel = uilabel(app.ZAxisPanel);
            app.ZLampLabel.Text = 'Z+';
            app.SYSVALZLampUpper = uilamp(app.ZAxisPanel);
            app.ZLamp_2Label = uilabel(app.ZAxisPanel);
            app.ZLamp_2Label.Text = 'Z-';
            app.SYSVALZLampLower = uilamp(app.ZAxisPanel);
            app.ZUpperLimit = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZUpperLimit.ValueDisplayFormat = '%5.3f';
            app.ZLowerLimit = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZLowerLimit.ValueDisplayFormat = '%5.3f';
            app.DIALZLowerRecordButton = uibutton(app.ZAxisPanel, 'push');
            app.DIALZLowerRecordButton.ButtonPushedFcn = createCallbackFcn(app, @DIALZLowerRecordButtonPushed, true);
            app.DIALZLowerRecordButton.Text = 'RECORD';
            app.DIALZUpperRecordButton = uibutton(app.ZAxisPanel, 'push');
            app.DIALZUpperRecordButton.ButtonPushedFcn = createCallbackFcn(app, @DIALZUpperRecordButtonPushed, true);
            app.DIALZUpperRecordButton.Text = 'RECORD';
            app.RelativeZMovePanel = uipanel(app.ZAxisPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.mmLabel_25 = uilabel(app.RelativeZMovePanel);
            app.mmLabel_25.Text = 'mm';
            app.SysValincrementZButton = uibutton(app.RelativeZMovePanel, 'push');
            app.SysValincrementZButton.ButtonPushedFcn = createCallbackFcn(app, @SysValincrementZButtonPushed, true);
            app.SysValincrementZButton.Text = '+';
            app.SysValDecrementZButton = uibutton(app.RelativeZMovePanel, 'push');
            app.SysValDecrementZButton.ButtonPushedFcn = createCallbackFcn(app, @SysValDecrementZButtonPushed, true);
            app.SysValDecrementZButton.Text = '-';
            app.SYSVALSETUPRelativeZMoveEditField = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.SYSVALSETUPRelativeZMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteZMovePanel = uipanel(app.ZAxisPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.SYSVALSETUPAbsoluteZMoveEditField = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.SYSVALSETUPAbsoluteZMoveEditField.ValueDisplayFormat = '%5.3f';
            app.SysValStartButtonAbsZ = uibutton(app.AbsoluteZMovePanel, 'push');
            app.SysValStartButtonAbsZ.ButtonPushedFcn = createCallbackFcn(app, @SysValStartButtonAbsZPushed, true);
            app.SysValStartButtonAbsZ.Text = 'Start';
            app.mmLabel_26 = uilabel(app.AbsoluteZMovePanel);
            app.mmLabel_26.Text = 'mm';
            app.PresentZPosEditFieldLabel = uilabel(app.ZAxisPanel);
            app.PresentZPosEditFieldLabel.Text = 'Present Z Pos';
            app.SYSVALPresentZPosEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.SYSVALPresentZPosEditField.ValueDisplayFormat = '%5.3f';
            app.SYSVALPresentZPosEditField.Editable = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.Panel_4 = uipanel(app.Panel);
            app.GotoHOMEButton_2 = uibutton(app.Panel_4, 'push');
            app.GotoHOMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @GotoHOMEButton_2Pushed, true);
            app.GotoHOMEButton_2.Text = 'Go to HOME';
            app.GotoPARKButton_2 = uibutton(app.Panel_4, 'push');
            app.GotoPARKButton_2.ButtonPushedFcn = createCallbackFcn(app, @GotoPARKButton_2Pushed, true);
            app.GotoPARKButton_2.Text = 'Go to PARK';
            app.Panel_5 = uipanel(app.Panel_4);
            app.STOPMOTIONButton = uibutton(app.Panel_5, 'push');
            app.STOPMOTIONButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMOTIONButtonPushed, true);
            app.STOPMOTIONButton.Text = {'STOP'; 'MOTION'};
            app.BackToMainScreenButton = uibutton(app.Panel_5, 'push');
            app.BackToMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainScreenButtonPushed, true);
            app.BackToMainScreenButton.Text = 'Back To Main Screen';
            app.RecordasApproachPositionXYZButton = uibutton(app.Panel_4, 'push');
            app.RecordasApproachPositionXYZButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasApproachPositionXYZButtonPushed, true);
            app.RecordasApproachPositionXYZButton.Text = 'Record as Approach Position (X/Y/Z)';
            app.RecordasCheckingPositionXYZButton = uibutton(app.Panel_4, 'push');
            app.RecordasCheckingPositionXYZButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasCheckingPositionXYZButtonPushed, true);
            app.RecordasCheckingPositionXYZButton.Text = 'Record as Checking Position (X/Y/Z)';
            app.GotoApproachPositionXYZButton = uibutton(app.Panel_4, 'push');
            app.GotoApproachPositionXYZButton.ButtonPushedFcn = createCallbackFcn(app, @GotoApproachPositionXYZButtonPushed, true);
            app.GotoApproachPositionXYZButton.Text = 'Go to Approach Position (X/Y/Z)';
            app.PerformMotionValidationCheckButton = uibutton(app.Panel_4, 'push');
            app.PerformMotionValidationCheckButton.ButtonPushedFcn = createCallbackFcn(app, @PerformMotionValidationCheckButtonPushed, true);
            app.PerformMotionValidationCheckButton.Text = 'Perform Motion Validation Check';
            % Show the figure after all components are created
            app.RPASMotionSystemValidationSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_MotionSysValSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASMotionSystemValidationSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASMotionSystemValidationSetupUIFigure)
        end
    end
end