classdef RPAS_MotionSys_Val < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASMotionSystemValidationUIFigure  matlab.ui.Figure
        XAxisPanel                  matlab.ui.container.Panel
        SYSVALXLampLower            matlab.ui.control.Lamp
        XLamp_2Label                matlab.ui.control.Label
        SYSVALXLampUpper            matlab.ui.control.Lamp
        XLampLabel                  matlab.ui.control.Label
        TravelLimitLabel            matlab.ui.control.Label
        HomeStageButton_X           matlab.ui.control.Button
        PresentXPosPanel            matlab.ui.container.Panel
        mmLabel_27                  matlab.ui.control.Label
        SYSVALPresentXPosEditField  matlab.ui.control.NumericEditField
        StagePowerButton_X          matlab.ui.control.Button
        XStageHomedLamp             matlab.ui.control.Lamp
        XStageHomedLampLabel        matlab.ui.control.Label
        XStageOnLamp                matlab.ui.control.Lamp
        XStageOnLampLabel           matlab.ui.control.Label
        YAxisPanel                  matlab.ui.container.Panel
        SYSVALYLampLower            matlab.ui.control.Lamp
        YLampLabel                  matlab.ui.control.Label
        SYSVALYLampUpper            matlab.ui.control.Lamp
        YLamp_2Label                matlab.ui.control.Label
        TravelLimitLabel_2          matlab.ui.control.Label
        YStageHomedLamp             matlab.ui.control.Lamp
        YStageHomedLampLabel        matlab.ui.control.Label
        YStageOnLamp                matlab.ui.control.Lamp
        YStageOnLampLabel           matlab.ui.control.Label
        HomeStageButton_Y           matlab.ui.control.Button
        StagePowerButton_Y          matlab.ui.control.Button
        PresentYPosPanel            matlab.ui.container.Panel
        mmLabel_22                  matlab.ui.control.Label
        SYSVALPresentYPosEditField  matlab.ui.control.NumericEditField
        ZAxisPanel                  matlab.ui.container.Panel
        SYSVALZLampLower            matlab.ui.control.Lamp
        ZLamp_2Label                matlab.ui.control.Label
        SYSVALZLampUpper            matlab.ui.control.Lamp
        ZLampLabel                  matlab.ui.control.Label
        TravelLimitLabel_3          matlab.ui.control.Label
        ZStageHomedLamp             matlab.ui.control.Lamp
        ZStageHomedLampLabel        matlab.ui.control.Label
        ZStageOnLamp                matlab.ui.control.Lamp
        ZStageOnLampLabel           matlab.ui.control.Label
        HomeStageButton_Z           matlab.ui.control.Button
        StagePowerButton_Z          matlab.ui.control.Button
        PresentZPosPanel            matlab.ui.container.Panel
        mmLabel_17                  matlab.ui.control.Label
        SYSVALPresentZPosEditField  matlab.ui.control.NumericEditField
        MotionSysValidationPanel    matlab.ui.container.Panel
        DialIndicatorPanel          matlab.ui.container.Panel
        ZDialindicatorEditField     matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        YDialindicatorEditField     matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        XDialindicatorEditField     matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        ValidateXYZPositionandGoToParkButton  matlab.ui.control.Button
        MSVGOTPARKButton            matlab.ui.control.Button
        MSVGOTOHOMEButton           matlab.ui.control.Button
        HomeALLStagesButton         matlab.ui.control.Button
        StartMotionSystemValidationButton  matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        BackToMainScreenButton      matlab.ui.control.Button
        STOPMOTIONButton            matlab.ui.control.Button
    end

    
        properties (Access = private)
        systemVar; % variable hold system configurations
        rcdNames={'recordAxeLimit', 'recordCheckingPosition', ...
            'recordApproachPosition'} % Description
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        EnableColors=struct('Enabled', [0, 1, 0.5], 'Disabled', [0.6, 0, 0]);
        xupdater  % timer to query present x position
        yupdater % timer to query present y position
        zupdater % timer to query present z position
        
        % travel limits
        XplusLimit; 
        XminusLimit;
        YplusLimit; 
        YminusLimit;
        ZplusLimit; 
        ZminusLimit;
        
        motionStopped; % Description
    end
    
    methods (Access = private)
        
        function init(app)   
            %read in system setup
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables();
            end
            app.systemVar=systemVar; %read in system setup'
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end

            var=app.systemVar.motionSetupVar;
            var2=app.systemVar.motionSysValSetupVar;
            
            if 0
                var=app.systemVar.motionSysValSetupVar;
                app.XplusLimit = var.XupperLimit; 
                app.XminusLimit = var.XlowerLimit; 
                app.YplusLimit = var.YupperLimit; 
                app.YminusLimit = var.YlowerLimit; 
                app.ZplusLimit = var.ZupperLimit; 
                app.ZminusLimit = var.ZlowerLimit;
            else
                var=app.systemVar.motionSetupVar;
                app.XplusLimit = var.Xlimit_Plus;
                app.XminusLimit = var.Xlimit_Minus;
                app.YplusLimit = var.Ylimit_Plus;
                app.YminusLimit = var.Ylimit_Minus;
                app.ZplusLimit = var.Zlimit_Plus;
                app.ZminusLimit = var.Zlimit_Minus;
            end

            app.motionStopped=false;

            
            if RPAS_C.A3200Available
                % Retrieves the entire DriveStatus word for x-axis:
                handle=A3200Connect;
                drive=A3200StatusItem.DriveStatus;
                drives=[drive, drive, drive];
                flags=[0, 0, 0];
                temp = A3200StatusGetItems(handle, [0, 1, 2], drives, flags);
                % Retrieves the Enabled bit from the DriveStatus word:
                status = bitget(temp, A3200DriveStatus.Enabled);

                if status(1)
                    app.XStageOnLamp.Color=app.EnableColors.Enabled;
                else
                    app.XStageOnLamp.Color=app.EnableColors.Disabled;
                end

                if status(2)
                    app.YStageOnLamp.Color=app.EnableColors.Enabled;
                else
                    app.YStageOnLamp.Color=app.EnableColors.Disabled;
                end

                if status(3)
                    app.ZStageOnLamp.Color=app.EnableColors.Enabled;
                else
                    app.ZStageOnLamp.Color=app.EnableColors.Disabled;
                end

                axisStatus=A3200StatusItem.AxisStatus;
                axesStatus=[axisStatus, axisStatus, axisStatus];

                % Retrieves the Homed bit from the AxisStatus word:
                temp = A3200StatusGetItems(A3200Connect, [0,1,2], axesStatus, flags);
                status = bitget(temp, A3200AxisStatus.Homed);

                if status(1)
                    app.XStageHomedLamp.Color=app.EnableColors.Enabled;
                else
                    app.XStageHomedLamp.Color=app.EnableColors.Disabled;
                end

                if status(2)
                    app.YStageHomedLamp.Color=app.EnableColors.Enabled;
                else
                    app.YStageHomedLamp.Color=app.EnableColors.Disabled;
                end

                if status(3)
                    app.ZStageHomedLamp.Color=app.EnableColors.Enabled;
                else
                    app.ZStageHomedLamp.Color=app.EnableColors.Disabled;
                end

            else
                app.XStageOnLamp.Color=app.EnableColors.Disabled;
                app.YStageOnLamp.Color=app.EnableColors.Disabled;
                app.ZStageOnLamp.Color=app.EnableColors.Disabled;

                app.XStageHomedLamp.Color=app.EnableColors.Disabled;
                app.YStageHomedLamp.Color=app.EnableColors.Disabled;
                app.ZStageHomedLamp.Color=app.EnableColors.Disabled;
            end
            
            app.xupdater=updatePosition(app.SYSVALPresentXPosEditField, 0, .05, ...
                {app.SYSVALXLampUpper, app.SYSVALXLampLower}, ...
                {app.XplusLimit, app.XminusLimit}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater=updatePosition(app.SYSVALPresentYPosEditField, 1, .05, ...
                {app.SYSVALYLampUpper, app.SYSVALYLampLower}, ...
                {app.YplusLimit, app.YminusLimit}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater=updatePosition(app.SYSVALPresentZPosEditField, 2, .05, ...
                {app.SYSVALZLampUpper, app.SYSVALZLampLower}, ...
                {app.ZplusLimit, app.ZminusLimit}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1);
            app.stopTimer();
        end
        function updateRecords(app, rcdName)
            try
                var=struct('XlowerLimit', app.DialLowerLimitXEditField.Value, ...
                    'XupperLimit', app.DialUpperLimitXEditField.Value, ...
                    'YlowerLimit', app.YDialLowerLimitYEditField.Value, ...
                    'YupperLimit', app.YDialUpperLimitYEditField.Value, ...
                    'ZlowerLimit', app.DialLowerLimitZEditField.Value, ...
                    'ZupperLimit', app.DialUpperLimitZEditField.Value, ...
                    'approachPosition_X', 0, ...
                    'approachPosition_Y', 0, ...
                    'approachPosition_Z', 0, ...
                    'checkingPosition_X', 0, ...
                    'checkingPosition_Y', 0, ...
                    'checkingPosition_Z', 0);
                switch rcdName
                    case app.rcdNames{2}
                        var.checkingPosition_X=...
                            app.PresentXPosPanel.Value;
                        var.checkingPosition_Y=...
                            app.SYSVALPresentYPosEditField.Value;
                        var.checkingPosition_Z=...
                            app.SYSVALPresentZPosEditField.Value;
                    case app.rcdNames{3}
                        var.approachPosition_X=...
                            app.PresentXPosPanel.Value;
                        var.approachPosition_Y=...
                            app.SYSVALPresentYPosEditField.Value;
                        var.approachPosition_Z=...
                            app.SYSVALPresentZPosEditField.Value;
                end
                app.systemVar.updateRecords(var, app.RPASMotionSystemValidationUIFigure.Tag);
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
        end
        
        
        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, PlusLamp, MinusLamp)
            val=round(val*1.0e3)*1.0e-3;

            try
                c1=PlusLamp.Color;
                c2=MinusLamp.Color;
                warning=false;
                if val>upperLimit
                    PlusLamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if val<lowerLimit
                    MinusLamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
                PlusLamp.Color=c1;
                MinusLamp.Color=c2;
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
        end
        
        function flag=confirmRecord(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
            title = 'Confirm';
            selection = uiconfirm(fig, [msg '?'], title,...
                                   'Options',{'Save','Cancel'},...
                                   'DefaultOption', 1,'CancelOption', 2);

            switch selection
                case {'Save'}
                    flag=true;
                otherwise
                    flag=false;
            end
            delete(fig);
            return;
        end
        
        function stopTimer(app)
            app.xupdater.stop();
            app.yupdater.stop();
            app.zupdater.stop();
        end
        
        function warningMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Warning';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'icon', 'warning');
            switch selection
                case {'OK'}
                otherwise
            end
            delete(fig);
        end
        
        function showInfoMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Info';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'icon', 'info');
            switch selection
                case {'OK'}
                otherwise
            end
            delete(fig);
        end
    end

    methods (Access = public)
        function close(app)
            app.RPASMotionSystemValidationUIFigureCloseRequest();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init;
        end

        % Button pushed function: HomeStageButton_X
        function HomeStageButton_XPushed(app, event)
            status=all(app.XStageOnLamp.Color==app.EnableColors.Enabled);
            if status
                HomeStage(0,app.xupdater, app.XStageHomedLamp, app.EnableColors);
            else 
                app.warningMsg('Please Enable X Stage by Clicking "Stage Power"');
            end
            
        end

        % Button pushed function: HomeStageButton_Y
        function HomeStageButton_YPushed(app, event)
            status=all(app.YStageOnLamp.Color==app.EnableColors.Enabled);
            if status
                HomeStage(1,app.yupdater, app.YStageHomedLamp, app.EnableColors);
            else 
                app.warningMsg('Please Enable Y Stage by Clicking "Stage Power"');
            end
            
        end

        % Button pushed function: HomeStageButton_Z
        function HomeStageButton_ZPushed(app, event)
            status=all(app.ZStageOnLamp.Color==app.EnableColors.Enabled);
            if status
                HomeStage(2,app.zupdater, app.ZStageHomedLamp, app.EnableColors);
            else 
                app.warningMsg('Please Enable Z Stage by Clicking "Stage Power"');
            end
            
        end

        % Button pushed function: StagePowerButton_Z
        function StagePowerButton_ZPushed(app, event)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            if RPAS_C.A3200Available
                status=all(app.ZStageOnLamp.Color==app.EnableColors.Enabled);

%                 if status %drive will be disabled so disable the homed lamp
%                     app.ZStageHomedLamp.Color=app.EnableColors.Disabled;
%                 else % drive will be enabled so enable the homed lamp if it is alreqdy homed
%                     temp = A3200StatusGetItem(A3200Connect, 2, ...
%                         A3200StatusItem.AxisStatus, 0);
%                     homeStatus = bitget(temp, A3200AxisStatus.Homed);
%                     if homeStatus
%                         app.ZStageHomedLamp.Color=app.EnableColors.Enabled;
%                     end
%                 end
                changeDriveStatus(2, status, app.ZStageOnLamp, app.EnableColors);
            end
        end

        % Button pushed function: StagePowerButton_Y
        function StagePowerButton_YPushed(app, event)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            if RPAS_C.A3200Available
                status=all(app.YStageOnLamp.Color==app.EnableColors.Enabled);

%                 if status %drive will be disabled so disable the homed lamp
%                     app.YStageHomedLamp.Color=app.EnableColors.Disabled;
%                 else % drive will be enabled so enable the homed lamp if it is alreqdy homed
%                     temp = A3200StatusGetItem(A3200Connect, 1, ...
%                         A3200StatusItem.AxisStatus, 0);
%                     homeStatus = bitget(temp, A3200AxisStatus.Homed);
%                     if homeStatus
%                         app.YStageHomedLamp.Color=app.EnableColors.Enabled;
%                     end
%                 end
                changeDriveStatus(1, status, app.YStageOnLamp, app.EnableColors);
            end
        end

        % Button pushed function: StagePowerButton_X
        function StagePowerButton_XPushed(app, event)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            if RPAS_C.A3200Available
                status=all(app.XStageOnLamp.Color==app.EnableColors.Enabled);
%                 if status %drive will be disabled so disable the homed lamp
%                     app.XStageHomedLamp.Color=app.EnableColors.Disabled;
%                 else % drive will be enabled so enable the homed lamp if it is alreqdy homed
%                     temp = A3200StatusGetItem(A3200Connect, 0, ...
%                         A3200StatusItem.AxisStatus, 0);
%                     homeStatus = bitget(temp, A3200AxisStatus.Homed)
%                     if homeStatus
%                         app.XStageHomedLamp.Color=app.EnableColors.Enabled;
%                     end
%                 end
                changeDriveStatus(0, status, app.XStageOnLamp, app.EnableColors);
            end
        end

        % Button pushed function: HomeALLStagesButton
        function HomeALLStagesButtonPushed(app, event)
             axes=[0,1,2];
             dist=[0, 0, 0];
             status=all(app.XStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.YStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.ZStageOnLamp.Color==app.EnableColors.Enabled);
             if status
                HomeStage(axes, [app.xupdater, app.yupdater, app.zupdater], ...
                    [app.XStageHomedLamp, app.YStageHomedLamp, app.ZStageHomedLamp], ...
                    app.EnableColors);
             else
                 app.warningMsg('Please Enable all Stages by Clicking "Stage Power" buttons.');
             end
        end

        % Button pushed function: MSVGOTOHOMEButton
        function MSVGOTOHOMEButtonPushed(app, event)
            status=all(app.XStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.YStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.ZStageOnLamp.Color==app.EnableColors.Enabled);
            if status
                speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
                axes=[0,1,2];
                var=app.systemVar.motionSetupVar;
                dist=[var.Xhome var.Yhome var.Zhome];
                ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
            else
                 app.warningMsg('Please Enable all Stages by Clicking "Stage Power" buttons.');
            end
        end

        % Button pushed function: MSVGOTPARKButton
        function MSVGOTPARKButtonPushed(app, event)
            status=all(app.XStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.YStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.ZStageOnLamp.Color==app.EnableColors.Enabled);
            if status
                speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
                axes=[0,1,2];
                var=app.systemVar.motionSetupVar;
                dist=[var.XPark var.YPark var.ZPark];
                ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
            else
                 app.warningMsg('Please Enable all Stages by Clicking "Stage Power" buttons.');
            end
        end

        % Button pushed function: STOPMOTIONButton
        function STOPMOTIONButtonPushed(app, event)
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            app.motionStopped=true;
        end

        % Window key press function: RPASMotionSystemValidationUIFigure
        function MSVUIWindowKeyPress(app, event)
            key = event.Key;
            if strcmpi(key,'s')
          % the key that was pressed was the space bar so set focus to 
          % the snap button
          STOPMOTIONButtonPushed(app,event);
            end
        end

        % Button pushed function: BackToMainScreenButton
        function BackToMainScreenButtonPushed(app, event)
            app.RPASMotionSystemValidationUIFigureCloseRequest();
        end

        % Button pushed function: StartMotionSystemValidationButton
        function StartMotionSystemValidationButtonPushed(app, event)
            status=all(app.XStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.YStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.ZStageOnLamp.Color==app.EnableColors.Enabled);
            if status
                var=app.systemVar.motionSetupVar;
                var2=app.systemVar.motionSysValSetupVar;
    
                % moving to home position
                axes = [0,1,2]; % X  (using default names)
                speed=5;
                dist=[var.Xhome var.Yhome var.Zhome];
                ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
                if app.motionStopped
                    app.motionStopped=false;
                    return;
                else
                    pause(1);
                end
            
                % moving to approch position
                dist=[var2.approachPosition_X var2.approachPosition_Y var2.approachPosition_Z];
                ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
                if app.motionStopped
                    app.motionStopped=false;
                    return;
                else
                    pause(1);
                end

                %moving to checking position
                speed=speed/10;
                dist=[var2.checkingPosition_X var2.checkingPosition_Y var2.checkingPosition_Z];
                ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
            else
                 app.warningMsg('Please Enable all Stages by Clicking "Stage Power" buttons.');
            end
        end

        % Button pushed function: ValidateXYZPositionandGoToParkButton
        function ValidateXYZPositionandGoToParkButtonPushed(app, event)
            status=all(app.XStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.YStageOnLamp.Color==app.EnableColors.Enabled) && ...
                 all(app.ZStageOnLamp.Color==app.EnableColors.Enabled);
            if status
                var=app.systemVar.motionSetupVar;
                var2=app.systemVar.motionSysValSetupVar;
                axes = [0,1,2]; % X  (using default names)
                speed=app.systemVar.engineeringPar1Var.ManualMotionSpeed;
                
                % moving to approch position
                dist=[var2.approachPosition_X var2.approachPosition_Y var2.approachPosition_Z];
                ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
                if app.motionStopped
                    app.motionStopped=false;
                    return;
                else
                    pause(1);
                end
            
                % moving to park position
                dist=[var.XPark var.YPark var.ZPark];
                ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
                if app.motionStopped
                    app.motionStopped=false;
                    return;
                else
                    pause(1);
                end

                allowableLimits=struct('upperLimit', [var2.XupperLimit, var2.YupperLimit, var2.ZupperLimit], ...
                    'lowerLimit', [var2.XlowerLimit, var2.YlowerLimit, var2.ZlowerLimit]);
                indicatorValues=[app.XDialindicatorEditField.Value, ...
                    app.YDialindicatorEditField.Value, ...
                    app.ZDialindicatorEditField.Value];
                %export dial indicator readings
                saveMotionSystemValidationResults(indicatorValues);
    
                flag= all(allowableLimits.upperLimit-indicatorValues>=0) & ...
                    all(indicatorValues-allowableLimits.lowerLimit>=0);
    
                if flag %pass
                    app.showInfoMsg('Validation Passed');
                else
                    app.showInfoMsg('Validation Failed');
                end
            else
                 app.warningMsg('Please Enable all Stages by Clicking "Stage Power" buttons.');
            end
        end

        % Close request function: RPASMotionSystemValidationUIFigure
        function RPASMotionSystemValidationUIFigureCloseRequest(app, event)
            app.STOPMOTIONButtonPushed();
%            changeDriveStatus(0, 1, app.XStageOnLamp, app.EnableColors);
%            changeDriveStatus(1, 1, app.YStageOnLamp, app.EnableColors);
%            changeDriveStatus(2, 1, app.ZStageOnLamp, app.EnableColors);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASMotionSystemValidationUIFigure = uifigure('Visible', 'off');
            app.RPASMotionSystemValidationUIFigure.Name = 'RPAS Motion System Validation';
            app.RPASMotionSystemValidationUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASMotionSystemValidationUIFigureCloseRequest, true);
            app.RPASMotionSystemValidationUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @MSVUIWindowKeyPress, true);
            app.RPASMotionSystemValidationUIFigure.Tag = 'MotionSys_Val';
            app.MotionSysValidationPanel = uipanel(app.RPASMotionSystemValidationUIFigure);
            app.MotionSysValidationPanel.Title = 'Motion Sys Validation';
            app.Panel = uipanel(app.MotionSysValidationPanel);
            app.STOPMOTIONButton = uibutton(app.Panel, 'push');
            app.STOPMOTIONButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMOTIONButtonPushed, true);
            app.STOPMOTIONButton.Text = {'STOP'; 'MOTION'};
            app.BackToMainScreenButton = uibutton(app.Panel, 'push');
            app.BackToMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainScreenButtonPushed, true);
            app.BackToMainScreenButton.Text = 'Back To Main Screen';
            app.StartMotionSystemValidationButton = uibutton(app.MotionSysValidationPanel, 'push');
            app.StartMotionSystemValidationButton.ButtonPushedFcn = createCallbackFcn(app, @StartMotionSystemValidationButtonPushed, true);
            app.StartMotionSystemValidationButton.Text = 'Start Motion System Validation';
            app.HomeALLStagesButton = uibutton(app.MotionSysValidationPanel, 'push');
            app.HomeALLStagesButton.ButtonPushedFcn = createCallbackFcn(app, @HomeALLStagesButtonPushed, true);
            app.HomeALLStagesButton.Text = 'Home ALL Stages';
            app.MSVGOTOHOMEButton = uibutton(app.MotionSysValidationPanel, 'push');
            app.MSVGOTOHOMEButton.ButtonPushedFcn = createCallbackFcn(app, @MSVGOTOHOMEButtonPushed, true);
            app.MSVGOTOHOMEButton.Text = 'Go To Home Position';
            app.MSVGOTPARKButton = uibutton(app.MotionSysValidationPanel, 'push');
            app.MSVGOTPARKButton.ButtonPushedFcn = createCallbackFcn(app, @MSVGOTPARKButtonPushed, true);
            app.MSVGOTPARKButton.Text = 'Go To Park Position';
            app.ValidateXYZPositionandGoToParkButton = uibutton(app.MotionSysValidationPanel, 'push');
            app.ValidateXYZPositionandGoToParkButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateXYZPositionandGoToParkButtonPushed, true);
            app.ValidateXYZPositionandGoToParkButton.Text = 'Validate X/Y/Z Position and Go To Park';
            app.DialIndicatorPanel = uipanel(app.MotionSysValidationPanel);
            app.DialIndicatorPanel.Title = 'Dial Indicator';
            app.XEditFieldLabel = uilabel(app.DialIndicatorPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XDialindicatorEditField = uieditfield(app.DialIndicatorPanel, 'numeric');
            app.XDialindicatorEditField.ValueDisplayFormat = '%.3f';
            app.YEditFieldLabel = uilabel(app.DialIndicatorPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YDialindicatorEditField = uieditfield(app.DialIndicatorPanel, 'numeric');
            app.YDialindicatorEditField.ValueDisplayFormat = '%.3f';
            app.ZEditFieldLabel = uilabel(app.DialIndicatorPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZDialindicatorEditField = uieditfield(app.DialIndicatorPanel, 'numeric');
            app.ZDialindicatorEditField.ValueDisplayFormat = '%.3f';
            app.ZAxisPanel = uipanel(app.RPASMotionSystemValidationUIFigure);
            app.ZAxisPanel.Title = 'Z Axis';
            app.PresentZPosPanel = uipanel(app.ZAxisPanel);
            app.PresentZPosPanel.Title = 'Present Z Pos';
            app.SYSVALPresentZPosEditField = uieditfield(app.PresentZPosPanel, 'numeric');
            app.SYSVALPresentZPosEditField.ValueDisplayFormat = '%5.3f';
            app.SYSVALPresentZPosEditField.Editable = 'off';
            app.mmLabel_17 = uilabel(app.PresentZPosPanel);
            app.mmLabel_17.Text = 'mm';
            app.StagePowerButton_Z = uibutton(app.ZAxisPanel, 'push');
            app.StagePowerButton_Z.ButtonPushedFcn = createCallbackFcn(app, @StagePowerButton_ZPushed, true);
            app.StagePowerButton_Z.Text = 'Stage Power';
            app.HomeStageButton_Z = uibutton(app.ZAxisPanel, 'push');
            app.HomeStageButton_Z.ButtonPushedFcn = createCallbackFcn(app, @HomeStageButton_ZPushed, true);
            app.HomeStageButton_Z.Text = 'Home Stage';
            app.ZStageOnLampLabel = uilabel(app.ZAxisPanel);
            app.ZStageOnLampLabel.Text = 'Z Stage On';
            app.ZStageOnLamp = uilamp(app.ZAxisPanel);
            app.ZStageHomedLampLabel = uilabel(app.ZAxisPanel);
            app.ZStageHomedLampLabel.Text = 'Z Stage Homed';
            app.ZStageHomedLamp = uilamp(app.ZAxisPanel);
            app.TravelLimitLabel_3 = uilabel(app.ZAxisPanel);
            app.TravelLimitLabel_3.Text = {'Travel'; 'Limit'};
            app.ZLampLabel = uilabel(app.ZAxisPanel);
            app.ZLampLabel.Text = 'Z+';
            app.SYSVALZLampUpper = uilamp(app.ZAxisPanel);
            app.ZLamp_2Label = uilabel(app.ZAxisPanel);
            app.ZLamp_2Label.Text = 'Z-';
            app.SYSVALZLampLower = uilamp(app.ZAxisPanel);
            app.YAxisPanel = uipanel(app.RPASMotionSystemValidationUIFigure);
            app.YAxisPanel.Title = 'Y Axis';
            app.PresentYPosPanel = uipanel(app.YAxisPanel);
            app.PresentYPosPanel.Title = 'Present Y Pos';
            app.SYSVALPresentYPosEditField = uieditfield(app.PresentYPosPanel, 'numeric');
            app.SYSVALPresentYPosEditField.ValueDisplayFormat = '%5.3f';
            app.SYSVALPresentYPosEditField.Editable = 'off';
            app.mmLabel_22 = uilabel(app.PresentYPosPanel);
            app.mmLabel_22.Text = 'mm';
            app.StagePowerButton_Y = uibutton(app.YAxisPanel, 'push');
            app.StagePowerButton_Y.ButtonPushedFcn = createCallbackFcn(app, @StagePowerButton_YPushed, true);
            app.StagePowerButton_Y.Text = 'Stage Power';
            app.HomeStageButton_Y = uibutton(app.YAxisPanel, 'push');
            app.HomeStageButton_Y.ButtonPushedFcn = createCallbackFcn(app, @HomeStageButton_YPushed, true);
            app.HomeStageButton_Y.Text = 'Home Stage';
            app.YStageOnLampLabel = uilabel(app.YAxisPanel);
            app.YStageOnLampLabel.Text = 'Y Stage On';
            app.YStageOnLamp = uilamp(app.YAxisPanel);
            app.YStageHomedLampLabel = uilabel(app.YAxisPanel);
            app.YStageHomedLampLabel.Text = 'Y Stage Homed';
            app.YStageHomedLamp = uilamp(app.YAxisPanel);
            app.TravelLimitLabel_2 = uilabel(app.YAxisPanel);
            app.TravelLimitLabel_2.Text = {'Travel'; 'Limit'};
            app.YLamp_2Label = uilabel(app.YAxisPanel);
            app.YLamp_2Label.Text = 'Y+';
            app.SYSVALYLampUpper = uilamp(app.YAxisPanel);
            app.YLampLabel = uilabel(app.YAxisPanel);
            app.YLampLabel.Text = 'Y-';
            app.SYSVALYLampLower = uilamp(app.YAxisPanel);
            app.XAxisPanel = uipanel(app.RPASMotionSystemValidationUIFigure);
            app.XAxisPanel.Title = 'X Axis';
            app.XStageOnLampLabel = uilabel(app.XAxisPanel);
            app.XStageOnLampLabel.Text = 'X Stage On';
            app.XStageOnLamp = uilamp(app.XAxisPanel);
            app.XStageHomedLampLabel = uilabel(app.XAxisPanel);
            app.XStageHomedLampLabel.Text = 'X Stage Homed';
            app.XStageHomedLamp = uilamp(app.XAxisPanel);
            app.StagePowerButton_X = uibutton(app.XAxisPanel, 'push');
            app.StagePowerButton_X.ButtonPushedFcn = createCallbackFcn(app, @StagePowerButton_XPushed, true);
            app.StagePowerButton_X.Text = 'Stage Power';
            app.PresentXPosPanel = uipanel(app.XAxisPanel);
            app.PresentXPosPanel.Title = 'Present X Pos';
            app.SYSVALPresentXPosEditField = uieditfield(app.PresentXPosPanel, 'numeric');
            app.SYSVALPresentXPosEditField.ValueDisplayFormat = '%5.3f';
            app.SYSVALPresentXPosEditField.Editable = 'off';
            app.mmLabel_27 = uilabel(app.PresentXPosPanel);
            app.mmLabel_27.Text = 'mm';
            app.HomeStageButton_X = uibutton(app.XAxisPanel, 'push');
            app.HomeStageButton_X.ButtonPushedFcn = createCallbackFcn(app, @HomeStageButton_XPushed, true);
            app.HomeStageButton_X.Text = 'Home Stage';
            app.TravelLimitLabel = uilabel(app.XAxisPanel);
            app.TravelLimitLabel.Text = {'Travel'; 'Limit'};
            app.XLampLabel = uilabel(app.XAxisPanel);
            app.XLampLabel.Text = 'X+';
            app.SYSVALXLampUpper = uilamp(app.XAxisPanel);
            app.XLamp_2Label = uilabel(app.XAxisPanel);
            app.XLamp_2Label.Text = 'X-';
            app.SYSVALXLampLower = uilamp(app.XAxisPanel);
            % Show the figure after all components are created
            app.RPASMotionSystemValidationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_MotionSys_Val

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASMotionSystemValidationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASMotionSystemValidationUIFigure)
        end
    end
end