classdef RPAS_Qual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASQualificationUIFigure     matlab.ui.Figure
        RPASQualificationPanel        matlab.ui.container.Panel
        TestPointDropDown             matlab.ui.control.DropDown
        TestPointiLabel_2             matlab.ui.control.Label
        TestNumberDropDown            matlab.ui.control.DropDown
        PositionNoLabel               matlab.ui.control.Label
        ResultsPanel                  matlab.ui.container.Panel
        ShowEstimationsButton         matlab.ui.control.Button
        GoToQualResultButton          matlab.ui.control.Button
        DeltaE_Z                      matlab.ui.control.NumericEditField
        RzmradLabel                   matlab.ui.control.Label
        PassLamp_Z                    matlab.ui.control.Lamp
        Found_Z                       matlab.ui.control.NumericEditField
        Expected_Z                    matlab.ui.control.NumericEditField
        DeltaE_Y                      matlab.ui.control.NumericEditField
        YumLabel                      matlab.ui.control.Label
        PassLamp_Y                    matlab.ui.control.Lamp
        Found_Y                       matlab.ui.control.NumericEditField
        Expected_Y                    matlab.ui.control.NumericEditField
        PresentPosNo                  matlab.ui.control.EditField
        PresentPosNoLabel             matlab.ui.control.Label
        DeltaE_X                      matlab.ui.control.NumericEditField
        DeltaEEditFieldLabel          matlab.ui.control.Label
        XumLabel                      matlab.ui.control.Label
        PassLamp_X                    matlab.ui.control.Lamp
        PassFailLabel                 matlab.ui.control.Label
        Found_X                       matlab.ui.control.NumericEditField
        MeasuredLabel                 matlab.ui.control.Label
        Expected_X                    matlab.ui.control.NumericEditField
        ExpectedLabel                 matlab.ui.control.Label
        ZLamp                         matlab.ui.control.Lamp
        ZLamp_2Label                  matlab.ui.control.Label
        YLamp                         matlab.ui.control.Lamp
        YLamp_2Label                  matlab.ui.control.Label
        XLamp                         matlab.ui.control.Lamp
        XLamp_2Label                  matlab.ui.control.Label
        TravelLimitLabel              matlab.ui.control.Label
        BacktoMasterQualScreenButton  matlab.ui.control.Button
        AbortTestButton               matlab.ui.control.Button
        PauseResumeButton             matlab.ui.control.Button
        GoButton                      matlab.ui.control.Button
        StartRPASQualRoutineButton    matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        Operator                      matlab.ui.control.EditField
        OperatorEditFieldLabel        matlab.ui.control.Label
        PRAS_ID                       matlab.ui.control.EditField
        RPASIDEditFieldLabel          matlab.ui.control.Label
        ImageCaptureDataCollectionInfoLabel  matlab.ui.control.Label
        WDSwitch                      matlab.ui.control.Switch
        PresentYPosPanel              matlab.ui.container.Panel
        mmLabel_9                     matlab.ui.control.Label
        PresentYPos                   matlab.ui.control.NumericEditField
        PresentZPosPanel              matlab.ui.container.Panel
        mmLabel_8                     matlab.ui.control.Label
        PresentZPos                   matlab.ui.control.NumericEditField
        PresentXPosPanel              matlab.ui.container.Panel
        mmLabel_7                     matlab.ui.control.Label
        PresentXPos                   matlab.ui.control.NumericEditField
        ReticlePositionPanel          matlab.ui.container.Panel
        RelativeXMovePanel            matlab.ui.container.Panel
        Rel_X                         matlab.ui.control.NumericEditField
        Xminus                        matlab.ui.control.Button
        mmLabel_15                    matlab.ui.control.Label
        Xplus                         matlab.ui.control.Button
        AbsoluteXMovePanel            matlab.ui.container.Panel
        Abs_X                         matlab.ui.control.NumericEditField
        mmLabel_14                    matlab.ui.control.Label
        XStart                        matlab.ui.control.Button
        RelativeYMovePanel            matlab.ui.container.Panel
        Rel_Y                         matlab.ui.control.NumericEditField
        Yminus                        matlab.ui.control.Button
        Yplus                         matlab.ui.control.Button
        mmLabel_13                    matlab.ui.control.Label
        AbsoluteYMovePanel            matlab.ui.container.Panel
        Abs_Y                         matlab.ui.control.NumericEditField
        mmLabel_12                    matlab.ui.control.Label
        YStart                        matlab.ui.control.Button
        RelativeZMovePanel            matlab.ui.container.Panel
        Rel_Z                         matlab.ui.control.NumericEditField
        Zminus                        matlab.ui.control.Button
        Zplus                         matlab.ui.control.Button
        mmLabel_11                    matlab.ui.control.Label
        AbsoluteZMovePanel            matlab.ui.container.Panel
        Abs_Z                         matlab.ui.control.NumericEditField
        ZStart                        matlab.ui.control.Button
        mmLabel_10                    matlab.ui.control.Label
        GoToSWDXcYcZnomButton         matlab.ui.control.Button
        GoToLWDXcYcZnomButton         matlab.ui.control.Button
        GoToPARKButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        FailColor=[0.6, 0, 0];
        PassColor=[0, 1, 0.5];
        xupdater  % timer to query present x position
        yupdater % timer to query present y position
        zupdater % timer to query present z position
        manualQualMethod=1;
        autoQualMethod = 0;

        manualSpeed;
        autoSpeed;
        movingSpeed;

        qualMethod;

        currentPos;
        currentTestPoint;
        positionSheet;
        pointsFinished;
        
        systemVar; % variable hold system configurations
        Park_X;
        Park_Y;
        Park_Z;
        SWD_Xc;
        SWD_Yc;
        SWD_Znorm;
        LWD_Xc;
        LWD_Yc;
        LWD_Znorm;

        Xlimit_plus;
        Xlimit_minus;
        Ylimit_plus;
        Ylimit_minus;
        Zlimit_plus;
        Zlimit_minus;

        capturedImg; % Description
        totalImages;
        spotList;
        bwImg;
        thresHold; % Description
        matchedResult; % result and info for single image
        matchedInfo; 
        results; % results and infos for auto qual
        infos;
        settingRecords;
        imageName; % Description
        
        
        MaxXErr;
        MaxYErr;
        MaxRzErr;% Description
        
        pauseFlag; % Description
        previousStatus;
        isFrozen;
        
        resultImageHandle; % Description
        resultHandle;
        
        % variables used to bookmark the aborting status;
        register;
        outerAbortFlag;
    end
    properties (Access=public)
        motionStatus;
    end
    
    methods (Access = private)  
        function settings=recordSettings(app)
            settings=[];
            
            %properties
            %settings.xupdater =app.xupdater; % timer to query present x position
            %settings.yupdater =app.yupdater;% timer to query present y position
            %settings.zupdater =app.zupdater;% timer to query present z position
            
            %settings.systemVar=app.systemVar; % variable hold system configurations
            settings.manualQualMethod=app.manualQualMethod;
            settings.autoQualMethod =app.autoQualMethod;
    
            settings.manualSpeed=app.manualSpeed;
            settings.autoSpeed=app.autoSpeed;
            settings.movingSpeed=app.movingSpeed;

            settings.qualMethod=app.qualMethod;
            
            settings.currentPos = app.currentPos;
            settings.currentTestPoint = app.currentTestPoint;
            settings.positionSheet = app.positionSheet;
            settings.pointsFinished = app.pointsFinished;
    
            settings.Park_X=app.Park_X;
            settings.Park_Y=app.Park_Y;
            settings.Park_Z=app.Park_Z;
            settings.SWD_Xc=app.SWD_Xc;
            settings.SWD_Yc=app.SWD_Yc;
            settings.SWD_Znorm=app.SWD_Znorm;
            settings.LWD_Xc=app.LWD_Xc;
            settings.LWD_Yc=app.LWD_Yc;
            settings.LWD_Znorm=app.LWD_Znorm;
            
            settings.Xlimit_plus=app.Xlimit_plus;
            settings.Xlimit_minus=app.Xlimit_minus;
            settings.Ylimit_plus=app.Ylimit_plus;
            settings.Ylimit_minus=app.Ylimit_minus;
            settings.Zlimit_plus=app.Zlimit_plus;
            settings.Zlimit_minus=app.Zlimit_minus;
            
            settings.capturedImg = app.capturedImg; % Description
            settings.totalImages = app.totalImages; % Description
            settings.spotList = app.spotList;
            settings.bwImg = app.bwImg;
            settings.thresHold = app.thresHold; % Description
            settings.matchedResult = app.matchedResult; % result and info for single image
            settings.matchedInfo = app.matchedInfo; 
            settings.results = app.results; % results and infos for auto qual
            settings.infos = app.infos;
            settings.settingRecords = app.settingRecords;
            settings.imageName = app.imageName; % Description
        
        
            settings.MaxXErr = app.MaxXErr;
            settings.MaxYErr = app.MaxYErr;
            settings.MaxRzErr = app.MaxRzErr;
            
            
            % variable flag for pausing
            settings.pauseFlag=app.pauseFlag; % Description
            settings.previousStatus=app.previousStatus;
            settings.isFrozen=app.isFrozen;
            settings.resultHandle=app.resultHandle;
            
            settings.register = app.register;
            settings.outerAbortFlag = app.outerAbortFlag;

            %components
            settings.PRAS_ID.Value = app.PRAS_ID.Value;
            settings.PRAS_ID.Enable=app.PRAS_ID.Enable;
            
            settings.Operator.Value = app.Operator.Value;
            settings.Operator.Enable = app.Operator.Enable;
            
            settings.WDSwitch.Value = app.WDSwitch.Value;
            settings.WDSwitch.Enable = app.WDSwitch.Enable;
            
            
            settings.Rel_X.Value=app.Rel_X.Value;
            settings.Rel_X.Enable=app.Rel_X.Enable;
            
            settings.Abs_X.Value=app.Abs_X.Value;
            settings.Abs_X.Enable=app.Abs_X.Enable;
        
            settings.Rel_Y.Value=app.Rel_Y.Value;
            settings.Rel_Y.Enable=app.Rel_Y.Enable;
            
            settings.Abs_Y.Value=app.Abs_Y.Value;
            settings.Abs_Y.Enable=app.Abs_Y.Enable;
            
            settings.Rel_Z.Value=app.Rel_Z.Value;
            settings.Rel_Z.Enable=app.Rel_Z.Enable;
            
            settings.Abs_Z.Value=app.Abs_Z.Value;
            settings.Abs_Z.Enable=app.Abs_Z.Enable;
            
            settings.Xplus.Enable=app.Xplus.Enable;
            
            settings.Xminus.Enable=app.Xminus.Enable;
            
            settings.XStart.Enable=app.XStart.Enable;
        
            settings.Yplus.Enable=app.Yplus.Enable;
            
            settings.Yminus.Enable=app.Yminus.Enable;
            
            settings.YStart.Enable=app.YStart.Enable;
            
            settings.Zplus.Enable=app.Zplus.Enable;
            
            settings.Zminus.Enable=app.Zminus.Enable;
            
            settings.ZStart.Enable=app.ZStart.Enable;
            
            settings.GoToLWDXcYcZnomButton.Enable=app.GoToLWDXcYcZnomButton.Enable;
            settings.GoToPARKButton.Enable=app.GoToPARKButton.Enable;
            settings.GoToSWDXcYcZnomButton.Enable=app.GoToSWDXcYcZnomButton.Enable;
        
            settings.PauseResumeButton.Enable=app.PauseResumeButton.Enable;
            settings.AbortTestButton.Enable=app.AbortTestButton.Enable;
            settings.BacktoMasterQualScreenButton.Enable=app.BacktoMasterQualScreenButton.Enable;
            
            settings.StartRPASQualRoutineButton.Enable = app.StartRPASQualRoutineButton.Enable;
            
            settings.XLamp.Color=app.XLamp.Color;
            settings.YLamp.Color=app.YLamp.Color;
            settings.ZLamp.Color=app.ZLamp.Color;
            
            settings.PositionNoDropDown.Value = str2num(app.TestNumberDropDown.Value);
            settings.PositionNoDropDown.Enable = app.TestNumberDropDown.Enable;
            settings.PositionNoDropDown.Items = app.TestNumberDropDown.Items;
            settings.PositionNoDropDown.ItemsData = app.TestNumberDropDown.ItemsData;
            
            settings.TestPointDropDown.Value = app.TestPointDropDown.Value;
            settings.TestPointDropDown.Enable = app.TestPointDropDown.Enable;
            settings.TestPointDropDown.Items = app.TestPointDropDown.Items;
            settings.TestPointDropDown.ItemsData = app.TestPointDropDown.ItemsData;
            
            settings.GoButton.Enable = app.GoButton.Enable;
            
            settings.PresentPosNo.Value=app.PresentPosNo.Value;
            settings.PresentPosNo.Enable=app.PresentPosNo.Enable;
            
            settings.GoToQualResultButton.Enable=app.GoToQualResultButton.Enable;
            
            settings.ShowEstimationsButton.Enable=app.ShowEstimationsButton.Enable;
            
            settings.Expected_X.Value=app.Expected_X.Value;
            settings.Expected_X.Enable=app.Expected_X.Enable;
            
            settings.Expected_Y.Value=app.Expected_Y.Value;
            settings.Expected_Y.Enable=app.Expected_Y.Enable;
            
            settings.Expected_Z.Value=app.Expected_Z.Value;
            settings.Expected_Z.Enable=app.Expected_Z.Enable;
            
            settings.Found_X.Value=app.Found_X.Value;
            settings.Found_X.Enable=app.Found_X.Enable;
            
            settings.Found_Y.Value=app.Found_Y.Value;
            settings.Found_Y.Enable=app.Found_Y.Enable;
            
            settings.Found_Z.Value=app.Found_Z.Value;
            settings.Found_Z.Enable=app.Found_Z.Enable;
            
            settings.DeltaE_X.Value=app.DeltaE_X.Value;
            settings.DeltaE_X.Enable=app.DeltaE_X.Enable;
            
            settings.DeltaE_Y.Value=app.DeltaE_Y.Value;
            settings.DeltaE_Y.Enable=app.DeltaE_Y.Enable;
            
            settings.DeltaE_Z.Value=app.DeltaE_Z.Value;
            settings.DeltaE_Z.Enable=app.DeltaE_Z.Enable;
            
            
            settings.PassLamp_X.Color=app.PassLamp_X.Color;
            settings.PassLamp_Y.Color=app.PassLamp_Y.Color;
            settings.PassLamp_Z.Color=app.PassLamp_Z.Color;
            
            settings.image=(getimage(app.UIAxes))';
        end
    end

    methods (Access = public) 

        function init(app)
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables(); %read in system setup

            var=app.systemVar.motionSetupVar;
            app.Park_X=var.XPark;
            app.Park_Y=var.YPark;
            app.Park_Z=var.ZPark;
            app.Xlimit_plus = var.Xlimit_Plus;
            app.Xlimit_minus = var.Xlimit_Minus;
            app.Ylimit_plus = var.Ylimit_Plus;
            app.Ylimit_minus = var.Ylimit_Minus;
            app.Zlimit_plus = var.Zlimit_Plus;
            app.Zlimit_minus = var.Zlimit_Minus;
            
            var=app.systemVar.engineeringPar1Var;
            app.SWD_Znorm = var.SWDNominalZ;
            app.LWD_Znorm = var.LWDNominalZ;

            app.manualSpeed=var.ManualMotionSpeed;
            app.autoSpeed = var.AutoQualScanSpeed;
            app.movingSpeed = app.manualSpeed;
            app.qualMethod = app.manualQualMethod;
            
            var=app.systemVar.toolCalibrationVar;
            app.SWD_Xc = var.SWD_Xc;
            app.SWD_Yc = var.SWD_Yc;
            app.LWD_Xc = var.LWD_Xc;
            app.LWD_Yc = var.LWD_Yc;
            
            %initialize lamp color
            app.XLamp.Color=app.UnderLimitColor;
            app.YLamp.Color=app.UnderLimitColor;
            app.ZLamp.Color=app.UnderLimitColor;
            
            app.Rel_X.Value=0;
            app.Abs_X.Value=0;
            app.Rel_Y.Value=0;
            app.Abs_Y.Value=0;
            app.Rel_Z.Value=0;
            app.Abs_Z.Value=0;
            if ~isempty(app.resultImageHandle) & isvalid(app.resultImageHandle)
                delete(app.resultImageHandle);
            end
            
            
            str=[];
            str{1}='Green only if Present X Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Xlimit_minus, app.Xlimit_plus);
            app.XLamp.Tooltip=str;

            str{1}='Green only if Present Y Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Ylimit_minus, app.Ylimit_plus);
            app.YLamp.Tooltip=str;

            str{1}='Green only if Present Z Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Zlimit_minus, app.Zlimit_plus);
            app.ZLamp.Tooltip=str;
             
            %disable buttons
            app.StartRPASQualRoutineButton.Enable=false;
            app.GoToQualResultButton.Enable=false;
            app.ShowEstimationsButton.Enable=false;
            
            %initialize pausing related variables
            app.pauseFlag=false;
            app.isFrozen=false;

            if isempty(app.register) 
                app.register=processRegister(app, 0.5);
            elseif isvalid(app.register)
                app.register.reset();
            else
                app.register=processRegister(app, 0.5);
            end

            app.outerAbortFlag = false;

            %initialize motion speed;
            app.qualMethod =app.manualQualMethod;
              
            app.previousStatus.StartRPASQualRoutineButtonValue= ...
                    app.StartRPASQualRoutineButton.Enable;
            app.previousStatus.GoToQualResultButtonValue = ...
                    app.GoToQualResultButton.Enable;
            app.previousStatus.GotoButtonValue= app.GoButton.Enable;
            app.previousStatus.ShowEstimationsButtonValue = ...
                    app.ShowEstimationsButton.Enable;

            app.capturedImg=[];
            app.totalImages=struct();


            app.xupdater=updatePosition(app.PresentXPos, 0, .05, ...
                {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater=updatePosition(app.PresentYPos, 1, .05, ...
                {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater=updatePosition(app.PresentZPos, 2, .05, ...
                {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1);
            app.stopTimer();

             if app.PresentXPos.Value==app.LWD_Xc && ...
                     app.PresentYPos.Value==app.LWD_Yc && ...
                     app.PresentZPos.Value==app.LWD_Znorm
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
             else
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
             end
              
            app.TestNumberDropDown.Value=app.TestNumberDropDown.ItemsData(1);
            app.TestNumberDropDownValueChanged();
            drawnow;   
        end
        
        function stopTimer(app)
            if ~isempty(app.xupdater)
                app.xupdater.stop();
                app.yupdater.stop();
                app.zupdater.stop();
            end
        end
    end

    methods (Access = private)

        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, Lamp)
            try
                val=round(val*1.0e3)*1.0e-3;
                upperLimit=round(upperLimit*1.0e6)*1.0e-6;
                lowerLimit=round(lowerLimit*1.0e6)*1.0e-6;
                c=Lamp.Color;
                warning=false;
                Lamp.Color=app.UnderLimitColor;

                if val>upperLimit || val<lowerLimit
                    Lamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
            Lamp.Color=c;
        end
        
        function [img, name] = captureImage(app, cameraSource)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            
            if RPAS_C.CameraAvailable
                img = RPAS_ImageAcquisition_7dot1(cameraSource, 1);
                if cameraSource==1 % SWD
                    name='SWD_MSensor';
                else
                    name='LWD_MSensor';
                end
            else
                global tmpIndex;
                if isempty(tmpIndex)
                    tmpIndex=0;
                end
                tmpIndex=mod(tmpIndex, 2)+1;
                path=[RPAS_C.QUAL_IMAGE_DIR '/PgmImages/'];
                files={'IBframe_M00000.pgm', 'IBframe_P00000.pgm', ...
                    'IBframe_M00001.pgm', 'IBframe_P00001.pgm', ...
                    'IBframe_M00002.pgm', 'IBframe_P00002.pgm'};
                fnm=[path '\' files{tmpIndex}];fnm=[path '\' files{1}];
                img=PRAD_readPgmFile(fnm);
                name=files{tmpIndex};
            end

            if app.qualMethod==app.manualQualMethod

                path=[RPAS_C.QUAL_RESULT_DIR '/Manual_Qualification'];
                if ischar(app.TestNumberDropDown.Value)
                    path=[path '/TEST_NO=' app.TestNumberDropDown.Value];
                else
                    path=[path '/TEST_NO=' num2str(app.TestNumberDropDown.Value)];
                end
                RPAS_Make_folder(path);
    
                dt=datetime();
                datetimestring=datestr(dt, 'mmm_dd_yyyy_HH_MM_SS');
                if ischar(app.TestPointDropDown.Value)
                    if cameraSource==1 % SWD
                        fnm=[path '/SWD_Test_Point=' app.TestPointDropDown.Value '_DateTime=' datetimestring '.png'];
                    else
                        fnm=[path '/LWD_Test_Point=' app.TestPointDropDown.Value '_DateTime=' datetimestring '.png'];
                    end
                else
                    if cameraSource==1 % SWD
                        fnm=[path '/SWD_Test_Point=' num2str(app.TestPointDropDown.Value) '_DateTime=' datetimestring '.png'];
                    else
                        fnm=[path '/LWD_Test_Point=' num2str(app.TestPointDropDown.Value) '_DateTime=' datetimestring '.png'];
                    end
                end
                imwrite(img,fnm);
            end
        end
        
        function displayImage(app, img, fnm)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            fnm=replace(fnm,'_', ' ');
            img=img';
            %app.UIAxes.Title.String = fnm;
            app.UIAxes.XLim=[0, size(img,2)];
            app.UIAxes.YLim=[0, size(img,1)];
            tik=0:100:600;
            tikLbl={};
            for k=1:numel(tik)
                tikLbl{k}=num2str(tik(k)*RPAS_C.Pixel_img_plane*1.0e-3);
            end
            app.UIAxes.XTick=tik;
            app.UIAxes.YTick=tik(1:5);
            
            
            R=imref2d(size(img));
            imshow(img, R,'Parent', app.UIAxes);
            
            drawnow;
        end
        
        function popupWindow(app, img, src)
            hd=figure(1);
            hd.Name='Captured Image';
            hd.DockControls='on';
            gca.Title.String=src;
            ax=gca;
            gca.Xlim=[0,size(img,2)];
            gca.Ylim= [0,size(img,1)];
            ax.XAxisLocation='top';
            ax.YAxisLocation='left';
            ax.YDir='reverse';

            mX=round(size(img,2)/100);
            dx=round(mX/6)*100;
            xtik=[0, dx, 2*dx, 3*dx, 4*dx, 5*dx, min(6*dx, mX*100)];
            ax.XTick=xtik;
            label={};
            for j=1:numel(xtik)
                label{end+1}=num2str(xtik(j));
            end
            ax.YTick=xtik(1:5);
            ax.YTickLabel=label(1:5);
            imshow(img, imref2d(size(img)));
            drawnow;
        end
        
        
        function displayThresholdImg(app)

            fnm=['Threshold=' num2str(app.thresHold)];
            img=app.bwImg';
            
            R=imref2d(size(img));
            imshow(img, R,'Parent', app.UIAxes);
            app.UIAxes.NextPlot='add';
             plot3(app.spotList.sPosEstimate(:,1)+1, ...
                 app.spotList.sPosEstimate(:,2) +1, ...
                 app.spotList.surface(:), 'o', ...
                 'MarkerSize',30, 'MarkerEdgeColor', 'r', ...
                 'LineWidth',2, ...
                 'Parent', app.UIAxes);
            app.UIAxes.NextPlot='replace';
             
            drawnow;
            
        end
        
        
        function displayResultImages(app)
            global markerPositions
            % Create a gray color map with 255 gray levels
              gray255 = zeros(255, 3);
              delta = 1/(255 - 1);
              for i = 0:(255 - 1)
                gray255(i + 1, 1) = i * delta; 
                gray255(i + 1, 2) = gray255(i + 1, 1); 
                gray255(i + 1, 3) = gray255(i + 1, 1); 
              end
  
              app.resultImageHandle=figure('Name', app.imageName);
                subplot(1,4,1) 
                    imagesc(app.capturedImg); axis image;
                    fnm=replace(app.imageName,'_', ' ');
                    title(fnm);
                subplot(1,4,2)
                    imagesc(app.bwImg); axis image; hold on;
                subplot(1,4,2)
                    plot3(app.spotList.sPosEstimate(:,2)+1, ...
                        app.spotList.sPosEstimate(:,1) +1, ...
                        app.spotList.surface(:), 'o', ...
                        'MarkerSize',20, 'MarkerEdgeColor', 'r', ...
                        'LineWidth',2);
                        view(0,90);
                        txt=sprintf('Thresholded: %g ', app.thresHold);
                        title(txt);
                subplot(1,4,[3,4])
                    flipx=-1;
                    flipy=-1;
                    plot3(markerPositions(:,1)*flipx, ...
                        markerPositions(:,2)*flipy, ...
                        markerPositions(:,3), '.', ...
                        'MarkerSize', 10, 'MarkerEdgeColor','k');
                        view(0,90); hold on;
                    plot3(app.matchedInfo.mPos(:,1)*flipx, ...
                        app.matchedInfo.mPos(:,2)*flipy, ...
                        app.matchedInfo.mPos(:,3),'O', ...
                        'MarkerSize',10, 'MarkerEdgeColor','b');
                        view(0,90);
                    plot3(app.matchedInfo.mSpotRefPair([1:2], 1)*flipx, ...
                        app.matchedInfo.mSpotRefPair([1:2], 2)*flipy, ...
                        app.matchedInfo.mSpotRefPair([1:2], 3),'O', ...
                        'MarkerSize',12, 'MarkerEdgeColor','r');
                        view(0,90); 
                        axis image;
                colormap(gray255);
        end
        
        function displayResults(app)
            dist=app.results(:,4:6);
            mat=~isnan(dist);
            dist=dist(mat);
            dist=reshape(dist,[numel(dist)/3,3]);
            app.resultHandle=RPAS_QualResults(dist, ...
                [app.MaxXErr, app.MaxYErr, app.MaxRzErr]);
        end
        
        function qualification(app)
            global markerPositions;
            global nearestSpotImage;
            global reticlePixel;
            global imagePixel;
            
            app.register.addProcess();

            % if pause is required program will pause util it is resumed
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            % find the spots
            [app.spotList, app.bwImg, app.thresHold]= ...
                PRAD_computeSpotList(app.capturedImg);
            
            % check pause again;
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            % display Threhold image
            app.displayThresholdImg();
            
            % check pause again;
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            if strfind(app.imageName, '_P')
                sensor='sensor_P';
                defaultRotation=pi * 0;
            else
                sensor='sensor_M';
                defaultRotation=pi * 1;
            end
            
            % check pause again;
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            defaultFlip=2;
            [app.matchedResult, app.matchedInfo] = PRAD_estimate_SingleImg( ...
                app.spotList, sensor, defaultFlip, defaultRotation, ...
                markerPositions, nearestSpotImage);
            
            % check pause again;
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            if (app.matchedResult.mInD.angle*180/pi)>90
                if defaultRotation==0
                    defaultRotation=pi*1;
                else
                    defaultRotation=0;
                end
                [app.matchedResult, app.matchedInfo] = PRAD_estimate_SingleImg( ...
                    app.spotList, sensor, defaultFlip, defaultRotation, ...
                    markerPositions, nearestSpotImage);
            end


            % check pause again;
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            %converting to reticle values
            app.matchedResult.mInD.vect=app.matchedResult.mInD.vect* ...
                reticlePixel/imagePixel*1.0e6; %converting to um
            app.matchedResult.mInD.angle=app.matchedResult.mInD.angle * ...
                1.0e3; % converting to mrad
            app.matchedResult.mInD.dist(:,1:2) = ...
                app.matchedResult.mInD.dist(:,1:2)* ...
                reticlePixel/imagePixel*1.0e6; %converting to um
            app.matchedResult.mInD.dist(:,3) = ...
                app.matchedResult.mInD.dist(:,3)*1.0e3; % converting to mrad
                
            app.fillResults(app.matchedResult.mInD);

            %record qualified points and results
            index=app.TestPointDropDown.Value;
            app.pointsFinished(index)=1;
            app.results(index, :) = ...
                [app.Found_X.Value, app.Found_Y.Value, app.Found_Z.Value, ...
                app.DeltaE_X.Value, app.DeltaE_Y.Value, app.DeltaE_Z.Value];
            app.infos{index} = app.matchedInfo;
            app.settingRecords{index} = ...
                app.recordSettings();

            app.register.removeProcess();
        end
        
%         function app.checkPause(app)
%             while app.pauseFlag
%                 pause(0.1);
%             end
%         end
        
        function freeze(app, val)
            val=~val;
            
            app.WDSwitch.Enable=val;
            app.Xplus.Enable=val;
            app.Xminus.Enable=val;
            app.XStart.Enable=val;
            
            app.Yplus.Enable=val;
            app.Yminus.Enable=val;
            app.YStart.Enable=val;
            
            app.Zplus.Enable=val;
            app.Zminus.Enable=val;
            app.ZStart.Enable=val;
            
            app.GoToLWDXcYcZnomButton.Enable=val;
            app.GoToSWDXcYcZnomButton.Enable=val;
            app.GoToPARKButton.Enable=val;
            
            app.BacktoMasterQualScreenButton.Enable=val;
            
            if val
                app.StartRPASQualRoutineButton.Enable = ...
                    app.previousStatus.StartRPASQualRoutineButtonValue;
                app.GoToQualResultButton.Enable= ...
                    app.previousStatus.GoToQualResultButtonValue;
                app.GoButton.Enable = app.previousStatus.GotoButtonValue;
                app.ShowEstimationsButton.Enable= ...
                    app.previousStatus.ShowEstimationsButtonValue;
            else
                app.previousStatus.StartRPASQualRoutineButtonValue= ...
                    app.StartRPASQualRoutineButton.Enable;
                app.StartRPASQualRoutineButton.Enable=val;
            
                app.previousStatus.GoToQualResultButtonValue = ...
                    app.GoToQualResultButton.Enable;
                app.GoToQualResultButton.Enable=val;
            
                app.previousStatus.GotoButtonValue= app.GoButton.Enable;
                app.GoButton.Enable = val;
                
                app.previousStatus.ShowEstimationsButtonValue = ...
                    app.ShowEstimationsButton.Enable;
                app.ShowEstimationsButton.Enable = val;
            end
            
            app.Rel_X.Editable=val;
            app.Abs_X.Editable=val;
            
            app.Rel_Y.Editable=val;
            app.Abs_Y.Editable=val;
            
            app.Rel_Z.Editable=val;
            app.Abs_Z.Editable=val;
            
            app.PRAS_ID.Enable=val;
            app.Operator.Enable=val;
            app.TestPointDropDown.Enable=val;
            app.TestNumberDropDown.Enable=val;
        end
        
        function checkPause(app)
            while app.pauseFlag
                pause(0.1);
            end
        end
        
        function showInfoMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Info';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'icon', 'info');
            switch selection
                case {'OK'}
                otherwise
            end
            delete(fig);
        end
        
        function updatePosition(app, val)
            app.currentPos=val;
            obj=app.systemVar.autoQualTableVar();
            sheetNames=obj.qualSheets;
            try
                switch app.currentPos
                    case 1
                        fnm=sheetNames.pos1;
                    case 2
                        fnm=sheetNames.pos2;
                    case 3
                        fnm=sheetNames.pos3;
                    case 4
                        fnm=sheetNames.pos4;
                    case 5
                        fnm=sheetNames.pos5;
                    case 6
                        fnm=sheetNames.pos6;
                end
                        
                app.positionSheet = RPAS_ReadDataSheet(fnm);
                app.TestNumberDropDown.Value= ...
                    app.TestNumberDropDown.ItemsData(app.currentPos);
                app.PresentPosNo.Value = num2str(app.currentPos);

                %change the spinner limits according to the position changed
                items=1:app.positionSheet.numTestPoints;
                itemsC=cell(app.positionSheet.numTestPoints,1);
                for k=1:app.positionSheet.numTestPoints
                    itemsC{k}=num2str(k);
                end
                app.TestPointDropDown.Items=itemsC;
                app.TestPointDropDown.ItemsData=items;
                app.TestPointDropDown.Value=app.TestPointDropDown.ItemsData(1);
                app.TestPointDropDownValueChanged();
            catch ME
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                title = 'File not found';
                selection = uiconfirm(fig, ME.message, ME.identifier,...
                                    'Options',{'OK'},...
                                    'icon', 'error');
                delete(fig);
                rethrow(ME);
            end
        end

        
        function position = selectTestSheet(app, testPoint)
            %get absolute moving position
            position=app.positionSheet.Stage(testPoint,:);
            if app.WDSwitch.Value==1 %SWD
                position=position+[app.SWD_Xc, app.SWD_Yc, app.SWD_Znorm];
            else
                position=position+[app.LWD_Xc, app.LWD_Yc, app.LWD_Znorm];
            end
        end
        
        function warningMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);

            title = 'Warning';
            selection = uiconfirm(fig, msg, title,...
                                       'Options',{'OK'},...
                                       'icon', 'warning');
            switch selection
                case {'OK'}
                    %donothing;
                otherwise
                    %donothing;
            end
            delete(fig);
        end
    end
    
    methods(Access = public)

        function hide(app, val)
            if (val && app.RPASQualificationUIFigure.Visible==false)
                return;
            end
%             if val
%                 app.stopTimer();
%             else
%                 app.xupdater.start();
%                 app.yupdater.start();
%                 app.zupdater.start();
%             end
            app.RPASQualificationUIFigure.Visible=~val;
        end
        
        function [result, settings, images]=getResults(app)
            result=app.results;
            settings=app.settingRecords;  
            images=app.totalImages;
        end
        
        function freezeAll(app, val)
            app.freeze(val);
            app.PauseResumeButton.Enable=~val;
            app.AbortTestButton.Enable=~val;
            app.isFrozen=val;
        end
        
        function reinit(app, dataSheet)
            app.positionSheet=dataSheet;
            app.currentPos=dataSheet.Pos;
            
            app.TestNumberDropDown.Value= ...
                    app.TestNumberDropDown.ItemsData(app.currentPos);
            app.PresentPosNo.Value = num2str(app.currentPos);

            %change the spinner limits according to the position changed
            items=1:app.positionSheet.numTestPoints;
            itemsC=cell(app.positionSheet.numTestPoints,1);
            for k=1:app.positionSheet.numTestPoints
                itemsC{k}=num2str(k);
            end
            app.TestPointDropDown.Items=itemsC;
            app.TestPointDropDown.ItemsData=items;
            app.TestPointDropDown.Value=app.TestPointDropDown.ItemsData(1);
            app.TestPointDropDownValueChanged();

            app.Rel_X.Value=0;
            app.Abs_X.Value=0;
            app.Rel_Y.Value=0;
            app.Abs_Y.Value=0;
            app.Rel_Z.Value=0;
            app.Abs_Z.Value=0;
            if ~isempty(app.resultImageHandle) & isvalid(app.resultImageHandle)
                delete(app.resultImageHandle);
            end
            
            %reset container
            app.results=zeros(app.positionSheet.numTestPoints, 6);
            for k=1:app.positionSheet.numTestPoints
                app.results(k,:)=[nan, nan, nan, nan, nan, nan];
            end
            app.infos=cell(app.positionSheet.numTestPoints,1);
            app.settingRecords=cell(app.positionSheet.numTestPoints,1);

            app.capturedImg=[];
            app.totalImages=struct;

            if isempty(app.register) 
                app.register=processRegister(app, 0.5);
            elseif isvalid(app.register)
                app.register.reset();
            else
                app.register=processRegister(app, 0.5);
            end
        end
        
        function autoQual(app, id, operator, data, showDetail)
            app.register.addProcess();

            app.PRAS_ID.Value = id;
            app.Operator.Value = operator;

            %set to auto qualification
            app.qualMethod = app.autoQualMethod;

            % check pause
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();

            app.reinit(data);

            if strcmp(data.camera, 'SWD')
                app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
            else
                app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
            end
            
            % check pause
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();

            for k=1:data.numTestPoints
                if data.BI(k)==1

                    % check pause
                    if app.register.isAborting | app.outerAbortFlag
                        return;
                    end
                    app.checkPause();

                    %change Test point and initiate the callback;
                    app.TestPointDropDown.Value=...
                        app.TestPointDropDown.ItemsData(k);
                    app.TestPointDropDownValueChanged();


                    % check pause
                    if app.register.isAborting | app.outerAbortFlag
                        return;
                    end
                    app.checkPause();

                    %moving and acquire image
                    app.GoButtonPushed();
                    
                    % check pause
                    if app.register.isAborting | app.outerAbortFlag
                        return;
                    end
                    app.checkPause();

                    %qualification
                    app.qualification();
                    
                    % check pause
                    if app.register.isAborting | app.outerAbortFlag
                        return;
                    end
                    app.checkPause();

                    if showDetail
                        app.showInfoMsg("Paused. Press OK to continue.");
                    else
                        pause(0.3);
                    end
                end
            end
            % reset back to manual qualification
            app.qualMethod = app.manualQualMethod;

            app.register.removeProcess();
        end
        
        function pausing(app, val)
            app.pauseFlag=val;

            if ~isempty(app.motionStatus)
                if app.motionStatus.isPausing
                    lamps=[];
                    for k=1:numel(app.motionStatus.axes)
                        switch app.motionStatus.axes(k)
                            case {0}
                                lamps=[lamps app.xupdater];
                            case {1}
                                lamps=[lamps app.yupdater];
                            case {2}
                                lamps=[lamps app.zupdater];
                        end
                    end
                    app.motionStatus.isPausing=false;
                    if ~isempty(app.motionStatus.axes)
                        ABSMoving(app.motionStatus.axes, app.motionStatus.movingDistances, ...
                            app.motionStatus.movingSpeeds, lamps, app);
                    end
                else
                    app.motionStatus.isPausing=true;
                    if ~isempty(app.motionStatus.axes)
                        stopMotion([app.xupdater, app.yupdater, app.zupdater], app);
                    end
                end
            end
        end
        
        function fillResults(app, result)
            
            app.Found_X.Value= result.vect(1);
            app.Found_Y.Value= result.vect(2);
            app.Found_Z.Value= result.angle;
            
            app.DeltaE_X.Value = ...
                app.Found_X.Value-app.Expected_X.Value;
            app.DeltaE_Y.Value = ...
                app.Found_Y.Value - app.Expected_Y.Value;
            app.DeltaE_Z.Value = ...
                app.Found_Z.Value - app.Expected_Z.Value;
            
            if abs(app.DeltaE_X.Value)>app.MaxXErr
                app.PassLamp_X.Color=app.FailColor;
            else
                app.PassLamp_X.Color=app.PassColor;
            end
            
            if abs(app.DeltaE_Y.Value)>app.MaxYErr
                app.PassLamp_Y.Color=app.FailColor;
            else
                app.PassLamp_Y.Color=app.PassColor;
            end
            
            if abs(app.DeltaE_Z.Value)>app.MaxRzErr
                app.PassLamp_Z.Color=app.FailColor;
            else
                app.PassLamp_Z.Color=app.PassColor;
            end
            
            str=sprintf('Fail if abs(Delta E) > %g', app.MaxXErr);
            app.PassLamp_X.Tooltip=str;
            str=sprintf('Fail if abs(Delta E) > %g', app.MaxYErr);
            app.PassLamp_Y.Tooltip=str;
            str=sprintf('Fail if abs(Delta E) > %g', app.MaxRzErr);
            app.PassLamp_Z.Tooltip=str;
            
        end

        function close(app)
            app.RPASQualificationUIFigureCloseRequest;
        end

        function setAutoQualMethod(app)
            app.qualMethod=app.autoQualMethod;
        end
        
        function setManualQualMethod(app)
            app.qualMethod=app.manualQualMethod;
        end
        
        function restoreSettings(app, settings)
            
            %properties
            %app.xupdater =settings.xupdater; % timer to query present x position
            %app.yupdater =settings.yupdater;% timer to query present y position
            %app.zupdater =settings.zupdater;% timer to query present z position
            
            %app.systemVar=settings.systemVar; % variable hold system configurations
            app.manualQualMethod=settings.manualQualMethod;
            app.autoQualMethod =settings.autoQualMethod;
    
            app.manualSpeed=settings.manualSpeed;
            app.autoSpeed=settings.autoSpeed;
            app.movingSpeed=settings.movingSpeed;

            app.qualMethod=settings.qualMethod;
            
            app.currentPos = settings.currentPos;
            app.currentTestPoint = settings.currentTestPoint;
            app.positionSheet = settings.positionSheet;
            app.pointsFinished = settings.pointsFinished;
    
            app.Park_X=settings.Park_X;
            app.Park_Y=settings.Park_Y;
            app.Park_Z=settings.Park_Z;
            app.SWD_Xc=settings.SWD_Xc;
            app.SWD_Yc=settings.SWD_Yc;
            app.SWD_Znorm=settings.SWD_Znorm;
            app.LWD_Xc=settings.LWD_Xc;
            app.LWD_Yc=settings.LWD_Yc;
            app.LWD_Znorm=settings.LWD_Znorm;
            
            app.Xlimit_plus=settings.Xlimit_plus;
            app.Xlimit_minus=settings.Xlimit_minus;
            app.Ylimit_plus=settings.Ylimit_plus;
            app.Ylimit_minus=settings.Ylimit_minus;
            app.Zlimit_plus=settings.Zlimit_plus;
            app.Zlimit_minus=settings.Zlimit_minus;
            
            app.capturedImg = settings.capturedImg; % Description
            app.totalImages = settings.totalImages; % Description
            app.spotList = settings.spotList;
            app.bwImg = settings.bwImg;
            app.thresHold = settings.thresHold; % Description
            app.matchedResult = settings.matchedResult; % result and info for single image
            app.matchedInfo = settings.matchedInfo; 
            app.results = settings.results; % results and infos for auto qual
            app.infos = settings.infos;
            app.settingRecords=settings.settingRecords;
            app.imageName = settings.imageName; % Description
        
        
            app.MaxXErr = settings.MaxXErr;
            app.MaxYErr = settings.MaxYErr;
            app.MaxRzErr = settings.MaxRzErr;
            
            
            % variable flag for pausing
            app.pauseFlag=settings.pauseFlag; % Description
            app.previousStatus=settings.previousStatus;
            app.isFrozen=settings.isFrozen;
            app.resultHandle=settings.resultHandle;
            
            app.register = settings.register;
            app.outerAbortFlag = settings.outerAbortFlag;

            %components
            app.PRAS_ID.Value = settings.PRAS_ID.Value;
            app.PRAS_ID.Enable=settings.PRAS_ID.Enable;
            
            app.Operator.Value = settings.Operator.Value;
            app.Operator.Enable = settings.Operator.Enable;
            
            app.WDSwitch.Value = settings.WDSwitch.Value;
            app.WDSwitch.Enable = settings.WDSwitch.Enable;
            
            
            app.Rel_X.Value=settings.Rel_X.Value;
            app.Rel_X.Enable=settings.Rel_X.Enable;
            
            app.Abs_X.Value=settings.Abs_X.Value;
            app.Abs_X.Enable=settings.Abs_X.Enable;
        
            app.Rel_Y.Value=settings.Rel_Y.Value;
            app.Rel_Y.Enable=settings.Rel_Y.Enable;
            
            app.Abs_Y.Value=settings.Abs_Y.Value;
            app.Abs_Y.Enable=settings.Abs_Y.Enable;
            
            app.Rel_Z.Value=settings.Rel_Z.Value;
            app.Rel_Z.Enable=settings.Rel_Z.Enable;
            
            app.Abs_Z.Value=settings.Abs_Z.Value;
            app.Abs_Z.Enable=settings.Abs_Z.Enable;
            
            app.Xplus.Enable=settings.Xplus.Enable;
            
            app.Xminus.Enable=settings.Xminus.Enable;
            
            app.XStart.Enable=settings.XStart.Enable;
        
            app.Yplus.Enable=settings.Yplus.Enable;
            
            app.Yminus.Enable=settings.Yminus.Enable;
            
            app.YStart.Enable=settings.YStart.Enable;
            
            app.Zplus.Enable=settings.Zplus.Enable;
            
            app.Zminus.Enable=settings.Zminus.Enable;
            
            app.ZStart.Enable=settings.ZStart.Enable;
            
            app.GoToLWDXcYcZnomButton.Enable=settings.GoToLWDXcYcZnomButton.Enable;
            app.GoToPARKButton.Enable=settings.GoToPARKButton.Enable;
            app.GoToSWDXcYcZnomButton.Enable=settings.GoToSWDXcYcZnomButton.Enable;
        
            app.PauseResumeButton.Enable=settings.PauseResumeButton.Enable;
            app.AbortTestButton.Enable=settings.AbortTestButton.Enable;
            app.BacktoMasterQualScreenButton.Enable=settings.BacktoMasterQualScreenButton.Enable;
            
            app.StartRPASQualRoutineButton.Enable = settings.StartRPASQualRoutineButton.Enable;
            
            app.XLamp.Color=settings.XLamp.Color;
            app.YLamp.Color=settings.YLamp.Color;
            app.ZLamp.Color=settings.ZLamp.Color;
            
            app.TestNumberDropDown.Items = settings.PositionNoDropDown.Items;
            app.TestNumberDropDown.ItemsData = settings.PositionNoDropDown.ItemsData;
            app.TestNumberDropDown.Value = ...
                app.TestNumberDropDown.ItemsData(...
                settings.PositionNoDropDown.Value);
            app.TestNumberDropDown.Enable = settings.PositionNoDropDown.Enable;
            
            
            app.TestPointDropDown.Enable = settings.TestPointDropDown.Enable;
            app.TestPointDropDown.Items = settings.TestPointDropDown.Items;
            app.TestPointDropDown.ItemsData = settings.TestPointDropDown.ItemsData;
            app.TestPointDropDown.Value = app.TestPointDropDown.ItemsData(...
                settings.TestPointDropDown.Value);
            
            app.GoButton.Enable = settings.GoButton.Enable;
            
            app.PresentPosNo.Value=settings.PresentPosNo.Value;
            app.PresentPosNo.Enable=settings.PresentPosNo.Enable;
            
            app.GoToQualResultButton.Enable=settings.GoToQualResultButton.Enable;
            
            app.ShowEstimationsButton.Enable=settings.ShowEstimationsButton.Enable;
            
            app.Expected_X.Value=settings.Expected_X.Value;
            app.Expected_X.Enable=settings.Expected_X.Enable;
            
            app.Expected_Y.Value=settings.Expected_Y.Value;
            app.Expected_Y.Enable=settings.Expected_Y.Enable;
            
            app.Expected_Z.Value=settings.Expected_Z.Value;
            app.Expected_Z.Enable=settings.Expected_Z.Enable;
            
            app.Found_X.Value=settings.Found_X.Value;
            app.Found_X.Enable=settings.Found_X.Enable;
            
            app.Found_Y.Value=settings.Found_Y.Value;
            app.Found_Y.Enable=settings.Found_Y.Enable;
            
            app.Found_Z.Value=settings.Found_Z.Value;
            app.Found_Z.Enable=settings.Found_Z.Enable;
            
            app.DeltaE_X.Value=settings.DeltaE_X.Value;
            app.DeltaE_X.Enable=settings.DeltaE_X.Enable;
            
            app.DeltaE_Y.Value=settings.DeltaE_Y.Value;
            app.DeltaE_Y.Enable=settings.DeltaE_Y.Enable;
            
            app.DeltaE_Z.Value=settings.DeltaE_Z.Value;
            app.DeltaE_Z.Enable=settings.DeltaE_Z.Enable;
            
            
            app.PassLamp_X.Color=settings.PassLamp_X.Color;
            app.PassLamp_Y.Color=settings.PassLamp_Y.Color;
            app.PassLamp_Z.Color=settings.PassLamp_Z.Color;
            
            if ~isempty(settings.image)
                %app.displayImage(settings.image, settings.imageName);
                app.displayThresholdImg();
            end
        end
        
        function abortProgram(app)
            app.AbortTestButtonPushed();
            app.hide(true);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, settings)
            
            if nargin==2
                app.restoreSettings(settings);
                global systemVar;
                if isempty(systemVar)
                    systemVar=savedVariables; %read in system setup
                end
                app.systemVar=systemVar;
                %app.systemVar=savedVariables; %read in system setup
                
                app.xupdater=updatePosition(app.PresentXPos, 0, .3, ...
                    {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
                app.yupdater=updatePosition(app.PresentYPos, 1, .3, ...
                    {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
                app.zupdater=updatePosition(app.PresentZPos, 2, .3, ...
                    {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
            
                app.xupdater.start();
                app.yupdater.start();
                app.zupdater.start();
            else
                app.init();
            end
        end

        % Close request function: RPASQualificationUIFigure
        function RPASQualificationUIFigureCloseRequest(app, event)
            if ~isempty(app.register) && isvalid(app.register) ...
                    && app.register.numberOfActiveProcess()>0
                app.showInfoMsg("Please abort test or waiting the test finish before closing.");
                return;
            end
            
            if ~isempty(app.resultImageHandle) & isvalid(app.resultImageHandle)
                delete(app.resultImageHandle);
            end
            if ~isempty(app.register) & isvalid(app.register)
                app.register.delete();
            end

            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            delete(app)
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.register.addProcess();

            % check pause
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();

            if app.positionSheet.BI(app.TestPointDropDown.Value) == 1 %test enabled

                %disable the buttons that might causing problem before the qual
                %finishes
                if ~app.isFrozen % not disabled by outside auto qual
                    app.WDSwitch.Enable=false;
                    app.TestNumberDropDown.Enable=false;
                    app.TestPointDropDown.Enable=false;
                end

                if app.qualMethod == app.manualQualMethod
                    app.movingSpeed=app.manualSpeed;
                elseif app.qualMethod== app.autoQualMethod
                    app.movingSpeed=app.autoSpeed;
                else
                    error('Unsupported Qualification method.')
                end
                
                
                % check pause
                if app.register.isAborting | app.outerAbortFlag
                    return;
                end
                app.checkPause();

                % moving to position p(i)
                axes=[0, 1, 2];
                position=app.selectTestSheet(app.TestPointDropDown.Value);
                ABSMoving(axes, position, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
                
                % check pause
                if app.register.isAborting | app.outerAbortFlag
                    return;
                end
                app.checkPause();
                
                % take the image
                [app.capturedImg, app.imageName]=app.captureImage(app.WDSwitch.Value);
                name=['TEST_POINT_' num2str(app.TestPointDropDown.Value)];
                app.totalImages=setfield(app.totalImages, name, app.capturedImg);
                
                % check pause
                if app.register.isAborting | app.outerAbortFlag
                    return;
                end
                app.checkPause();
            
                % display image
                app.displayImage(app.capturedImg, app.imageName);
                
                
                % check pause
                if app.register.isAborting | app.outerAbortFlag
                    return;
                end
                app.checkPause();
                
                %enable button for qual
                if ~app.isFrozen
                    app.StartRPASQualRoutineButton.Enable=true;
                    app.GoToQualResultButton.Enable=false;
                    app.ShowEstimationsButton.Enable=false;
                    app.GoButton.Enable=false;
                end
            else
                app.warningMsg('Test not enabled. Select next one.');
            end

            app.register.removeProcess();
        end

        % Button pushed function: StartRPASQualRoutineButton
        function StartRPASQualRoutineButtonPushed(app, event)
            app.register.addProcess();

            % check pause
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();

            app.qualification();

            % check pause
            if app.register.isAborting | app.outerAbortFlag
                return;
            end
            app.checkPause();
            
            if ~app.isFrozen % not frozen by auto qual
                %if all the points are qualified, we can see the
                % distribution of errors

                if sum(abs(app.pointsFinished-app.positionSheet.BI))==0
                    app.GoToQualResultButton.Enable=true;
                end
                app.ShowEstimationsButton.Enable=true;
                app.GoButton.Enable=true;
                app.TestNumberDropDown.Enable=true;
                app.TestPointDropDown.Enable=true;
                app.WDSwitch.Enable=true;
            end

            app.register.removeProcess();
        end

        % Button pushed function: GoToQualResultButton
        function GoToQualResultButtonPushed(app, event)
            app.displayResults();
            app.StartRPASQualRoutineButton.Enable=false;
        end

        % Button pushed function: Xplus
        function XplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end

            val=app.PresentXPos.Value + app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                RelativeMoving(0, app.Rel_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: Xminus
        function XminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentXPos.Value - app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
               RelativeMoving(0, -app.Rel_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: XStart
        function XStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                ABSMoving(0, app.Abs_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: Yplus
        function YplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentYPos.Value + app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, app.Rel_Y.Value, app.movingSpeed, app.yupdater, app);;
            end
        end

        % Button pushed function: Yminus
        function YminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentYPos.Value - app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, -app.Rel_Y.Value, app.movingSpeed, app.yupdater, app);
            end
        end

        % Button pushed function: YStart
        function YStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                ABSMoving(1, app.Abs_Y.Value, app.movingSpeed, app.yupdater, app);
            end
        end

        % Button pushed function: Zplus
        function ZplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentZPos.Value + app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
               RelativeMoving(2, app.Rel_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: Zminus
        function ZminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentZPos.Value - app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                RelativeMoving(2, -app.Rel_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: ZStart
        function ZStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                ABSMoving(2, app.Abs_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: GoToLWDXcYcZnomButton
        function GoToLWDXcYcZnomButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.LWD_Xc, app.LWD_Yc, app.LWD_Znorm];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
        end

        % Button pushed function: GoToPARKButton
        function GoToPARKButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.Park_X, app.Park_Y, app.Park_Z];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
        end

        % Button pushed function: GoToSWDXcYcZnomButton
        function GoToSWDXcYcZnomButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.SWD_Xc, app.SWD_Yc, app.SWD_Znorm];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            app.pauseFlag=~app.pauseFlag;
            app.freeze(app.pauseFlag);

            if ~isempty(app.motionStatus)
                if app.motionStatus.isPausing
                    lamps=[];
                    for k=1:numel(app.motionStatus.axes)
                        switch app.motionStatus.axes(k)
                            case {0}
                                lamps=[lamps app.xupdater];
                            case {1}
                                lamps=[lamps app.yupdater];
                            case {2}
                                lamps=[lamps app.zupdater];
                        end
                    end
                    app.motionStatus.isPausing=false;
                    if ~isempty(app.motionStatus.axes)
                        ABSMoving(app.motionStatus.axes, app.motionStatus.movingDistances, ...
                            app.motionStatus.movingSpeeds, lamps, app);
                    end
                else
                    app.motionStatus.isPausing=true;
                    if ~isempty(app.motionStatus.axes)
                        stopMotion([app.xupdater, app.yupdater, app.zupdater], app);
                    end
                end
            end
        end

        % Button pushed function: ShowEstimationsButton
        function ShowEstimationsButtonPushed(app, event)
            app.displayResultImages();
        end

        % Value changed function: TestNumberDropDown
        function TestNumberDropDownValueChanged(app, event)
            indexValue = str2double(app.TestNumberDropDown.Value);
            try
                app.updatePosition(indexValue);
                
                %reset container
                app.results=zeros(app.positionSheet.numTestPoints, 6);
                for k=1:app.positionSheet.numTestPoints
                    app.results(k,:)=[nan, nan, nan, nan, nan, nan];
                end
                app.infos=cell(app.positionSheet.numTestPoints,1);
                app.settingRecords=cell(app.positionSheet.numTestPoints,1);
                app.pointsFinished=zeros(app.positionSheet.numTestPoints,1);
            catch ME
                app.RPASQualificationUIFigureCloseRequest();
            end
        end

        % Value changed function: TestPointDropDown
        function TestPointDropDownValueChanged(app, event)
            index = app.TestPointDropDown.Value;
            
            %update Expected/ Error fields
            app.Expected_X.Value = app.positionSheet.Expected(index,1);
            app.Expected_Y.Value = app.positionSheet.Expected(index,2);
            app.Expected_Z.Value = app.positionSheet.Expected(index,3);

            app.Found_X.Value=0;
            app.Found_Y.Value=0;
            app.Found_Z.Value=0;
            app.DeltaE_X.Value=0;
            app.DeltaE_Y.Value=0;
            app.DeltaE_Z.Value=0;
            
            app.MaxXErr=app.positionSheet.Error(index,1);
            app.MaxYErr=app.positionSheet.Error(index,2);
            app.MaxRzErr=app.positionSheet.Error(index,3);

            %initialize Pass/Fail Lamps
            app.PassLamp_X.Color=app.PassColor;
            app.PassLamp_Y.Color=app.PassColor;
            app.PassLamp_Z.Color=app.PassColor;

            str=sprintf('Fail if abs(Delta E) > %g', app.MaxXErr);
            app.PassLamp_X.Tooltip=str;
            str=sprintf('Fail if abs(Delta E) > %g', app.MaxYErr);
            app.PassLamp_Y.Tooltip=str;
            str=sprintf('Fail if abs(Delta E) > %g', app.MaxRzErr);
            app.PassLamp_Z.Tooltip=str;

            cla(app.UIAxes);

            if ~app.isFrozen % not frozen by auto qual
                app.GoButton.Enable=true;
            end
        end

        % Button pushed function: AbortTestButton
        function AbortTestButtonPushed(app, event)
            app.pauseFlag=false;
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            app.motionStatus=struct('axes', [], 'movingSpeeds', [], ...
                'movingDistances', [], 'movingType', '', ...
                'status', '', 'isPausing', false);
            app.register.Aborting(true);
        end

        % Button pushed function: BacktoMasterQualScreenButton
        function BacktoMasterQualScreenButtonPushed(app, event)
            app.RPASQualificationUIFigureCloseRequest();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASQualificationUIFigure = uifigure('Visible', 'off');
            app.RPASQualificationUIFigure.Name = 'RPAS Qualification';
            app.RPASQualificationUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASQualificationUIFigureCloseRequest, true);
            app.RPASQualificationUIFigure.Tag = 'RPAS_Qual';
            app.RPASQualificationPanel = uipanel(app.RPASQualificationUIFigure);
            app.RPASQualificationPanel.Title = 'RPAS Qualification';
            app.UIAxes = uiaxes(app.RPASQualificationPanel);
            xlabel(app.UIAxes, 'Y (Pixels)')
            ylabel(app.UIAxes, 'X (Pixels)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.YTick = [0 100 200 300 400];
            app.ReticlePositionPanel = uipanel(app.RPASQualificationPanel);
            app.ReticlePositionPanel.Title = 'Reticle Position';
            app.GoToPARKButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToPARKButton.ButtonPushedFcn = createCallbackFcn(app, @GoToPARKButtonPushed, true);
            app.GoToPARKButton.Text = 'Go To PARK';
            app.GoToLWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToLWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToLWDXcYcZnomButtonPushed, true);
            app.GoToLWDXcYcZnomButton.Text = 'Go To LWD Xc Yc Znom';
            app.GoToSWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToSWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToSWDXcYcZnomButtonPushed, true);
            app.GoToSWDXcYcZnomButton.Text = 'Go To SWD Xc Yc Znom';
            app.AbsoluteZMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.mmLabel_10 = uilabel(app.AbsoluteZMovePanel);
            app.mmLabel_10.Text = 'mm';
            app.ZStart = uibutton(app.AbsoluteZMovePanel, 'push');
            app.ZStart.ButtonPushedFcn = createCallbackFcn(app, @ZStartButtonPushed, true);
            app.ZStart.Text = 'Start';
            app.Abs_Z = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.Abs_Z.ValueDisplayFormat = '%.3f';
            app.RelativeZMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.mmLabel_11 = uilabel(app.RelativeZMovePanel);
            app.mmLabel_11.Text = 'mm';
            app.Zplus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zplus.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.Zplus.Text = '+';
            app.Zminus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zminus.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.Zminus.Text = '-';
            app.Rel_Z = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.Rel_Z.ValueDisplayFormat = '%.3f';
            app.AbsoluteYMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.YStart = uibutton(app.AbsoluteYMovePanel, 'push');
            app.YStart.ButtonPushedFcn = createCallbackFcn(app, @YStartButtonPushed, true);
            app.YStart.Text = 'Start';
            app.mmLabel_12 = uilabel(app.AbsoluteYMovePanel);
            app.mmLabel_12.Text = 'mm';
            app.Abs_Y = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.Abs_Y.ValueDisplayFormat = '%.3f';
            app.RelativeYMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.mmLabel_13 = uilabel(app.RelativeYMovePanel);
            app.mmLabel_13.Text = 'mm';
            app.Yplus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yplus.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.Yplus.Text = '+';
            app.Yminus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yminus.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.Yminus.Text = '-';
            app.Rel_Y = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.Rel_Y.ValueDisplayFormat = '%.3f';
            app.AbsoluteXMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.XStart = uibutton(app.AbsoluteXMovePanel, 'push');
            app.XStart.ButtonPushedFcn = createCallbackFcn(app, @XStartButtonPushed, true);
            app.XStart.Text = 'Start';
            app.mmLabel_14 = uilabel(app.AbsoluteXMovePanel);
            app.mmLabel_14.Text = 'mm';
            app.Abs_X = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.Abs_X.ValueDisplayFormat = '%.3f';
            app.RelativeXMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.Xplus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xplus.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.Xplus.Text = '+';
            app.mmLabel_15 = uilabel(app.RelativeXMovePanel);
            app.mmLabel_15.Text = 'mm';
            app.Xminus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xminus.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.Xminus.Text = '-';
            app.Rel_X = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.Rel_X.ValueDisplayFormat = '%.3f';
            app.PresentXPosPanel = uipanel(app.RPASQualificationPanel);
            app.PresentXPosPanel.Title = 'Present X Pos';
            app.PresentXPos = uieditfield(app.PresentXPosPanel, 'numeric');
            app.PresentXPos.ValueDisplayFormat = '%.2f';
            app.PresentXPos.Editable = 'off';
            app.mmLabel_7 = uilabel(app.PresentXPosPanel);
            app.mmLabel_7.Text = 'mm';
            app.PresentZPosPanel = uipanel(app.RPASQualificationPanel);
            app.PresentZPosPanel.Title = 'Present Z Pos';
            app.PresentZPos = uieditfield(app.PresentZPosPanel, 'numeric');
            app.PresentZPos.ValueDisplayFormat = '%.2f';
            app.PresentZPos.Editable = 'off';
            app.mmLabel_8 = uilabel(app.PresentZPosPanel);
            app.mmLabel_8.Text = 'mm';
            app.PresentYPosPanel = uipanel(app.RPASQualificationPanel);
            app.PresentYPosPanel.Title = 'Present Y Pos';
            app.PresentYPos = uieditfield(app.PresentYPosPanel, 'numeric');
            app.PresentYPos.ValueDisplayFormat = '%.2f';
            app.PresentYPos.Editable = 'off';
            app.mmLabel_9 = uilabel(app.PresentYPosPanel);
            app.mmLabel_9.Text = 'mm';
            app.Panel = uipanel(app.RPASQualificationPanel);
            app.WDSwitch = uiswitch(app.Panel, 'slider');
            app.WDSwitch.Items = {'SWD', 'LWD'};
            app.WDSwitch.ItemsData = [1 0];
            app.WDSwitch.Value = 1;
            app.ImageCaptureDataCollectionInfoLabel = uilabel(app.Panel);
            app.ImageCaptureDataCollectionInfoLabel.Text = {'Image Capture / Data'; 'Collection Info'};
            app.RPASIDEditFieldLabel = uilabel(app.Panel);
            app.RPASIDEditFieldLabel.Text = 'RPAS ID';
            app.PRAS_ID = uieditfield(app.Panel, 'text');
            app.OperatorEditFieldLabel = uilabel(app.Panel);
            app.OperatorEditFieldLabel.Text = 'Operator ';
            app.Operator = uieditfield(app.Panel, 'text');
            app.StartRPASQualRoutineButton = uibutton(app.RPASQualificationPanel, 'push');
            app.StartRPASQualRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @StartRPASQualRoutineButtonPushed, true);
            app.StartRPASQualRoutineButton.Text = {'Start RPAS Qual '; 'Routine'; ''};
            app.GoButton = uibutton(app.RPASQualificationPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.PauseResumeButton = uibutton(app.RPASQualificationPanel, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause/Resume';
            app.AbortTestButton = uibutton(app.RPASQualificationPanel, 'push');
            app.AbortTestButton.ButtonPushedFcn = createCallbackFcn(app, @AbortTestButtonPushed, true);
            app.AbortTestButton.Text = 'Abort Test';
            app.BacktoMasterQualScreenButton = uibutton(app.RPASQualificationPanel, 'push');
            app.BacktoMasterQualScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMasterQualScreenButtonPushed, true);
            app.BacktoMasterQualScreenButton.Text = {'Back to Master'; 'Qual Screen'; ''};
            app.TravelLimitLabel = uilabel(app.RPASQualificationPanel);
            app.TravelLimitLabel.Text = 'Travel Limit';
            app.XLamp_2Label = uilabel(app.RPASQualificationPanel);
            app.XLamp_2Label.Text = 'X';
            app.XLamp = uilamp(app.RPASQualificationPanel);
            app.YLamp_2Label = uilabel(app.RPASQualificationPanel);
            app.YLamp_2Label.Text = 'Y';
            app.YLamp = uilamp(app.RPASQualificationPanel);
            app.ZLamp_2Label = uilabel(app.RPASQualificationPanel);
            app.ZLamp_2Label.Text = 'Z';
            app.ZLamp = uilamp(app.RPASQualificationPanel);
            app.ResultsPanel = uipanel(app.RPASQualificationPanel);
            app.ResultsPanel.Title = 'Results';
            app.ExpectedLabel = uilabel(app.ResultsPanel);
            app.ExpectedLabel.Text = 'Expected';
            app.Expected_X = uieditfield(app.ResultsPanel, 'numeric');
            app.Expected_X.ValueDisplayFormat = '%5.2f';
            app.Expected_X.Editable = 'off';
            app.MeasuredLabel = uilabel(app.ResultsPanel);
            app.MeasuredLabel.Text = 'Measured';
            app.Found_X = uieditfield(app.ResultsPanel, 'numeric');
            app.Found_X.ValueDisplayFormat = '%5.2f';
            app.Found_X.Editable = 'off';
            app.PassFailLabel = uilabel(app.ResultsPanel);
            app.PassFailLabel.Text = 'Pass/Fail';
            app.PassLamp_X = uilamp(app.ResultsPanel);
            app.XumLabel = uilabel(app.ResultsPanel);
            app.XumLabel.Text = 'X (um)';
            app.DeltaEEditFieldLabel = uilabel(app.ResultsPanel);
            app.DeltaEEditFieldLabel.Text = 'Delta E';
            app.DeltaE_X = uieditfield(app.ResultsPanel, 'numeric');
            app.DeltaE_X.ValueDisplayFormat = '%5.2f';
            app.DeltaE_X.Editable = 'off';
            app.PresentPosNoLabel = uilabel(app.ResultsPanel);
            app.PresentPosNoLabel.Text = 'Present Test No.';
            app.PresentPosNo = uieditfield(app.ResultsPanel, 'text');
            app.PresentPosNo.Editable = 'off';
            app.Expected_Y = uieditfield(app.ResultsPanel, 'numeric');
            app.Expected_Y.ValueDisplayFormat = '%5.2f';
            app.Expected_Y.Editable = 'off';
            app.Found_Y = uieditfield(app.ResultsPanel, 'numeric');
            app.Found_Y.ValueDisplayFormat = '%5.2f';
            app.Found_Y.Editable = 'off';
            app.PassLamp_Y = uilamp(app.ResultsPanel);
            app.YumLabel = uilabel(app.ResultsPanel);
            app.YumLabel.Text = 'Y (um)';
            app.DeltaE_Y = uieditfield(app.ResultsPanel, 'numeric');
            app.DeltaE_Y.ValueDisplayFormat = '%5.2f';
            app.DeltaE_Y.Editable = 'off';
            app.Expected_Z = uieditfield(app.ResultsPanel, 'numeric');
            app.Expected_Z.ValueDisplayFormat = '%5.2f';
            app.Expected_Z.Editable = 'off';
            app.Found_Z = uieditfield(app.ResultsPanel, 'numeric');
            app.Found_Z.ValueDisplayFormat = '%5.2f';
            app.Found_Z.Editable = 'off';
            app.PassLamp_Z = uilamp(app.ResultsPanel);
            app.RzmradLabel = uilabel(app.ResultsPanel);
            app.RzmradLabel.Text = 'Rz (mrad)';
            app.DeltaE_Z = uieditfield(app.ResultsPanel, 'numeric');
            app.DeltaE_Z.ValueDisplayFormat = '%5.2f';
            app.DeltaE_Z.Editable = 'off';
            app.GoToQualResultButton = uibutton(app.ResultsPanel, 'push');
            app.GoToQualResultButton.ButtonPushedFcn = createCallbackFcn(app, @GoToQualResultButtonPushed, true);
            app.GoToQualResultButton.Text = {'Go To '; 'Qual Result'};
            app.ShowEstimationsButton = uibutton(app.ResultsPanel, 'push');
            app.ShowEstimationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowEstimationsButtonPushed, true);
            app.ShowEstimationsButton.Text = {'Show'; 'Estimations'};
            app.PositionNoLabel = uilabel(app.RPASQualificationPanel);
            app.PositionNoLabel.Text = 'Test Number';
            app.TestNumberDropDown = uidropdown(app.RPASQualificationPanel);
            app.TestNumberDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.TestNumberDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.TestNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @TestNumberDropDownValueChanged, true);
            app.TestNumberDropDown.Value = '1';
            app.TestPointiLabel_2 = uilabel(app.RPASQualificationPanel);
            app.TestPointiLabel_2.Text = 'Test Point (i)';
            app.TestPointDropDown = uidropdown(app.RPASQualificationPanel);
            app.TestPointDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.TestPointDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.TestPointDropDown.ValueChangedFcn = createCallbackFcn(app, @TestPointDropDownValueChanged, true);
            app.TestPointDropDown.Value = '1';
            % Show the figure after all components are created
            app.RPASQualificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_Qual(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASQualificationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASQualificationUIFigure)
        end
    end
end