classdef RPAS_QualResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        RPASQualificationResultsPanel  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        CloseWindowButton              matlab.ui.control.Button
        RzPanel                        matlab.ui.container.Panel
        mradLabel_2                    matlab.ui.control.Label
        mradLabel                      matlab.ui.control.Label
        LampZ                          matlab.ui.control.Lamp
        PassFailLabel_3                matlab.ui.control.Label
        NoZ                            matlab.ui.control.NumericEditField
        NoofdataptsEditFieldLabel_3    matlab.ui.control.Label
        StdZ                           matlab.ui.control.NumericEditField
        StandDevEditFieldLabel_3       matlab.ui.control.Label
        AvgErrorZ                      matlab.ui.control.NumericEditField
        AvgErrorEditFieldLabel_3       matlab.ui.control.Label
        UIAxesZ                        matlab.ui.control.UIAxes
        YPanel                         matlab.ui.container.Panel
        umLabel_4                      matlab.ui.control.Label
        umLabel_3                      matlab.ui.control.Label
        LampY                          matlab.ui.control.Lamp
        PassFailLabel_2                matlab.ui.control.Label
        NoY                            matlab.ui.control.NumericEditField
        NoofdataptsEditFieldLabel_2    matlab.ui.control.Label
        StdY                           matlab.ui.control.NumericEditField
        StandDevEditFieldLabel_2       matlab.ui.control.Label
        AvgErrorY                      matlab.ui.control.NumericEditField
        AvgErrorEditFieldLabel_2       matlab.ui.control.Label
        UIAxesY                        matlab.ui.control.UIAxes
        XPanel                         matlab.ui.container.Panel
        umLabel_2                      matlab.ui.control.Label
        umLabel                        matlab.ui.control.Label
        LampX                          matlab.ui.control.Lamp
        PassFailLabel                  matlab.ui.control.Label
        NoX                            matlab.ui.control.NumericEditField
        NoofdataptsEditFieldLabel      matlab.ui.control.Label
        StdX                           matlab.ui.control.NumericEditField
        StandDevEditFieldLabel         matlab.ui.control.Label
        AvgErrorX                      matlab.ui.control.NumericEditField
        AvgErrorEditFieldLabel         matlab.ui.control.Label
        UIAxesX                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        passColor=[0, 1, 0.5];
        failColor=[0.6, 0, 0]; % Description
    end
    
    methods (Access = public)
        
        function close(app)
            app.CloseWindowButtonPushed();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dist, AllowableError)
            %X axis
            histogram(dist(:,1), 'numBins', length(dist), ...
                'parent', app.UIAxesX)
            app.AvgErrorX.Value=mean(dist(:,1));
            app.StdX.Value=std(dist(:,1));
            app.NoX.Value=length(dist);
            if abs(app.AvgErrorX.Value)>AllowableError(1)
                app.LampX.Color=app.failColor;
            else
                app.LampX.Color=app.passColor;
            end
            str=sprintf('Fail if abs(Avg. Err) > %g', AllowableError(1));
            app.LampX.Tooltip=str;
            
            %Y axis
            histogram(dist(:,2), 'numBins', length(dist), ...
                'parent', app.UIAxesY)
            app.AvgErrorY.Value=mean(dist(:,2));
            app.StdY.Value=std(dist(:,2));
            app.NoY.Value=length(dist);
            if abs(app.AvgErrorY.Value)>AllowableError(2)
                app.LampY.Color=app.failColor;
            else
                app.LampY.Color=app.passColor;
            end
            str=sprintf('Fail if abs(Avg. Err) > %g', AllowableError(2));
            app.LampY.Tooltip=str;
            
            %Z axis
            histogram(dist(:,3), 'numBins', length(dist), ...
                'parent', app.UIAxesZ)
            app.AvgErrorZ.Value=mean(dist(:,3));
            app.StdZ.Value=std(dist(:,3));
            app.NoZ.Value=length(dist);
            if abs(app.AvgErrorZ.Value)>AllowableError(3)
                app.LampZ.Color=app.failColor;
            else
                app.LampZ.Color=app.passColor;
            end
            str=sprintf('Fail if abs(Avg. Err) > %g', AllowableError(3));
            app.LampZ.Tooltip=str;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CloseWindowButton
        function CloseWindowButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RPASQualificationResultsPanel = uipanel(app.GridLayout);
            app.RPASQualificationResultsPanel.Title = 'RPAS Qualification Results';
            app.GridLayout2 = uigridlayout(app.RPASQualificationResultsPanel);
            app.XPanel = uipanel(app.GridLayout2);
            app.XPanel.Title = 'X';
            app.UIAxesX = uiaxes(app.XPanel);
            xlabel(app.UIAxesX, 'Error (um)')
            ylabel(app.UIAxesX, 'Count')
            zlabel(app.UIAxesX, 'Z')
            app.AvgErrorEditFieldLabel = uilabel(app.XPanel);
            app.AvgErrorEditFieldLabel.Text = 'Avg. Error';
            app.AvgErrorX = uieditfield(app.XPanel, 'numeric');
            app.AvgErrorX.ValueDisplayFormat = '%5.2f';
            app.AvgErrorX.Editable = 'off';
            app.StandDevEditFieldLabel = uilabel(app.XPanel);
            app.StandDevEditFieldLabel.Text = 'Stand Dev';
            app.StdX = uieditfield(app.XPanel, 'numeric');
            app.StdX.ValueDisplayFormat = '%5.2f';
            app.StdX.Editable = 'off';
            app.NoofdataptsEditFieldLabel = uilabel(app.XPanel);
            app.NoofdataptsEditFieldLabel.Text = 'No. of data pts';
            app.NoX = uieditfield(app.XPanel, 'numeric');
            app.NoX.ValueDisplayFormat = '%d';
            app.NoX.Editable = 'off';
            app.PassFailLabel = uilabel(app.XPanel);
            app.PassFailLabel.Text = {'Pass/'; 'Fail'};
            app.LampX = uilamp(app.XPanel);
            app.umLabel = uilabel(app.XPanel);
            app.umLabel.Text = {'um'; ''};
            app.umLabel_2 = uilabel(app.XPanel);
            app.umLabel_2.Text = {'um'; ''};
            app.YPanel = uipanel(app.GridLayout2);
            app.YPanel.Title = 'Y';
            app.UIAxesY = uiaxes(app.YPanel);
            xlabel(app.UIAxesY, 'Error (um)')
            ylabel(app.UIAxesY, 'Count')
            zlabel(app.UIAxesY, 'Z')
            app.AvgErrorEditFieldLabel_2 = uilabel(app.YPanel);
            app.AvgErrorEditFieldLabel_2.Text = 'Avg. Error';
            app.AvgErrorY = uieditfield(app.YPanel, 'numeric');
            app.AvgErrorY.ValueDisplayFormat = '%5.2f';
            app.AvgErrorY.Editable = 'off';
            app.StandDevEditFieldLabel_2 = uilabel(app.YPanel);
            app.StandDevEditFieldLabel_2.Text = 'Stand Dev';
            app.StdY = uieditfield(app.YPanel, 'numeric');
            app.StdY.ValueDisplayFormat = '%5.2f';
            app.StdY.Editable = 'off';
            app.NoofdataptsEditFieldLabel_2 = uilabel(app.YPanel);
            app.NoofdataptsEditFieldLabel_2.Text = 'No. of data pts';
            app.NoY = uieditfield(app.YPanel, 'numeric');
            app.NoY.ValueDisplayFormat = '%d';
            app.NoY.Editable = 'off';
            app.PassFailLabel_2 = uilabel(app.YPanel);
            app.PassFailLabel_2.Text = {'Pass/'; 'Fail'};
            app.LampY = uilamp(app.YPanel);
            app.umLabel_3 = uilabel(app.YPanel);
            app.umLabel_3.Text = {'um'; ''};
            app.umLabel_4 = uilabel(app.YPanel);
            app.umLabel_4.Text = {'um'; ''};
            app.RzPanel = uipanel(app.GridLayout2);
            app.RzPanel.Title = 'Rz';
            app.UIAxesZ = uiaxes(app.RzPanel);
            xlabel(app.UIAxesZ, 'Error (mrad)')
            ylabel(app.UIAxesZ, 'Count')
            zlabel(app.UIAxesZ, 'Z')
            app.AvgErrorEditFieldLabel_3 = uilabel(app.RzPanel);
            app.AvgErrorEditFieldLabel_3.Text = 'Avg. Error';
            app.AvgErrorZ = uieditfield(app.RzPanel, 'numeric');
            app.AvgErrorZ.ValueDisplayFormat = '%5.2f';
            app.AvgErrorZ.Editable = 'off';
            app.StandDevEditFieldLabel_3 = uilabel(app.RzPanel);
            app.StandDevEditFieldLabel_3.Text = 'Stand Dev';
            app.StdZ = uieditfield(app.RzPanel, 'numeric');
            app.StdZ.ValueDisplayFormat = '%5.2f';
            app.StdZ.Editable = 'off';
            app.NoofdataptsEditFieldLabel_3 = uilabel(app.RzPanel);
            app.NoofdataptsEditFieldLabel_3.Text = 'No. of data pts';
            app.NoZ = uieditfield(app.RzPanel, 'numeric');
            app.NoZ.ValueDisplayFormat = '%d';
            app.NoZ.Editable = 'off';
            app.PassFailLabel_3 = uilabel(app.RzPanel);
            app.PassFailLabel_3.Text = {'Pass/'; 'Fail'};
            app.LampZ = uilamp(app.RzPanel);
            app.mradLabel = uilabel(app.RzPanel);
            app.mradLabel.Text = {'mrad'; ''};
            app.mradLabel_2 = uilabel(app.RzPanel);
            app.mradLabel_2.Text = {'mrad'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.CloseWindowButton = uibutton(app.GridLayout3, 'push');
            app.CloseWindowButton.ButtonPushedFcn = createCallbackFcn(app, @CloseWindowButtonPushed, true);
            app.CloseWindowButton.Text = 'Close Window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_QualResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end