classdef RPAS_TWINSCANResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResultFig    matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Panel        matlab.ui.container.Panel
        UITable      matlab.ui.control.Table
        header_2     matlab.ui.control.Table
        header_1     matlab.ui.control.Table
        GridLayout2  matlab.ui.container.GridLayout
        DropDown     matlab.ui.control.DropDown
        CloseButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ALLOW_ERROR;
        RPAS_DATA;
        TWNSCN_DATA; % Description
    end
    
    methods (Access = public)
        
        function result = Pass(app)
            result=true;
            if ~isempty(app.RPAS_DATA.SWDvalue)
                error=max(abs(app.RPAS_DATA.SWDerror), abs(app.TWNSCN_DATA.SWDerror)) ...
                    -app.ALLOW_ERROR;
                error=max(error(:));
                if error>0
                    result=false;
                    return;
                end
            end
            if ~isempty(app.RPAS_DATA.LWDvalue)
                error=max(abs(app.RPAS_DATA.LWDerror), abs(app.TWNSCN_DATA.LWDerror)) ...
                    -app.ALLOW_ERROR;
                error=max(error(:));
                if error>0
                    result=false;
                    return;
                end
            end
        end
    end
    
    methods (Access = private)
        
        function data = displayData(app, wd)
            
            %fprintf(1,'numel(uitable)=%d\n', numel(app.UITable.ColumnName));
            data=cell(size(app.ALLOW_ERROR,1),17);
            for k=1:size(app.ALLOW_ERROR,1)
                %Test Point
                data{k,1}=num2str(k);
                
                %Allowable Error
                error=app.ALLOW_ERROR(k,:);
                a=error;
                val=num2str(error(1));
                for j=2:3
                    val =[val  ', '  num2str(error(j))];
                end
                data{k,2}=val;
                
                %RPAS Qual Measured value
                if wd==1 % SWD
                    xyz=app.RPAS_DATA.SWDvalue(k,:);
                else
                    xyz=app.RPAS_DATA.LWDvalue(k,:);
                end
                val=num2str(xyz(1));
                for j=2:3
                    val =[val  ', '  num2str(xyz(j))];
                end
                data{k,3}=val;
                
                %RPAS Qual Measured error
                if wd==1 % SWD
                    error=app.RPAS_DATA.SWDerror(k,:);
                else
                    error=app.RPAS_DATA.LWDerror(k,:);
                end
                b=max(abs(error)-a);
                
                val=num2str(error(1));
                for j=2:3
                    val =[val  ', '  num2str(error(j))];
                end
                data{k,4}=val;
                
                %TWNSCN Qual Measured value
                if wd==1 % SWD
                    xyz=app.TWNSCN_DATA.SWDvalue(k,:);
                else
                    xyz=app.TWNSCN_DATA.LWDvalue(k,:);
                end
                val=num2str(xyz(1));
                for j=2:3
                    val =[val  ', '  num2str(xyz(j))];
                end
                data{k,5}=val;
                
                %TWNSCN Qual Measured error
                if wd==1 %SWD
                    error=app.TWNSCN_DATA.SWDerror(k,:);
                else
                    error=app.TWNSCN_DATA.LWDerror(k,:);
                end
                c=max(abs(error)-a);
                
                val=num2str(error(1));
                for j=2:3
                    val =[val  ', '  num2str(error(j))];
                end
                data{k,6}=val;
                
                a=max(max(b), max(c));
                if a>0
                    data{k,7}='Fail';
                else
                    data{k,7}='Pass';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, wd, allowableError, RPAS, TWNSCN)
            app.ResultFig.Visible=false;
            if wd==1 %SWD
                if numel(RPAS.SWDvalue)==0 | numel(TWNSCN.SWDvalue)==0
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300; h=160;
                    fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                    title='Info';
                    selection=uiconfirm(fig,'No results to display','Error...', ...
                        'Options',{'OK'}, ...
                        'icon', 'error');
                    switch selection
                        case {'OK'}
                        otherwise
                    end
                    delete(fig);
                    app.CloseButtonPushed();
                    return;
                else
                    app.DropDown.Value=app.DropDown.Items{1};
                    if numel(RPAS.LWDvalue)==0 
                        app.DropDown.Enable=false;
                    else
                        app.DropDown.Enable=true;
                    end
                end
            else %LWD
                if numel(RPAS.LWDvalue)==0 | numel(TWNSCN.LWDvalue)==0
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300; h=160;
                    fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                    title='Info';
                    selection=uiconfirm(fig,'No results to display','Error...', ...
                        'Options',{'OK'}, ...
                        'icon', 'error');
                    switch selection
                        case {'OK'}
                        otherwise
                    end
                    delete(fig);
                    app.CloseButtonPushed();
                    return;
                else
                    app.DropDown.Value=app.DropDown.Items{2};
                    if numel(RPAS.SWDvalue)==0
                        app.DropDown.Enable=false;
                    else
                        app.DropDown.Enable=true;
                    end
                end
            end
            names={'','','RPAS QUAL','TWINSCAN QUAL','Pass/Fail ?'};
            app.header_1.ColumnName=names;
            app.header_1.ColumnWidth={43,204, 336, 336, 80};
            %fprintf(1,'header_1=%d\n', numel(app.header_1.ColumnName));
            names={'Point','Allowed Error: X/Y(um), Rz(mrad)','Value: X, Y (um), Rz (mrad)','Error: X, Y (um), Rz (mrad)','Value: X, Y (um), Rz (mrad)','Error: X, Y (um), Rz (mrad)', ''};
            app.header_2.ColumnName=names;
            widths={43,204, 168, 168, 168, 168, 80};
            app.header_2.ColumnWidth=widths;
            names={'(i)','X','Y','Rz','X','Y','Rz','X','Y','Rz','X','Y','Rz','X','Y','Rz',''};
            %app.UITable.ColumnName=names;
            app.UITable.ColumnWidth=widths;

            app.ALLOW_ERROR=allowableError;
            app.RPAS_DATA=RPAS;
            app.TWNSCN_DATA=TWNSCN;
            data=app.displayData(wd);

            app.UITable.Data=data;
            %app.UITable.FontSize=10;
            app.ResultFig.Visible=true;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Close request function: ResultFig
        function ResultFigCloseRequest(app, event)
            delete(app);
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if strcmp(value, app.DropDown.Items{1})
                data=app.displayData(1);
            else
                data=app.displayData(0);
            end
            app.UITable.Data=data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResultFig = uifigure('Visible', 'off');
            app.ResultFig.Name = 'TWINSCAN Qual vs RPAS Qualification Results';
            app.ResultFig.CloseRequestFcn = createCallbackFcn(app, @ResultFigCloseRequest, true);
            app.GridLayout = uigridlayout(app.ResultFig);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'SWD', 'LWD'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'SWD';
            app.Panel = uipanel(app.GridLayout);
            app.header_1 = uitable(app.Panel);
            app.header_1.ColumnName = '';
            app.header_1.RowName = {};
            app.header_2 = uitable(app.Panel);
            app.header_2.ColumnName = '';
            app.header_2.RowName = {};
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.ResultFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_TWINSCANResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResultFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResultFig)
        end
    end
end