classdef RPAS_Tool_Calib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASToolCalibrationUIFigure    matlab.ui.Figure
        ToolCallibrationPanel          matlab.ui.container.Panel
        video_OnOffButton              matlab.ui.control.Button
        SnapShot                       matlab.ui.control.Button
        LWDPanel                       matlab.ui.container.Panel
        GotoLWDZnomButton              matlab.ui.control.Button
        GotoLWDXcYcButton              matlab.ui.control.Button
        RecordasLWDXcYcButton          matlab.ui.control.Button
        AbsoluteZMovePanel             matlab.ui.container.Panel
        mmLabel_16                     matlab.ui.control.Label
        ToolCalStartButtonAbsZ         matlab.ui.control.Button
        ToolCalAbsoluteZMoveEditField  matlab.ui.control.NumericEditField
        RelativeZMovePanel             matlab.ui.container.Panel
        ToolCalRelativeZMoveEditField  matlab.ui.control.NumericEditField
        ToolCalDecrementZButton        matlab.ui.control.Button
        ToolCalincrementZButton        matlab.ui.control.Button
        mmLabel_15                     matlab.ui.control.Label
        AbsoluteYMovePanel             matlab.ui.container.Panel
        mmLabel_14                     matlab.ui.control.Label
        ToolCalStartButtonAbsY         matlab.ui.control.Button
        ToolCalAbsoluteYMoveEditField  matlab.ui.control.NumericEditField
        RelativeYMovePanel             matlab.ui.container.Panel
        ToolCalRelativeYMoveEditField  matlab.ui.control.NumericEditField
        ToolCalDecrementYButton        matlab.ui.control.Button
        ToolCalincrementYButton        matlab.ui.control.Button
        mmLabel_13                     matlab.ui.control.Label
        AbsoluteXMovePanel             matlab.ui.container.Panel
        mmLabel_12                     matlab.ui.control.Label
        ToolCalStartButtonAbsX         matlab.ui.control.Button
        ToolCalAbsoluteXMoveEditField  matlab.ui.control.NumericEditField
        RelativeXMovePanel             matlab.ui.container.Panel
        ToolCalRelativeXMoveEditField  matlab.ui.control.NumericEditField
        ToolCalDecrementXButton        matlab.ui.control.Button
        ToolCalincrementXButton        matlab.ui.control.Button
        mmLabel_11                     matlab.ui.control.Label
        ZLamp                          matlab.ui.control.Lamp
        ZLampLabel                     matlab.ui.control.Label
        YLamp                          matlab.ui.control.Lamp
        YLampLabel                     matlab.ui.control.Label
        XLamp                          matlab.ui.control.Lamp
        XLabel                         matlab.ui.control.Label
        TravelLimitLabel               matlab.ui.control.Label
        PresentYPosPanel               matlab.ui.container.Panel
        mmLabel_10                     matlab.ui.control.Label
        ToolCalPresentYPosEditField    matlab.ui.control.NumericEditField
        PresentZPosPanel               matlab.ui.container.Panel
        mmLabel_9                      matlab.ui.control.Label
        ToolCalPresentZPosEditField    matlab.ui.control.NumericEditField
        PresentXPosPanel               matlab.ui.container.Panel
        mmLabel_8                      matlab.ui.control.Label
        ToolCalPresentXPosEditField    matlab.ui.control.NumericEditField
        BackToMainScreenButton         matlab.ui.control.Button
        STOPMOTIONButton               matlab.ui.control.Button
        SWDPanel                       matlab.ui.container.Panel
        GotoSWDZnomButton              matlab.ui.control.Button
        GotoSWDXcYcButton              matlab.ui.control.Button
        RecordasSWDXcYcButton          matlab.ui.control.Button
        GoToPARKButton                 matlab.ui.control.Button
        ToolCalGoToHOMEButton          matlab.ui.control.Button
        GoToZOffsetButton              matlab.ui.control.Button
        IndZOffsetEditField            matlab.ui.control.NumericEditField
        IndZOffsetEditFieldLabel       matlab.ui.control.Label
        mmLabel_7                      matlab.ui.control.Label
        RECORDASZOffsetButton          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        width=640; % image width
        height=480; % image height
        refImg;
        systemVar; % variable hold system configurations
        rcdNames={'ZOffset', 'SWD', 'LWD'};
        xupdater;% timers
        yupdater;
        zupdater;
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        
        % variables extracted from systemVar
        
        % travel limits
        XplusLimit; 
        XminusLimit;
        YplusLimit; 
        YminusLimit;
        ZplusLimit; 
        ZminusLimit;
        
        %Home position
        Xhome;
        Yhome;
        Zhome;
        
        %Park position
        Xpark;
        Ypark;
        Zpark;

        imageCaptureEnabled;
        imageCaptureTime;
        
    end
    
    methods (Access = public)
        
        function displayImage(app, img, src, showCrossHair)
            app.UIAxes.Title.String=src;
            app.UIAxes.XLim=[0, size(img,2)];
            app.UIAxes.YLim=[0, size(img,1)];
            mX=round(size(img,2)/100);
            dx=round(mX/6)*100;
            xtik=[0, dx, 2*dx, 3*dx, 4*dx, 5*dx, min(6*dx, mX*100)];
            app.UIAxes.XTick=xtik;
            xtikLabel={};
            for j=1:numel(xtik)
                xtikLabel{end+1}=num2str(xtik(j));
            end
            app.UIAxes.XTickLabel=xtikLabel;
            mY=round(size(img,1)/100);
            dy=round(mY/4)*100;
            ytik=[0, dy, 2*dy, 3*dy, min(4*dy, mY*100)];
            app.UIAxes.YTick=ytik;
            ytikLabel={};
            for j=1:numel(ytik)
                ytikLabel{end+1}=num2str(ytik(j));
            end
            app.UIAxes.YTickLabel=ytikLabel;
            app.UIAxes.YAxisLocation='left';
            app.UIAxes.XAxisLocation='bottom';
            if showCrossHair
                app.updateCrossHair(size(img));
                imshowpair(img, imref2d(size(img)), ...
                    app.refImg, imref2d(size(app.refImg)), ...
                    'method', 'blend', 'Parent', app.UIAxes);
            else
                imshow(img, imref2d(size(img)), 'Parent', app.UIAxes);
            end
            drawnow;
            app.popupWindow(img, src, showCrossHair);
        end
    end

    methods (Access = public)
        function close(app)
            app.RPASToolCalibrationUIFigureCloseRequest();
        end
    end
    
      methods (Access = private)
        
        function stopTimers(app)
            app.xupdater.stop();
            app.yupdater.stop();
            app.zupdater.stop();
        end
        
        function flag=confirmRecord(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
            title = 'Confirm';
            selection = uiconfirm(fig, [msg '?'], title,...
                                   'Options',{'Save','Cancel'},...
                                   'DefaultOption', 1,'CancelOption', 2);

            switch selection
                case {'Save'}
                    flag=true;
                otherwise
                    flag=false;
            end
            delete(fig);
            return;
        end
        
        function init(app)
            
            %read in system setup
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables();
            end
            app.systemVar=systemVar; %read in system setup'
            %app.systemVar=savedVariables;
            
            %for convenience, copy the variables in systemVar
            %to the properties
            var=app.systemVar.motionSetupVar;
            %Home position
            app.Xhome=var.Xhome;
            app.Yhome=var.Yhome;
            app.Zhome=var.Zhome;
            
            %Park position
            app.Xpark = var.XPark;
            app.Ypark = var.YPark;
            app.Zpark = var.ZPark;

            var=app.systemVar.engineeringPar1Var;
            app.imageCaptureTime=max(var.MinTimeBetweenImageCaptures,50)*1.0e-3;
            
            if 0
                var=app.systemVar.motionSysValSetupVar;
                app.XplusLimit = var.XupperLimit; 
                app.XminusLimit = var.XlowerLimit; 
                app.YplusLimit = var.YupperLimit; 
                app.YminusLimit = var.YlowerLimit; 
                app.ZplusLimit = var.ZupperLimit; 
                app.ZminusLimit = var.ZlowerLimit;
            else
                var=app.systemVar.motionSetupVar;
                app.XplusLimit = var.Xlimit_Plus;
                app.XminusLimit = var.Xlimit_Minus;
                app.YplusLimit = var.Ylimit_Plus;
                app.YminusLimit = var.Ylimit_Minus;
                app.ZplusLimit = var.Zlimit_Plus;
                app.ZminusLimit = var.Zlimit_Minus;
            end
            
            app.IndZOffsetEditField.Editable=false;
            app.ToolCalPresentXPosEditField.Editable=false;
            app.ToolCalPresentYPosEditField.Editable=false;
            app.ToolCalPresentZPosEditField.Editable=false;

            app.IndZOffsetEditField.Value = ...
                app.systemVar.toolCalibrationVar.ZOffset;

            str=[];
            str{1}='Green only if Present X Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.XminusLimit, app.XplusLimit);
            app.XLamp.Tooltip=str;

            str{1}='Green only if Present Y Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.YminusLimit, app.YplusLimit);
            app.YLamp.Tooltip=str;

            str{1}='Green only if Present Z Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.ZminusLimit, app.ZplusLimit);
            app.ZLamp.Tooltip=str;

            %start timers
            app.xupdater=updatePosition(app.ToolCalPresentXPosEditField, 0, .05, ...
                {app.XLamp}, {app.XplusLimit, app.XminusLimit}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater=updatePosition(app.ToolCalPresentYPosEditField, 1, .05, ...
                {app.YLamp}, {app.YplusLimit, app.YminusLimit}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater=updatePosition(app.ToolCalPresentZPosEditField, 2, .05, ...
                {app.ZLamp}, {app.ZplusLimit, app.ZminusLimit}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1);
            app.stopTimers();

            app.imageCaptureEnabled=false;

        end
        
        function updateRecords(app, rcdName)
            try
                var=app.systemVar.toolCalibrationVar;
                switch rcdName
                    case app.rcdNames{1}
                        var.ZOffset=app.IndZOffsetEditField.Value;
                    case app.rcdNames{2}
                        var.SWD_Xc=app.ToolCalPresentXPosEditField.Value;
                        var.SWD_Yc=app.ToolCalPresentYPosEditField.Value;
                    case app.rcdNames{3}
                        var.LWD_Xc=app.ToolCalPresentXPosEditField.Value;
                        var.LWD_Yc =app.ToolCalPresentYPosEditField.Value;
                    otherwise
                        error('Unsupported variable to record.');
                end

                app.systemVar.updateRecords(var, app.RPASToolCalibrationUIFigure.Tag);
            catch EM
                app.stopTimers();
                rethrow(EM);
            end
        end
        
        function updateCrossHair(app, sz)
            img=255*ones(sz(1), sz(2));
            ct=floor((sz+1)/2);
            %horizontal hair
            for dt=1:20:sz(2)
                img(ct(1)-1:ct(1)+1,dt:min(dt+9, sz(2)))=0;
            end
            %vertical hair
            for dt=1:20:sz(1)
                img(dt:min(dt+9, sz(1)),ct(2)-1:ct(2)+1)=0;
            end
            app.refImg=uint8(img);
            drawnow;
        end
        
        function popupWindow(app, img, src, showCrossHair)
            hd=figure(1);
            hd.Name='Captured Image';
            gca.Title.String=src;
            gca.XLim=[0, size(img,2)];
            gca.YLim=[0, size(img,1)];
            mX=round(size(img,2)/100);
            dx=round(mX/6)*100;
            xtik=[0, dx, 2*dx, 3*dx, 4*dx, 5*dx, min(6*dx, mX*100)];
            gca.XTick=xtik;
            xtikLabel={};
            for j=1:numel(xtik)
                xtikLabel{end+1}=num2str(xtik(j));
            end
            gca.XTickLabel=xtikLabel;
            mY=round(size(img,1)/100);
            dy=round(mY/4)*100;
            ytik=[0, dy, 2*dy, 3*dy, min(4*dy, mY*100)];
            gca.YTick=ytik;
            ytikLabel={};
            for j=1:numel(ytik)
                ytikLabel{end+1}=num2str(ytik(j));
            end
            gca.YTickLabel=ytikLabel;
            gca.YAxisLocation='left';
            gca.XAxisLocation='bottom';
            if showCrossHair
                app.updateCrossHair(size(img));
                imshowpair(img, imref2d(size(img)), ...
                app.refImg, imref2d(size(app.refImg)), ...
                'method', 'blend');
            else
                imshow(img, imref2d(size(img)));
            end
            drawnow;
        end


        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, PlusLamp, MinusLamp)

            try
                val=round(val*1.0e3)*1.0e-3;
                upperLimit=round(upperLimit*1.0e6)*1.0e-6;
                lowerLimit=round(lowerLimit*1.0e6)*1.0e-6;
                c1=PlusLamp.Color;
                c2=MinusLamp.Color;
                warning=false;
                if val>upperLimit
                    PlusLamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if val<lowerLimit
                    MinusLamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
                PlusLamp.Color=c1;
                MinusLamp.Color=c2;
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
        end
        
        function captureImage(app)
            app.imageCaptureEnabled=true;
            fprintf(1,'captureImage: app.imageCaptureEnabled=%d\n', ...
                app.imageCaptureEnabled)
            if RPAS_Constants.HardwareAvailable
                RPAS_ImageAcquisition_7dot1(2,0, app.UIAxes, app.imageCaptureTime);
            else
                path=[RPAS_Constants().RPAS_HOME '/testImages/AlignMagImages'];
                if 0
                    files={'450µm-2.5b-off-Focus-adj-noise-640.bmp', ...
                        '450µm-2.5b-off-Focus-adj-noise-640-1.05.bmp', ...
                        '850µm-7b-off-Focus-adj-noise-640.bmp', ...
                        'InFocus-2° rotated.bmp', ...
                        'InFocus-adj-noise-640-0.4°.bmp', ...
                        'InFocusdj+noise-1.02.bmp', ...
                        'InFocus-FFT-of-final02-640-2adjnois-ot-0.95.bmp'};
                else
                    files={'Inverse FFT of final021024-2adj+noise90°-1.tif', ...
                        'Inverse FFT of final021024-2adj+noise87°-1.bmp', ...
                        'Inverse FFT of final021024-2adj+noise93°-1.bmp'};
                end
                while isvalid(app) & app.imageCaptureEnabled
                    k=max(round(rand*numel(files)),1);
                    fnm=[path '/' files{k} ];
                    img=imread(fnm);

                    app.UIAxes.XLim=[0, size(img,2)];
                    app.UIAxes.YLim=[0, size(img,1)];
                    tik=0:100:600;
                    tikLbl={};
                    for k=1:numel(tik)
                        tikLbl{k}=num2str(tik(k)*RPAS_Constants.Pixel_img_plane*1.0e-3);
                    end
                    app.UIAxes.XTick=tik;
                    app.UIAxes.XTickLabel=tikLbl;
                    app.UIAxes.YTick=tik(1:5);
                    app.UIAxes.YTickLabel=tikLbl(1:5);
            
                    R=imref2d(size(img));
                    imshow(img, R,'Parent', app.UIAxes);
                    pause(app.imageCaptureTime);
                end
                return;
            end
        end
        
        function stopCaptureImage(app)
            global stopICBSignal;
            stopICBSignal=1;

            app.imageCaptureEnabled=false;
            pause(2*app.imageCaptureTime); %wait for a while for ICB power down
            fprintf(1,'after stopCaptureImage: app.imageCaptureEnabled=%d\n', ...
                app.imageCaptureEnabled)
        end
      end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateCrossHair([app.height, app.width]);
            imshow(app.refImg, imref2d(size(app.refImg)), ...
                'Parent', app.UIAxes);
            drawnow;
            app.init();
        end

        % Button pushed function: RECORDASZOffsetButton
        function RECORDASZOffsetButtonPushed(app, event)
           if isAuthorized()
                app.IndZOffsetEditField.Value=app.ToolCalPresentZPosEditField.Value;
                app.updateRecords(app.rcdNames{1});
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
           end
        end

        % Button pushed function: ToolCalGoToHOMEButton
        function ToolCalGoToHOMEButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1,2];
            dist=[app.Xhome app.Yhome app.Zhome];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: GoToPARKButton
        function GoToPARKButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1,2];
            dist=[app.Xpark app.Ypark app.Zpark];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: ToolCalincrementXButton
        function ToolCalincrementXButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalPresentXPosEditField.Value + ...
                    app.ToolCalRelativeXMoveEditField.Value;
            if app.isValidPosition(val, app.XplusLimit, app.XminusLimit, ...
                    app.XLamp, app.XLamp)
                RelativeMoving(0, app.ToolCalRelativeXMoveEditField.Value, speed, app.xupdater);
            end
        end

        % Button pushed function: ToolCalStartButtonAbsX
        function ToolCalStartButtonAbsXPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalAbsoluteXMoveEditField.Value;
            if app.isValidPosition(val, app.XplusLimit, app.XminusLimit, ...
                    app.XLamp, app.XLamp)
                ABSMoving(0, val, speed, app.xupdater);
            end
        end

        % Button pushed function: ToolCalDecrementXButton
        function ToolCalDecrementXButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalPresentXPosEditField.Value - ...
                    app.ToolCalRelativeXMoveEditField.Value;
            if app.isValidPosition(val, app.XplusLimit, app.XminusLimit, ...
                    app.XLamp, app.XLamp)
                RelativeMoving(0, -app.ToolCalRelativeXMoveEditField.Value, speed, app.xupdater);
            end
        end

        % Button pushed function: RecordasSWDXcYcButton
        function RecordasSWDXcYcButtonPushed(app, event)
             if isAuthorized()
                app.updateRecords(app.rcdNames{2});
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
             end
        end

        % Button pushed function: RecordasLWDXcYcButton
        function RecordasLWDXcYcButtonPushed(app, event)
            if isAuthorized()
                app.updateRecords(app.rcdNames{3});
            else
                sz=get(0,'ScreenSize');
                ct=[sz(3)/2, sz(4)/2];
                w=300;h=160;
                fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            
                msg = {'You are not authorized!'};
                title = 'Error';
                selection = uiconfirm(fig, msg, title,...
                                   'Options',{'OK'},...
                                   'icon', 'Error');

                switch selection
                    case {'OK'}
                        %confirmed
                    otherwise
                        %do nothing
                end
                delete(fig);
            end
        end

        % Button pushed function: GotoSWDXcYcButton
        function GotoSWDXcYcButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1];
            var=app.systemVar.toolCalibrationVar;
            dist=[var.SWD_Xc var.SWD_Yc];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater]);
        end

        % Close request function: RPASToolCalibrationUIFigure
        function RPASToolCalibrationUIFigureCloseRequest(app, event)
            app.STOPMOTIONButtonPushed();
            if app.imageCaptureEnabled
                app.stopCaptureImage();
            end
            delete(app)
            
        end

        % Button pushed function: ToolCalDecrementYButton
        function ToolCalDecrementYButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalPresentYPosEditField.Value - ...
                    app.ToolCalRelativeYMoveEditField.Value;
            if app.isValidPosition(val, app.YplusLimit, app.YminusLimit, ...
                    app.YLamp, app.YLamp)
                RelativeMoving(1, -app.ToolCalRelativeYMoveEditField.Value, speed, app.yupdater);
            end
        end

        % Button pushed function: ToolCalDecrementZButton
        function ToolCalDecrementZButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalPresentZPosEditField.Value - ...
                    app.ToolCalRelativeZMoveEditField.Value;
            if app.isValidPosition(val, app.ZplusLimit, app.ZminusLimit, ...
                    app.ZLamp, app.ZLamp)
                RelativeMoving(2, -app.ToolCalRelativeZMoveEditField.Value, speed, app.zupdater);
            end
        end

        % Button pushed function: ToolCalincrementYButton
        function ToolCalincrementYButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalPresentYPosEditField.Value + ...
                    app.ToolCalRelativeYMoveEditField.Value;
            if app.isValidPosition(val, app.YplusLimit, app.YminusLimit, ...
                    app.YLamp, app.YLamp)
                RelativeMoving(1, app.ToolCalRelativeYMoveEditField.Value, speed, app.yupdater);
            end
        end

        % Button pushed function: ToolCalincrementZButton
        function ToolCalincrementZButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalPresentZPosEditField.Value + ...
                    app.ToolCalRelativeZMoveEditField.Value;
            if app.isValidPosition(val, app.ZplusLimit, app.ZminusLimit, ...
                    app.ZLamp, app.ZLamp)
                RelativeMoving(2, app.ToolCalRelativeZMoveEditField.Value, speed, app.zupdater);
            end
        end

        % Button pushed function: ToolCalStartButtonAbsY
        function ToolCalStartButtonAbsYPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalAbsoluteYMoveEditField.Value;
            if app.isValidPosition(val, app.YplusLimit, app.YminusLimit, ...
                    app.YLamp, app.YLamp)
                ABSMoving(1, val, speed, app.yupdater);
            end
        end

        % Button pushed function: ToolCalStartButtonAbsZ
        function ToolCalStartButtonAbsZPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            val=app.ToolCalAbsoluteZMoveEditField.Value;
            if app.isValidPosition(val, app.ZplusLimit, app.ZminusLimit, ...
                    app.ZLamp, app.ZLamp)
                ABSMoving(2, val, speed, app.zupdater);
            end
        end

        % Button pushed function: STOPMOTIONButton
        function STOPMOTIONButtonPushed(app, event)
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
        end

        % Button pushed function: GoToZOffsetButton
        function GoToZOffsetButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.toolCalibrationVar;
            ABSMoving(2, var.ZOffset, speed, app.zupdater);
        end

        % Button pushed function: GotoLWDXcYcButton
        function GotoLWDXcYcButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            axes=[0,1];
            var=app.systemVar.toolCalibrationVar;
            dist=[var.LWD_Xc var.LWD_Yc];
            ABSMoving(axes, dist, speed, [app.xupdater, app.yupdater]);
        end

        % Button pushed function: GotoSWDZnomButton
        function GotoSWDZnomButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.engineeringPar1Var;
            ABSMoving(2, var.SWDNominalZ, speed, app.zupdater);
        end

        % Button pushed function: GotoLWDZnomButton
        function GotoLWDZnomButtonPushed(app, event)
            speed = app.systemVar.engineeringPar1Var.ManualMotionSpeed;
            var=app.systemVar.engineeringPar1Var;
            ABSMoving(2, var.LWDNominalZ, speed, app.zupdater);
        end

        % Button pushed function: BackToMainScreenButton
        function BackToMainScreenButtonPushed(app, event)
            app.RPASToolCalibrationUIFigureCloseRequest();
        end

        % Button pushed function: SnapShot
        function SnapShotButtonPushed(app, event)
            fprintf(1,'SnapShotButtonPushed: app.imageCaptureEnabled=%d\n', ...
                app.imageCaptureEnabled)
            tmpflag=app.imageCaptureEnabled;
            if tmpflag
                %stop video
                 app.stopCaptureImage();
            end
             
            if RPAS_Constants.HardwareAvailable
                img=RPAS_ImageAcquisition_7dot1(2,1);
            else
                global kindex;
                path=[RPAS_Constants().RPAS_HOME '/testImages/AlignMagImages/'];
                if 0
                    files={'450µm-2.5b-off-Focus-adj-noise-640.bmp', ...
                        '450µm-2.5b-off-Focus-adj-noise-640-1.05.bmp', ...
                        '850µm-7b-off-Focus-adj-noise-640.bmp', ...
                        'InFocus-2° rotated.bmp', ...
                        'InFocus-adj-noise-640-0.4°.bmp', ...
                        'InFocusdj+noise-1.02.bmp', ...
                        'InFocus-FFT-of-final02-640-2adjnois-ot-0.95.bmp'};
                else
                    files={'Inverse FFT of final021024-2adj+noise90°-1.tif', ...
                        'Inverse FFT of final021024-2adj+noise87°-1.bmp', ...
                        'Inverse FFT of final021024-2adj+noise93°-1.bmp'};
                end


                if isempty(kindex)
                    kindex=1;
                else
                    kindex=mod(kindex+1,numel(files))+1;
                end

                fnm=[path  files{kindex} ];
                img=imread(fnm);
                name=files{kindex};
            end

            path=[RPAS_Constants().RPAS_HOME '/' RPAS_Constants.QUAL_RESULT_DIR '/ToolCalib'];
            RPAS_Make_folder(path);
    
            dt=datetime();
            datetimestring=datestr(dt, 'mmm_dd_yyyy_HH_MM_SS');
            fnm=[path '/Telescope_snapshot_DateTime=' datetimestring '.png'];
            imwrite(img,fnm);
            app.displayImage(img,'',0);

            %resume video
            if tmpflag
                app.captureImage();
            end
        end

        % Button pushed function: video_OnOffButton
        function video_OnOffButtonPushed(app, event)
            fprintf(1,'video_OnOffButtonPushed: app.imageCaptureEnabled=%d\n', ...
                app.imageCaptureEnabled)
            if app.imageCaptureEnabled
                app.stopCaptureImage();
            else
                app.captureImage();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASToolCalibrationUIFigure = uifigure('Visible', 'off');
            app.RPASToolCalibrationUIFigure.Name = 'RPAS Tool Calibration';
            app.RPASToolCalibrationUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASToolCalibrationUIFigureCloseRequest, true);
            app.RPASToolCalibrationUIFigure.Tag = 'toolCalibration';
            app.ToolCallibrationPanel = uipanel(app.RPASToolCalibrationUIFigure);
            app.ToolCallibrationPanel.Title = 'Tool Callibration';
            app.UIAxes = uiaxes(app.ToolCallibrationPanel);
            title(app.UIAxes, {'Image Source'; ''})
            xlabel(app.UIAxes, 'Y (Pixels)')
            ylabel(app.UIAxes, 'X (Pixels)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.XTick = [0 100 200 300 400 500 600];
            app.UIAxes.XTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'};
            app.UIAxes.YTick = [0 100 200 300 400];
            app.UIAxes.YTickLabel = {'0'; '100'; '200'; '300'; '400'};
            app.RECORDASZOffsetButton = uibutton(app.ToolCallibrationPanel, 'push');
            app.RECORDASZOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDASZOffsetButtonPushed, true);
            app.RECORDASZOffsetButton.Text = 'RECORD AS Z Offset';
            app.mmLabel_7 = uilabel(app.ToolCallibrationPanel);
            app.mmLabel_7.Text = 'mm';
            app.IndZOffsetEditFieldLabel = uilabel(app.ToolCallibrationPanel);
            app.IndZOffsetEditFieldLabel.Text = 'Ind Z Offset';
            app.IndZOffsetEditField = uieditfield(app.ToolCallibrationPanel, 'numeric');
            app.IndZOffsetEditField.ValueDisplayFormat = '%.3f';
            app.IndZOffsetEditField.Editable = 'off';
            app.GoToZOffsetButton = uibutton(app.ToolCallibrationPanel, 'push');
            app.GoToZOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @GoToZOffsetButtonPushed, true);
            app.GoToZOffsetButton.Text = 'Go To Z Offset';
            app.ToolCalGoToHOMEButton = uibutton(app.ToolCallibrationPanel, 'push');
            app.ToolCalGoToHOMEButton.ButtonPushedFcn = createCallbackFcn(app, @ToolCalGoToHOMEButtonPushed, true);
            app.ToolCalGoToHOMEButton.Text = 'Go To HOME';
            app.GoToPARKButton = uibutton(app.ToolCallibrationPanel, 'push');
            app.GoToPARKButton.ButtonPushedFcn = createCallbackFcn(app, @GoToPARKButtonPushed, true);
            app.GoToPARKButton.Text = 'Go To PARK';
            app.SWDPanel = uipanel(app.ToolCallibrationPanel);
            app.SWDPanel.Title = 'SWD';
            app.RecordasSWDXcYcButton = uibutton(app.SWDPanel, 'push');
            app.RecordasSWDXcYcButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasSWDXcYcButtonPushed, true);
            app.RecordasSWDXcYcButton.Text = {'Record as SWD'; 'Xc & Yc'};
            app.GotoSWDXcYcButton = uibutton(app.SWDPanel, 'push');
            app.GotoSWDXcYcButton.ButtonPushedFcn = createCallbackFcn(app, @GotoSWDXcYcButtonPushed, true);
            app.GotoSWDXcYcButton.Text = {'Go to SWD'; 'Xc Yc'};
            app.GotoSWDZnomButton = uibutton(app.SWDPanel, 'push');
            app.GotoSWDZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GotoSWDZnomButtonPushed, true);
            app.GotoSWDZnomButton.Text = {'Go to SWD'; 'Znom'};
            app.STOPMOTIONButton = uibutton(app.ToolCallibrationPanel, 'push');
            app.STOPMOTIONButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMOTIONButtonPushed, true);
            app.STOPMOTIONButton.Text = {'STOP'; 'MOTION'};
            app.BackToMainScreenButton = uibutton(app.ToolCallibrationPanel, 'push');
            app.BackToMainScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMainScreenButtonPushed, true);
            app.BackToMainScreenButton.Text = 'Back To Main Screen';
            app.PresentXPosPanel = uipanel(app.ToolCallibrationPanel);
            app.PresentXPosPanel.Title = 'Present X Pos';
            app.ToolCalPresentXPosEditField = uieditfield(app.PresentXPosPanel, 'numeric');
            app.ToolCalPresentXPosEditField.ValueDisplayFormat = '%.3f';
            app.ToolCalPresentXPosEditField.Editable = 'off';
            app.mmLabel_8 = uilabel(app.PresentXPosPanel);
            app.mmLabel_8.Text = 'mm';
            app.PresentZPosPanel = uipanel(app.ToolCallibrationPanel);
            app.PresentZPosPanel.Title = 'Present Z Pos';
            app.ToolCalPresentZPosEditField = uieditfield(app.PresentZPosPanel, 'numeric');
            app.ToolCalPresentZPosEditField.ValueDisplayFormat = '%.3f';
            app.ToolCalPresentZPosEditField.Editable = 'off';
            app.mmLabel_9 = uilabel(app.PresentZPosPanel);
            app.mmLabel_9.Text = 'mm';
            app.PresentYPosPanel = uipanel(app.ToolCallibrationPanel);
            app.PresentYPosPanel.Title = 'Present Y Pos';
            app.ToolCalPresentYPosEditField = uieditfield(app.PresentYPosPanel, 'numeric');
            app.ToolCalPresentYPosEditField.ValueDisplayFormat = '%.3f';
            app.ToolCalPresentYPosEditField.Editable = 'off';
            app.mmLabel_10 = uilabel(app.PresentYPosPanel);
            app.mmLabel_10.Text = 'mm';
            app.TravelLimitLabel = uilabel(app.ToolCallibrationPanel);
            app.TravelLimitLabel.Text = 'Travel Limit';
            app.XLabel = uilabel(app.ToolCallibrationPanel);
            app.XLabel.Text = 'X';
            app.XLamp = uilamp(app.ToolCallibrationPanel);
            app.YLampLabel = uilabel(app.ToolCallibrationPanel);
            app.YLampLabel.Text = 'Y';
            app.YLamp = uilamp(app.ToolCallibrationPanel);
            app.ZLampLabel = uilabel(app.ToolCallibrationPanel);
            app.ZLampLabel.Text = 'Z';
            app.ZLamp = uilamp(app.ToolCallibrationPanel);
            app.RelativeXMovePanel = uipanel(app.ToolCallibrationPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.mmLabel_11 = uilabel(app.RelativeXMovePanel);
            app.mmLabel_11.Text = 'mm';
            app.ToolCalincrementXButton = uibutton(app.RelativeXMovePanel, 'push');
            app.ToolCalincrementXButton.ButtonPushedFcn = createCallbackFcn(app, @ToolCalincrementXButtonPushed, true);
            app.ToolCalincrementXButton.Text = '+';
            app.ToolCalDecrementXButton = uibutton(app.RelativeXMovePanel, 'push');
            app.ToolCalDecrementXButton.ButtonPushedFcn = createCallbackFcn(app, @ToolCalDecrementXButtonPushed, true);
            app.ToolCalDecrementXButton.Text = '-';
            app.ToolCalRelativeXMoveEditField = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.ToolCalRelativeXMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteXMovePanel = uipanel(app.ToolCallibrationPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.ToolCalAbsoluteXMoveEditField = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.ToolCalAbsoluteXMoveEditField.ValueDisplayFormat = '%5.3f';
            app.ToolCalStartButtonAbsX = uibutton(app.AbsoluteXMovePanel, 'push');
            app.ToolCalStartButtonAbsX.ButtonPushedFcn = createCallbackFcn(app, @ToolCalStartButtonAbsXPushed, true);
            app.ToolCalStartButtonAbsX.Text = 'Start';
            app.mmLabel_12 = uilabel(app.AbsoluteXMovePanel);
            app.mmLabel_12.Text = 'mm';
            app.RelativeYMovePanel = uipanel(app.ToolCallibrationPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.mmLabel_13 = uilabel(app.RelativeYMovePanel);
            app.mmLabel_13.Text = 'mm';
            app.ToolCalincrementYButton = uibutton(app.RelativeYMovePanel, 'push');
            app.ToolCalincrementYButton.ButtonPushedFcn = createCallbackFcn(app, @ToolCalincrementYButtonPushed, true);
            app.ToolCalincrementYButton.Text = '+';
            app.ToolCalDecrementYButton = uibutton(app.RelativeYMovePanel, 'push');
            app.ToolCalDecrementYButton.ButtonPushedFcn = createCallbackFcn(app, @ToolCalDecrementYButtonPushed, true);
            app.ToolCalDecrementYButton.Text = '-';
            app.ToolCalRelativeYMoveEditField = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.ToolCalRelativeYMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteYMovePanel = uipanel(app.ToolCallibrationPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.ToolCalAbsoluteYMoveEditField = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.ToolCalAbsoluteYMoveEditField.ValueDisplayFormat = '%5.3f';
            app.ToolCalStartButtonAbsY = uibutton(app.AbsoluteYMovePanel, 'push');
            app.ToolCalStartButtonAbsY.ButtonPushedFcn = createCallbackFcn(app, @ToolCalStartButtonAbsYPushed, true);
            app.ToolCalStartButtonAbsY.Text = 'Start';
            app.mmLabel_14 = uilabel(app.AbsoluteYMovePanel);
            app.mmLabel_14.Text = 'mm';
            app.RelativeZMovePanel = uipanel(app.ToolCallibrationPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.mmLabel_15 = uilabel(app.RelativeZMovePanel);
            app.mmLabel_15.Text = 'mm';
            app.ToolCalincrementZButton = uibutton(app.RelativeZMovePanel, 'push');
            app.ToolCalincrementZButton.ButtonPushedFcn = createCallbackFcn(app, @ToolCalincrementZButtonPushed, true);
            app.ToolCalincrementZButton.Text = '+';
            app.ToolCalDecrementZButton = uibutton(app.RelativeZMovePanel, 'push');
            app.ToolCalDecrementZButton.ButtonPushedFcn = createCallbackFcn(app, @ToolCalDecrementZButtonPushed, true);
            app.ToolCalDecrementZButton.Text = '-';
            app.ToolCalRelativeZMoveEditField = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.ToolCalRelativeZMoveEditField.ValueDisplayFormat = '%5.3f';
            app.AbsoluteZMovePanel = uipanel(app.ToolCallibrationPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.ToolCalAbsoluteZMoveEditField = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.ToolCalAbsoluteZMoveEditField.ValueDisplayFormat = '%5.3f';
            app.ToolCalStartButtonAbsZ = uibutton(app.AbsoluteZMovePanel, 'push');
            app.ToolCalStartButtonAbsZ.ButtonPushedFcn = createCallbackFcn(app, @ToolCalStartButtonAbsZPushed, true);
            app.ToolCalStartButtonAbsZ.Text = 'Start';
            app.mmLabel_16 = uilabel(app.AbsoluteZMovePanel);
            app.mmLabel_16.Text = 'mm';
            app.LWDPanel = uipanel(app.ToolCallibrationPanel);
            app.LWDPanel.Title = 'LWD';
            app.RecordasLWDXcYcButton = uibutton(app.LWDPanel, 'push');
            app.RecordasLWDXcYcButton.ButtonPushedFcn = createCallbackFcn(app, @RecordasLWDXcYcButtonPushed, true);
            app.RecordasLWDXcYcButton.Text = {'Record as LWD'; 'Xc & Yc'};
            app.GotoLWDXcYcButton = uibutton(app.LWDPanel, 'push');
            app.GotoLWDXcYcButton.ButtonPushedFcn = createCallbackFcn(app, @GotoLWDXcYcButtonPushed, true);
            app.GotoLWDXcYcButton.Text = {'Go to LWD'; 'Xc Yc'};
            app.GotoLWDZnomButton = uibutton(app.LWDPanel, 'push');
            app.GotoLWDZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GotoLWDZnomButtonPushed, true);
            app.GotoLWDZnomButton.Text = {'Go to LWD'; 'Znom'};
            app.SnapShot = uibutton(app.ToolCallibrationPanel, 'push');
            app.SnapShot.ButtonPushedFcn = createCallbackFcn(app, @SnapShotButtonPushed, true);
            app.SnapShot.Text = {'Save snapshot'; ''};
            app.video_OnOffButton = uibutton(app.ToolCallibrationPanel, 'push');
            app.video_OnOffButton.ButtonPushedFcn = createCallbackFcn(app, @video_OnOffButtonPushed, true);
            app.video_OnOffButton.Text = {'Turn On/Off '; 'Telescope Video'; ''};
            % Show the figure after all components are created
            app.RPASToolCalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_Tool_Calib

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASToolCalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASToolCalibrationUIFigure)
        end
    end
end