classdef RPAS_TwinScanQual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RPASQualificationwTWINSCANUIFigure  matlab.ui.Figure
        RPASQualificationwTWINSCANPanel  matlab.ui.container.Panel
        TestPoint                     matlab.ui.control.NumericEditField
        TestPointiEditFieldLabel      matlab.ui.control.Label
        ResultsPanel                  matlab.ui.container.Panel
        GotoQualResultsButton         matlab.ui.control.Button
        PassFailLamp                  matlab.ui.control.Lamp
        PassFailLampLabel             matlab.ui.control.Label
        SubmitTWINSCANMeasurementButton  matlab.ui.control.Button
        TwinscanMeasuredLabel         matlab.ui.control.Label
        RzmradLabel_2                 matlab.ui.control.Label
        RzValue                       matlab.ui.control.NumericEditField
        YumLabel_2                    matlab.ui.control.Label
        YValue                        matlab.ui.control.NumericEditField
        XumLabel_2                    matlab.ui.control.Label
        XValue                        matlab.ui.control.NumericEditField
        StartTWINSCANValidationroutineButton  matlab.ui.control.Button
        StartTWINSCANPrequalroutineButton  matlab.ui.control.Button
        ZLamp                         matlab.ui.control.Lamp
        ZLamp_2Label                  matlab.ui.control.Label
        YLamp                         matlab.ui.control.Lamp
        YLamp_2Label                  matlab.ui.control.Label
        XLamp                         matlab.ui.control.Lamp
        XLamp_2Label                  matlab.ui.control.Label
        TravelLimitLabel              matlab.ui.control.Label
        BacktoMasterQualScreenButton  matlab.ui.control.Button
        AbortTestButton               matlab.ui.control.Button
        PauseResumeButton             matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        Operator                      matlab.ui.control.EditField
        OperatorEditFieldLabel        matlab.ui.control.Label
        PRAS_ID                       matlab.ui.control.EditField
        RPASIDEditFieldLabel          matlab.ui.control.Label
        ImageCaptureDataCollectionInfoLabel  matlab.ui.control.Label
        WDSwitch                      matlab.ui.control.Switch
        PresentYPosPanel              matlab.ui.container.Panel
        mmLabel_9                     matlab.ui.control.Label
        PresentYPos                   matlab.ui.control.NumericEditField
        PresentZPosPanel              matlab.ui.container.Panel
        mmLabel_8                     matlab.ui.control.Label
        PresentZPos                   matlab.ui.control.NumericEditField
        PresentXPosPanel              matlab.ui.container.Panel
        mmLabel_7                     matlab.ui.control.Label
        PresentXPos                   matlab.ui.control.NumericEditField
        ReticlePositionPanel          matlab.ui.container.Panel
        RelativeXMovePanel            matlab.ui.container.Panel
        Rel_X                         matlab.ui.control.NumericEditField
        Xminus                        matlab.ui.control.Button
        mmLabel_15                    matlab.ui.control.Label
        Xplus                         matlab.ui.control.Button
        AbsoluteXMovePanel            matlab.ui.container.Panel
        Abs_X                         matlab.ui.control.NumericEditField
        mmLabel_14                    matlab.ui.control.Label
        XStart                        matlab.ui.control.Button
        RelativeYMovePanel            matlab.ui.container.Panel
        Rel_Y                         matlab.ui.control.NumericEditField
        Yminus                        matlab.ui.control.Button
        Yplus                         matlab.ui.control.Button
        mmLabel_13                    matlab.ui.control.Label
        AbsoluteYMovePanel            matlab.ui.container.Panel
        Abs_Y                         matlab.ui.control.NumericEditField
        mmLabel_12                    matlab.ui.control.Label
        YStart                        matlab.ui.control.Button
        RelativeZMovePanel            matlab.ui.container.Panel
        Rel_Z                         matlab.ui.control.NumericEditField
        Zminus                        matlab.ui.control.Button
        Zplus                         matlab.ui.control.Button
        mmLabel_11                    matlab.ui.control.Label
        AbsoluteZMovePanel            matlab.ui.container.Panel
        Abs_Z                         matlab.ui.control.NumericEditField
        ZStart                        matlab.ui.control.Button
        mmLabel_10                    matlab.ui.control.Label
        GoToSWDXcYcZnomButton         matlab.ui.control.Button
        GoToLWDXcYcZnomButton         matlab.ui.control.Button
        GoToPARKButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OverLimitColor=[0.6, 0, 0];
        UnderLimitColor=[0, 1, 0.5];
        FailColor=[0.6, 0, 0];
        PassColor=[0, 1, 0.5];
        xupdater  % timer to query present x position
        yupdater % timer to query present y position
        zupdater % timer to query present z position
        manualQualMethod=1;
        autoQualMethod = 0;

        manualSpeed;
        autoSpeed;
        movingSpeed;

        qualMethod;

%         currentPos;
        currentTestPoint;
        positionSheet;
        pointsFinished;
        
        systemVar; % variable hold system configurations
        Park_X;
        Park_Y;
        Park_Z;
        SWD_Xc;
        SWD_Yc;
        SWD_Znorm;
        LWD_Xc;
        LWD_Yc;
        LWD_Znorm;

        Xlimit_plus;
        Xlimit_minus;
        Ylimit_plus;
        Ylimit_minus;
        Zlimit_plus;
        Zlimit_minus;

        capturedImg; % Description
        SWD_qualImages;
        LWD_qualImages;
        spotList;
        bwImg;
        thresHold; % Description
        matchedResult; % result and info for single image
        matchedInfo; 
        SWDqualResults; 
        LWDqualResults; 
        SWDtwinscanResults;
        LWDtwinscanResults;
        SWDinfos;
        LWDinfos
        settingRecords;
        imageName; % Description
        
        
%         MaxXErr;
%         MaxYErr;
%         MaxRzErr;% Description
        
        pauseFlag; % Description
        previousStatus;
        isFrozen;
        
%         resultImageHandle; % Description
        resultHandle;
        qualFinished;
        twinFinished;
        twinFlag;
        
        % variables used to bookmark the aborting status;
        register;
%         outerAbortFlag;
    end
    properties (Access=public)
        motionStatus;
    end
    
    methods (Access = private)  
        function settings=recordSettings(app)
            settings=[];
            
            %properties
            settings.manualQualMethod=app.manualQualMethod;
            settings.autoQualMethod =app.autoQualMethod;
    
            settings.manualSpeed=app.manualSpeed;
            settings.autoSpeed=app.autoSpeed;
            settings.movingSpeed=app.movingSpeed;

            settings.qualMethod=app.qualMethod;
            
%            settings.currentPos = app.currentPos;
            settings.currentTestPoint = app.currentTestPoint;
            settings.positionSheet = app.positionSheet;
            settings.pointsFinished = app.pointsFinished;
    
            settings.Park_X=app.Park_X;
            settings.Park_Y=app.Park_Y;
            settings.Park_Z=app.Park_Z;
            settings.SWD_Xc=app.SWD_Xc;
            settings.SWD_Yc=app.SWD_Yc;
            settings.SWD_Znorm=app.SWD_Znorm;
            settings.LWD_Xc=app.LWD_Xc;
            settings.LWD_Yc=app.LWD_Yc;
            settings.LWD_Znorm=app.LWD_Znorm;
            
            settings.Xlimit_plus=app.Xlimit_plus;
            settings.Xlimit_minus=app.Xlimit_minus;
            settings.Ylimit_plus=app.Ylimit_plus;
            settings.Ylimit_minus=app.Ylimit_minus;
            settings.Zlimit_plus=app.Zlimit_plus;
            settings.Zlimit_minus=app.Zlimit_minus;
            
            settings.capturedImg = app.capturedImg; 
            settings.SWD_qualImages = app.SWD_qualImages;
            settings.LWD_qualImages = app.LWD_qualImages;
            settings.spotList = app.spotList;
            settings.bwImg = app.bwImg;
            settings.thresHold = app.thresHold; % Description
            settings.matchedResult = app.matchedResult; % result and info for single image
            settings.matchedInfo = app.matchedInfo; 
            settings.SWDqualResults = app.SWDqualResults; % results and infos for auto qual
            settings.LWDqualResults = app.LWDqualResults; % results and infos for auto qual
            settings.SWDtwinscanResults = app.SWDtwinscanResults; % results and infos for auto qual
            settings.LWDtwinscanResults = app.LWDtwinscanResults; % results and infos for auto qual
            settings.SWDinfos = app.SWDinfos;
            settings.LWDinfos = app.LWDinfos;
            settings.settingRecords = app.settingRecords;
            settings.imageName = app.imageName; % Description
            
            % variable flag for pausing
            settings.pauseFlag=app.pauseFlag; % Description
            settings.previousStatus=app.previousStatus;
            settings.isFrozen=app.isFrozen;
            settings.resultHandle=app.resultHandle;
            settings.qualFinished=app.qualFinished;
            settings.twinFinished=app.twinFinished;
            
            settings.register = app.register;
%             settings.outerAbortFlag = app.outerAbortFlag;

            %components
            settings.PRAS_ID.Value = app.PRAS_ID.Value;
            settings.PRAS_ID.Enable=app.PRAS_ID.Enable;
            
            settings.Operator.Value = app.Operator.Value;
            settings.Operator.Enable = app.Operator.Enable;
            
            settings.WDSwitch.Value = app.WDSwitch.Value;
            settings.WDSwitch.Enable = app.WDSwitch.Enable;
            
            
            settings.Rel_X.Value=app.Rel_X.Value;
            settings.Rel_X.Enable=app.Rel_X.Enable;
            
            settings.Abs_X.Value=app.Abs_X.Value;
            settings.Abs_X.Enable=app.Abs_X.Enable;
        
            settings.Rel_Y.Value=app.Rel_Y.Value;
            settings.Rel_Y.Enable=app.Rel_Y.Enable;
            
            settings.Abs_Y.Value=app.Abs_Y.Value;
            settings.Abs_Y.Enable=app.Abs_Y.Enable;
            
            settings.Rel_Z.Value=app.Rel_Z.Value;
            settings.Rel_Z.Enable=app.Rel_Z.Enable;
            
            settings.Abs_Z.Value=app.Abs_Z.Value;
            settings.Abs_Z.Enable=app.Abs_Z.Enable;
            
            settings.Xplus.Enable=app.Xplus.Enable;
            
            settings.Xminus.Enable=app.Xminus.Enable;
            
            settings.XStart.Enable=app.XStart.Enable;
        
            settings.Yplus.Enable=app.Yplus.Enable;
            
            settings.Yminus.Enable=app.Yminus.Enable;
            
            settings.YStart.Enable=app.YStart.Enable;
            
            settings.Zplus.Enable=app.Zplus.Enable;
            
            settings.Zminus.Enable=app.Zminus.Enable;
            
            settings.ZStart.Enable=app.ZStart.Enable;
            
            settings.GoToLWDXcYcZnomButton.Enable=app.GoToLWDXcYcZnomButton.Enable;
            settings.GoToPARKButton.Enable=app.GoToPARKButton.Enable;
            settings.GoToSWDXcYcZnomButton.Enable=app.GoToSWDXcYcZnomButton.Enable;
        
            settings.PauseResumeButton.Enable=app.PauseResumeButton.Enable;
            settings.AbortTestButton.Enable=app.AbortTestButton.Enable;
            settings.BacktoMasterQualScreenButton.Enable=app.BacktoMasterQualScreenButton.Enable;
            
            settings.XLamp.Color=app.XLamp.Color;
            settings.YLamp.Color=app.YLamp.Color;
            settings.ZLamp.Color=app.ZLamp.Color;
            
            
            settings.TestPoint.Value = app.TestPoint.Value;
            
            
            settings.StartTWINSCANPrequalroutineButton.Enable=...
                app.StartTWINSCANPrequalroutineButton.Enable;
            settings.StartTWINSCANValidationroutineButton.Enable=...
                app.StartTWINSCANValidationroutineButton.Enable;
            
            settings.SubmitTWINSCANMeasurementButton.Enable= ...
                app.SubmitTWINSCANMeasurementButton.Enable;
            settings.GotoQualResultsButton.Enable= ...
                app.GotoQualResultsButton.Enable;
            
            settings.XValue.Value=app.XValue.Value;
            settings.XValue.Enable=app.XValue.Enable;
            
            settings.YValue.Value=app.YValue.Value;
            settings.YValue.Enable=app.YValue.Enable;
            
            settings.RzValue.Value=app.RzValue.Value;
            settings.RzValue.Enable=app.RzValue.Enable;
            
            
            settings.PassFailLamp.Color=app.PassFailLamp.Color;
            
            settings.image=(getimage(app.UIAxes))';
        end
        
        function enableMovingComponents(app, val)
            app.WDSwitch.Enable=val;
            app.Xplus.Enable=val;
            app.Xminus.Enable=val;
            app.XStart.Enable=val;
            app.Yplus.Enable=val;
            app.Yminus.Enable=val;
            app.YStart.Enable=val;
            app.Zplus.Enable=val;
            app.Zminus.Enable=val;
            app.ZStart.Enable=val;
            app.GoToLWDXcYcZnomButton.Enable=val;
            app.GoToPARKButton.Enable=val;
            app.GoToSWDXcYcZnomButton.Enable=val;
            app.BacktoMasterQualScreenButton.Enable=val;
            app.Rel_X.Enable=val;
            app.Abs_X.Enable=val;
            app.Rel_Y.Enable=val;
            app.Abs_Y.Enable=val;
            app.Rel_Z.Enable=val;
            app.Abs_Z.Enable=val;
        end
    end

    methods (Access = public)    
        function init(app)
            global systemVar;
            if isempty(systemVar)
                systemVar=savedVariables(); %read in system setup
            end
            app.systemVar=systemVar;
            %app.systemVar=savedVariables(); %read in system setup

            var=app.systemVar.motionSetupVar;
            app.Park_X=var.XPark;
            app.Park_Y=var.YPark;
            app.Park_Z=var.ZPark;
            app.Xlimit_plus = var.Xlimit_Plus;
            app.Xlimit_minus = var.Xlimit_Minus;
            app.Ylimit_plus = var.Ylimit_Plus;
            app.Ylimit_minus = var.Ylimit_Minus;
            app.Zlimit_plus = var.Zlimit_Plus;
            app.Zlimit_minus = var.Zlimit_Minus;
            
            var=app.systemVar.engineeringPar1Var;
            app.SWD_Znorm = var.SWDNominalZ;
            app.LWD_Znorm = var.LWDNominalZ;

            app.manualSpeed=var.ManualMotionSpeed;
            app.autoSpeed = var.AutoQualScanSpeed;
            app.movingSpeed = app.manualSpeed;
            app.qualMethod = app.manualQualMethod;
            
            %read in test table
            var=app.systemVar.engineeringPar3Var;
            app.positionSheet = RPAS_ReadDataSheet(var.twinscanFnm);
            
            var=app.systemVar.toolCalibrationVar;
            app.SWD_Xc = var.SWD_Xc;
            app.SWD_Yc = var.SWD_Yc;
            app.LWD_Xc = var.LWD_Xc;
            app.LWD_Yc = var.LWD_Yc;
            
            %initialize lamp color
            app.XLamp.Color=app.UnderLimitColor;
            app.YLamp.Color=app.UnderLimitColor;
            app.ZLamp.Color=app.UnderLimitColor;
            
            app.PassFailLamp.Color=app.PassColor;

            app.TestPoint.Value=0;
            
            %disable buttons
            
            app.StartTWINSCANPrequalroutineButton.Enable=true;
            app.StartTWINSCANValidationroutineButton.Enable=false;
            app.SubmitTWINSCANMeasurementButton.Enable=false;
            app.GotoQualResultsButton.Enable=false;
            
            app.XValue.Value=0;
            app.YValue.Value=0;
            app.RzValue.Value=0;


            app.capturedImg=[];
            app.SWD_qualImages=struct();
            app.LWD_qualImages=struct();

            app.XValue.Enable=false;
            app.YValue.Enable=false;
            app.RzValue.Enable=false;
            
            %initialize pausing related variables
            app.pauseFlag=false;
            app.isFrozen=false;
            app.qualFinished=false;
            app.twinFinished=false;
            app.twinFlag=false;

            if isempty(app.register) 
                app.register=processRegister(app, 0.5);
            elseif isvalid(app.register)
                app.register.reset();
            else
                app.register=processRegister(app, 0.5);
            end

%             app.outerAbortFlag = false;

            %initialize motion speed;
            app.qualMethod =app.autoQualMethod;
            
            app.previousStatus.StartTWINSCANPrequalroutineButtonValue = ...
                    app.StartTWINSCANPrequalroutineButton.Enable;
            app.previousStatus.StartTWINSCANValidationroutineButtonValue= ...
                app.StartTWINSCANValidationroutineButton.Enable;
            app.previousStatus.SubmitTWINSCANMeasurementButtonValue = ...
                    app.SubmitTWINSCANMeasurementButton.Enable;
            app.previousStatus.GotoQualResultsButtonValue = ...
                    app.GotoQualResultsButton.Enable;
            
            app.enableMovingComponents(true);

            str=[];
            str{1}='Green only if Present X Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Xlimit_minus, app.Xlimit_plus);
            app.XLamp.Tooltip=str;

            str{1}='Green only if Present Y Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Ylimit_minus, app.Ylimit_plus);
            app.YLamp.Tooltip=str;

            str{1}='Green only if Present Z Pos';
            str{2}=sprintf('is between %.2f and %.2f',...
                app.Zlimit_minus, app.Zlimit_plus);
            app.ZLamp.Tooltip=str;
            
            app.xupdater=updatePosition(app.PresentXPos, 0, .05, ...
                {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.yupdater=updatePosition(app.PresentYPos, 1, .05, ...
                {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
            app.zupdater=updatePosition(app.PresentZPos, 2, .05, ...
                {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                {app.OverLimitColor, app.UnderLimitColor});
           
            app.xupdater.start();
            app.yupdater.start();
            app.zupdater.start();

            pause(0.1);
            app.stopTimer();

             if app.PresentXPos.Value==app.LWD_Xc && ...
                     app.PresentYPos.Value==app.LWD_Yc && ...
                     app.PresentZPos.Value==app.LWD_Znorm
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
             else
                 app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
             end
        end

        function stopTimer(app)
            if ~isempty(app.xupdater)
                app.xupdater.stop();
            end
            if ~isempty(app.yupdater)
                app.yupdater.stop();
            end
            if ~isempty(app.zupdater)
                app.zupdater.stop();
            end
        end
    end

    methods (Access = private)
        
        function results = isValidPosition(app, val, ...
                upperLimit, lowerLimit, Lamp)
            try
                val=round(val*1.0e3)*1.0e-3;
                upperLimit=round(upperLimit*1.0e6)*1.0e-6;
                lowerLimit=round(lowerLimit*1.0e6)*1.0e-6;
                c=Lamp.Color;
                warning=false;
                Lamp.Color=app.UnderLimitColor;

                if val>upperLimit || val<lowerLimit
                    Lamp.Color=app.OverLimitColor;
                    warning=true;
                end
                if warning
                    sz=get(0,'ScreenSize');
                    ct=[sz(3)/2, sz(4)/2];
                    w=300;h=160;
                    fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
                
                    msg = {'Operation will exceed Travel Limit', ...
                         'Do you want to move'};
                    title = 'Warning';
                    selection = uiconfirm(fig, msg, title,...
                                       'Options',{'Yes','No'},...
                                       'DefaultOption', 2, ...
                                       'CancelOption', 2, ...
                                       'icon', 'warning');
    
                    switch selection
                        case {'Yes'}
                            results=true;
                        otherwise
                            results=false;
                    end
                    delete(fig);
                else
                    results=true;
                end
            catch EM
                app.stopTimer();
                rethrow(EM);
            end
            Lamp.Color=c;
        end
        
        
        function [img, name] = captureImage(app, cameraSource)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            
            if RPAS_C.CameraAvailable
                img = RPAS_ImageAcquisition_7dot1(cameraSource, 1);
                if cameraSource==1 % SWD
                    name='SWD_MSensor';
                else
                    name='LWD_MSensor';
                end
            else
                global tmpIndex;
                if isempty(tmpIndex)
                    tmpIndex=0;
                end
                tmpIndex=mod(tmpIndex, 2)+1;
                
                path=[RPAS_C.QUAL_IMAGE_DIR '/PgmImages/'];
                files={'IBframe_M00000.pgm', 'IBframe_P00000.pgm', ...
                    'IBframe_M00001.pgm', 'IBframe_P00001.pgm', ...
                    'IBframe_M00002.pgm', 'IBframe_P00002.pgm'};
                fnm=[path '\' files{tmpIndex}];
                img=PRAD_readPgmFile(fnm);
                name=files{tmpIndex};
            end
        end
        
        function displayImage(app, img, fnm)
            global RPAS_C
            if isempty(RPAS_C)
                RPAS_C=RPAS_Constants(parentDir(pwd));
            end
            
            fnm=replace(fnm,'_', ' ');
            img=img';
            %app.UIAxes.Title.String = fnm;
            app.UIAxes.XLim=[0, size(img,2)];
            app.UIAxes.YLim=[0, size(img,1)];
            tik=0:100:600;
            tikLbl={};
            for k=1:numel(tik)
                tikLbl{k}=num2str(tik(k)*RPAS_C.Pixel_img_plane*1.0e-3);
            end
            app.UIAxes.XTick=tik;
            app.UIAxes.YTick=tik(1:5);
            
            
            R=imref2d(size(img));
            imshow(img, R,'Parent', app.UIAxes);
            
            drawnow;
        end


        function displayThresholdImg(app)

            fnm=['Threshold=' num2str(app.thresHold)];
            img=app.bwImg';
            
            R=imref2d(size(img));
            imshow(img, R,'Parent', app.UIAxes);
            app.UIAxes.NextPlot='add';
             plot3(app.spotList.sPosEstimate(:,1)+1, ...
                 app.spotList.sPosEstimate(:,2) +1, ...
                 app.spotList.surface(:), 'o', ...
                 'MarkerSize',30, 'MarkerEdgeColor', 'r', ...
                 'LineWidth',2, ...
                 'Parent', app.UIAxes);
            app.UIAxes.NextPlot='replace';
             
            drawnow;
            
        end
        
        function qualification(app)
            global markerPositions;
            global nearestSpotImage;
            global reticlePixel;
            global imagePixel;
            
            app.register.addProcess();

            % if pause is reuired program will pause util it is resumed
            if app.register.isAborting 
                return;
            end
            app.checkPause();
            
            % find the spots
            [app.spotList, app.bwImg, app.thresHold]= ...
                PRAD_computeSpotList(app.capturedImg);
            
            % check pause again;
            if app.register.isAborting 
                return;
            end
            app.checkPause();
            
            % display Threhold image
            app.displayThresholdImg();
            
            % check pause again;
            if app.register.isAborting 
                return;
            end
            app.checkPause();
            
            if strfind(app.imageName, '_P')
                sensor='sensor_P';
                defaultRotation=pi * 0;
            else
                sensor='sensor_M';
                defaultRotation=pi * 1;
            end
            
            % check pause again;
            if app.register.isAborting 
                return;
            end
            app.checkPause();
            
            defaultFlip=2;
            [app.matchedResult, app.matchedInfo] = PRAD_estimate_SingleImg( ...
                app.spotList, sensor, defaultFlip, defaultRotation, ...
                markerPositions, nearestSpotImage);
            
            % check pause again;
            if app.register.isAborting 
                return;
            end
            app.checkPause();

            if abs(app.matchedResult.mInD.angle*180/pi)>90
                if defaultRotation==0
                    defaultRotation=pi*1;
                else
                    defaultRotation=0;
                end
                [app.matchedResult, app.matchedInfo] = PRAD_estimate_SingleImg( ...
                    app.spotList, sensor, defaultFlip, defaultRotation, ...
                    markerPositions, nearestSpotImage);
            end

            % check pause again;
            if app.register.isAborting 
                return;
            end
            app.checkPause();
            
            %converting to reticle values
            app.matchedResult.mInD.vect=round(app.matchedResult.mInD.vect* ...
                reticlePixel/imagePixel*1.0e6,2); %converting to um
            app.matchedResult.mInD.angle=round(app.matchedResult.mInD.angle * ...
                1.0e3,2); % converting to mrad
            app.matchedResult.mInD.dist(:,1:2) = ...
                round(app.matchedResult.mInD.dist(:,1:2)* ...
                reticlePixel/imagePixel*1.0e6,2); %converting to um
            app.matchedResult.mInD.dist(:,3) = ...
                round(app.matchedResult.mInD.dist(:,3)*1.0e3,2); % converting to mrad
                
            
            %record qualified points and results
            index=app.TestPoint.Value;
            
            val = [app.matchedResult.mInD.vect(1:2), ...
                app.matchedResult.mInD.angle];
            val=[val val-app.positionSheet.Expected(index,:)];
            if app.WDSwitch.Value==1 % SWD
                app.SWDqualResults(index, :) = val;
                app.SWDinfos{index} = app.matchedInfo;
            else
                app.LWDqualResults(index, :) = val;
                app.LWDinfos{index} = app.matchedInfo;
            end
            
            app.settingRecords{index} = ...
                app.recordSettings();

            app.register.removeProcess();
        end
        
        function freeze(app, val)
            val=~val;
            
            app.WDSwitch.Enable=val;
            app.Xplus.Enable=val;
            app.Xminus.Enable=val;
            app.XStart.Enable=val;
            
            app.Yplus.Enable=val;
            app.Yminus.Enable=val;
            app.YStart.Enable=val;
            
            app.Zplus.Enable=val;
            app.Zminus.Enable=val;
            app.ZStart.Enable=val;
            
            app.GoToLWDXcYcZnomButton.Enable=val;
            app.GoToSWDXcYcZnomButton.Enable=val;
            app.GoToPARKButton.Enable=val;
            
            app.BacktoMasterQualScreenButton.Enable=val;
            
            if val
                app.StartTWINSCANPrequalroutineButton.Enable= ...
                    app.previousStatus.StartTWINSCANPrequalroutineButtonValue;
                app.StartTWINSCANValidationroutineButton.Enable= ...
                    app.previousStatus.StartTWINSCANValidationroutineButtonValue;
                app.SubmitTWINSCANMeasurementButton.Enable= ...
                    app.previousStatus.SubmitTWINSCANMeasurementButtonValue;

                app.XValue.Enable=app.previousStatus.XValue;
                app.YValue.Enable=app.previousStatus.YValue;
                app.RzValue.Enable=app.previousStatus.RzValue;
            else
                app.previousStatus.StartTWINSCANPrequalroutineButtonValue = ...
                    app.StartTWINSCANPrequalroutineButton.Enable;
                app.StartTWINSCANPrequalroutineButton.Enable=val;
            
                app.previousStatus.StartTWINSCANValidationroutineButtonValue = ...
                    app.StartTWINSCANValidationroutineButton.Enable;
                app.StartTWINSCANValidationroutineButton.Enable=val;
                
                app.previousStatus.SubmitTWINSCANMeasurementButtonValue = ...
                    app.SubmitTWINSCANMeasurementButton.Enable;
                app.SubmitTWINSCANMeasurementButton.Enable = val;
                
                app.previousStatus.GotoQualResultsButtonValue = ...
                    app.GotoQualResultsButton.Enable;
                app.GotoQualResultsButton.Enable = val;

                app.previousStatus.XValue=app.XValue.Enable;
                app.XValue.Enable=val;

                app.previousStatus.YValue=app.YValue.Enable;
                app.YValue.Enable=val;

                app.previousStatus.RzValue=app.RzValue.Enable;
                app.RzValue.Enable=val;
            end
            
            app.Rel_X.Editable=val;
            app.Abs_X.Editable=val;
            
            app.Rel_Y.Editable=val;
            app.Abs_Y.Editable=val;
            
            app.Rel_Z.Editable=val;
            app.Abs_Z.Editable=val;
            
            app.PRAS_ID.Enable=val;
            app.Operator.Enable=val;
        end
        
        function checkPause(app)
            while app.pauseFlag
                pause(0.1);
            end
        end

        function result=showInfoMsg2(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=420; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Info';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'Done', 'Cancel'}, ...
                'icon', 'info');
            switch selection
                case {'Done'}
                    result=true;
                otherwise
                    result=false;
            end
            delete(fig);
        end
        
        function showInfoMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300; h=160;
            fig=uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);
            title='Info';
            selection=uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'icon', 'info');
            switch selection
                case {'OK'}
                otherwise
            end
            delete(fig);
        end

        
        function position = selectTestSheet(app, testPoint)
            %get absolute moving position
            position=app.positionSheet.Stage(testPoint,:);
            if app.WDSwitch.Value==1 %SWD
                position=position+[app.SWD_Xc, app.SWD_Yc, app.SWD_Znorm];
            else
                position=position+[app.LWD_Xc, app.LWD_Yc, app.LWD_Znorm];
            end
        end
        
        function warningMsg(app, msg)
            sz=get(0,'ScreenSize');
            ct=[sz(3)/2, sz(4)/2];
            w=300;h=160;
            fig = uifigure('position',[ct(1)-w/2, ct(2)-h/2, w, h]);

            title = 'Warning';
            selection = uiconfirm(fig, msg, title,...
                                       'Options',{'OK'},...
                                       'icon', 'warning');
            switch selection
                case {'OK'}
                    %donothing;
                otherwise
                    %donothing;
            end
            delete(fig);
        end
        
        function results = moveTo(app, pos)
            app.register.addProcess();

            % check pause
            if app.register.isAborting 
                return;
            end
            app.checkPause();

            if app.positionSheet.BI(pos) == 1 %test enabled
                if app.qualMethod == app.manualQualMethod
                    app.movingSpeed=app.manualSpeed;
                elseif app.qualMethod== app.autoQualMethod
                    app.movingSpeed=app.autoSpeed;
                else
                    error('Unsupported Qualification method.')
                end
                
                
                % check pause
                if app.register.isAborting 
                    return;
                end
                app.checkPause();
                
                %set test point to current position
                app.TestPoint.Value=app.positionSheet.TestPoint(pos);

                % moving to position p(i)
                axes=[0, 1, 2];
                position=app.selectTestSheet(pos);
                ABSMoving(axes, position, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            else
                app.warningMsg('Test not enabled. Select next one.');
            end

            app.register.removeProcess();
        end
    end
    
    methods(Access = public)

        function hide(app, val)
            if val
                app.stopTimer();
            else
                app.xupdater.start();
                app.yupdater.start();
                app.zupdater.start();
            end
            app.RPASQualificationwTWINSCANUIFigure.Visible=~val;
        end

        function close(app)
            app.RPASQualificationwTWINSCANUIFigureCloseRequest;
        end

        function setAutoQualMethod(app)
            app.qualMethod=app.autoQualMethod;
        end
        
        function setManualQualMethod(app)
            app.qualMethod=app.manualQualMethod;
        end
        
        function restoreSettings(app, settings)
            
            %properties
            app.manualQualMethod=settings.manualQualMethod;
            app.autoQualMethod =settings.autoQualMethod;
    
            app.manualSpeed=settings.manualSpeed;
            app.autoSpeed=settings.autoSpeed;
            app.movingSpeed=settings.movingSpeed;

            app.qualMethod=settings.qualMethod;
            
%            app.currentPos = app.currentPos;
            app.currentTestPoint = settings.currentTestPoint;
            app.positionSheet = settings.positionSheet;
            app.pointsFinished = settings.pointsFinished;
    
            app.Park_X=settings.Park_X;
            app.Park_Y=settings.Park_Y;
            app.Park_Z=settings.Park_Z;
            app.SWD_Xc=settings.SWD_Xc;
            app.SWD_Yc=settings.SWD_Yc;
            app.SWD_Znorm=settings.SWD_Znorm;
            app.LWD_Xc=settings.LWD_Xc;
            app.LWD_Yc=settings.LWD_Yc;
            app.LWD_Znorm=settings.LWD_Znorm;
            
            app.Xlimit_plus=settings.Xlimit_plus;
            app.Xlimit_minus=settings.Xlimit_minus;
            app.Ylimit_plus=settings.Ylimit_plus;
            app.Ylimit_minus=settings.Ylimit_minus;
            app.Zlimit_plus=settings.Zlimit_plus;
            app.Zlimit_minus=settings.Zlimit_minus;
            
            app.capturedImg = settings.capturedImg; 
            app.SWD_qualImages = settings.SWD_qualImages;
            app.LWD_qualImages = settings.LWD_qualImages;
            app.spotList = settings.spotList;
            app.bwImg = settings.bwImg;
            app.thresHold = settings.thresHold; % Description
            app.matchedResult = settings.matchedResult; % result and info for single image
            app.matchedInfo = settings.matchedInfo; 

            app.SWDqualResults = settings.SWDqualResults;
            app.LWDqualResults = settings.LWDqualResults; % results and infos for auto qual
            app.SWDtwinscanResults=settings.SWDtwinscanResults;
            app.LWDtwinscanResults=settings.LWDtwinscanResults;
            app.SWDinfos = settings.SWDinfos;
            app.LWDinfos = settings.LWDinfos;
            app.settingRecords = settings.settingRecords;
            app.imageName = apsettingsp.imageName; % Description
            
            % variable flag for pausing
            app.pauseFlag=settings.pauseFlag; % Description
            app.previousStatus=settings.previousStatus;
            app.isFrozen=settings.isFrozen;
            app.resultHandle=settings.resultHandle;
            app.qualFinished=settings.qualFinished;
            app.twinFinished=settings.twinFinished;
            
            app.register = settings.register;
%             app.outerAbortFlag = settings.outerAbortFlag;

            %components
            app.PRAS_ID.Value = settings.PRAS_ID.Value;
            app.PRAS_ID.Enable=settings.PRAS_ID.Enable;
            
            app.Operator.Value = settings.Operator.Value;
            app.Operator.Enable = settings.Operator.Enable;
            
            app.WDSwitch.Value = settings.WDSwitch.Value;
            app.WDSwitch.Enable = settings.WDSwitch.Enable;
            
            
            app.Rel_X.Value=settings.Rel_X.Value;
            app.Rel_X.Enable=settings.Rel_X.Enable;
            
            app.Abs_X.Value=settings.Abs_X.Value;
            app.Abs_X.Enable=settings.Abs_X.Enable;
        
            app.Rel_Y.Value=settings.Rel_Y.Value;
            app.Rel_Y.Enable=settings.Rel_Y.Enable;
            
            app.Abs_Y.Value=settings.Abs_Y.Value;
            app.Abs_Y.Enable=settings.Abs_Y.Enable;
            
            app.Rel_Z.Value=settings.Rel_Z.Value;
            app.Rel_Z.Enable=settings.Rel_Z.Enable;
            
            app.Abs_Z.Value=settings.Abs_Z.Value;
            app.Abs_Z.Enable=settings.Abs_Z.Enable;
            
            app.Xplus.Enable=settings.Xplus.Enable;
            
            app.Xminus.Enable=settings.Xminus.Enable;
            
            app.XStart.Enable=settings.XStart.Enable;
        
            app.Yplus.Enable=settings.Yplus.Enable;
            
            app.Yminus.Enable=settings.Yminus.Enable;
            
            app.YStart.Enable=settings.YStart.Enable;
            
            app.Zplus.Enable=settings.Zplus.Enable;
            
            app.Zminus.Enable=settings.Zminus.Enable;
            
            app.ZStart.Enable=settings.ZStart.Enable;
            
            app.GoToLWDXcYcZnomButton.Enable=settings.GoToLWDXcYcZnomButton.Enable;
            app.GoToPARKButton.Enable=settings.GoToPARKButton.Enable;
            app.GoToSWDXcYcZnomButton.Enable=settings.GoToSWDXcYcZnomButton.Enable;
        
            app.PauseResumeButton.Enable=settings.PauseResumeButton.Enable;
            app.AbortTestButton.Enable=settings.AbortTestButton.Enable;
            app.BacktoMasterQualScreenButton.Enable=settings.BacktoMasterQualScreenButton.Enable;
            
            app.XLamp.Color=settings.XLamp.Color;
            app.YLamp.Color=settings.YLamp.Color;
            app.ZLamp.Color=settings.ZLamp.Color;
            
            
            app.TestPoint.Value = settings.TestPoint.Value;
            
            
            app.StartTWINSCANPrequalroutineButton.Enable=...
                settings.StartTWINSCANPrequalroutineButton.Enable;
            app.StartTWINSCANValidationroutineButton.Enable=...
                settings.StartTWINSCANValidationroutineButton.Enable;
            
            app.SubmitTWINSCANMeasurementButton.Enable= ...
                settings.SubmitTWINSCANMeasurementButton.Enable;
            app.GotoQualResultsButton.Enable= ...
                settings.GotoQualResultsButton.Enable;
            
            app.XValue.Value=settings.XValue.Value;
            app.XValue.Enable=settings.XValue.Enable;
            
            app.YValue.Value=settings.YValue.Value;
            app.YValue.Enable=settings.YValue.Enable;
            
            app.RzValue.Value=settings.RzValue.Value;
            app.RzValue.Enable=settings.RzValue.Enable;
            
            
            app.PassFailLamp.Color=settings.PassFailLamp.Color;
            
            if ~isempty(settings.image)
                %app.displayImage(settings.image, settings.imageName);
                app.displayThresholdImg();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, settings)
            
            if nargin==2
                app.restoreSettings(settings);
                global systemVar;
                if isempty(systemVar)
                    systemVar=savedVariables; %read in system setup
                end
                app.systemVar=systemVar;
                %app.systemVar=savedVariables; %read in system setup
                
                app.xupdater=updatePosition(app.PresentXPos, 0, .3, ...
                    {app.XLamp}, {app.Xlimit_plus, app.Xlimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
                app.yupdater=updatePosition(app.PresentYPos, 1, .3, ...
                    {app.YLamp}, {app.Ylimit_plus, app.Ylimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
                app.zupdater=updatePosition(app.PresentZPos, 2, .3, ...
                    {app.ZLamp}, {app.Zlimit_plus, app.Zlimit_minus}, ...
                    {app.OverLimitColor, app.UnderLimitColor});
            
                app.xupdater.start();
                app.yupdater.start();
                app.zupdater.start();
            else
                app.init();
            end
        end

        % Close request function: RPASQualificationwTWINSCANUIFigure
        function RPASQualificationwTWINSCANUIFigureCloseRequest(app, event)
            if ~isempty(app.register) && isvalid(app.register) ...
                    && app.register.numberOfActiveProcess()>0
                app.showInfoMsg("Please abort test or waiting the test finish before closing.");
                return;
            end
            
            if ~isempty(app.resultHandle) & isvalid(app.resultHandle)
                delete(app.resultHandle);
            end
            if ~isempty(app.register) & isvalid(app.register)
                app.register.delete();
            end

            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            delete(app)
            
        end

        % Button pushed function: Xplus
        function XplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end

            val=app.PresentXPos.Value + app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                RelativeMoving(0, app.Rel_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: Xminus
        function XminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentXPos.Value - app.Rel_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
               RelativeMoving(0, -app.Rel_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: XStart
        function XStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_X.Value;
            if app.isValidPosition(val, app.Xlimit_plus, app.Xlimit_minus, app.XLamp)
                ABSMoving(0, app.Abs_X.Value, app.movingSpeed, app.xupdater, app);
            end
        end

        % Button pushed function: Yplus
        function YplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentYPos.Value + app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, app.Rel_Y.Value, app.movingSpeed, app.yupdater, app);
            end
        end

        % Button pushed function: Yminus
        function YminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentYPos.Value - app.Rel_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                RelativeMoving(1, -app.Rel_Y.Value, app.movingSpeed, app.yupdater, app);
            end
        end

        % Button pushed function: YStart
        function YStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_Y.Value;
            if app.isValidPosition(val, app.Ylimit_plus, app.Ylimit_minus, app.YLamp)
                ABSMoving(1, app.Abs_Y.Value, app.movingSpeed, app.yupdater, app);
            end
        end

        % Button pushed function: Zplus
        function ZplusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentZPos.Value + app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
               RelativeMoving(2, app.Rel_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: Zminus
        function ZminusButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.PresentZPos.Value - app.Rel_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                RelativeMoving(2, -app.Rel_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: ZStart
        function ZStartButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            val=app.Abs_Z.Value;
            if app.isValidPosition(val, app.Zlimit_plus, app.Zlimit_minus, app.ZLamp)
                ABSMoving(2, app.Abs_Z.Value, app.movingSpeed, app.zupdater, app);
            end
        end

        % Button pushed function: GoToLWDXcYcZnomButton
        function GoToLWDXcYcZnomButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.LWD_Xc, app.LWD_Yc, app.LWD_Znorm];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(2);
        end

        % Button pushed function: GoToPARKButton
        function GoToPARKButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.Park_X, app.Park_Y, app.Park_Z];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
        end

        % Button pushed function: GoToSWDXcYcZnomButton
        function GoToSWDXcYcZnomButtonPushed(app, event)
            if app.qualMethod == app.manualQualMethod
                app.movingSpeed=app.manualSpeed;
            elseif app.qualMethod== app.autoQualMethod
                app.movingSpeed=app.autoSpeed;
            else
                error('Unsupported Qualification method.')
            end
            axes=[0, 1, 2];
            dist=[app.SWD_Xc, app.SWD_Yc, app.SWD_Znorm];
            
            ABSMoving(axes, dist, app.movingSpeed, [app.xupdater, app.yupdater, app.zupdater], app);
            app.WDSwitch.Value=app.WDSwitch.ItemsData(1);
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            app.pauseFlag=~app.pauseFlag;
            app.freeze(app.pauseFlag);

            if ~isempty(app.motionStatus)
                if app.motionStatus.isPausing
                    lamps=[];
                    for k=1:numel(app.motionStatus.axes)
                        switch app.motionStatus.axes(k)
                            case {0}
                                lamps=[lamps app.xupdater];
                            case {1}
                                lamps=[lamps app.yupdater];
                            case {2}
                                lamps=[lamps app.zupdater];
                        end
                    end
                    app.motionStatus.isPausing=false;
                    if ~isempty(app.motionStatus.axes)
                        ABSMoving(app.motionStatus.axes, app.motionStatus.movingDistances, ...
                            app.motionStatus.movingSpeeds, lamps, app);
                    end
                else
                    app.motionStatus.isPausing=true;
                    if ~isempty(app.motionStatus.axes)
                        stopMotion([app.xupdater, app.yupdater, app.zupdater], app);
                    end
                end
            end
        end

        % Button pushed function: StartTWINSCANValidationroutineButton
        function StartTWINSCANValidationroutineButtonPushed(app, event)
            %register process
            app.register.addProcess();
            
            msg={'Please switch Ethernet Switch to TWSCN PC.','', ...
                'When ready, press the "Done" button to navigate stage to Position 1.'};
            result=app.showInfoMsg2(msg);
            if ~result
                app.register.removeProcess();
                return;
            end

            
            %disable itself and other buttons during the qualification process
            app.StartTWINSCANValidationroutineButton.Enable=false;
            app.StartTWINSCANPrequalroutineButton.Enable=false;
            app.SubmitTWINSCANMeasurementButton.Enable=false;
            app.GotoQualResultsButton.Enable=false;
            app.WDSwitch.Enable=false;
            app.enableMovingComponents(false);
            
            %begin twin scan;
            % check pause
            if app.register.isAborting 
                return;
            end
            app.checkPause();

            app.twinFinished=false;

            %initialize container
            if app.WDSwitch.Value==1 % SWD
                app.SWDtwinscanResults=[];
            else
                app.LWDtwinscanResults=[];
            end

            for k=1:app.positionSheet.numTestPoints

                % check pause
                if app.register.isAborting 
                    return;
                end
                app.checkPause();

                app.twinFlag=false;
                %move to test point k
                app.moveTo(k);
                
                % check pause
                if app.register.isAborting 
                    return;
                end
                app.checkPause();

                %tell operator moving is is position
                % ready for twin scan qual
                msg=['Stage is in Position ' num2str(k) '.'];
                app.showInfoMsg({msg, 'Ready for Twinscan Qualification.'});
                
                % check pause
                if app.register.isAborting 
                    return;
                end
                app.checkPause();

                %enable text fields and submit button for operator
                %input values
                app.XValue.Enable=true;
                app.YValue.Enable=true;
                app.RzValue.Enable=true;
                app.SubmitTWINSCANMeasurementButton.Enable=true;
                
                % check pause
                if app.register.isAborting 
                    return;
                end
                app.checkPause();

                %waiting for operator input results
                while ~app.twinFlag
                    pause(0.005);
                    
                    %begin twin scan;
                    % check pause
                    if app.register.isAborting 
                        return;
                    end
                    app.checkPause();
                end
            end
            
            % check pause
            if app.register.isAborting 
                return;
            end
            app.checkPause();
            
            %qualification finished
            % enable qual resuls button
            app.twinFinished=true;
            app.GotoQualResultsButton.Enable=true;
            
            %reenable itself
            app.StartTWINSCANValidationroutineButton.Enable=true;
            app.StartTWINSCANPrequalroutineButton.Enable=true;
            app.WDSwitch.Enable=true;
            app.enableMovingComponents(true);
            
            %delete process
            app.register.removeProcess();
        end

        % Button pushed function: AbortTestButton
        function AbortTestButtonPushed(app, event)
            stopMotion([app.xupdater, app.yupdater, app.zupdater]);
            app.motionStatus=struct('axes', [], 'movingSpeeds', [], ...
                'movingDistances', [], 'movingType', '', ...
                'status', '', 'isPausing', false);
            app.pauseFlag=false;
            app.register.Aborting(true);
        end

        % Button pushed function: BacktoMasterQualScreenButton
        function BacktoMasterQualScreenButtonPushed(app, event)
            app.RPASQualificationwTWINSCANUIFigureCloseRequest();
        end

        % Button pushed function: GotoQualResultsButton
        function GotoQualResultsButtonPushed(app, event)
            app.register.addProcess();

            if app.qualFinished 
                if app.twinFinished

                    %begin twin scan;
                    % check pause
                    if app.register.isAborting 
                        return;
                    end
                    app.checkPause();

                    AllErr=app.positionSheet.Error;
                    QUAL=struct('SWDvalue', [], ...
                        'SWDerror', [], ...
                        'SWD_images', [], ...
                        'LWD_images', [], ...
                        'LWDvalue', [], ...
                        'LWDerror', []);
                    TWIN=QUAL;
                    if ~isempty(app.SWDqualResults)
                        QUAL.SWDvalue= app.SWDqualResults(:,1:3);
                        QUAL.SWDerror=app.SWDqualResults(:,4:6);
                        QUAL.SWD_images=app.SWD_qualImages;
                        TWIN.SWDvalue= app.SWDtwinscanResults(:,1:3);
                        TWIN.SWDerror=app.SWDtwinscanResults(:,4:6);
                    end
                    if ~isempty(app.LWDqualResults)
                        QUAL.LWDvalue= app.LWDqualResults(:,1:3);
                        QUAL.LWDerror=app.LWDqualResults(:,4:6);
                        QUAL.LWD_images=app.LWD_qualImages;
                        TWIN.LWDvalue= app.LWDtwinscanResults(:,1:3);
                        TWIN.LWDerror=app.LWDtwinscanResults(:,4:6);
                    end

                    %begin twin scan;
                    % check pause
                    if app.register.isAborting 
                        return;
                    end
                    app.checkPause();

                    if isempty(app.PRAS_ID.Value) || isempty(app.Operator.Value)
                        app.warningMsg('Please input RPAS ID and Operator information.');
                        app.register.removeProcess();
                        return;
                    end
                    operatorInfo=struct('ID', app.PRAS_ID.Value, ...
                        'operator', app.Operator.Value);
                    RPAS_saveTwinScanResults(operatorInfo, AllErr, QUAL, TWIN);

                    app.resultHandle=RPAS_TWINSCANResults(app.WDSwitch.Value, ...
                        AllErr, QUAL, TWIN);

                    if app.resultHandle.Pass()
                        app.PassFailLamp.Color=app.PassColor;
                    else
                        app.PassFailLamp.Color=app.FailColor;
                    end

                    %begin twin scan;
                    % check pause
                    if app.register.isAborting 
                        return;
    
                    end
                    app.checkPause();
                else
                    app.warningMsg("Please finish the TwinScan Qual.");
                    app.register.removeProcess();
                    return;
                end
            else
                if app.twinFinished
                    app.warningMsg("Please finish the RPAS Qual.");
                    app.register.removeProcess();
                    return;
                else
                    app.warningMsg("Please finish the Qualification first.");
                    app.register.removeProcess();
                    return;
                end
            end

            app.register.removeProcess();
        end

        % Button pushed function: SubmitTWINSCANMeasurementButton
        function SubmitTWINSCANMeasurementButtonPushed(app, event)
            app.register.addProcess();

            k=app.TestPoint.Value;
            xx=[app.XValue.Value app.YValue.Value app.RzValue.Value];
            if app.WDSwitch.Value==1 % SWD
                app.SWDtwinscanResults(k,:)=[xx xx-app.positionSheet.Expected(k,:)];
            else
                app.LWDtwinscanResults(k,:)=[xx xx-app.positionSheet.Expected(k,:)];
            end

            %begin twin scan;
            % check pause
            if app.register.isAborting 
                return;
            end
            app.checkPause();

            %disable text fields and itself
            app.XValue.Value=0;
            app.YValue.Value=0;
            app.RzValue.Value=0;
            app.XValue.Enable=false;
            app.YValue.Enable=false;
            app.RzValue.Enable=false;
            app.SubmitTWINSCANMeasurementButton.Enable=false;
            
            %begin twin scan;
            % check pause
            if app.register.isAborting 
                return;
            end
            app.checkPause();

            %Qual for next test point
            app.twinFlag=true;

            app.register.removeProcess();
        end

        % Button pushed function: StartTWINSCANPrequalroutineButton
        function StartTWINSCANPrequalroutineButtonPushed(app, event)
            %register process
            app.register.addProcess();
            
            %disable itself and other buttons during the qualification process
            app.StartTWINSCANPrequalroutineButton.Enable=false;
            app.StartTWINSCANValidationroutineButton.Enable=false;
            app.SubmitTWINSCANMeasurementButton.Enable=false;
            app.GotoQualResultsButton.Enable=false;
            app.enableMovingComponents(false);

            %initialize container
            if app.WDSwitch.Value==1 % SWD
                app.SWDqualResults=[];
            else
                app.LWDqualResults=[];
            end
            
            app.qualFinished=false;
            for k=1:app.positionSheet.numTestPoints
                %moving to test point k
                app.moveTo(k);
                
                % check pause
                if app.register.isAborting 
                    return;
                end
                app.checkPause();
                
                % capture image
                [app.capturedImg, app.imageName]=app.captureImage(app.WDSwitch.Value);
                fieldname=['Test_Point_' num2str(k)];
                if app.WDSwitch.Value==1 % SWD
                    app.SWD_qualImages=setfield(app.SWD_qualImages, fieldname, app.capturedImg);
                else
                    app.LWD_qualImages=setfield(app.LWD_qualImages, fieldname, app.capturedImg);
                end
                
                % check pause
                if app.register.isAborting 
                    return;
                end
                app.checkPause();
            
                % display image
                app.displayImage(app.capturedImg, app.imageName);
                
                % check pause
                if app.register.isAborting 
                    return;
                end
                app.checkPause();
                
                %start qualification
                app.qualification();
            end
            
            app.qualFinished=true;
            %reenable itself
            app.StartTWINSCANPrequalroutineButton.Enable=true;
            app.enableMovingComponents(true);
            
            %enable twinscan qual
            app.StartTWINSCANValidationroutineButton.Enable=true;
            
            %delete process
            app.register.removeProcess();
        end

        % Value changed function: WDSwitch
        function WDSwitchValueChanged(app, event)
            app.StartTWINSCANPrequalroutineButton.Enable=true;
            app.StartTWINSCANValidationroutineButton.Enable=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RPASQualificationwTWINSCANUIFigure = uifigure('Visible', 'off');
            app.RPASQualificationwTWINSCANUIFigure.Name = 'RPAS Qualification w/ TWINSCAN';
            app.RPASQualificationwTWINSCANUIFigure.CloseRequestFcn = createCallbackFcn(app, @RPASQualificationwTWINSCANUIFigureCloseRequest, true);
            app.RPASQualificationwTWINSCANUIFigure.Tag = 'TwinScan';
            app.RPASQualificationwTWINSCANPanel = uipanel(app.RPASQualificationwTWINSCANUIFigure);
            app.RPASQualificationwTWINSCANPanel.Title = 'RPAS Qualification w/ TWINSCAN';
            app.UIAxes = uiaxes(app.RPASQualificationwTWINSCANPanel);
            xlabel(app.UIAxes, 'Y (Pixels)')
            ylabel(app.UIAxes, 'X (Pixels)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.YTick = [0 100 200 300 400];
            app.ReticlePositionPanel = uipanel(app.RPASQualificationwTWINSCANPanel);
            app.ReticlePositionPanel.Title = 'Reticle Position';
            app.GoToPARKButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToPARKButton.ButtonPushedFcn = createCallbackFcn(app, @GoToPARKButtonPushed, true);
            app.GoToPARKButton.Text = 'Go To PARK';
            app.GoToLWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToLWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToLWDXcYcZnomButtonPushed, true);
            app.GoToLWDXcYcZnomButton.Text = 'Go To LWD Xc Yc Znom';
            app.GoToSWDXcYcZnomButton = uibutton(app.ReticlePositionPanel, 'push');
            app.GoToSWDXcYcZnomButton.ButtonPushedFcn = createCallbackFcn(app, @GoToSWDXcYcZnomButtonPushed, true);
            app.GoToSWDXcYcZnomButton.Text = 'Go To SWD Xc Yc Znom';
            app.AbsoluteZMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteZMovePanel.Title = 'Absolute Z Move';
            app.mmLabel_10 = uilabel(app.AbsoluteZMovePanel);
            app.mmLabel_10.Text = 'mm';
            app.ZStart = uibutton(app.AbsoluteZMovePanel, 'push');
            app.ZStart.ButtonPushedFcn = createCallbackFcn(app, @ZStartButtonPushed, true);
            app.ZStart.Text = 'Start';
            app.Abs_Z = uieditfield(app.AbsoluteZMovePanel, 'numeric');
            app.Abs_Z.ValueDisplayFormat = '%.3f';
            app.RelativeZMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeZMovePanel.Title = 'Relative Z Move';
            app.mmLabel_11 = uilabel(app.RelativeZMovePanel);
            app.mmLabel_11.Text = 'mm';
            app.Zplus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zplus.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.Zplus.Text = '+';
            app.Zminus = uibutton(app.RelativeZMovePanel, 'push');
            app.Zminus.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.Zminus.Text = '-';
            app.Rel_Z = uieditfield(app.RelativeZMovePanel, 'numeric');
            app.Rel_Z.ValueDisplayFormat = '%.3f';
            app.AbsoluteYMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteYMovePanel.Title = 'Absolute Y Move';
            app.YStart = uibutton(app.AbsoluteYMovePanel, 'push');
            app.YStart.ButtonPushedFcn = createCallbackFcn(app, @YStartButtonPushed, true);
            app.YStart.Text = 'Start';
            app.mmLabel_12 = uilabel(app.AbsoluteYMovePanel);
            app.mmLabel_12.Text = 'mm';
            app.Abs_Y = uieditfield(app.AbsoluteYMovePanel, 'numeric');
            app.Abs_Y.ValueDisplayFormat = '%.3f';
            app.RelativeYMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeYMovePanel.Title = 'Relative Y Move';
            app.mmLabel_13 = uilabel(app.RelativeYMovePanel);
            app.mmLabel_13.Text = 'mm';
            app.Yplus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yplus.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.Yplus.Text = '+';
            app.Yminus = uibutton(app.RelativeYMovePanel, 'push');
            app.Yminus.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.Yminus.Text = '-';
            app.Rel_Y = uieditfield(app.RelativeYMovePanel, 'numeric');
            app.Rel_Y.ValueDisplayFormat = '%.3f';
            app.AbsoluteXMovePanel = uipanel(app.ReticlePositionPanel);
            app.AbsoluteXMovePanel.Title = 'Absolute X Move';
            app.XStart = uibutton(app.AbsoluteXMovePanel, 'push');
            app.XStart.ButtonPushedFcn = createCallbackFcn(app, @XStartButtonPushed, true);
            app.XStart.Text = 'Start';
            app.mmLabel_14 = uilabel(app.AbsoluteXMovePanel);
            app.mmLabel_14.Text = 'mm';
            app.Abs_X = uieditfield(app.AbsoluteXMovePanel, 'numeric');
            app.Abs_X.ValueDisplayFormat = '%.3f';
            app.RelativeXMovePanel = uipanel(app.ReticlePositionPanel);
            app.RelativeXMovePanel.Title = 'Relative X Move';
            app.Xplus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xplus.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.Xplus.Text = '+';
            app.mmLabel_15 = uilabel(app.RelativeXMovePanel);
            app.mmLabel_15.Text = 'mm';
            app.Xminus = uibutton(app.RelativeXMovePanel, 'push');
            app.Xminus.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.Xminus.Text = '-';
            app.Rel_X = uieditfield(app.RelativeXMovePanel, 'numeric');
            app.Rel_X.ValueDisplayFormat = '%.3f';
            app.PresentXPosPanel = uipanel(app.RPASQualificationwTWINSCANPanel);
            app.PresentXPosPanel.Title = 'Present X Pos';
            app.PresentXPos = uieditfield(app.PresentXPosPanel, 'numeric');
            app.PresentXPos.ValueDisplayFormat = '%.2f';
            app.PresentXPos.Editable = 'off';
            app.mmLabel_7 = uilabel(app.PresentXPosPanel);
            app.mmLabel_7.Text = 'mm';
            app.PresentZPosPanel = uipanel(app.RPASQualificationwTWINSCANPanel);
            app.PresentZPosPanel.Title = 'Present Z Pos';
            app.PresentZPos = uieditfield(app.PresentZPosPanel, 'numeric');
            app.PresentZPos.ValueDisplayFormat = '%.2f';
            app.PresentZPos.Editable = 'off';
            app.mmLabel_8 = uilabel(app.PresentZPosPanel);
            app.mmLabel_8.Text = 'mm';
            app.PresentYPosPanel = uipanel(app.RPASQualificationwTWINSCANPanel);
            app.PresentYPosPanel.Title = 'Present Y Pos';
            app.PresentYPos = uieditfield(app.PresentYPosPanel, 'numeric');
            app.PresentYPos.ValueDisplayFormat = '%.2f';
            app.PresentYPos.Editable = 'off';
            app.mmLabel_9 = uilabel(app.PresentYPosPanel);
            app.mmLabel_9.Text = 'mm';
            app.Panel = uipanel(app.RPASQualificationwTWINSCANPanel);
            app.WDSwitch = uiswitch(app.Panel, 'slider');
            app.WDSwitch.Items = {'SWD', 'LWD'};
            app.WDSwitch.ItemsData = [1 0];
            app.WDSwitch.ValueChangedFcn = createCallbackFcn(app, @WDSwitchValueChanged, true);
            app.WDSwitch.Value = 1;
            app.ImageCaptureDataCollectionInfoLabel = uilabel(app.Panel);
            app.ImageCaptureDataCollectionInfoLabel.Text = {'Image Capture / Data'; 'Collection Info'};
            app.RPASIDEditFieldLabel = uilabel(app.Panel);
            app.RPASIDEditFieldLabel.Text = 'RPAS ID';
            app.PRAS_ID = uieditfield(app.Panel, 'text');
            app.OperatorEditFieldLabel = uilabel(app.Panel);
            app.OperatorEditFieldLabel.Text = 'Operator ';
            app.Operator = uieditfield(app.Panel, 'text');
            app.PauseResumeButton = uibutton(app.RPASQualificationwTWINSCANPanel, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause/Resume';
            app.AbortTestButton = uibutton(app.RPASQualificationwTWINSCANPanel, 'push');
            app.AbortTestButton.ButtonPushedFcn = createCallbackFcn(app, @AbortTestButtonPushed, true);
            app.AbortTestButton.Text = 'Abort Test';
            app.BacktoMasterQualScreenButton = uibutton(app.RPASQualificationwTWINSCANPanel, 'push');
            app.BacktoMasterQualScreenButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMasterQualScreenButtonPushed, true);
            app.BacktoMasterQualScreenButton.Text = {'Back to Master'; 'Qual Screen'; ''};
            app.TravelLimitLabel = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.TravelLimitLabel.Text = 'Travel Limit';
            app.XLamp_2Label = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.XLamp_2Label.Text = 'X';
            app.XLamp = uilamp(app.RPASQualificationwTWINSCANPanel);
            app.YLamp_2Label = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.YLamp_2Label.Text = 'Y';
            app.YLamp = uilamp(app.RPASQualificationwTWINSCANPanel);
            app.ZLamp_2Label = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.ZLamp_2Label.Text = 'Z';
            app.ZLamp = uilamp(app.RPASQualificationwTWINSCANPanel);
            app.StartTWINSCANPrequalroutineButton = uibutton(app.RPASQualificationwTWINSCANPanel, 'push');
            app.StartTWINSCANPrequalroutineButton.ButtonPushedFcn = createCallbackFcn(app, @StartTWINSCANPrequalroutineButtonPushed, true);
            app.StartTWINSCANPrequalroutineButton.Text = {'Start TWINSCAN'; 'Prequal routine'};
            app.StartTWINSCANValidationroutineButton = uibutton(app.RPASQualificationwTWINSCANPanel, 'push');
            app.StartTWINSCANValidationroutineButton.ButtonPushedFcn = createCallbackFcn(app, @StartTWINSCANValidationroutineButtonPushed, true);
            app.StartTWINSCANValidationroutineButton.Text = {'Start TWINSCAN'; 'Validation routine'};
            app.XValue = uieditfield(app.RPASQualificationwTWINSCANPanel, 'numeric');
            app.XValue.ValueDisplayFormat = '%5.2f';
            app.XumLabel_2 = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.XumLabel_2.Text = 'X (um)';
            app.YValue = uieditfield(app.RPASQualificationwTWINSCANPanel, 'numeric');
            app.YValue.ValueDisplayFormat = '%5.2f';
            app.YumLabel_2 = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.YumLabel_2.Text = 'Y (um)';
            app.RzValue = uieditfield(app.RPASQualificationwTWINSCANPanel, 'numeric');
            app.RzValue.ValueDisplayFormat = '%5.2f';
            app.RzmradLabel_2 = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.RzmradLabel_2.Text = 'Rz (mrad)';
            app.TwinscanMeasuredLabel = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.TwinscanMeasuredLabel.Text = 'Twinscan Measured';
            app.SubmitTWINSCANMeasurementButton = uibutton(app.RPASQualificationwTWINSCANPanel, 'push');
            app.SubmitTWINSCANMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitTWINSCANMeasurementButtonPushed, true);
            app.SubmitTWINSCANMeasurementButton.Text = {'Submit'; 'TWINSCAN'; 'Measurement'};
            app.ResultsPanel = uipanel(app.RPASQualificationwTWINSCANPanel);
            app.ResultsPanel.Title = 'Results';
            app.PassFailLampLabel = uilabel(app.ResultsPanel);
            app.PassFailLampLabel.Text = 'Pass/Fail';
            app.PassFailLamp = uilamp(app.ResultsPanel);
            app.GotoQualResultsButton = uibutton(app.ResultsPanel, 'push');
            app.GotoQualResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoQualResultsButtonPushed, true);
            app.GotoQualResultsButton.Text = {'Go to Qual'; 'Results'};
            app.TestPointiEditFieldLabel = uilabel(app.RPASQualificationwTWINSCANPanel);
            app.TestPointiEditFieldLabel.Text = 'Test Point (i)';
            app.TestPoint = uieditfield(app.RPASQualificationwTWINSCANPanel, 'numeric');
            % Show the figure after all components are created
            app.RPASQualificationwTWINSCANUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPAS_TwinScanQual(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RPASQualificationwTWINSCANUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RPASQualificationwTWINSCANUIFigure)
        end
    end
end